classdef Divertor_PnP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CurveStyleSwitch                matlab.ui.control.ToggleSwitch
        CurveStyleLabel                 matlab.ui.control.Label
        FigNumEditField                 matlab.ui.control.NumericEditField
        FigNumEditFieldLabel            matlab.ui.control.Label
        OutputFolderPrefixLcScanEditField  matlab.ui.control.EditField
        OutputFolderPrefixLcScanEditFieldLabel  matlab.ui.control.Label
        FLAREboundaryfilenameEditField  matlab.ui.control.EditField
        boundaryfilenameEditFieldLabel  matlab.ui.control.Label
        MakeFLAREFilesButton            matlab.ui.control.Button
        BufferZonesSlider               matlab.ui.control.Slider
        BufferZonesSliderLabel          matlab.ui.control.Label
        DivertorZonePrefixEditField     matlab.ui.control.EditField
        DivertorZonePrefixEditFieldLabel  matlab.ui.control.Label
        PoloidalPointsEditField         matlab.ui.control.NumericEditField
        PoloidalPointsEditFieldLabel    matlab.ui.control.Label
        ResidueLevelEditField           matlab.ui.control.NumericEditField
        ResidueLevelEditFieldLabel      matlab.ui.control.Label
        ExportSlicesasPNGsButton        matlab.ui.control.Button
        EdgeWallSpinner                 matlab.ui.control.Spinner
        EdgeWallSpinnerLabel            matlab.ui.control.Label
        ZoneProximityLimitEditField     matlab.ui.control.NumericEditField
        ZoneProximityLimitEditFieldLabel  matlab.ui.control.Label
        MaxSectionWidthSpinner          matlab.ui.control.Spinner
        MaxSectionWidthSpinnerLabel     matlab.ui.control.Label
        NumSectionsperZoneSpinner       matlab.ui.control.Spinner
        NumSectionsperZoneSpinnerLabel  matlab.ui.control.Label
        MaxTorExtSpinner                matlab.ui.control.Spinner
        MaxTorExtSpinnerLabel           matlab.ui.control.Label
        MinWidthSpinner                 matlab.ui.control.Spinner
        MinWidthSpinnerLabel            matlab.ui.control.Label
        ExportZonesInPCoTGformatButton  matlab.ui.control.Button
        MakeCurvesButton                matlab.ui.control.Button
        ClearAllSegmentsButton          matlab.ui.control.Button
        MakeADivertorButton             matlab.ui.control.Button
        MakeADivertorPanelButton        matlab.ui.control.Button
        WidthSpinner                    matlab.ui.control.Spinner
        WidthSpinnerLabel               matlab.ui.control.Label
        AimAtAxisSwitch                 matlab.ui.control.Switch
        AimAtAxisSwitchLabel            matlab.ui.control.Label
        AnchortoVesselSwitch            matlab.ui.control.ToggleSwitch
        AnchortoVesselSwitchLabel       matlab.ui.control.Label
        InwardOffsetSpinner             matlab.ui.control.Spinner
        InwardOffsetSpinnerLabel        matlab.ui.control.Label
        UpdateAssistButton              matlab.ui.control.Button
        thetaSpinner                    matlab.ui.control.Spinner
        thetaSpinnerLabel               matlab.ui.control.Label
        ShowSurfDataCheckBox            matlab.ui.control.CheckBox
        AssistSwitchLabel               matlab.ui.control.Label
        AssistSwitch                    matlab.ui.control.Switch
        SurfaceDeltaEditField           matlab.ui.control.NumericEditField
        SurfaceDeltaEditFieldLabel      matlab.ui.control.Label
        PoloidalIndexSpinner            matlab.ui.control.Spinner
        PoloidalIndexSpinnerLabel       matlab.ui.control.Label
        PickCenterSwitch                matlab.ui.control.ToggleSwitch
        PickCenterSwitchLabel           matlab.ui.control.Label
        DeltaEditField                  matlab.ui.control.NumericEditField
        DeltaEditFieldLabel             matlab.ui.control.Label
        UpdateFigureButton              matlab.ui.control.Button
        Z2EditField                     matlab.ui.control.NumericEditField
        Z2EditFieldLabel                matlab.ui.control.Label
        R2EditField                     matlab.ui.control.NumericEditField
        R2EditFieldLabel                matlab.ui.control.Label
        Z1EditField                     matlab.ui.control.NumericEditField
        Z1EditFieldLabel                matlab.ui.control.Label
        R1EditField                     matlab.ui.control.NumericEditField
        R1EditFieldLabel                matlab.ui.control.Label
        SaveSegmentsButton              matlab.ui.control.Button
        LoadSegmentsButton              matlab.ui.control.Button
        SelectSegmentButton             matlab.ui.control.Button
        RemoveSegmentButton             matlab.ui.control.Button
        AddSegmentButton                matlab.ui.control.Button
        PreviousToroidalAngleButton     matlab.ui.control.Button
        NextToroiedalAngleButton        matlab.ui.control.Button
        InitializeDataButton            matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        toroidal_index       % Description
        toroidal_index_max
        toroidal_stepsize
        poloidal_index_max
        segment_information
        interference_pts
        nescin_data_1
        nescin_data_2
        nescin_data_2b
        nescin_data_3
        nescin_data_4
        nescin_data_5
        nescin_data_3cm_wall
        nescin_data_7cm_wall
        surface_3D_1
        surface_3D_2
        surface_3D_2b
        surface_3D_3
        surface_3D_4
        surface_3D_5
        surface_3D_3cm_wall
        surface_3D_7cm_wall
        active_segment_index
        first_point_active
        raxisline
        zaxisline
        distance_map_allfp
        distance_map_1fp
        temp_line_handle_1
        temp_point_handle_1
        ZoneInfo
        ZoneDetails
        CurrentZoneID
        NumberOfZones
        lcolor_list
        curve_information
        vmec_data
        save_to_png
        divertor_section_count
        points_per_section
        magic_number_1
        Lc_map_inset
        Lc_launch_multiplier
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeDataButton
        function InitializeDataButtonPushed(app, event)

            app.magic_number_1 = 12;

            disp('<----Begin Initialization')
            app.save_to_png = 0;
            app.InitializeDataButton.BackgroundColor = [0.86 0.16 0.16];
            pause(0.001)
            tic

            app.first_point_active = 0;
            app.active_segment_index = 0;
            %app.poloidal_index_max =  361;
            app.poloidal_index_max =  app.PoloidalPointsEditField.Value;
            app.curve_information = [];
            app.NumberOfZones = 0;

            if 0
                %set 1
                % The plasms LCFS
                filename_nescin_plasma_1 = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/local_temp/nescin_plasma_reduced_resolution.out';
                % The Divertor.
                %filename_2 = 'nescin_div_friday_1.out';
                filename_nescin_divertor_1 = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_J/nescin_loft_divertor_step_3_M40_N28_Tol1em6.out';

                % 7cm lofted wall
                %filename_nescing_7cm_lofted_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/local_temp/nescin_7cm_loft_from_red_resolution_qhs.out';
                filename_nescin_interference_lofted_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_J/nescin_loft_vessel_minus_31.4mm_M24_N24_Tol1e-5.out';

                % auto-lofted 'far' wall
                filename_nescin_far_lofted_outer_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_J/nescin_loft_vessel_minus_25.4mm_M24_N24_Tol1e-5.out';
                % auto-lofted 'far' wall
                filename_nescin_far_lofted_inner_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_J/nescin_loft_vessel_minus_30.4mm_M24_N24_Tol1e-5.out';

            end

            if 0
                % set 2
                % The plasms LCFS
                filename_nescin_plasma_1 = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/local_temp/nescin_plasma_reduced_resolution.out';
                % The Divertor.
                %filename_2 = 'nescin_div_friday_1.out';
                filename_nescin_divertor_1 = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_J/nescin_loft_divertor_step_3_M40_N28_Tol1em6.out';

                % 7cm lofted wall
                %filename_nescing_7cm_lofted_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/local_temp/nescin_7cm_loft_from_red_resolution_qhs.out';
                %filename_nescin_interference_lofted_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_J/nescin_loft_vessel_minus_31.4mm_M24_N24_Tol1e-5.out';
                filename_nescin_interference_lofted_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_L/nesc_autoloft_minus_15mm_M24_N24_Tol1e-5.out';

                % auto-lofted 'far' wall
                %filename_nescin_far_lofted_outer_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_J/nescin_loft_vessel_minus_25.4mm_M24_N24_Tol1e-5.out';
                filename_nescin_far_lofted_outer_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_L/nesc_autoloft_M24_N24_Tol1e-5.out';
                % auto-lofted 'far' wall
                %filename_nescin_far_lofted_inner_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_J/nescin_loft_vessel_minus_30.4mm_M24_N24_Tol1e-5.out';
                filename_nescin_far_lofted_inner_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_L/nesc_autoloft_minus_5mm_M24_N24_Tol1e-5.out';


            end

            if 0
                % set 3
                % The plasms LCFS
                filename_nescin_plasma_1 = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/local_temp/nescin_plasma_reduced_resolution.out';
                % The Divertor.
                %filename_nescin_divertor_1 = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_L_div/nesc_div_1mm_M24_N44_Tol1e-4.out';
                filename_nescin_divertor_1 = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_L_div/nesc_div_1mm_plus_5mm_M24_N44_Tol1e-4.out';

                % 7cm lofted wall
                filename_nescin_interference_lofted_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_L/nesc_autoloft_minus_15mm_M24_N24_Tol1e-5.out';

                % auto-lofted 'far' wall
                filename_nescin_far_lofted_outer_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_L/nesc_autoloft_M24_N24_Tol1e-5.out';
                % auto-lofted 'far' wall
                filename_nescin_far_lofted_inner_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_L/nesc_autoloft_minus_5mm_M24_N24_Tol1e-5.out';

            end


            if 1
                % set 4


                % The plasms LCFS
                filename_nescin_plasma_1 = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/local_temp/nescin_plasma_reduced_resolution.out';
                % The Divertor.
                %filename_nescin_divertor_1 = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_L_div/nesc_div_1mm_M24_N44_Tol1e-4.out';

                %wow!
                filename_nescin_divertor_1 = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_M_div/nesc_div_1mm_plus_5mm_M24_N44_Tol1e-4.out';
                filename_nescin_divertor_2 = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_M_div/nesc_div_1mm_M24_N44_Tol1e-4.out';
                % not so good: filename_nescin_divertor_1 = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_M_div/nesc_div_1mm_plus_10mm_M24_N44_Tol1e-4.out';

                % 7cm lofted wall
                filename_nescin_interference_lofted_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_L/nesc_autoloft_minus_15mm_M24_N24_Tol1e-5.out';


                % auto-lofted 'far' wall
                filename_nescin_far_lofted_outer_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_L/nesc_autoloft_M24_N24_Tol1e-5.out';
                % auto-lofted 'far' wall
                filename_nescin_far_lofted_inner_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_L/nesc_autoloft_minus_5mm_M24_N24_Tol1e-5.out';


            end


            %
            filename_nescin_3cm_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/nesc_loft_qhs_rstart_1_513_32polmodes_18x24_plus_3cm_M23_N44_Tol1em4.out';
            % lofted from 0.8?
            %filename_nescin_7cm_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/local_temp/nescin_7cm_loft_from_red_resolution_qhs.out';
            % lofted from LCFS
            filename_nescin_7cm_wall = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/wall_7cm_from_qhs_red_res/nescin_qhs_red_res_plus_7cm_M24_N24_Tol1e-5.out';
            % woutfile
            filename_vmec_woutfile = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/wout_Reduced_Resolution.nc';
            % interference points


            filename_interference_points_traces_set_1 = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Trace_Folder/1p6em6_sets_p1_p2_and_1em7.mat';
            filename_interference_points_traces_set_2 = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Trace_Folder/1p6em6_sets_p1_p2.mat';
            filename_interference_points_coils_on_div_grid = '/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_J/Interfernce_Points_Coils_on_Divertor_Grid.mat';


            app.lcolor_list = {'r', 'b', 'g', 'm', 'c',  [.2 .6 .1], [.4 .1 .9], [.75 .1 .4], 'y', [.3 .9 .3], [1 .2 .2], [.2 0 .2], [.8 0 .8], [0.9 0.2 0.1]};


            app.vmec_data = read_vmec(filename_vmec_woutfile);


            disp('<----Read vmec')
            toc


            % if strcmpi(strategy_type, 'neural_network_plates')
            interference_points_set_1 = load(filename_interference_points_traces_set_1); %'Tuesday_Inteference_Points.mat');
            interference_points_set_2 = load(filename_interference_points_traces_set_2); %'Tuesday_Inteference_Points.mat');
            coil_interference_points = load(filename_interference_points_coils_on_div_grid);

            % plates will follow center of 'troughs' that are identfied in this step
            % How are plates defined?
            %  Toroidal points are defined: plate_tor_start, and plate_tor_end,
            %                               plate_tor_middle
            %      These will be defiend for each section of the divertor.
            %      Midpoint and connecting line (vector) are identified and stored.
            %      Local surface curvature (or some similar vector) will be used as the
            %      'inward-directed' normal vector, calculated at/near the Midpoint
            %       the plates
            %  A third direction is defined as the (normalized) cross-product of the
            %  connecting line (vector) and 'inwad-directed' vector
            %  Build plates that exted the full tor_start->tor_end angular rangea
            %poloidal_circ_approx = app.vmec_data.Aminor * 2*pi
            %plate_width = 0.0254  % 2.54 cm
            %plate_width = 1.5*0.0254 % 1.5 inches = 3.81 cm

            % build all toroidal interfence plots
            counter = 0;
            num_cp_set1 = length(interference_points_set_1.ip_data.interferencePoints_trace_cp);
            num_ip_set1 = length(interference_points_set_1.ip_data.interferencePoints_trace_ip);
            num_cp_set2 = length(interference_points_set_2.ip_data.interferencePoints_trace_cp);
            num_ip_set2 = length(interference_points_set_2.ip_data.interferencePoints_trace_ip);

            num_cp2 = length(coil_interference_points.ip_data.interferencePoints_cp);
            num_ip2 = length(coil_interference_points.ip_data.interferencePoints_ip);

            num_ip_between_cp = num_ip_set1 / (num_cp_set1-1);
            num_ip_between_cp2 = num_ip2 / (num_cp2-1);


            for ii = 1:(num_cp_set1-1)
                counter = counter+1;
                app.interference_pts{counter} = [];
                app.interference_pts{counter}.R = interference_points_set_1.ip_data.interferencePoints_trace_cp{ii}.R;
                app.interference_pts{counter}.X = interference_points_set_1.ip_data.interferencePoints_trace_cp{ii}.X;
                app.interference_pts{counter}.Y = interference_points_set_1.ip_data.interferencePoints_trace_cp{ii}.Y;
                app.interference_pts{counter}.Z = interference_points_set_1.ip_data.interferencePoints_trace_cp{ii}.Z;
                app.interference_pts{counter}.R2 = interference_points_set_2.ip_data.interferencePoints_trace_cp{ii}.R;
                app.interference_pts{counter}.X2 = interference_points_set_2.ip_data.interferencePoints_trace_cp{ii}.X;
                app.interference_pts{counter}.Y2 = interference_points_set_2.ip_data.interferencePoints_trace_cp{ii}.Y;
                app.interference_pts{counter}.Z2 = interference_points_set_2.ip_data.interferencePoints_trace_cp{ii}.Z;
                app.interference_pts{counter}.Rc = coil_interference_points.ip_data.interferencePoints_cp{ii}.R;
                app.interference_pts{counter}.Xc = coil_interference_points.ip_data.interferencePoints_cp{ii}.X;
                app.interference_pts{counter}.Yc = coil_interference_points.ip_data.interferencePoints_cp{ii}.Y;
                app.interference_pts{counter}.Zc = coil_interference_points.ip_data.interferencePoints_cp{ii}.Z;
                for jj = 1:num_ip_between_cp
                    counter = counter+1;
                    app.interference_pts{counter} = [];
                    this_index = (ii-1) * num_ip_between_cp + jj;
                    app.interference_pts{counter}.R = interference_points_set_1.ip_data.interferencePoints_trace_ip{this_index}.R;
                    app.interference_pts{counter}.X = interference_points_set_1.ip_data.interferencePoints_trace_ip{this_index}.X;
                    app.interference_pts{counter}.Y = interference_points_set_1.ip_data.interferencePoints_trace_ip{this_index}.Y;
                    app.interference_pts{counter}.Z = interference_points_set_1.ip_data.interferencePoints_trace_ip{this_index}.Z;
                    app.interference_pts{counter}.R2 = interference_points_set_2.ip_data.interferencePoints_trace_ip{this_index}.R;
                    app.interference_pts{counter}.X2 = interference_points_set_2.ip_data.interferencePoints_trace_ip{this_index}.X;
                    app.interference_pts{counter}.Y2 = interference_points_set_2.ip_data.interferencePoints_trace_ip{this_index}.Y;
                    app.interference_pts{counter}.Z2 = interference_points_set_2.ip_data.interferencePoints_trace_ip{this_index}.Z;
                    app.interference_pts{counter}.Rc = coil_interference_points.ip_data.interferencePoints_ip{this_index}.R;
                    app.interference_pts{counter}.Xc = coil_interference_points.ip_data.interferencePoints_ip{this_index}.X;
                    app.interference_pts{counter}.Yc = coil_interference_points.ip_data.interferencePoints_ip{this_index}.Y;
                    app.interference_pts{counter}.Zc = coil_interference_points.ip_data.interferencePoints_ip{this_index}.Z;

                end
            end
            ii = ii + 1;
            counter = counter+1;
            app.interference_pts{counter} = [];
            app.interference_pts{counter}.R = interference_points_set_1.ip_data.interferencePoints_trace_cp{ii}.R;
            app.interference_pts{counter}.X = interference_points_set_1.ip_data.interferencePoints_trace_cp{ii}.X;
            app.interference_pts{counter}.Y = interference_points_set_1.ip_data.interferencePoints_trace_cp{ii}.Y;
            app.interference_pts{counter}.Z = interference_points_set_1.ip_data.interferencePoints_trace_cp{ii}.Z;
            app.interference_pts{counter}.R2 = interference_points_set_2.ip_data.interferencePoints_trace_cp{ii}.R;
            app.interference_pts{counter}.X2 = interference_points_set_2.ip_data.interferencePoints_trace_cp{ii}.X;
            app.interference_pts{counter}.Y2 = interference_points_set_2.ip_data.interferencePoints_trace_cp{ii}.Y;
            app.interference_pts{counter}.Z2 = interference_points_set_2.ip_data.interferencePoints_trace_cp{ii}.Z;
            app.interference_pts{counter}.Rc = coil_interference_points.ip_data.interferencePoints_cp{ii}.R;
            app.interference_pts{counter}.Xc = coil_interference_points.ip_data.interferencePoints_cp{ii}.X;
            app.interference_pts{counter}.Yc = coil_interference_points.ip_data.interferencePoints_cp{ii}.Y;
            app.interference_pts{counter}.Zc = coil_interference_points.ip_data.interferencePoints_cp{ii}.Z;

            num_tor_planes = counter;

            if (app.ShowSurfDataCheckBox.Value == 1)
                figure
                for ii = 1:num_tor_planes
                    subplot(8,8,ii);
                    plot(app.interference_pts{ii}.R, app.interference_pts{ii}.Z, '.');
                    title(['Index ' num2str(ii)]);
                    box on;
                    axis equal;
                    grid on;
                    %
                    %pause(.5);
                end


                figure
                surf(app.surface_3D_1.onefp.X, app.surface_3D_1.onefp.Y, app.surface_3D_1.onefp.Z );
                hold on;
                surf(app.surface_3D_2.onefp.X, app.surface_3D_2.onefp.Y, app.surface_3D_2.onefp.Z );
                box on

                figure
                box on
                surf(app.surface_3D_2.onefp.X, app.surface_3D_2.onefp.Y, app.surface_3D_2.onefp.Z, app.distance_map_1fp);

                figure
                contour(app.distance_map_1fp');


                figure
                surf(app.surface_3D_1.allfp.X, app.surface_3D_1.allfp.Y, app.surface_3D_1.allfp.Z );
                hold on;
                surf(app.surface_3D_2.allfp.X, app.surface_3D_2.allfp.Y, app.surface_3D_2.allfp.Z );
                box on

                figure
                box on
                surf(app.surface_3D_2.allfp.X, app.surface_3D_2.allfp.Y, app.surface_3D_2.allfp.Z, app.distance_map_allfp);

                figure
                contour(app.distance_map_allfp');


            end

            app.toroidal_index = 1;
            app.toroidal_index_max = num_tor_planes;
            app.toroidal_stepsize = (2*pi / (app.vmec_data.nfp * 2)) / (app.toroidal_index_max-1);
            for ii = 1:app.toroidal_index_max
                app.segment_information{ii} = [];
                app.segment_information{ii}.num_segments = 0;
            end


            num_tor_pts =  1+2*(num_tor_planes-1);
            num_pol_pts = app.poloidal_index_max;
            num_pol_pts_lowres = num_pol_pts / 3;

            app.nescin_data_1 = read_stelloft_nesc(filename_nescin_plasma_1);
            app.nescin_data_2 = read_stelloft_nesc(filename_nescin_divertor_1);
            app.nescin_data_2b = read_stelloft_nesc(filename_nescin_divertor_2);
            app.nescin_data_3 = read_stelloft_nesc(filename_nescin_interference_lofted_wall);
            app.nescin_data_4 = read_stelloft_nesc(filename_nescin_far_lofted_outer_wall);
            app.nescin_data_5 = read_stelloft_nesc(filename_nescin_far_lofted_inner_wall);
            app.nescin_data_3cm_wall = read_stelloft_nesc(filename_nescin_3cm_wall);
            app.nescin_data_7cm_wall = read_stelloft_nesc(filename_nescin_7cm_wall);


            toc
            disp('<----Building surface 1')

            app.surface_3D_1 = rebuild_stelloft_nesc( app.nescin_data_1, ...
                num_tor_pts, num_pol_pts);

            toc
            disp('<----Building surface 2')

            app.surface_3D_2 = rebuild_stelloft_nesc( app.nescin_data_2, ...
                num_tor_pts, num_pol_pts);
            app.surface_3D_2b = rebuild_stelloft_nesc( app.nescin_data_2b, ...
                num_tor_pts, num_pol_pts);

            toc
            disp('<----Building surface 3')

            app.surface_3D_3 = rebuild_stelloft_nesc( app.nescin_data_3, ...
                num_tor_pts, num_pol_pts);

            toc
            disp('<----Building surface 4')

            app.surface_3D_4 = rebuild_stelloft_nesc( app.nescin_data_4, ...
                num_tor_pts, num_pol_pts_lowres);

            disp('<----Building surface 5')

            app.surface_3D_5 = rebuild_stelloft_nesc( app.nescin_data_5, ...
                num_tor_pts, num_pol_pts_lowres);

            toc
            disp('<----Building surface 3cm wall')

            app.surface_3D_3cm_wall = rebuild_stelloft_nesc( app.nescin_data_3cm_wall, ...
                num_tor_pts, num_pol_pts_lowres);

            disp('<----Building surface 7cm wall')

            app.surface_3D_7cm_wall = rebuild_stelloft_nesc( app.nescin_data_7cm_wall, ...
                num_tor_pts, num_pol_pts_lowres);

            toc
            disp('<----Building distance maps')

            app.distance_map_1fp = 0*app.surface_3D_1.onefp.X;
            for ii = 1:num_tor_pts
                contour_R = app.surface_3D_1.onefp.R(ii,:);
                contour_Z = app.surface_3D_1.onefp.Z(ii,:);
                for jj = 1:num_pol_pts
                    this_R = app.surface_3D_2.onefp.R(ii,jj);
                    this_Z = app.surface_3D_2.onefp.Z(ii,jj);
                    distance = sqrt((this_R - contour_R).^2 + ...
                        (this_Z - contour_Z).^2);
                    app.distance_map_1fp(ii,jj) = min(distance);
                end
            end


            app.distance_map_allfp = 0*app.surface_3D_1.allfp.X;
            for ii = 1:(num_tor_pts*app.surface_3D_1.nfp)
                contour_R = app.surface_3D_1.allfp.R(ii,:);
                contour_Z = app.surface_3D_1.allfp.Z(ii,:);
                for jj = 1:num_pol_pts
                    this_R = app.surface_3D_2.allfp.R(ii,jj);
                    this_Z = app.surface_3D_2.allfp.Z(ii,jj);
                    distance = sqrt((this_R - contour_R).^2 + ...
                        (this_Z - contour_Z).^2);
                    app.distance_map_allfp(ii,jj) = min(distance);
                end
            end


            %
            %
            %
            % if strcmpi(strategy_type, 'single_trough_plates')
            %     % plates will follow center of 'troughs' that are identfied in this step
            %     % How are plates defined?
            %     %  Toroidal points are defined: plate_tor_start, and plate_tor_end,
            %     %                               plate_tor_middle
            %     %      These will be defiend for each section of the divertor.
            %     %      Midpoint and connecting line (vector) are identified and stored.
            %     %      Local surface curvature (or some similar vector) will be used as the
            %     %      'inward-directed' normal vector, calculated at/near the Midpoint
            %     %       the plates
            %     %  A third direction is defined as the (normalized) cross-product of the
            %     %  connecting line (vector) and 'inwad-directed' vector
            %     %  Build plates that exted the full tor_start->tor_end angular rangea
            %     poloidal_circ_approx = app.vmec_data.Aminor * 2*pi
            %     %plate_width = 0.0254  % 2.54 cm
            %     plate_width = 1.5*0.0254 % 1.5 inches = 3.81 cm
            %
            %     % use contours of distance map to identify location for
            %
            %
            %
            % end


            NFP = app.vmec_data.nfp;
            raxis = app.vmec_data.raxiscc;
            zaxis = app.vmec_data.zaxiscs;
            mode_numbers = (1:length(raxis))-1;
            num_modes = length(mode_numbers);

            v = linspace(0, pi, num_tor_planes);

            app.raxisline = 0*v;
            app.zaxisline = 0*v;
            for ii = 1:num_modes
                app.raxisline = app.raxisline+raxis(ii)* cos(v*mode_numbers(ii));
                app.zaxisline = app.zaxisline-zaxis(ii)* sin(v*mode_numbers(ii));
            end

            toc

            disp('<----Updating Figure')

            app.UpdateFigureButtonPushed(app)

            toc

            disp('<----Finished Initiialization');
            app.InitializeDataButton.BackgroundColor = [0.96 0.96 0.96];


        end

        % Button pushed function: UpdateFigureButton
        function UpdateFigureButtonPushed(app, event)

            if (app.save_to_png)
                fh = figure;
                ax = axes(fh);
                plot_to = ax;
            else
                plot_to = app.UIAxes;
            end
            % app.UIAxes -> plot_to

            hold(plot_to, 'off');
            plot(plot_to, ...
                app.interference_pts{app.toroidal_index}.R, ...
                app.interference_pts{app.toroidal_index}.Z, 'b.', ...
                'Markersize', 6.1);
            hold(plot_to, 'on');
            plot(plot_to, ...
                app.interference_pts{app.toroidal_index}.R2, ...
                app.interference_pts{app.toroidal_index}.Z2, 'm.', ...
                'Markersize', 6.1);
            plot(plot_to, ...
                app.interference_pts{app.toroidal_index}.Rc, ...
                app.interference_pts{app.toroidal_index}.Zc, 'r+', ...
                'MarkerSize', 6);
            plot(plot_to, ...
                app.surface_3D_2.onefp.R(app.toroidal_index,:), ...
                app.surface_3D_2.onefp.Z(app.toroidal_index,:), 'g-', ...
                'Linewidth', 2);
            plot(plot_to, ...
                app.surface_3D_2b.onefp.R(app.toroidal_index,:), ...
                app.surface_3D_2b.onefp.Z(app.toroidal_index,:), 'g:', ...
                'Linewidth', 2);
            plot(plot_to, ...
                app.surface_3D_3.onefp.R(app.toroidal_index,:), ...
                app.surface_3D_3.onefp.Z(app.toroidal_index,:), 'k:', ...
                'Linewidth', 2 );
            plot(plot_to, ...
                app.surface_3D_4.onefp.R(app.toroidal_index,:), ...
                app.surface_3D_4.onefp.Z(app.toroidal_index,:), 'k-.', ...
                'Linewidth', 2 );
            plot(plot_to, ...
                app.surface_3D_5.onefp.R(app.toroidal_index,:), ...
                app.surface_3D_5.onefp.Z(app.toroidal_index,:), 'k-', ...
                'Linewidth', 2 );


            plot(plot_to, ...
                app.surface_3D_3cm_wall.onefp.R(app.toroidal_index,:), ...
                app.surface_3D_3cm_wall.onefp.Z(app.toroidal_index,:), 'k--', ...
                'Linewidth', 2 );

            plot(plot_to, ...
                app.surface_3D_7cm_wall.onefp.R(app.toroidal_index,:), ...
                app.surface_3D_7cm_wall.onefp.Z(app.toroidal_index,:), 'b--', ...
                'Linewidth', 2 );
            
            plot(plot_to, app.raxisline(app.toroidal_index), ...
                app.zaxisline(app.toroidal_index), 'ko')

            for ii = 1:app.NumberOfZones
                try
                    plot(plot_to, ...
                        app.curve_information{ii}.toroidal_slice(app.toroidal_index).R, ...
                        app.curve_information{ii}.toroidal_slice(app.toroidal_index).Z, 'c', ...
                        'Linewidth', 3);
                catch
                    disp('<---Problem plotting curve_information');
                end
            end

            for ii = 1:app.segment_information{app.toroidal_index}.num_segments
                R1 = app.segment_information{app.toroidal_index}.R1(ii);
                R2 = app.segment_information{app.toroidal_index}.R2(ii);
                Z1 = app.segment_information{app.toroidal_index}.Z1(ii);
                Z2 = app.segment_information{app.toroidal_index}.Z2(ii);
                ind = mod(app.ZoneInfo{app.toroidal_index}.ID(ii), ...
                    length(app.lcolor_list));
                if (ind == 0)
                    ind = length(app.lcolor_list);
                end
                lcolor = app.lcolor_list{ind};

                plot(plot_to, [R1 R2], [Z1 Z2], '-','Color', lcolor, ...
                    'Linewidth', 5);
                text(plot_to, R1, Z1+0.02, ...
                    num2str(app.ZoneInfo{app.toroidal_index}.ID(ii)));

            end


            if (app.active_segment_index > 0)
                R1_sel = app.segment_information{app.toroidal_index}.R1(app.active_segment_index);
                R2_sel = app.segment_information{app.toroidal_index}.R2(app.active_segment_index);
                Z1_sel = app.segment_information{app.toroidal_index}.Z1(app.active_segment_index);
                Z2_sel = app.segment_information{app.toroidal_index}.Z2(app.active_segment_index);
                plot(plot_to, [R1_sel R2_sel], [Z1_sel Z2_sel], 'r--');
            end
            axis(plot_to, 'equal');
            axis(plot_to, 'tight');
            grid(plot_to, 'on')
            this_Phi = (180/pi)*app.surface_3D_3.onefp.Phi(app.toroidal_index, 1);

            title(plot_to, ['Toroidal Index: ' ...
                num2str(app.toroidal_index) ' of ' ...
                num2str(app.toroidal_index_max), ' Angle = ' ...
                num2str(this_Phi) '^o']);
        end

        % Button pushed function: NextToroiedalAngleButton
        function NextToroiedalAngleButtonPushed(app, event)
            app.toroidal_index = app.toroidal_index + 1;
            if (app.toroidal_index > app.toroidal_index_max)
                app.toroidal_index = app.toroidal_index_max;
            end
            app.UpdateFigureButtonPushed(app);
            app.UpdateAssistButtonPushed(app);

        end

        % Button pushed function: PreviousToroidalAngleButton
        function PreviousToroidalAngleButtonPushed(app, event)
            app.toroidal_index = app.toroidal_index - 1;
            if (app.toroidal_index < 1)
                app.toroidal_index = 1;
            end
            app.UpdateFigureButtonPushed(app);
            app.UpdateAssistButtonPushed(app);
        end

        % Button pushed function: AddSegmentButton
        function AddSegmentButtonPushed(app, event)
            app.segment_information{app.toroidal_index}.num_segments = ...
                app.segment_information{app.toroidal_index}.num_segments + 1;
            app.segment_information{app.toroidal_index}.R1(app.segment_information{app.toroidal_index}.num_segments) = app.R1EditField.Value;
            app.segment_information{app.toroidal_index}.R2(app.segment_information{app.toroidal_index}.num_segments) = app.R2EditField.Value;
            app.segment_information{app.toroidal_index}.Z1(app.segment_information{app.toroidal_index}.num_segments) = app.Z1EditField.Value;
            app.segment_information{app.toroidal_index}.Z2(app.segment_information{app.toroidal_index}.num_segments) = app.Z2EditField.Value;

            app.UpdateFigureButtonPushed(app);

        end

        % Button pushed function: SelectSegmentButton
        function SelectSegmentButtonPushed(app, event)
            app.active_segment_index = app.active_segment_index + 1;
            if (app.active_segment_index > app.segment_information{app.toroidal_index}.num_segments)
                app.active_segment_index = 1;
            end
        end

        % Button pushed function: RemoveSegmentButton
        function RemoveSegmentButtonPushed(app, event)
            if (app.active_segment_index == 1)
                new_indices = 2:app.active_segment_index == app.segment_information{app.toroidal_index}.num_segments;
            elseif (app.active_segment_index == app.segment_information{app.toroidal_index}.num_segments)
                new_indices = 1:(app.segment_information{app.toroidal_index}.num_segments-1);
            elseif (app.active_segment_index == 0)
                new_indices = 1:app.segment_information{app.toroidal_index}.num_segments;
            else
                new_indices = [1:(app.active_segment_index-1) app.active_segment_index:app.segment_information{app.toroidal_index}.num_segments];
            end


            app.segment_information{app.toroidal_index}.R1 =  ...
                app.segment_information{app.toroidal_index}.R1(new_indices);
            app.segment_information{app.toroidal_index}.R2 =  ...
                app.segment_information{app.toroidal_index}.R2(new_indices);
            app.segment_information{app.toroidal_index}.Z1 =  ...
                app.segment_information{app.toroidal_index}.Z1(new_indices);
            app.segment_information{app.toroidal_index}.Z2 =  ...
                app.segment_information{app.toroidal_index}.Z2(new_indices);

            app.segment_information{app.toroidal_index}.num_segments = ...
                app.segment_information{app.toroidal_index}.num_segments - 1;

            if (app.active_segment_index > ...
                    app.segment_information{app.toroidal_index}.num_segments)
                app.active_segment_index = ...
                    app.segment_information{app.toroidal_index}.num_segments;

            end

        end

        % Button pushed function: LoadSegmentsButton
        function LoadSegmentsButtonPushed(app, event)

            [file,path] = uigetfile('*.mat', 'Select the segment file', "MultiSelect","off");
            data_infile = fullfile(path,file);
            file_data = load(data_infile, "segment_information");
            app.segment_information = file_data.segment_information;

        end

        % Button pushed function: SaveSegmentsButton
        function SaveSegmentsButtonPushed(app, event)
            [file,path] = uiputfile('*.mat', 'Select the segment output file');
            data_outfile = fullfile(path,file);
            segment_information = app.segment_information;
            save(data_outfile, "segment_information", '-mat', '-v7.3');

        end

        % Value changed function: R1EditField
        function R1EditFieldValueChanged(app, event)
            value = app.R1EditField.Value;
            app.segment_information{app.toroidal_index}.R1(...
                app.segment_information(app.active_segment_index)) = value;

        end

        % Value changed function: Z1EditField
        function Z1EditFieldValueChanged(app, event)
            value = app.Z1EditField.Value;
            app.segment_information{app.toroidal_index}.Z1(...
                app.segment_information(app.active_segment_index)) = value;

        end

        % Value changed function: R2EditField
        function R2EditFieldValueChanged(app, event)
            value = app.R2EditField.Value;
            app.segment_information{app.toroidal_index}.R2(...
                app.segment_information(app.active_segment_index)) = value;

        end

        % Value changed function: Z2EditField
        function Z2EditFieldValueChanged(app, event)
            value = app.Z2EditField.Value;
            app.segment_information{app.toroidal_index}.Z2(...
                app.segment_information(app.active_segment_index)) = value;

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            if (app.PickCenterSwitch.Value == 0)
                if (app.first_point_active)
                    app.R2EditField.Value = event.IntersectionPoint(1);
                    app.Z2EditField.Value = event.IntersectionPoint(2);
                    app.DeltaEditField.Value = sqrt(...
                        (app.R2EditField.Value - app.R1EditField.Value)^2 + ...
                        (app.Z2EditField.Value - app.Z1EditField.Value)^2);
                    %app.AddSegmentButtonPushed(app);
                    app.first_point_active = 0;
                    %app.UpdateFigureButtonPushed(app);
                else
                    app.R1EditField.Value = event.IntersectionPoint(1);
                    app.Z1EditField.Value = event.IntersectionPoint(2);
                    app.first_point_active = 1;
                end
            else
                app.first_point_active = 0;
                this_R = event.IntersectionPoint(1);
                this_Z = event.IntersectionPoint(2);
                R0 = app.raxisline(app.toroidal_index);
                Z0 = app.zaxisline(app.toroidal_index);
                this_theta = atan2((Z0 - this_Z), (R0 - this_R));
                angle_to_project = this_theta + pi/2;
                app.R1EditField.Value = this_R + cos(angle_to_project) * ...
                    app.WidthSpinner.Value/2;
                app.R2EditField.Value = this_R - cos(angle_to_project) * ...
                    app.WidthSpinner.Value/2;
                app.Z1EditField.Value = this_Z + sin(angle_to_project) * ...
                    app.WidthSpinner.Value/2;
                app.Z2EditField.Value = this_Z - sin(angle_to_project) * ...
                    app.WidthSpinner.Value/2;
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)

        end

        % Value changed function: PoloidalIndexSpinner
        function PoloidalIndexSpinnerValueChanged(app, event)
            value = app.PoloidalIndexSpinner.Value;
            if (value < 1)
                app.PoloidalIndexSpinner.Value = app.poloidal_index_max;
                value = app.poloidal_index_max;
            elseif (value > app.poloidal_index_max)
                app.PoloidalIndexSpinner.Value = 1;
                value = 1;
            end
            app.UpdateAssistButtonPushed(app);


        end

        % Value changed function: thetaSpinner
        function thetaSpinnerValueChanged(app, event)
            value = app.thetaSpinner.Value;
            if (value < 0)
                app.thetaSpinner.Value = 359;
            elseif (value > 359)
                app.thetaSpinner.Value = 0;
            end
            app.UpdateAssistButtonPushed(app);
        end

        % Button pushed function: UpdateAssistButton
        function UpdateAssistButtonPushed(app, event)
            if (strcmpi(app.AssistSwitch.Value, 'on'))
                if (strcmpi(app.AnchortoVesselSwitch.Value, 'on'))
                    anchor_R = app.surface_3D_3.onefp.R(app.toroidal_index, app.PoloidalIndexSpinner.Value);
                    anchor_Z = app.surface_3D_2.onefp.Z(app.toroidal_index, app.PoloidalIndexSpinner.Value);
                    distance = app.distance_map_1fp(app.toroidal_index, app.PoloidalIndexSpinner.Value);
                else
                    anchor_R = app.surface_3D_2.onefp.R(app.toroidal_index, app.PoloidalIndexSpinner.Value);
                    anchor_Z = app.surface_3D_2.onefp.Z(app.toroidal_index, app.PoloidalIndexSpinner.Value);
                    distance = app.distance_map_1fp(app.toroidal_index, app.PoloidalIndexSpinner.Value);
                end
                app.SurfaceDeltaEditField.Value = distance;
                R0 = app.raxisline(app.toroidal_index);
                Z0 = app.zaxisline(app.toroidal_index);
                inward_offset = app.InwardOffsetSpinner.Value;

                if (strcmpi(app.AimAtAxisSwitch.Value, 'On'))
                    this_theta = atan2((Z0 - anchor_Z), (R0 - anchor_R)) + ...
                        app.thetaSpinner.Value * pi/ 180;
                    this_R = anchor_R + inward_offset*cos(this_theta);
                    this_Z = anchor_Z + inward_offset*sin(this_theta);
                    angle_to_project = this_theta + pi/2;
                else
                    ind_p1 = app.PoloidalIndexSpinner.Value + 1;
                    ind_m1 = app.PoloidalIndexSpinner.Value - 1;
                    if (ind_p1 > app.poloidal_index_max)
                        ind_p1 = 2;  % not 1, because first and lasat index are the same point in space
                    end
                    if (ind_m1 < 1)
                        ind_m1 = app.poloidal_index_max - 1; %because first and lasat index are the same point in space
                    end

                    Rtp1 = app.surface_3D_3.onefp.R(app.toroidal_index, ...
                        ind_p1);
                    Rtm1 = app.surface_3D_3.onefp.R(app.toroidal_index, ...
                        ind_m1);
                    Ztp1 = app.surface_3D_3.onefp.Z(app.toroidal_index, ...
                        ind_p1);
                    Ztm1 = app.surface_3D_3.onefp.Z(app.toroidal_index, ...
                        ind_m1);
                    angle_to_project = atan2( (Ztp1 - Ztm1), ( Rtp1- Rtm1)) + ...
                        app.thetaSpinner.Value * pi/ 180;

                    this_theta = angle_to_project + pi/2;
                    this_R = anchor_R + inward_offset*cos(this_theta);
                    this_Z = anchor_Z + inward_offset*sin(this_theta);
                end


                app.R1EditField.Value = this_R + cos(angle_to_project) * ...
                    app.WidthSpinner.Value/2;
                app.R2EditField.Value = this_R - cos(angle_to_project) * ...
                    app.WidthSpinner.Value/2;
                app.Z1EditField.Value = this_Z + sin(angle_to_project) * ...
                    app.WidthSpinner.Value/2;
                app.Z2EditField.Value = this_Z - sin(angle_to_project) * ...
                    app.WidthSpinner.Value/2;


                delete(app.temp_point_handle_1);
                delete(app.temp_line_handle_1);
                app.temp_point_handle_1 = plot(app.UIAxes, ...
                    anchor_R, anchor_Z, 'm*');
                app.temp_line_handle_1 = plot(app.UIAxes, ...
                    [app.R1EditField.Value app.R2EditField.Value], ...
                    [app.Z1EditField.Value app.Z2EditField.Value], 'm-', ...
                    'Linewidth', 2.5);

            end
        end

        % Value changed function: InwardOffsetSpinner
        function InwardOffsetSpinnerValueChanged(app, event)
            value = app.InwardOffsetSpinner.Value;
            app.UpdateAssistButtonPushed(app);
        end

        % Value changed function: WidthSpinner
        function WidthSpinnerValueChanged(app, event)
            value = app.WidthSpinner.Value;
            app.UpdateAssistButtonPushed(app);

        end

        % Button pushed function: MakeADivertorPanelButton
        function MakeADivertorPanelButtonPushed(app, event)

            %app.ZoneInfo{app.toroidal_index}.ID(ii)
            %app.ZoneInfo{app.toroidal_index}.indexes_start(ii)
            %app.ZoneInfo{app.toroidal_index}.indexes_end(ii)

            % identify 'zones' for  divertor plates
            % use residue calculation here.
            zones_found = 0;
            poloidal_indexes_start = [];
            poloidal_indexes_end = [];
            this_residue = zeros(1, app.poloidal_index_max);
            currently_in_zone = 0;
            started_in_zone = nan;
            %ended_in_zone = nan;

            % for debugging.
            %if (app.toroidal_index == 142)
            %    keyboard;
            %end

            if (strcmpi(app.CurveStyleSwitch.Value, 'Inner'))
                % the 1st and last index refer to the same point in space!
                for ii = 1:app.poloidal_index_max
                    % if residue ~> 2*pi, then the point is inside the contour
                    % surface_3D_2 is the green (inner) surface
                    % surface_3D_3 is the black (outer) surface
                    this_residue(ii) = check_cauchy_integral(app.surface_3D_2.onefp.R(app.toroidal_index,:), ...
                        app.surface_3D_2.onefp.Z(app.toroidal_index,:), ...
                        app.surface_3D_3.onefp.R(app.toroidal_index,ii), ...
                        app.surface_3D_3.onefp.Z(app.toroidal_index,ii));
                end
                % interesections for residue > 6
                in_zone = zeros(1, app.poloidal_index_max);
                for ii = 1:app.poloidal_index_max
                    in_zone(ii) = this_residue(ii) > app.ResidueLevelEditField.Value;
                end
            elseif  (strcmpi(app.CurveStyleSwitch.Value, 'Outer'))
                % the 1st and last index refer to the same point in space!
                for ii = 1:app.poloidal_index_max
                    % if residue ~> 2*pi, then the point is inside the contour
                    % surface_3D_2 is the green (inner) surface
                    % surface_3D_3 is the black (outer) surface
                    this_residue(ii) = check_cauchy_integral(app.surface_3D_3.onefp.R(app.toroidal_index,:), ...
                        app.surface_3D_3.onefp.Z(app.toroidal_index,:), ...
                        app.surface_3D_2.onefp.R(app.toroidal_index,ii), ...
                        app.surface_3D_2.onefp.Z(app.toroidal_index,ii));
                end

                % interesections for residue > 6
                in_zone = zeros(1, app.poloidal_index_max);
                for ii = 1:app.poloidal_index_max
                    in_zone(ii) = this_residue(ii) <= app.ResidueLevelEditField.Value;
                end

            end

            % inzone is '1' or '0'
            % if everything in 'inzone', then just use '1' and
            % 'app.poloidal_index_max' as the anchor/end points
            % if nothing is inzone, then, well. job well done!
            % otherwise, shift
            % shift inzone so that no '1s' are continiguous across the
            % app.poloidal_index_max -> 1 wrap-around
            % identify 'zones' as any set of continuguous '1s'
            in_zone_indices = find(in_zone==1);
            num_indices_in_zones = length(in_zone_indices);
            all_inside = (num_indices_in_zones == app.poloidal_index_max);

            if all_inside

            end

            if ~all_inside
                index_array = 1:app.poloidal_index_max;
                % in_zone

                % 'end' = app.poloidal_index_max
                % case 1
                % 1 2 3 4 5 end-1 end
                % 1 1 0 0 0 0     1
                % first = normal; last = normal;
                % or
                % case 2
                % 1 2 3 4 5 end
                % 0 0 1 1 0 0
                % first = normal; last = normal;
                % or
                % case 3
                % 1 2 3 4 5 end-1 end
                % 0 0 0 0 1 1     0
                % first = normal; last = normal;
                % or
                %
                % case 5A
                %     1 2 3 4 5 6 end-1 end
                %     1 0 1 1 0 0 1     1
                % or
                % case 5B
                %     1 2 3 4 5 6 7 end-1 end
                %     1 1 0 0 1 1 0 0     1
                % first = adjusted to second; normal; last = normal;
                % rotate index and value to the left until a '0' fills the
                % left-most (new 1st) index.  nte that the 'end' one is
                % good for debugging, but doesn't need to be used in the
                % shifting (the current '1st' index can be used).


                keep_shifting = 1;
                while keep_shifting
                    if (in_zone(1))
                        in_zone = [in_zone(end-1) in_zone(1:end-2) in_zone(end-1)];
                        index_array = [index_array(end-1) index_array(1:end-2) index_array(end-1)];
                    else
                        keep_shifting = 0;
                    end

                end

                for ii = 1:app.poloidal_index_max
                    if (in_zone(ii))

                        if (currently_in_zone == 0)
                            zones_found = zones_found + 1;
                            currently_in_zone = 1;
                            index_to_append = index_array(ii) - round(app.BufferZonesSlider.Value);
                            if (index_to_append < 1)
                                index_to_append = app.poloidal_index_max + index_to_append - 1;
                            end
                            poloidal_indexes_start = [poloidal_indexes_start index_to_append];
                        end
                    else
                        if (currently_in_zone == 1)
                            currently_in_zone = 0;
                            index_to_append = index_array(ii-1) + round(app.BufferZonesSlider.Value);
                            if (index_to_append > app.poloidal_index_max)
                                index_to_append = index_to_append - ...
                                    app.poloidal_index_max + 1;
                            end
                            poloidal_indexes_end = [poloidal_indexes_end index_to_append];
                        end

                    end

                end
            end

            % need the following:
            % zones_found
            % poloidal_indexes_start
            % poloidal_indexes_end

            %             for ii = 1:app.poloidal_index_max
            %                 % fix this logic. it is broken for the case when index 1 is
            %                 % 'in a zone'
            %                 if (this_residue(ii) > 6)
            %                     if (app.toroidal_index == 142)
            %                         keyboard;
            %                     end
            %                     if (currently_in_zone == 0)
            %                         zones_found = zones_found + 1;
            %                         currently_in_zone = 1;
            %                         if (ii == 1)
            %                             started_in_zone = 1;
            %                             poloidal_indexes_start = [app.poloidal_index_max-1];
            %                         else
            %                             poloidal_indexes_start = [poloidal_indexes_start ii-1];
            %                         end
            %                     end
            %                     if (ii == app.poloidal_index_max)
            %
            %                         %ended_in_zone = 1;
            %                         if started_in_zone
            %                             %%poloidal_indexes_start = poloidal_indexes_start(2:zones_found);
            %                             %%% and shift the first 'end' index to the end
            %                             %%poloidal_indexes_end = [poloidal_indexes_end(2:end) poloidal_indexes_end(1)];
            %                             % fix start
            %                             zones_found = zones_found - 1;
            %                             if (zones_found > 1)
            %                                 poloidal_indexes_start = [poloidal_indexes_start(zones_found+1) ...
            %                                     poloidal_indexes_start(2:(zones_found))];
            %                             else
            %                                 poloidal_indexes_start = poloidal_indexes_start(zones_found);
            %                             end
            %                             % and do nothing to the ends!
            %                             %poloidal_indexes_end = [poloidal_indexes_end(2:end) poloidal_indexes_end(1)];
            %                         else
            %                             %indexes_end = [indexes_end ii];
            %                             poloidal_indexes_end = [poloidal_indexes_end 2];
            %                         end
            %
            %                     end
            %                 end
            %                 if (this_residue(ii) <= 6)
            %                     if (currently_in_zone == 1)
            %                         currently_in_zone = 0;
            %                         poloidal_indexes_end = [poloidal_indexes_end ii];
            %                     end
            %
            %                 end
            %
            %             end

            % check each zone and assign a ZoneID - If a zone's endpoints
            % are 'close' to a zone at a prior angle, then use that same
            % ZoneID.
            for ii = 1:zones_found
                if (app.toroidal_index == 142)
                    %                        keyboard;
                end
                if (app.toroidal_index == 1)
                    % if it is the first index, just add each zone with IDs
                    % (simple)
                    app.CurrentZoneID = app.CurrentZoneID + 1;%the following should be true: CurrentID == ii
                    app.ZoneInfo{app.toroidal_index}.ID(ii) = app.CurrentZoneID;
                    app.ZoneInfo{app.toroidal_index}.indexes_start(ii) = ...
                        poloidal_indexes_start(ii);
                    app.ZoneInfo{app.toroidal_index}.indexes_end(ii) = ...
                        poloidal_indexes_end(ii);


                else
                    % Check to see if there are any zones in the previous
                    % toroidal index.  If not, treat it like the first
                    % index.
                    if isempty( app.ZoneInfo{app.toroidal_index - 1}.ID )
                        % (simple)
                        app.CurrentZoneID = app.CurrentZoneID + 1;
                        app.ZoneInfo{app.toroidal_index}.ID(ii) = app.CurrentZoneID;
                        app.ZoneInfo{app.toroidal_index}.indexes_start(ii) = ...
                            poloidal_indexes_start(ii);
                        app.ZoneInfo{app.toroidal_index}.indexes_end(ii) = ...
                            poloidal_indexes_end(ii);
                    else
                        found_it = 0; % was a 'close match' found?
                        for jj = 1:length(app.ZoneInfo{app.toroidal_index - 1}.ID)
                            lenR1 = app.surface_3D_3.onefp.R(...
                                app.toroidal_index - 1, ...
                                app.ZoneInfo{app.toroidal_index - 1}.indexes_start(jj)) - ...
                                app.surface_3D_3.onefp.R(...
                                app.toroidal_index, ...
                                poloidal_indexes_start(ii));
                            lenR2 = app.surface_3D_3.onefp.R(...
                                app.toroidal_index - 1, ...
                                app.ZoneInfo{app.toroidal_index - 1}.indexes_end(jj)) - ...
                                app.surface_3D_3.onefp.R(...
                                app.toroidal_index, ...
                                poloidal_indexes_end(ii));
                            lenZ1 = app.surface_3D_3.onefp.Z(...
                                app.toroidal_index - 1, ...
                                app.ZoneInfo{app.toroidal_index - 1}.indexes_start(jj)) - ...
                                app.surface_3D_3.onefp.Z(...
                                app.toroidal_index, ...
                                poloidal_indexes_start(ii));
                            lenZ2 = app.surface_3D_3.onefp.Z(...
                                app.toroidal_index - 1, ...
                                app.ZoneInfo{app.toroidal_index - 1}.indexes_end(jj)) - ...
                                app.surface_3D_3.onefp.Z(...
                                app.toroidal_index, ...
                                poloidal_indexes_end(ii));
                            vals = [sqrt(lenR1^2 + lenZ1^2) sqrt(lenR2^2 + lenZ2^2)];
                            if (max(vals) < app.ZoneProximityLimitEditField.Value)
                                % close enough. Use the same ZoneID
                                found_it = 1;

                                app.ZoneInfo{app.toroidal_index}.ID(ii) = ...
                                    app.ZoneInfo{app.toroidal_index - 1}.ID(jj);
                                app.ZoneInfo{app.toroidal_index}.indexes_start(ii) = ...
                                    poloidal_indexes_start(ii);
                                app.ZoneInfo{app.toroidal_index}.indexes_end(ii) = ...
                                    poloidal_indexes_end(ii);
                                break;


                            end


                        end

                        if ~found_it
                            % add it as a new zone
                            % simple
                            app.CurrentZoneID = app.CurrentZoneID + 1;
                            app.ZoneInfo{app.toroidal_index}.ID(ii) = app.CurrentZoneID;
                            app.ZoneInfo{app.toroidal_index}.indexes_start(ii) = ...
                                poloidal_indexes_start(ii);
                            app.ZoneInfo{app.toroidal_index}.indexes_end(ii) = ...
                                poloidal_indexes_end(ii);
                        end
                    end
                end
            end


            disp(['<--- Found: ' num2str(zones_found) ' zones']);
            disp(['<--- Total: ' num2str(app.CurrentZoneID) ' unique zones']);


            for ii = 1:zones_found
                app.R1EditField.Value = ...
                    app.surface_3D_3.onefp.R(app.toroidal_index,poloidal_indexes_start(ii));
                app.R2EditField.Value = ...
                    app.surface_3D_3.onefp.R(app.toroidal_index,poloidal_indexes_end(ii));
                app.Z1EditField.Value = ...
                    app.surface_3D_3.onefp.Z(app.toroidal_index,poloidal_indexes_start(ii));
                app.Z2EditField.Value = ...
                    app.surface_3D_3.onefp.Z(app.toroidal_index,poloidal_indexes_end(ii));
                app.DeltaEditField.Value = sqrt(...
                    (app.R2EditField.Value - app.R1EditField.Value)^2 + ...
                    (app.Z2EditField.Value - app.Z1EditField.Value)^2);

                app.AddSegmentButtonPushed(app);
            end
        end

        % Button pushed function: MakeADivertorButton
        function MakeADivertorButtonPushed(app, event)
            app.ZoneInfo = [];
            app.CurrentZoneID = 0;

            for ii = 1:app.toroidal_index_max
                app.ZoneInfo{ii}.ID = [];
            end
            for ii = 1:app.toroidal_index_max
                app.toroidal_index = ii;
                app.UpdateFigureButtonPushed(app)
                pause(0.01)
                app.MakeADivertorPanelButtonPushed(app);
                pause(0.01)
            end
            app.NumberOfZones = app.CurrentZoneID; % this is true at the end of the previous loop
            disp(['<--- Found a total of ' num2str(app.NumberOfZones) ' unique zones']);

            %keyboard
            figure
            %app.ZoneInfo
            for ii= 1:app.toroidal_index_max
                hold on
                plot(ii*ones(size(app.ZoneInfo{ii}.ID)), app.ZoneInfo{ii}.ID, 'ko')
            end
            xlabel('Toroidal Index');
            ylabel('Zone ID')

            grid on
            %keyboard
            %app.ClearAllSegmentsButtonPushed(app);

        end

        % Button pushed function: ClearAllSegmentsButton
        function ClearAllSegmentsButtonPushed(app, event)

            app.curve_information = [];
            app.NumberOfZones = 0;
            for ii = 1:app.toroidal_index_max
                app.toroidal_index = ii;

                app.segment_information{app.toroidal_index}.num_segments = 0;

                app.segment_information{app.toroidal_index}.R1 = [];
                app.segment_information{app.toroidal_index}.R2 = [];
                app.segment_information{app.toroidal_index}.Z1 = [];
                app.segment_information{app.toroidal_index}.Z2 = [];
                app.UpdateFigureButtonPushed(app);
            end

        end

        % Button pushed function: MakeCurvesButton
        function MakeCurvesButtonPushed(app, event)
            %app.ZoneInfo{app.toroidal_index}.ID(ii)
            %app.ZoneInfo{app.toroidal_index}.indexes_start(ii)
            %app.ZoneInfo{app.toroidal_index}.indexes_end(ii)
            %app.ZoneDetails{jj}.max_len(kk)
            %app.ZoneDetails{jj}.min_len(kk)
            %app.ZoneDetails{jj}.total_len(kk)
            %app.ZoneDetails{jj}.tor_start(kk)
            %app.ZoneDetails{jj}.tor_end(kk)

            delta_Phi_degrees = app.toroidal_stepsize * 180/pi
            delta_Phi = app.toroidal_stepsize

            number_of_additional_zones_needed = 0;
            mark_for_split = zeros(1,app.NumberOfZones);
            zones_needed_here = zeros(1,app.NumberOfZones);
            if (app.toroidal_index == 142)
                % debug this !
                %                         keyboard;
            end
            disp('<----First loop');
            for ii = 1:app.NumberOfZones
                num_segments = 0;
                total_len = 0;
                min_len = inf;
                max_len = -inf;
                tor_start = inf;
                tor_end = -inf;
                first_tor_index = inf;
                last_tor_index = -inf;

                % loop over each zone.
                % scan over toroidal locations. build stats for each zone
                for jj = 1:app.toroidal_index_max
                    num_zones_here = length(app.ZoneInfo{jj}.ID);
                    for kk = 1:num_zones_here
                        if (app.ZoneInfo{jj}.ID(kk) == ii)
                            % it matches - add it to stats
                            num_segments = num_segments + 1;

                            if (strcmpi(app.CurveStyleSwitch.Value, 'Inner'))
                                R1 = app.surface_3D_3.onefp.R(jj, app.ZoneInfo{jj}.indexes_start(kk));
                                R2 = app.surface_3D_3.onefp.R(jj, app.ZoneInfo{jj}.indexes_end(kk));
                                Z1 = app.surface_3D_3.onefp.Z(jj, app.ZoneInfo{jj}.indexes_start(kk));
                                Z2 = app.surface_3D_3.onefp.Z(jj, app.ZoneInfo{jj}.indexes_end(kk));
                                this_Phi = app.surface_3D_3.onefp.Phi(jj, ...
                                    app.ZoneInfo{jj}.indexes_start(kk));
                            elseif (strcmpi(app.CurveStyleSwitch.Value, 'Outer'))
                                R1 = app.surface_3D_2.onefp.R(jj, app.ZoneInfo{jj}.indexes_start(kk));
                                R2 = app.surface_3D_2.onefp.R(jj, app.ZoneInfo{jj}.indexes_end(kk));
                                Z1 = app.surface_3D_2.onefp.Z(jj, app.ZoneInfo{jj}.indexes_start(kk));
                                Z2 = app.surface_3D_2.onefp.Z(jj, app.ZoneInfo{jj}.indexes_end(kk));
                                this_Phi = app.surface_3D_2.onefp.Phi(jj, ...
                                    app.ZoneInfo{jj}.indexes_start(kk));
                            end
                            this_len = sqrt((R2-R1)^2 + (Z2-Z1)^2);
                            max_len = max([max_len this_len]);
                            min_len = min([min_len this_len]);
                            total_len = total_len + this_len;
                            tor_start = min([tor_start this_Phi]);
                            tor_end = max([tor_end this_Phi]);
                            first_tor_index = min([first_tor_index jj]);
                            last_tor_index = max([last_tor_index jj]);


                        end
                    end
                end
                app.ZoneDetails{ii}.max_len = max_len;
                app.ZoneDetails{ii}.min_len = min_len;
                app.ZoneDetails{ii}.total_len = total_len;
                app.ZoneDetails{ii}.tor_start = tor_start;
                app.ZoneDetails{ii}.tor_end = tor_end;
                app.ZoneDetails{ii}.first_tor_index = first_tor_index;
                app.ZoneDetails{ii}.last_tor_index = last_tor_index;

                disp(['<---Zone ' num2str(ii)]);
                disp(['<---first_index: ' num2str(first_tor_index)]);
                disp(['<---last_index: ' num2str(last_tor_index)]);

                disp(['<---tor_start (x180/pi): ' num2str(tor_start*180/pi)]);
                disp(['<---tor_end   (x180/pi): ' num2str(tor_end*180/pi)]);
                disp(['<---min: ' num2str(min_len)]);
                disp(['<---max: ' num2str(max_len)]);
                disp(['<---ave: ' num2str(total_len/num_segments)]);
                figure
                %app.ZoneInfo
                for iii= 1:app.toroidal_index_max
                    hold on
                    plot(iii*ones(size(app.ZoneInfo{iii}.ID)), app.ZoneInfo{iii}.ID, 'ko')
                end
                xlabel('Toroidal Index');
                ylabel('Zone ID')

                grid on

                toroidal_extent_degrees = (tor_end-tor_start) * 180/pi;
                if (toroidal_extent_degrees > app.MaxTorExtSpinner.Value)
                    zones_needed_here(ii) = ...
                        ceil( toroidal_extent_degrees / ...
                        (app.MaxTorExtSpinner.Value + delta_Phi_degrees));
                    number_of_additional_zones_needed = ...
                        number_of_additional_zones_needed + ...
                        zones_needed_here(ii) - 1;
                    mark_for_split(ii) = 1;
                end

            end
            extra_zone_counter = 0;

            disp('<---Dividing new zones ');
            PhiStepsNewZones_noround_degrees = (app.MaxTorExtSpinner.Value /delta_Phi_degrees);
            PhiStepsNewZones_noround = (app.MaxTorExtSpinner.Value *(pi/180)/delta_Phi);
            PhiStepsNewZones = floor(PhiStepsNewZones_noround);
            disp(['<---Step size, not rounded: ' num2str(PhiStepsNewZones_noround)]);
            disp(['<---Step size, rounded: ' num2str(PhiStepsNewZones)]);

            disp('<----Second loop');

            for ii = 1:app.NumberOfZones
                if mark_for_split(ii)
                    % for  now, make each new zones full length, except for the
                    % last new zone. For zones that need to be split,
                    % identify new 'ID's and which toroidal indexes are
                    % necessary to change.  The first zone will retain its
                    % ID.

                    tor_index_start = app.ZoneDetails{ii}.first_tor_index;
                    tor_index_end = app.ZoneDetails{ii}.last_tor_index;
                    zones_needed_here(ii);
                    for jj = 1:(zones_needed_here(ii))
                        if (jj == 1)
                            % keep zone ID
                            % Do not increment Number of Zones
                            app.ZoneDetails{ii}.last_tor_index = tor_index_start + PhiStepsNewZones;
                        else
                            if (jj == zones_needed_here(ii))
                                % generate and use new zone ID, index start,
                                % and use old index end
                                % Increment Number of Zones
                                extra_zone_counter = extra_zone_counter + 1;
                                newID = app.NumberOfZones + extra_zone_counter;
                                first_tor_index_to_change = tor_index_start + PhiStepsNewZones * (jj-1) + 1;
                                last_tor_index_to_change = tor_index_end;

                            else
                                % generate and use new zone ID
                                % Increment Number of Zones (count now, add later)
                                % loop over some toroidal indices and change ID
                                extra_zone_counter = extra_zone_counter + 1;
                                newID = app.NumberOfZones + extra_zone_counter;
                                % toroidal indices of original run from
                                % app.ZoneInfo{ii}.tor_start to *.tor_end
                                first_tor_index_to_change = tor_index_start + PhiStepsNewZones * (jj-1) + 1;
                                last_tor_index_to_change = first_tor_index_to_change + PhiStepsNewZones;

                            end
                            for kk = first_tor_index_to_change:last_tor_index_to_change
                                app.ZoneInfo{kk}.ID
                                for ll = 1:length(app.ZoneInfo{kk}.ID)
                                    if (app.ZoneInfo{kk}.ID(ll) == ii)
                                        disp(['<---adding ID = ' num2str(newID)]);
                                        app.ZoneInfo{kk}.ID(ll) = newID;
                                    end

                                end

                            end
                        end

                    end

                end
            end
            app.NumberOfZones = app.NumberOfZones + extra_zone_counter;


            disp('<---Finished split')
            %             figure
            %             %app.ZoneInfo
            %             for iii= 1:app.toroidal_index_max
            %                 hold on
            %                 plot(iii*ones(size(app.ZoneInfo{iii}.ID)), app.ZoneInfo{iii}.ID, 'ko')
            %             end
            %             xlabel('Toroidal Index');
            %             ylabel('Zone ID')
            %
            %             grid on

            % recheck !!!!
            disp('<----Third loop');
            app.NumberOfZones
            for ii = 1:app.NumberOfZones
                num_segments = 0;
                total_len = 0;
                min_len = inf;
                max_len = -inf;
                tor_start = inf;
                tor_end = -inf;
                first_tor_index = inf;
                last_tor_index = -inf;

                % loop over each zone.
                % scan over toroidal locations. build stats for each zone
                for jj = 1:app.toroidal_index_max
                    num_zones_here = length(app.ZoneInfo{jj}.ID);
                    for kk = 1:num_zones_here
                        if (app.ZoneInfo{jj}.ID(kk) == ii)
                            % it matches - add it to stats
                            num_segments = num_segments + 1;

                            if (strcmpi(app.CurveStyleSwitch.Value, 'Inner'))
                                R1 = app.surface_3D_3.onefp.R(jj, app.ZoneInfo{jj}.indexes_start(kk));
                                R2 = app.surface_3D_3.onefp.R(jj, app.ZoneInfo{jj}.indexes_end(kk));
                                Z1 = app.surface_3D_3.onefp.Z(jj, app.ZoneInfo{jj}.indexes_start(kk));
                                Z2 = app.surface_3D_3.onefp.Z(jj, app.ZoneInfo{jj}.indexes_end(kk));
                                this_Phi = app.surface_3D_3.onefp.Phi(jj, ...
                                    app.ZoneInfo{jj}.indexes_start(kk));
                            elseif (strcmpi(app.CurveStyleSwitch.Value, 'Outer'))
                                R1 = app.surface_3D_2.onefp.R(jj, app.ZoneInfo{jj}.indexes_start(kk));
                                R2 = app.surface_3D_2.onefp.R(jj, app.ZoneInfo{jj}.indexes_end(kk));
                                Z1 = app.surface_3D_2.onefp.Z(jj, app.ZoneInfo{jj}.indexes_start(kk));
                                Z2 = app.surface_3D_2.onefp.Z(jj, app.ZoneInfo{jj}.indexes_end(kk));
                                this_Phi = app.surface_3D_2.onefp.Phi(jj, ...
                                    app.ZoneInfo{jj}.indexes_start(kk));

                            end
                            this_len = sqrt((R2-R1)^2 + (Z2-Z1)^2);
                            max_len = max([max_len this_len]);
                            min_len = min([min_len this_len]);
                            total_len = total_len + this_len;
                            tor_start = min([tor_start this_Phi]);
                            tor_end = max([tor_end this_Phi]);
                            first_tor_index = min([first_tor_index jj]);
                            last_tor_index = max([last_tor_index jj]);

                        end
                    end
                end
                app.ZoneDetails{ii}.max_len = max_len;
                app.ZoneDetails{ii}.min_len = min_len;
                app.ZoneDetails{ii}.total_len = total_len;
                app.ZoneDetails{ii}.tor_start = tor_start;
                app.ZoneDetails{ii}.tor_end = tor_end;
                app.ZoneDetails{ii}.first_tor_index = first_tor_index;
                app.ZoneDetails{ii}.last_tor_index = last_tor_index;

                disp(['<---Zone Recheck ==== ' num2str(ii) ' ===' ]);
                disp(['<---first_index: ' num2str(first_tor_index)]);
                disp(['<---last_index: ' num2str(last_tor_index)]);

                disp(['<---tor_start (x180/pi): ' num2str(tor_start*180/pi)]);
                disp(['<---tor_end   (x180/pi): ' num2str(tor_end*180/pi)]);
                disp(['<---min: ' num2str(min_len)]);
                disp(['<---max: ' num2str(max_len)]);
                disp(['<---ave: ' num2str(total_len/num_segments)]);


            end

            % end recheck
            %
            %             figure
            %             %app.ZoneInfo
            %             for iii= 1:app.toroidal_index_max
            %                 hold on
            %                 plot(iii*ones(size(app.ZoneInfo{iii}.ID)), app.ZoneInfo{iii}.ID, 'ko')
            %             end
            %             xlabel('Toroidal Index');
            %             ylabel('Zone ID')
            %
            %             grid on
            % add edge buffer zones before and after !!!!
            disp('<----3.5 After Third, Before Fourth loop');

            for ii = 1:app.NumberOfZones
                app.ZoneDetails{ii}.first_tor_index;
                app.ZoneDetails{ii}.last_tor_index;


                disp(['<---Zone Buffer Build ==== ' num2str(ii) ' ===' ]);
                disp(['<---first_index: ' num2str(app.ZoneDetails{ii}.first_tor_index)]);
                disp(['<---last_index: ' num2str(app.ZoneDetails{ii}.last_tor_index)]);

                if (app.ZoneDetails{ii}.first_tor_index == 1)
                    % do nothing
                else
                    % find first and last poloidal index information for the 1st toroidal index -
                    % add a new zone to the previous toroidal index
                    % update app.ZoneInfo{jj}.ID(kk),
                    % app.ZoneInfo{jj}.indexes_start(kk) and
                    % app.ZoneInfo{jj}.indexes_end(kk)

                    num_zones_here = length(app.ZoneInfo{app.ZoneDetails{ii}.first_tor_index}.ID);
                    for kk = 1:num_zones_here
                        if (app.ZoneInfo{app.ZoneDetails{ii}.first_tor_index}.ID(kk) == ii)
                            % it matches - do something

                            app.ZoneInfo{app.ZoneDetails{ii}.first_tor_index-1}.ID = ...
                                [app.ZoneInfo{app.ZoneDetails{ii}.first_tor_index-1}.ID ...
                                ii];
                            app.ZoneInfo{app.ZoneDetails{ii}.first_tor_index-1}.indexes_start = ...
                                [app.ZoneInfo{app.ZoneDetails{ii}.first_tor_index-1}.indexes_start ...
                                app.ZoneInfo{app.ZoneDetails{ii}.first_tor_index}.indexes_start(kk)];
                            app.ZoneInfo{app.ZoneDetails{ii}.first_tor_index-1}.indexes_end = ...
                                [app.ZoneInfo{app.ZoneDetails{ii}.first_tor_index-1}.indexes_end ...
                                app.ZoneInfo{app.ZoneDetails{ii}.first_tor_index}.indexes_end(kk)];
                        end
                    end

                end

                if (app.ZoneDetails{ii}.last_tor_index == app.toroidal_index_max)
                    % do nothing
                else

                    if 0
                        % add a new zone to the next toroidal index

                        num_zones_here = length(app.ZoneInfo{app.ZoneDetails{ii}.last_tor_index}.ID);
                        for kk = 1:num_zones_here
                            if (app.ZoneInfo{app.ZoneDetails{ii}.last_tor_index}.ID(kk) == ii)
                                % it matches - do something

                                app.ZoneInfo{app.ZoneDetails{ii}.last_tor_index+1}.ID = ...
                                    [app.ZoneInfo{app.ZoneDetails{ii}.last_tor_index+1}.ID ...
                                    ii];
                                app.ZoneInfo{app.ZoneDetails{ii}.last_tor_index+1}.indexes_start = ...
                                    [app.ZoneInfo{app.ZoneDetails{ii}.last_tor_index+1}.indexes_start ...
                                    app.ZoneInfo{app.ZoneDetails{ii}.last_tor_index}.indexes_start(kk)];
                                app.ZoneInfo{app.ZoneDetails{ii}.last_tor_index+1}.indexes_end = ...
                                    [app.ZoneInfo{app.ZoneDetails{ii}.last_tor_index+1}.indexes_end ...
                                    app.ZoneInfo{app.ZoneDetails{ii}.last_tor_index}.indexes_end(kk)];
                            end
                        end
                    end

                end

            end


            % end 3.5 build out
            %
            %             figure
            %             %app.ZoneInfo
            %             for iii= 1:app.toroidal_index_max
            %                 hold on
            %                 plot(iii*ones(size(app.ZoneInfo{iii}.ID)), app.ZoneInfo{iii}.ID, 'ko')
            %             end
            %             xlabel('Toroidal Index');
            %             ylabel('Zone ID')
            %
            %             grid on

            %app.ZoneInfo{app.toroidal_index}.ID(ii)
            %app.ZoneInfo{app.toroidal_index}.indexes_start(ii)
            %app.ZoneInfo{app.toroidal_index}.indexes_end(ii)
            %app.ZoneInfo{jj}.max_len(kk)
            %app.ZoneInfo{jj}.min_len(kk)
            %app.ZoneInfo{jj}.total_len(kk)
            %app.ZoneInfo{jj}.tor_start(kk)
            %app.ZoneInfo{jj}.tor_end(kk)


            % loop over each zone.  Create divertor contour at each
            % location. Divide contour into an equal number of contours at
            % each toroidal angle at which the Zone exists.

            % include 'edges' that are directed away from and
            % extend 1 cm
            disp('<----Fourth loop');
            app.curve_information = [];
            for ii = 1:app.NumberOfZones
                disp(['<---Zone ' num2str(ii)]);
                app.curve_information{ii} = [];
                app.curve_information{ii}.ID = ii;


                % loop over each zone.
                % scan over toroidal locations. build stats for each zone
                for jj = 1:app.toroidal_index_max
                    app.curve_information{ii}.toroidal_slice(jj).R = [];
                    app.curve_information{ii}.toroidal_slice(jj).Z = [];
                    app.curve_information{ii}.toroidal_slice(jj).Phi = [];

                    app.ZoneInfo{jj}.ID;

                    num_zones_here = length(app.ZoneInfo{jj}.ID);
                    if (jj == 142)
                        %keyboard;
                    end

                    R0 = app.raxisline(jj);
                    Z0 = app.zaxisline(jj);


                    for kk = 1:num_zones_here
                        if (app.ZoneInfo{jj}.ID(kk) == ii)
                            % it matches - do something

                            if (strcmpi(app.CurveStyleSwitch.Value, 'Inner'))

                                R1 = app.surface_3D_3.onefp.R(jj, app.ZoneInfo{jj}.indexes_start(kk));
                                R2 = app.surface_3D_3.onefp.R(jj, app.ZoneInfo{jj}.indexes_end(kk));
                                Z1 = app.surface_3D_3.onefp.Z(jj, app.ZoneInfo{jj}.indexes_start(kk));
                                Z2 = app.surface_3D_3.onefp.Z(jj, app.ZoneInfo{jj}.indexes_end(kk));
                                this_Phi = app.surface_3D_3.onefp.Phi(jj, app.ZoneInfo{jj}.indexes_start(kk));
                                min_theta = app.surface_3D_3.onefp.theta(jj, app.ZoneInfo{jj}.indexes_start(kk));
                                max_theta = app.surface_3D_3.onefp.theta(jj, app.ZoneInfo{jj}.indexes_end(kk));

                            elseif (strcmpi(app.CurveStyleSwitch.Value, 'Outer'))


                                R1 = app.surface_3D_2.onefp.R(jj, app.ZoneInfo{jj}.indexes_start(kk));
                                R2 = app.surface_3D_2.onefp.R(jj, app.ZoneInfo{jj}.indexes_end(kk));
                                Z1 = app.surface_3D_2.onefp.Z(jj, app.ZoneInfo{jj}.indexes_start(kk));
                                Z2 = app.surface_3D_2.onefp.Z(jj, app.ZoneInfo{jj}.indexes_end(kk));
                                this_Phi = app.surface_3D_2.onefp.Phi(jj, app.ZoneInfo{jj}.indexes_start(kk));
                                min_theta = app.surface_3D_2.onefp.theta(jj, app.ZoneInfo{jj}.indexes_start(kk));
                                max_theta = app.surface_3D_2.onefp.theta(jj, app.ZoneInfo{jj}.indexes_end(kk));
                            end
                            this_len = sqrt((R2-R1)^2 + (Z2-Z1)^2);

                            if (this_len > app.MinWidthSpinner.Value)
                                % only construct the object if it is large
                                % enough
                                num_SectionsPerZone = ...
                                    app.NumSectionsperZoneSpinner.Value;
                                %if (jj == 142)
                                %keyboard;
                                %end
                                if (max_theta < min_theta)
                                    min_theta = min_theta - 2*pi;
                                end
                                theta_list = linspace(min_theta, max_theta, ...
                                    num_SectionsPerZone);
                                phi_list = this_Phi * ones(size(theta_list));

                                if (strcmpi(app.CurveStyleSwitch.Value, 'Inner'))

                                    contour_3D = rebuild_stelloft_nesc_contour( app.nescin_data_3, ...
                                        phi_list, theta_list);
                                elseif (strcmpi(app.CurveStyleSwitch.Value, 'Outer'))
                                    contour_3D = rebuild_stelloft_nesc_contour( app.nescin_data_2, ...
                                        phi_list, theta_list);

                                end
                                projection_angle_1 = atan2( ...
                                    (contour_3D.Z(1) - contour_3D.Z(2)) , ...
                                    (contour_3D.R(1) - contour_3D.R(2))) + pi/2;
                                projection_angle_2 = atan2( ...
                                    (contour_3D.Z(end-1) - contour_3D.Z(end)) , ...
                                    (contour_3D.R(end-1) - contour_3D.R(end))) + pi/2;

                                end1_r = contour_3D.R(1) + app.EdgeWallSpinner.Value * cos(projection_angle_1);
                                end1_z = contour_3D.Z(1) + app.EdgeWallSpinner.Value * sin(projection_angle_1);
                                end2_r = contour_3D.R(end) + app.EdgeWallSpinner.Value * cos(projection_angle_2);
                                end2_z = contour_3D.Z(end) + app.EdgeWallSpinner.Value * sin(projection_angle_2);

                                % build the contour
                                er1ls = linspace(end1_r, contour_3D.R(1), app.magic_number_1);
                                er1ls = er1ls(1:(end-1));
                                er2ls = linspace(contour_3D.R(end), end2_r, app.magic_number_1);
                                er2ls = er2ls(2:(end));
                                ez1ls = linspace(end1_z, contour_3D.Z(1), app.magic_number_1);
                                ez1ls = ez1ls(1:(end-1));
                                ez2ls = linspace(contour_3D.Z(end), end2_z, app.magic_number_1);
                                ez2ls = ez2ls(2:(end));

                                app.curve_information{ii}.toroidal_slice(jj).R = [er1ls ...
                                    contour_3D.R er2ls];
                                app.curve_information{ii}.toroidal_slice(jj).Z = [ez1ls ...
                                    contour_3D.Z ez2ls];
                                app.curve_information{ii}.toroidal_slice(jj).Phi = this_Phi;

                            end
                        end
                    end
                end
                %keyboard

            end
            disp('<----Done with curve loops');


        end

        % Button pushed function: ExportZonesInPCoTGformatButton
        function ExportZonesInPCoTGformatButtonPushed(app, event)

            uiopen('/Users/schmittj/Documents/Stellarator/HSX_Vessel_Loft_2022A/Stelloft_Wall_Files/Set_J/modB_on_lcfs_half_field_period.fig',1)
            figure
            hold on;
            grid on;
            view(3);
            axis equal;
            box on;
            fh = figure(app.FigNumEditField.Value);

            myazes = gca;
            hold(myazes, 'on')

            % count number of divertor sections and determine number of
            % points/section
            app.divertor_section_count(1:app.NumberOfZones) = 0;
            app.points_per_section(1:app.NumberOfZones) = 0;

            for ii = 1:app.NumberOfZones
                %figure
                %hold on;
                %grid on;
                %view(3);
                %axis equal;
                %box on;

                ind = mod(ii, length(app.lcolor_list));
                if (ind == 0)
                    ind = length(app.lcolor_list);
                end

                lcolor = app.lcolor_list{ind};


                disp(['<----Zone: ' num2str(ii)]);
                for jj = 1:app.toroidal_index_max
                    R_info = app.curve_information{ii}.toroidal_slice(jj).R;
                    Z_info = app.curve_information{ii}.toroidal_slice(jj).Z;
                    Phi_info = app.curve_information{ii}.toroidal_slice(jj).Phi;
                    if isempty(R_info)
                    else
                        app.divertor_section_count(ii) = ...
                            app.divertor_section_count(ii) + 1;
                        app.points_per_section(ii) = length(R_info);
                        X1 = R_info * cos(Phi_info);
                        Y1 = R_info * sin(Phi_info);
                        plot3(myazes, X1, Y1, Z_info, '-', 'Color', lcolor, ...
                            'Linewidth', 2);
                        % 2nd half
                        %  plot3(Y1, X1, -Z_info, '-', 'Color', lcolor, ...
                        %     'Linewidth', 2);

                    end
                end
            end

            % loop over each zone.  Open a file and print out the divertor
            % info
            zone_file_counter = 0;
            for ii = 1:app.NumberOfZones
                if (app.divertor_section_count(ii) > 1)

                    this_filename = [app.DivertorZonePrefixEditField.Value '_divzone_' num2str(ii) '.txt'];
                    zone_file_counter = zone_file_counter + 1;
                    zone_files{zone_file_counter} = this_filename;
                    fid = fopen(this_filename, 'w');
                    fprintf(fid, [this_filename '\n']);
                    fprintf(fid, '%4i %4i %4i %12.7e %12.7e\n', ...
                        app.divertor_section_count(ii), app.points_per_section(ii), ...
                        app.vmec_data.nfp, 0.0, 0.0);
                    for jj = 1:app.toroidal_index_max
                        R_info = app.curve_information{ii}.toroidal_slice(jj).R;
                        Z_info = app.curve_information{ii}.toroidal_slice(jj).Z;
                        Phi_info = app.curve_information{ii}.toroidal_slice(jj).Phi;
                        if isempty(R_info)
                        else
                            fprintf(fid, '%6.3f\n', Phi_info*180/pi);
                            for kk = 1:length(R_info)
                                fprintf(fid, '    %16.10f    %16.10f\n', ...
                                    R_info(kk), Z_info(kk));
                            end
                        end
                    end
                    fclose(fid);
                end
            end


            % .boundary
            wall_out_name_gui = 'wall.txt';

            fid = fopen('.boundary', 'w');

            fprintf(fid, ['[wall:torosurf] \n']);
            fprintf(fid, ['filename: ' wall_out_name_gui '\n']);
            fprintf(fid, ['stellarator_symmetry: True\n']);
            fprintf(fid, ['units: ' 'meter' '\n']);
            fprintf(fid, ['\n']);
            for ii = 1:length(zone_files)
                fprintf(fid, ['[div_zone_' num2str(ii) ':torosurf] \n']);
                fprintf(fid, ['filename: ' zone_files{ii} '\n']);
                fprintf(fid, ['stellarator_symmetry: True\n']);
                fprintf(fid, ['units: ' 'meter' '\n']);
                fprintf(fid, ['\n']);
            end
            fclose(fid);


        end

        % Button pushed function: ExportSlicesasPNGsButton
        function ExportSlicesasPNGsButtonPushed(app, event)

            app.save_to_png = 1;
            for ii = 1:app.toroidal_index_max

                app.toroidal_index = ii;

                app.UpdateFigureButtonPushed(app);
                set(gcf,'Position', [20 20 800 800])
                %app.UpdateAssistButtonPushed(app);
                pause(0.001);
                aa=gcf;
                save_gcf_local(['HSX_divetor_detail_V1_tor_slice_' ...
                    num2str(ii) '_of_' ...
                    num2str(app.toroidal_index_max)],1, 1, 1);
                close(aa);
                pause(0.001);
                if 0
                    for iii = 1:app.toroidal_index_max
                        figure(iii);
                        set(gcf,'Position', [20 -300 1200 1200])

                        save_gcf_local(...
                            ['HSX_divetor_detail_v1_800x800_tor_slice_' ...
                            num2str(iii) '_of_' ...
                            num2str(app.toroidal_index_max)],1, 1, 1);

                        %close(gcf);
                    end

                end
            end
            app.save_to_png = 0;
        end

        % Value changed function: BufferZonesSlider
        function BufferZonesSliderValueChanged(app, event)
            value = app.BufferZonesSlider.Value;
            app.BufferZonesSlider.Value = round(value);
        end

        % Button pushed function: MakeFLAREFilesButton
        function MakeFLAREFilesButtonPushed(app, event)
            %close all
            app.Lc_map_inset = 1e-3;
            app.Lc_launch_multiplier = 10;%put 10 points where 1 was

            app.magic_number_1 = app.magic_number_1;
            magic_number_2 = app.magic_number_1 - 1;

            flare_runs_counter = 0;

            for ii = 1:app.NumberOfZones
                %if (ii == 6)
                %    keyboard
                %end

                if (app.divertor_section_count(ii) > 1)

                    for jj = 1:app.toroidal_index_max

                        R_info = app.curve_information{ii}.toroidal_slice(jj).R;
                        Z_info = app.curve_information{ii}.toroidal_slice(jj).Z;
                        Phi_info = app.curve_information{ii}.toroidal_slice(jj).Phi;
                        if isempty(R_info)
                        else
                            R0 = app.raxisline(jj);
                            Z0 = app.zaxisline(jj);

                            Lc_filename_out = ['Lc_Grid_' app.DivertorZonePrefixEditField.Value '_divzone_' num2str(ii) '_torsection_' num2str(jj) '.txt'];
                            fid = fopen(Lc_filename_out, 'w');
                            % write to file
                            fprintf(fid, '# MAP3D 1 2 %16.14e\n', Phi_info*180/pi);
                            fprintf(fid, '# COORDINATES cylindrical_deg\n');
                            fprintf(fid, '# UNITS meter\n');
                            fprintf(fid, '# TYPE qmesh\n');

                            num_Lc_Sections = 1 + app.Lc_launch_multiplier * ...
                                (app.NumSectionsperZoneSpinner.Value - 1);

                            fprintf(fid, '# NODES  %6i %6i\n', num_Lc_Sections, 1);

                            % Only use the points that aren't on the edge.
                            num_points = length(R_info);
                            R_use = R_info(app.magic_number_1:(num_points-magic_number_2));
                            Z_use = Z_info(app.magic_number_1:(num_points-magic_number_2));
                            Phi_use = Phi_info;

                            num_points_use = length(R_use); %

                            total_num_points = (num_points_use - 1) * app.Lc_launch_multiplier + 1;
                            %if (total_num_points ~= num_Lc_Sections)
                            %    keyboard
                            %end
                            %fh = figure(app.FigNumEditField.Value);

                            %myazes = gca;
                            %hold(myazes, 'on')
                            for kk = 1:(num_points_use-1)
                                %r_loop = interp1([0 1], [R_use(kk) R_use(kk+1)], 0.1+0.8*([0:(app.Lc_launch_multiplier-1)]/app.Lc_launch_multiplier));
                                %z_loop = interp1([0 1], [Z_use(kk) Z_use(kk+1)], 0.1+0.8*([0:(app.Lc_launch_multiplier-1)]/app.Lc_launch_multiplier));
                                r_loop = interp1([0 1], [R_use(kk) R_use(kk+1)], ([0:(app.Lc_launch_multiplier-1)]/app.Lc_launch_multiplier));
                                z_loop = interp1([0 1], [Z_use(kk) Z_use(kk+1)], ([0:(app.Lc_launch_multiplier-1)]/app.Lc_launch_multiplier));


                                for ll = 1:app.Lc_launch_multiplier
                                    L_norm = sqrt((r_loop(ll) - R0)^2 + ...
                                        (z_loop(ll) - Z0)^2);
                                    dR = (R0 - r_loop(ll)) / L_norm;
                                    dZ = (Z0 - z_loop(ll)) / L_norm;

                                    new_r = r_loop(ll) + app.Lc_map_inset * dR;
                                    new_z = z_loop(ll) + app.Lc_map_inset * dZ;
                                    fprintf(fid, '%16.14e %16.14e\n', new_r, new_z );

                                    %plot3(myazes, new_r * cos(Phi_use), new_r * sin(Phi_use), new_z, 'r.');
                                end


                            end
                            % handle last point
                            %r_loop = interp1([0 1], [R_use(num_points_use-1) R_use(num_points_use)], 0.9);
                            %z_loop = interp1([0 1], [Z_use(num_points_use-1) Z_use(num_points_use)], 0.9);
                            r_loop = R_use(num_points_use);
                            z_loop = Z_use(num_points_use);
                            %L_norm = sqrt((R_use(end) - R0)^2 + ...
                            %    (Z_use(end) - Z0))^2;
                            %dR = (R0 - R_use(end)) / L_norm;
                            %dZ = (Z0 - Z_use(end)) / L_norm;

                            %new_r = R_use(end) + app.Lc_map_inset * dR;
                            %new_z = Z_use(end) + app.Lc_map_inset * dZ;
                            L_norm = sqrt((r_loop - R0)^2 + ...
                                (z_loop - Z0)^2);
                            dR = (R0 - r_loop) / L_norm;
                            dZ = (Z0 - z_loop) / L_norm;
                            new_r = r_loop + app.Lc_map_inset * dR;
                            new_z = z_loop + app.Lc_map_inset * dZ;

                            fprintf(fid, '%16.14e %16.14e\n', new_r, new_z );
                            fclose(fid);
                            %plot3(myazes, new_r * cos(Phi_use), new_r * sin(Phi_use), new_z, 'k.');


                            myflarefoldername = app.OutputFolderPrefixLcScanEditField.Value;
                            % make  .ctr and slurm files
                            ctr_filename = ['L_c_map_' app.DivertorZonePrefixEditField.Value '_divzone_' num2str(ii) '_torsection_' num2str(jj) '.ctr'];
                            results_filename = ['results_' app.DivertorZonePrefixEditField.Value '_divzone_' num2str(ii) '_torsection_' num2str(jj) '.dat'];
                            fid = fopen(ctr_filename, 'w');
                            fprintf(fid, '#Sample\n');
                            fprintf(fid, '[FLARE]\n');
                            fprintf(fid, ['model: ' ...
                                myflarefoldername '\n']);

                            fprintf(fid, 'fieldline.epsabs:  %16.14e\n', ...
                                1e-10);

                            fprintf(fid, 'fieldline.edom: 1\n');
                            %if (abs(0) < eps)
                            fprintf(fid, '#fieldline.diffusion:  %16.14e\n', ...
                                0.0);
                            %else
                            %    fprintf(fid, 'fieldline.diffusion:  %16.14e\n', ...
                            %        app.LcDiffusionCoefficientEditField.Value);

                            %end
                            fprintf(fid, '[fieldline_connection]\n');
                            fprintf(fid, ['grid: ' Lc_filename_out ...
                                '\n']);
                            fprintf(fid, 'Lcmax: %16.14e\n', ...
                                1e5);
                            fprintf(fid, ['output: ' results_filename '\n']);
                            fprintf(fid, 'xbwd: True\n');
                            fprintf(fid, 'xfwd: True\n');
                            fprintf(fid, 'ubwd: True\n');
                            fprintf(fid, 'ufwd: True\n');
                            fclose(fid);

                            flare_runs_counter = flare_runs_counter + 1;
                            flare_runs{flare_runs_counter} = ctr_filename;

                        end

                    end

                end
            end


            bash_filename = 'doit_script.sh';
            fid = fopen(bash_filename, 'w');

            fprintf(fid, '#!/bin/bash -l\n');


            fprintf(fid, '# Load compiler and MPI modules (must be the same as used for compiling the code)\n');
            fprintf(fid, 'module purge\n');
            fprintf(fid, 'module use /u/hfrerich/software/modules\n');
            fprintf(fid, 'module load flare/2022.4\n');
            fprintf(fid, '\n');
            fprintf(fid, '# Run the program:\n');
            for  ii = 1:length(flare_runs)
                fprintf(fid, ['flare -n 1 run -c ' flare_runs{ii} ' \n']);
            end
            fclose(fid);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '---')
            xlabel(app.UIAxes, 'R')
            ylabel(app.UIAxes, 'Z')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.InitializeDataButton = uibutton(app.UIFigure, 'push');
            app.InitializeDataButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeDataButtonPushed, true);
            app.InitializeDataButton.Text = 'Initialize Data';
            app.NextToroiedalAngleButton = uibutton(app.UIFigure, 'push');
            app.NextToroiedalAngleButton.ButtonPushedFcn = createCallbackFcn(app, @NextToroiedalAngleButtonPushed, true);
            app.NextToroiedalAngleButton.Text = 'Next Toroiedal Angle';
            app.PreviousToroidalAngleButton = uibutton(app.UIFigure, 'push');
            app.PreviousToroidalAngleButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousToroidalAngleButtonPushed, true);
            app.PreviousToroidalAngleButton.Text = 'Previous Toroidal Angle';
            app.AddSegmentButton = uibutton(app.UIFigure, 'push');
            app.AddSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @AddSegmentButtonPushed, true);
            app.AddSegmentButton.Text = 'Add Segment';
            app.RemoveSegmentButton = uibutton(app.UIFigure, 'push');
            app.RemoveSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSegmentButtonPushed, true);
            app.RemoveSegmentButton.Text = 'Remove Segment';
            app.SelectSegmentButton = uibutton(app.UIFigure, 'push');
            app.SelectSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSegmentButtonPushed, true);
            app.SelectSegmentButton.Text = 'Select Segment';
            app.LoadSegmentsButton = uibutton(app.UIFigure, 'push');
            app.LoadSegmentsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSegmentsButtonPushed, true);
            app.LoadSegmentsButton.Text = 'Load Segments';
            app.SaveSegmentsButton = uibutton(app.UIFigure, 'push');
            app.SaveSegmentsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSegmentsButtonPushed, true);
            app.SaveSegmentsButton.Text = 'Save Segments';
            app.R1EditFieldLabel = uilabel(app.UIFigure);
            app.R1EditFieldLabel.Text = 'R(1)';
            app.R1EditField = uieditfield(app.UIFigure, 'numeric');
            app.R1EditField.ValueDisplayFormat = '%13.6f';
            app.R1EditField.ValueChangedFcn = createCallbackFcn(app, @R1EditFieldValueChanged, true);
            app.Z1EditFieldLabel = uilabel(app.UIFigure);
            app.Z1EditFieldLabel.Text = 'Z(1)';
            app.Z1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z1EditField.ValueDisplayFormat = '%13.6f';
            app.Z1EditField.ValueChangedFcn = createCallbackFcn(app, @Z1EditFieldValueChanged, true);
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'R(2)';
            app.R2EditField = uieditfield(app.UIFigure, 'numeric');
            app.R2EditField.ValueDisplayFormat = '%13.6f';
            app.R2EditField.ValueChangedFcn = createCallbackFcn(app, @R2EditFieldValueChanged, true);
            app.Z2EditFieldLabel = uilabel(app.UIFigure);
            app.Z2EditFieldLabel.Text = 'Z(2)';
            app.Z2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z2EditField.ValueDisplayFormat = '%13.6f';
            app.Z2EditField.ValueChangedFcn = createCallbackFcn(app, @Z2EditFieldValueChanged, true);
            app.UpdateFigureButton = uibutton(app.UIFigure, 'push');
            app.UpdateFigureButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateFigureButtonPushed, true);
            app.UpdateFigureButton.Text = 'Update Figure';
            app.DeltaEditFieldLabel = uilabel(app.UIFigure);
            app.DeltaEditFieldLabel.Text = 'Delta';
            app.DeltaEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeltaEditField.Editable = 'off';
            app.PickCenterSwitchLabel = uilabel(app.UIFigure);
            app.PickCenterSwitchLabel.Text = {'Pick'; 'Center'};
            app.PickCenterSwitch = uiswitch(app.UIFigure, 'toggle');
            app.PoloidalIndexSpinnerLabel = uilabel(app.UIFigure);
            app.PoloidalIndexSpinnerLabel.Text = {'Poloidal'; 'Index'};
            app.PoloidalIndexSpinner = uispinner(app.UIFigure);
            app.PoloidalIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @PoloidalIndexSpinnerValueChanged, true);
            app.PoloidalIndexSpinner.Value = 1;
            app.SurfaceDeltaEditFieldLabel = uilabel(app.UIFigure);
            app.SurfaceDeltaEditFieldLabel.Text = {'Surface'; 'Delta'};
            app.SurfaceDeltaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AssistSwitch = uiswitch(app.UIFigure, 'slider');
            app.AssistSwitchLabel = uilabel(app.UIFigure);
            app.AssistSwitchLabel.Text = 'Assist';
            app.ShowSurfDataCheckBox = uicheckbox(app.UIFigure);
            app.ShowSurfDataCheckBox.Text = 'Show Surf Data';
            app.thetaSpinnerLabel = uilabel(app.UIFigure);
            app.thetaSpinnerLabel.Text = 'theta*';
            app.thetaSpinner = uispinner(app.UIFigure);
            app.thetaSpinner.ValueChangedFcn = createCallbackFcn(app, @thetaSpinnerValueChanged, true);
            app.UpdateAssistButton = uibutton(app.UIFigure, 'push');
            app.UpdateAssistButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAssistButtonPushed, true);
            app.UpdateAssistButton.Text = 'Update Assist';
            app.InwardOffsetSpinnerLabel = uilabel(app.UIFigure);
            app.InwardOffsetSpinnerLabel.Text = {'Inward'; 'Offset'};
            app.InwardOffsetSpinner = uispinner(app.UIFigure);
            app.InwardOffsetSpinner.Step = 0.001;
            app.InwardOffsetSpinner.Limits = [-0.2 0.2];
            app.InwardOffsetSpinner.ValueDisplayFormat = '%11.5f';
            app.InwardOffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @InwardOffsetSpinnerValueChanged, true);
            app.InwardOffsetSpinner.Value = 0.01;
            app.AnchortoVesselSwitchLabel = uilabel(app.UIFigure);
            app.AnchortoVesselSwitchLabel.Text = {'Anchor'; 'to Vessel'};
            app.AnchortoVesselSwitch = uiswitch(app.UIFigure, 'toggle');
            app.AimAtAxisSwitchLabel = uilabel(app.UIFigure);
            app.AimAtAxisSwitchLabel.Text = 'Aim At Axis';
            app.AimAtAxisSwitch = uiswitch(app.UIFigure, 'slider');
            app.AimAtAxisSwitch.Value = 'On';
            app.WidthSpinnerLabel = uilabel(app.UIFigure);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.UIFigure);
            app.WidthSpinner.Step = 0.001;
            app.WidthSpinner.Limits = [0 0.3];
            app.WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @WidthSpinnerValueChanged, true);
            app.WidthSpinner.Value = 0.02;
            app.MakeADivertorPanelButton = uibutton(app.UIFigure, 'push');
            app.MakeADivertorPanelButton.ButtonPushedFcn = createCallbackFcn(app, @MakeADivertorPanelButtonPushed, true);
            app.MakeADivertorPanelButton.Text = 'Make-A-Divertor-Panel';
            app.MakeADivertorButton = uibutton(app.UIFigure, 'push');
            app.MakeADivertorButton.ButtonPushedFcn = createCallbackFcn(app, @MakeADivertorButtonPushed, true);
            app.MakeADivertorButton.Text = 'Make-A-Divertor';
            app.ClearAllSegmentsButton = uibutton(app.UIFigure, 'push');
            app.ClearAllSegmentsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllSegmentsButtonPushed, true);
            app.ClearAllSegmentsButton.Text = 'Clear All Segments';
            app.MakeCurvesButton = uibutton(app.UIFigure, 'push');
            app.MakeCurvesButton.ButtonPushedFcn = createCallbackFcn(app, @MakeCurvesButtonPushed, true);
            app.MakeCurvesButton.Text = 'Make-Curves';
            app.ExportZonesInPCoTGformatButton = uibutton(app.UIFigure, 'push');
            app.ExportZonesInPCoTGformatButton.ButtonPushedFcn = createCallbackFcn(app, @ExportZonesInPCoTGformatButtonPushed, true);
            app.ExportZonesInPCoTGformatButton.Text = {'Export Zones In'; 'PCoTG format'};
            app.MinWidthSpinnerLabel = uilabel(app.UIFigure);
            app.MinWidthSpinnerLabel.Text = 'Min Width';
            app.MinWidthSpinner = uispinner(app.UIFigure);
            app.MinWidthSpinner.Step = 0.001;
            app.MinWidthSpinner.Limits = [0 Inf];
            app.MinWidthSpinner.Value = 0.01;
            app.MaxTorExtSpinnerLabel = uilabel(app.UIFigure);
            app.MaxTorExtSpinnerLabel.Text = 'Max Tor Ext';
            app.MaxTorExtSpinner = uispinner(app.UIFigure);
            app.MaxTorExtSpinner.Step = 0.25;
            app.MaxTorExtSpinner.Limits = [0 360];
            app.MaxTorExtSpinner.Value = 6;
            app.NumSectionsperZoneSpinnerLabel = uilabel(app.UIFigure);
            app.NumSectionsperZoneSpinnerLabel.Text = 'Num Sections per Zone';
            app.NumSectionsperZoneSpinner = uispinner(app.UIFigure);
            app.NumSectionsperZoneSpinner.Limits = [0 360];
            app.NumSectionsperZoneSpinner.Value = 21;
            app.MaxSectionWidthSpinnerLabel = uilabel(app.UIFigure);
            app.MaxSectionWidthSpinnerLabel.Text = 'Max Section Width';
            app.MaxSectionWidthSpinner = uispinner(app.UIFigure);
            app.MaxSectionWidthSpinner.Step = 0.001;
            app.MaxSectionWidthSpinner.Limits = [0 1];
            app.MaxSectionWidthSpinner.ValueDisplayFormat = '%11.6f';
            app.MaxSectionWidthSpinner.Value = 0.003;
            app.ZoneProximityLimitEditFieldLabel = uilabel(app.UIFigure);
            app.ZoneProximityLimitEditFieldLabel.Text = 'Zone Proximity Limit';
            app.ZoneProximityLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZoneProximityLimitEditField.Limits = [0 1];
            app.ZoneProximityLimitEditField.ValueDisplayFormat = '%11.6f';
            app.ZoneProximityLimitEditField.Value = 0.02;
            app.EdgeWallSpinnerLabel = uilabel(app.UIFigure);
            app.EdgeWallSpinnerLabel.Text = 'Edge Wall';
            app.EdgeWallSpinner = uispinner(app.UIFigure);
            app.EdgeWallSpinner.Step = 0.001;
            app.EdgeWallSpinner.Limits = [0 1];
            app.EdgeWallSpinner.Value = 0.025;
            app.ExportSlicesasPNGsButton = uibutton(app.UIFigure, 'push');
            app.ExportSlicesasPNGsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSlicesasPNGsButtonPushed, true);
            app.ExportSlicesasPNGsButton.Text = 'Export Slices as PNGs';
            app.ResidueLevelEditFieldLabel = uilabel(app.UIFigure);
            app.ResidueLevelEditFieldLabel.Text = 'Residue Level';
            app.ResidueLevelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResidueLevelEditField.ValueDisplayFormat = '%11.4f';
            app.ResidueLevelEditField.Value = 5;
            app.PoloidalPointsEditFieldLabel = uilabel(app.UIFigure);
            app.PoloidalPointsEditFieldLabel.Text = 'Poloidal Points';
            app.PoloidalPointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PoloidalPointsEditField.Value = 361;
            app.DivertorZonePrefixEditFieldLabel = uilabel(app.UIFigure);
            app.DivertorZonePrefixEditFieldLabel.Text = 'Divertor Zone Prefix';
            app.DivertorZonePrefixEditField = uieditfield(app.UIFigure, 'text');
            app.BufferZonesSliderLabel = uilabel(app.UIFigure);
            app.BufferZonesSliderLabel.Text = {'Buffer'; 'Zones'};
            app.BufferZonesSlider = uislider(app.UIFigure);
            app.BufferZonesSlider.Limits = [0 10];
            app.BufferZonesSlider.ValueChangedFcn = createCallbackFcn(app, @BufferZonesSliderValueChanged, true);
            app.BufferZonesSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.BufferZonesSlider.Value = 1;
            app.MakeFLAREFilesButton = uibutton(app.UIFigure, 'push');
            app.MakeFLAREFilesButton.ButtonPushedFcn = createCallbackFcn(app, @MakeFLAREFilesButtonPushed, true);
            app.MakeFLAREFilesButton.Text = 'Make FLARE Files';
            app.boundaryfilenameEditFieldLabel = uilabel(app.UIFigure);
            app.boundaryfilenameEditFieldLabel.Text = '.boundary filename';
            app.FLAREboundaryfilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FLAREboundaryfilenameEditField.Value = 'wall.txt';
            app.OutputFolderPrefixLcScanEditFieldLabel = uilabel(app.UIFigure);
            app.OutputFolderPrefixLcScanEditFieldLabel.Text = 'Output Folder Prefix (Lc Scan)';
            app.OutputFolderPrefixLcScanEditField = uieditfield(app.UIFigure, 'text');
            app.FigNumEditFieldLabel = uilabel(app.UIFigure);
            app.FigNumEditFieldLabel.Text = 'FigNum';
            app.FigNumEditField = uieditfield(app.UIFigure, 'numeric');
            app.FigNumEditField.Value = 1450;
            app.CurveStyleLabel = uilabel(app.UIFigure);
            app.CurveStyleLabel.Text = {'Curve'; 'Style'};
            app.CurveStyleSwitch = uiswitch(app.UIFigure, 'toggle');
            app.CurveStyleSwitch.Items = {'Outer', 'Inner'};
            app.CurveStyleSwitch.Value = 'Inner';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Divertor_PnP

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end