classdef Stelloft_v0_20221222a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Stelloftv00aUIFigure            matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        TabGroup                        matlab.ui.container.TabGroup
        CoilsMHDTab                     matlab.ui.container.Tab
        UseNESCinsteadofWoutforLoftingSwitch  matlab.ui.control.Switch
        UseNESCinsteadofWoutforLoftingSwitchLabel  matlab.ui.control.Label
        ImportNESCSurfaceButton         matlab.ui.control.Button
        UseWithoutCoilsSwitch           matlab.ui.control.Switch
        UseWithoutCoilsLabel            matlab.ui.control.Label
        ImportAxisandLCFSGeometryLoadVMECoutoutfilewout_ncButton  matlab.ui.control.Button
        ImportCoilCornersGeometryorcoilfilamentGeometryButton  matlab.ui.control.Button
        MachineButtonGroup              matlab.ui.container.ButtonGroup
        OtherselectLIBSTELLcoilsfileButton  matlab.ui.control.RadioButton
        HSXButton                       matlab.ui.control.RadioButton
        WISTELLAATENButton              matlab.ui.control.RadioButton
        TraceImportTab                  matlab.ui.container.Tab
        TraceIndexListEditField         matlab.ui.control.EditField
        TraceIndexListEditFieldLabel    matlab.ui.control.Label
        InverseDensityTolEditField      matlab.ui.control.NumericEditField
        InverseDensityTolEditFieldLabel  matlab.ui.control.Label
        LoadTraceInformationButton      matlab.ui.control.Button
        SaveTraceInformationButton      matlab.ui.control.Button
        ClearIniializeTraceDataButton   matlab.ui.control.Button
        ImportManyTracesButton          matlab.ui.control.Button
        rhomaskminEditField             matlab.ui.control.NumericEditField
        rhomaskminEditFieldLabel        matlab.ui.control.Label
        ImportSingleTraceButton         matlab.ui.control.Button
        GridDefinitionsTab              matlab.ui.container.Tab
        NumberofToroidalGridpointsintheloftedsurfaceEditField  matlab.ui.control.NumericEditField
        NumberofToroidalGridpointsintheloftedsurfaceEditFieldLabel  matlab.ui.control.Label
        InitialStepSizeEditField        matlab.ui.control.NumericEditField
        InitialStepSizeEditFieldLabel   matlab.ui.control.Label
        InitialLoftOffsetEditField      matlab.ui.control.NumericEditField
        InitialLoftOffsetEditFieldLabel  matlab.ui.control.Label
        IndicesofCoilstoUseforInterferenceChecksEditField  matlab.ui.control.EditField
        IndicesofCoilstoUseforInterferenceChecksLabel  matlab.ui.control.Label
        ReferenceRadiusNormalizedToroidalFluxEditField  matlab.ui.control.NumericEditField
        ReferenceRadiusNormalizedToroidalFluxLabel  matlab.ui.control.Label
        numberOfpoloidalpointsintheloftedsurfacesEditField  matlab.ui.control.NumericEditField
        ofpoloidalpointsintheloftedsurfacesEditFieldLabel  matlab.ui.control.Label
        numberOfintermedplanesbtwntorcntrlptsEditField  matlab.ui.control.NumericEditField
        ofintermediateanglesplanesbetweencontrolpointsEditFieldLabel  matlab.ui.control.Label
        numberOfPoloidalControlPointsEditField  matlab.ui.control.NumericEditField
        ofPoloidalControlPointsEditFieldLabel  matlab.ui.control.Label
        numberOfToroidalControlPointsEditField  matlab.ui.control.NumericEditField
        ofToroidalControlPointsEditFieldLabel  matlab.ui.control.Label
        Phi_EnddegreesEditField         matlab.ui.control.NumericEditField
        Phi_EnddegreesEditFieldLabel    matlab.ui.control.Label
        Phi_StartdegreesEditField       matlab.ui.control.NumericEditField
        Phi_StartdegreesEditFieldLabel  matlab.ui.control.Label
        SaveGridDefinitionsButton       matlab.ui.control.Button
        LoadGridDefinitionsButton       matlab.ui.control.Button
        InitilizationTab                matlab.ui.container.Tab
        SaveonDefineCheckBox            matlab.ui.control.CheckBox
        ReSpaceTraceInterferencePointsButton  matlab.ui.control.Button
        PlotInterferencePointsatControlPlanesButton  matlab.ui.control.Button
        LoadInterferencePointsButton    matlab.ui.control.Button
        SaveInterferencePointsButton    matlab.ui.control.Button
        DefineControlandIntermediateSurfaceGridsButton_2  matlab.ui.control.Button
        InitializeLoftedSurfaceButton   matlab.ui.control.Button
        DefineControlandIntermediateSurfaceGridsButton  matlab.ui.control.Button
        InterpolationTab                matlab.ui.container.Tab
        ResidueTracesEditField          matlab.ui.control.NumericEditField
        ResidueTracesEditFieldLabel     matlab.ui.control.Label
        ResidueCoilsEditField           matlab.ui.control.NumericEditField
        ResidueCoilsEditFieldLabel      matlab.ui.control.Label
        ToroidalInterpolationButtonGroup  matlab.ui.container.ButtonGroup
        LinearInterpolationButton       matlab.ui.control.RadioButton
        PoloidalInterpoloationButtonGroup  matlab.ui.container.ButtonGroup
        EnforceStellaratorSymmetryatPhi0and2piNFPCheckBox  matlab.ui.control.CheckBox
        deltaEditField                  matlab.ui.control.NumericEditField
        deltaEditFieldLabel             matlab.ui.control.Label
        alphaEditField                  matlab.ui.control.NumericEditField
        alphaEditFieldLabel             matlab.ui.control.Label
        alphadeltaButton                matlab.ui.control.RadioButton
        pchipButton                     matlab.ui.control.RadioButton
        SplineButton                    matlab.ui.control.RadioButton
        LoftingTab                      matlab.ui.container.Tab
        MagicButton                     matlab.ui.control.Button
        PauseAutoLoftCheckBox           matlab.ui.control.CheckBox
        AutoLoftButton                  matlab.ui.control.Button
        DebugLevelKnob                  matlab.ui.control.DiscreteKnob
        DebugLevelKnobLabel             matlab.ui.control.Label
        EZEditPanel                     matlab.ui.container.Panel
        EZLoftStepSizeEditField         matlab.ui.control.NumericEditField
        EZLoftStepSizeEditFieldLabel    matlab.ui.control.Label
        LoftControlPointSpinner         matlab.ui.control.Spinner
        LoftControlPointSpinnerLabel    matlab.ui.control.Label
        PoloidalIndexSpinner            matlab.ui.control.Spinner
        PoloidalIndexSpinnerLabel       matlab.ui.control.Label
        ToroidalIndexSpinner            matlab.ui.control.Spinner
        ToroidalIndexSpinnerLabel       matlab.ui.control.Label
        AutoloftSettingsPanel           matlab.ui.container.Panel
        MinimumLoftDistanceEditField    matlab.ui.control.NumericEditField
        MinimumLoftDistanceEditFieldLabel  matlab.ui.control.Label
        PostOptimizeBackoffEditField    matlab.ui.control.NumericEditField
        PostOptimizeBackoffEditFieldLabel  matlab.ui.control.Label
        MaxLoftDistanceEditField        matlab.ui.control.NumericEditField
        MaxLoftDistanceEditFieldLabel   matlab.ui.control.Label
        ManipulateSplinePointsInteractivelyButton  matlab.ui.control.Button
        CheckCurrentLoftedSurfaceButton  matlab.ui.control.Button
        PlotCurrentLoftedSurfaceButton  matlab.ui.control.Button
        SaveControlPointLoftValuesButton  matlab.ui.control.Button
        LoadControlPointLoftValuesButton  matlab.ui.control.Button
        FLAREdotfilesTab                matlab.ui.container.Tab
        equi3doftoroidalpointsEditField  matlab.ui.control.NumericEditField
        equi3doftoroidalpointsEditFieldLabel  matlab.ui.control.Label
        equi3dsource                    matlab.ui.control.DropDown
        equi3dsourceLabel               matlab.ui.control.Label
        FLAREbfieldtypeDropDown         matlab.ui.control.DropDown
        bfieldtypeLabel                 matlab.ui.control.Label
        FLAREboundaryunitsEditField     matlab.ui.control.EditField
        boundaryunitsEditFieldLabel     matlab.ui.control.Label
        FLAREboundarystellarator_symmetryCheckBox  matlab.ui.control.CheckBox
        FLAREboundaryfilenameEditField  matlab.ui.control.EditField
        boundaryfilenameEditFieldLabel  matlab.ui.control.Label
        FLAREboundarydoclineEditField   matlab.ui.control.EditField
        boundarydoclineEditFieldLabel   matlab.ui.control.Label
        FLAREbfieldsourceEditField      matlab.ui.control.EditField
        bfieldsourceEditFieldLabel      matlab.ui.control.Label
        ExportLcTab                     matlab.ui.container.Tab
        MaximumLcLcEditField            matlab.ui.control.NumericEditField
        MaximumLconewayLabel            matlab.ui.control.Label
        LcepsabsEditField               matlab.ui.control.NumericEditField
        LcepsabsEditFieldLabel          matlab.ui.control.Label
        LaunchPlanesLocatedinButtonGroup  matlab.ui.container.ButtonGroup
        FullMachineButton_Lc            matlab.ui.control.RadioButton
        SinglePeriodButton_Lc           matlab.ui.control.RadioButton
        SingleHalfPeriodButton_Lc       matlab.ui.control.RadioButton
        LcDiffusionCoefficientEditField  matlab.ui.control.NumericEditField
        LcDiffusionCoefficientEditFieldLabel  matlab.ui.control.Label
        LcToroidalPlanesEditField       matlab.ui.control.NumericEditField
        TorodalPlanesEditFieldLabel     matlab.ui.control.Label
        LcGridofdivisionsEditField      matlab.ui.control.NumericEditField
        LcGridofdivisionsEditFieldLabel  matlab.ui.control.Label
        LcGridrhomaxEditField           matlab.ui.control.NumericEditField
        LcGridrhomaxEditFieldLabel      matlab.ui.control.Label
        LcGridrhominEditField           matlab.ui.control.NumericEditField
        LcGridrhominEditFieldLabel      matlab.ui.control.Label
        stlLabel                        matlab.ui.control.Label
        OutputFolderPrefixLcScanEditField  matlab.ui.control.EditField
        OutputFolderPrefixLcScanLabel   matlab.ui.control.Label
        ExportFLAREWallLCGridsButton    matlab.ui.control.Button
        ExportStrikelineTab             matlab.ui.container.Tab
        LaunchPlanesLocatedinButtonGroup_strikeline  matlab.ui.container.ButtonGroup
        FullMachineButton_strikeline    matlab.ui.control.RadioButton
        SinglePeriodButton_strikeline   matlab.ui.control.RadioButton
        SingleHalfPeriodButton_strikeline  matlab.ui.control.RadioButton
        MaximumLcStrikelineEditField    matlab.ui.control.NumericEditField
        MaximumLcEditField_2Label       matlab.ui.control.Label
        StriikelineepsabsEditField      matlab.ui.control.NumericEditField
        StriikelineepsabsEditFieldLabel  matlab.ui.control.Label
        StrikelineNumofpoloidalptsEditField  matlab.ui.control.NumericEditField
        StrikelineofpoloidalptsEditFieldLabel  matlab.ui.control.Label
        StrikelineToroidalPlanesEditField  matlab.ui.control.NumericEditField
        TorodalPlanesEditFieldLabel_2   matlab.ui.control.Label
        StrikelineDiffusionCoefficientEditField  matlab.ui.control.NumericEditField
        StrikelineDiffusionCoefficientEditFieldLabel  matlab.ui.control.Label
        StrkelineWallLocationEditFIeld  matlab.ui.control.NumericEditField
        WallLocation121LCFS2LoftedWal12InterpolationLabel  matlab.ui.control.Label
        StrikelineGridofdivisionsEditField  matlab.ui.control.NumericEditField
        StrikelineGridofdivisionsEditFieldLabel  matlab.ui.control.Label
        StrikelineGridrhomaxEditField   matlab.ui.control.NumericEditField
        StrikelineGridrhomaxEditFieldLabel  matlab.ui.control.Label
        StrikelineGridrhominEditField   matlab.ui.control.NumericEditField
        StrikelineGridrhominEditFieldLabel  matlab.ui.control.Label
        PauseForMaskEditsSwitch         matlab.ui.control.Switch
        PauseForMaskEditsSwitchLabel    matlab.ui.control.Label
        OutputFolderPrefixStrikelineScanEditField  matlab.ui.control.EditField
        OutputFolderPrefixStrikelineScanLabel  matlab.ui.control.Label
        ExportFLAREWallStrikelineGridsButton  matlab.ui.control.Button
        ExportSTLTab                    matlab.ui.container.Tab
        STLPrefixEditField              matlab.ui.control.EditField
        STLPrefixEditFieldLabel         matlab.ui.control.Label
        ExportLoftedSurfaceasSTLFilesButton  matlab.ui.control.Button
        ExportNESCOILTab                matlab.ui.container.Tab
        ExportManyNESCOILstylewindingsurfacesButton  matlab.ui.control.Button
        NESCOILToleranceArrayEditField  matlab.ui.control.EditField
        ToleranceArrayLabel             matlab.ui.control.Label
        NESCOILFilenameBaseEditField    matlab.ui.control.EditField
        NESCOILFilenameBaseEditFieldLabel  matlab.ui.control.Label
        NMAXArrayEditField              matlab.ui.control.EditField
        NMAXArrayLabel                  matlab.ui.control.Label
        MMAXArrayEditField              matlab.ui.control.EditField
        MMAXArrayLabel                  matlab.ui.control.Label
        NESCOILToleranceEditField       matlab.ui.control.NumericEditField
        NESCOILToleranceEditFieldLabel  matlab.ui.control.Label
        NESCOILNMAXEditField            matlab.ui.control.NumericEditField
        NESCOILNMAXEditFieldLabel       matlab.ui.control.Label
        NESCOILMMAXEditField            matlab.ui.control.NumericEditField
        NESCOILMMAXEditFieldLabel       matlab.ui.control.Label
        NESCOILFilenameEditField        matlab.ui.control.EditField
        NESCOILFilenameEditFieldLabel   matlab.ui.control.Label
        ExportNESCOILstylewindingsurfaceButton  matlab.ui.control.Button
        ExportMatlabTab                 matlab.ui.container.Tab
        ExportInformativePlotsButton    matlab.ui.control.Button
        ExportFigureWindowinMatlabFormatButton  matlab.ui.control.Button
        PlotCoilsButton                 matlab.ui.control.Button
        PlotPlasmaButton                matlab.ui.control.Button
        InfoStatusPanel                 matlab.ui.container.Panel
        NESCFilenameLabel               matlab.ui.control.Label
        NESCFileLabel                   matlab.ui.control.Label
        NESCLoadedLamp                  matlab.ui.control.Lamp
        NESCLoadedLampLabel             matlab.ui.control.Label
        TraceLoadedLamp                 matlab.ui.control.Lamp
        TraceLoadedLampLabel            matlab.ui.control.Label
        TraceFileFolder                 matlab.ui.control.Label
        TraceFolderorFileLabel          matlab.ui.control.Label
        StatusPanel                     matlab.ui.container.Panel
        OtherLamp_2                     matlab.ui.control.Lamp
        OtherLamp                       matlab.ui.control.Lamp
        OtherLabel                      matlab.ui.control.Label
        NotDefLamp                      matlab.ui.control.Lamp
        NotDefLampLabel                 matlab.ui.control.Label
        BadLamp                         matlab.ui.control.Lamp
        BadLampLabel                    matlab.ui.control.Label
        GoodLamp                        matlab.ui.control.Lamp
        GoodLampLabel                   matlab.ui.control.Label
        CoilCornersLoadedLamp           matlab.ui.control.Lamp
        CoilCornersLoadedLabel          matlab.ui.control.Label
        CoilCornerFilename              matlab.ui.control.Label
        CoilCornerFileLabel             matlab.ui.control.Label
        AutoLoftingLamp                 matlab.ui.control.Lamp
        AutoLoftingLampLabel            matlab.ui.control.Label
        LoftedSurfaceInitializedLamp    matlab.ui.control.Lamp
        LoftedSurfaceInitializedLabel   matlab.ui.control.Label
        InterferencePointsDefinedLamp   matlab.ui.control.Lamp
        InterferencePointsDefinedLabel  matlab.ui.control.Label
        GridsDefinedLamp                matlab.ui.control.Lamp
        GridsDefinedLabel               matlab.ui.control.Label
        LCFSLoadedLamp                  matlab.ui.control.Lamp
        LCFSLoadedLabel                 matlab.ui.control.Label
        CoilsLoadedLamp                 matlab.ui.control.Lamp
        CoilsLoadedLabel                matlab.ui.control.Label
        CoilFilename                    matlab.ui.control.Label
        AxisLCFSFIleLabel               matlab.ui.control.Label
        AxisLCFSFilename                matlab.ui.control.Label
        CoilFileLabel                   matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        coilStruct % Description
        vmec_data % Description
        nesc_data
        vmec_control_points
        vmec_control_normals
        vmec_control_normals_cyl
        vmec_intermediate_points
        vmec_intermediate_normals
        vmec_intermediate_normals_cyl
        vmec_control_points_blowup
        vmec_control_normals_blowup,
        vmec_control_normals_blowup_cyl
        vmec_intermediate_points_blowup
        vmec_intermediate_normals_blowup
        vmec_intermediate_normals_blowup_cyl
        numZeta_vmec_cp
        numTheta_vmec_cp
        numTheta_vmec_cp_bu
        numZeta_vmec_cp_bu
        zeta_cp
        numTheta_vmec_ip
        numTheta_vmec_ip_bu
        numZeta_vmec_ip_per_cp
        num_zeta_ip
        zeta_ip
        interferencePoints_cp
        interferencePoints_ip
        out_surf
        num_tor_angles_ctrl
        num_pol_angles_ctrl
        num_tor_angles_intermediate
        num_pol_angles_intermediate
        num_pol_angles_blowup
        spl_ctrl_pts
        spl_intrmd_pts
        mask_still_moving
        ctr_still_moving
        pol_angles_ctrl_pts
        step_expand
        intrmd_spline_contour
        ind_cp_middle
        num_control_points_even
        currentTraceData
        TraceDataList
        interferencePoints_trace_cp
        interferencePoints_trace_ip
        interferencePoints_loaded
        TraceInterferencePoints_loaded


        % Description


    end

    methods (Access = private)

        function results = define_control_and_intermediate_grids(app)
            results = 0;

            app.numZeta_vmec_cp = app.numberOfToroidalControlPointsEditField.Value;
            app.numTheta_vmec_cp = app.numberOfPoloidalControlPointsEditField.Value;


            theta_min_vmec_cp = 0;
            theta_max_vmec_cp = 2*pi;

            zeta_min_vmec_cp = app.Phi_StartdegreesEditField.Value*pi/180;
            zeta_max_vmec_cp = app.Phi_EnddegreesEditField.Value*pi/180;

            app.numTheta_vmec_cp_bu = app.numberOfpoloidalpointsintheloftedsurfacesEditField.Value;
            app.numZeta_vmec_cp_bu = app.numZeta_vmec_cp;
            theta_min_vmec_cp_bu = theta_min_vmec_cp;
            theta_max_vmec_cp_bu = theta_max_vmec_cp;
            zeta_min_vmec_cp_bu = zeta_min_vmec_cp;
            zeta_max_vmec_cp_bu = zeta_max_vmec_cp;

            app.zeta_cp = linspace(zeta_min_vmec_cp, zeta_max_vmec_cp, app.numZeta_vmec_cp);

            app.numTheta_vmec_ip = app.numTheta_vmec_cp;

            app.numZeta_vmec_ip_per_cp = app.numberOfintermedplanesbtwntorcntrlptsEditField.Value;
            zeta_delta_ip = ((zeta_max_vmec_cp - zeta_min_vmec_cp) / ...
                (app.numZeta_vmec_cp-1)) / (app.numZeta_vmec_ip_per_cp+1);
            theta_min_vmec_ip = 0;
            theta_max_vmec_ip = 2*pi;

            app.numTheta_vmec_ip_bu = app.numTheta_vmec_cp_bu;

            theta_min_vmec_ip_bu = theta_min_vmec_ip;
            theta_max_vmec_ip_bu = theta_max_vmec_ip;

            % intermediate points
            app.num_zeta_ip = ((app.numZeta_vmec_cp-1) * app.numZeta_vmec_ip_per_cp);
            app.zeta_ip = zeros(1, app.num_zeta_ip);
            zeta_ip_counter = 0;
            for phi_index = 1:(app.numZeta_vmec_cp-1)
                phi_angle = app.zeta_cp(phi_index);
                for intermediate_index = 1:app.numZeta_vmec_ip_per_cp
                    zeta_ip_counter = zeta_ip_counter + 1;
                    app.zeta_ip(zeta_ip_counter) = phi_angle + ...
                        intermediate_index * zeta_delta_ip;
                end
            end

            %if (strcmpi(app.UseNESCinsteadofWoutforLoftingSwitch.Value, 'Off'))
            % use the vmec data to fill in control points and
            % intermediate points
            [app.vmec_control_points, app.vmec_control_normals, app.vmec_control_normals_cyl] = ...
                app.get_VMEC_LCFS_points(app.vmec_data, app.numTheta_vmec_cp, app.numZeta_vmec_cp,  ...
                theta_min_vmec_cp, theta_max_vmec_cp, zeta_min_vmec_cp, zeta_max_vmec_cp, app.ReferenceRadiusNormalizedToroidalFluxEditField.Value);
            [app.vmec_intermediate_points, app.vmec_intermediate_normals, app.vmec_intermediate_normals_cyl] = ...
                app.get_VMEC_LCFS_points_2(app.vmec_data, ...
                linspace(theta_min_vmec_ip, theta_max_vmec_ip, app.numTheta_vmec_ip), app.zeta_ip, app.ReferenceRadiusNormalizedToroidalFluxEditField.Value);

            [app.vmec_control_points_blowup, app.vmec_control_normals_blowup, app.vmec_control_normals_blowup_cyl] = ...
                app.get_VMEC_LCFS_points(app.vmec_data, app.numTheta_vmec_cp_bu, app.numZeta_vmec_cp_bu,  ...
                theta_min_vmec_cp_bu, theta_max_vmec_cp_bu, zeta_min_vmec_cp_bu, zeta_max_vmec_cp_bu, app.ReferenceRadiusNormalizedToroidalFluxEditField.Value);
            [app.vmec_intermediate_points_blowup, app.vmec_intermediate_normals_blowup, app.vmec_intermediate_normals_blowup_cyl] = ...
                app.get_VMEC_LCFS_points_2(app.vmec_data, ...
                linspace(theta_min_vmec_ip_bu, theta_max_vmec_ip_bu, app.numTheta_vmec_ip_bu), app.zeta_ip, app.ReferenceRadiusNormalizedToroidalFluxEditField.Value);


            app.vmec_control_points.R = sqrt(app.vmec_control_points.X.^2 + app.vmec_control_points.Y.^2);
            app.vmec_intermediate_points.R = sqrt(app.vmec_intermediate_points.X.^2 + app.vmec_intermediate_points.Y.^2);


            app.vmec_control_points_blowup.R = sqrt(app.vmec_control_points_blowup.X.^2 + app.vmec_control_points_blowup.Y.^2);
            app.vmec_intermediate_points_blowup.R = sqrt(app.vmec_intermediate_points_blowup.X.^2 + app.vmec_intermediate_points_blowup.Y.^2);
            app.GridsDefinedLamp.Color = [0 0 1];


        end

        function results = define_inteference_points(app)
            results = 0;
            axis(app.UIAxes);
            view(app.UIAxes, 3)
            box(app.UIAxes, "on");
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'on');

            use_without_coils = app.UseWithoutCoilsSwitch.Value;
            if (strcmpi(use_without_coils,'No'))

                coils_to_include = str2num(app.IndicesofCoilstoUseforInterferenceChecksEditField.Value);

                for phi_index = 1:app.numZeta_vmec_cp
                    phi_angle = app.zeta_cp(phi_index);
                    this_x_ci = [];
                    this_y_ci = [];
                    this_z_ci = [];
                    %for jj = 1:app.coilStruct.num_coils
                    for jj = coils_to_include
                        for kk = 1:app.coilStruct.corners_per_coil
                            this_corner_line = app.coilStruct.coil_corner_data.coils{jj}.corner{kk}.points;
                            [x, y, z] =  app.extract_poloidal_plane_coil_points(phi_angle, this_corner_line);
                            this_x_ci = [this_x_ci x];
                            this_y_ci = [this_y_ci y];
                            this_z_ci = [this_z_ci z];
                            plot3(app.UIAxes, x,y,z,'r+');
                        end
                    end
                    this_r_ci = sqrt(this_x_ci.^2 + this_y_ci.^2);
                    app.interferencePoints_cp{phi_index}.X = this_x_ci;
                    app.interferencePoints_cp{phi_index}.Y = this_y_ci;
                    app.interferencePoints_cp{phi_index}.Z = this_z_ci;
                    app.interferencePoints_cp{phi_index}.R = this_r_ci;


                    this_x_trace_ci = [];
                    this_y_trace_ci = [];
                    this_z_trace_ci = [];


                    %for jj = 1:app.TraceDataList.numTraces
                    if (app.TraceDataList.numTraces > 0)
                        for jj = str2num(app.TraceIndexListEditField.Value)
                            X = app.TraceDataList.Traces(jj).X;
                            Y = app.TraceDataList.Traces(jj).Y;
                            Z = app.TraceDataList.Traces(jj).Z;
                            this_trace = [X' Y' Z'];


                            sym_angle = 2*pi/app.vmec_data.nfp;

                            for kk = 1:app.vmec_data.nfp
                                % using the number of field periods and
                                % stellarator symmetry to define many
                                % interference points for the edge traces

                                this_phi_angle = (kk-1) * sym_angle + phi_angle;
                                if (this_phi_angle < -pi)
                                    this_phi_angle2 = this_phi_angle + 2*pi;
                                elseif (this_phi_angle > pi)
                                    this_phi_angle2 = this_phi_angle - 2*pi;
                                else
                                    this_phi_angle2 = this_phi_angle;
                                end

                                [x,y,z] = app.extract_poloidal_plane_coil_points(this_phi_angle2, this_trace, 0);
                                temp_r = sqrt(x.^2 + y.^2);
                                new_phi = this_phi_angle - (kk-1)*sym_angle;
                                x = temp_r .* cos(new_phi);
                                y = temp_r .* sin(new_phi);

                                % check (R,Z)-proximity
                                r_cur = sqrt(this_x_trace_ci.^ 2+ ...
                                    this_y_trace_ci.^2);
                                z_cur = this_z_trace_ci;

                                x_add = [];
                                y_add = [];
                                z_add = [];

                                % not needed now.  needed later for masking, post-definition of
                                rho_min_mask = app.rhomaskminEditField.Value;
                                file_vmec_output = app.AxisLCFSFilename.Text;
                                % interference points
                                [~, ~, rho_now, ~, ~, ...
                                    ~, ~, ~, ...
                                    ~, ~, ...
                                    ~, ~, ...
                                    ~, ~, ~, ...
                                    ~, ~, ~, ~, ~, ...
                                    ~, ~, ~, ~, ~] = ...
                                    app.get_VMEC_derived_values(file_vmec_output, ...
                                    x, ...
                                    y, ...
                                    z, 1e-6, ...
                                    .9, pi/2);

                                for ll = 1:length(x)
                                    if (rho_now(ll) > rho_min_mask)

                                        r_chk = sqrt(x(ll)^2 + y(ll)^2);
                                        z_chk = z(ll);
                                        d_chk = sqrt( (r_cur-r_chk).^2 + ...
                                            (z_cur - z_chk).^2);
                                        if (min(d_chk) < ...
                                                app.InverseDensityTolEditField.Value)
                                        else
                                            x_add = [x_add x(ll)];
                                            y_add = [y_add y(ll)];
                                            z_add = [z_add z(ll)];
                                        end
                                    else
                                    end

                                end

                                this_x_trace_ci = [this_x_trace_ci x_add];
                                this_y_trace_ci = [this_y_trace_ci y_add];
                                this_z_trace_ci = [this_z_trace_ci z_add];
                                plot3(app.UIAxes, x_add,y_add,z_add,'b.', 'MarkerSize', 0.01);

                                this_phi_angle = kk * sym_angle - phi_angle;
                                if (this_phi_angle < -pi)
                                    this_phi_angle2 = this_phi_angle + 2*pi;
                                elseif (this_phi_angle > pi)
                                    this_phi_angle2 = this_phi_angle - 2*pi;
                                else
                                    this_phi_angle2 = this_phi_angle;
                                end
                                [x,y,z] = app.extract_poloidal_plane_coil_points(this_phi_angle2, this_trace, 0);
                                %temp_phi = atan2(y,x);
                                temp_r = sqrt(x.^2 + y.^2);
                                new_phi = kk * sym_angle - this_phi_angle;
                                x = temp_r .* cos(new_phi);
                                y = temp_r .* sin(new_phi);
                                z = -z;

                                %  check (R,Z)-proximity
                                r_cur = sqrt(this_x_trace_ci.^ 2+ ...
                                    this_y_trace_ci.^2);
                                z_cur = this_z_trace_ci;

                                x_add = [];
                                y_add = [];
                                z_add = [];

                                % not needed now.  needed later for masking, post-definition of
                                rho_min_mask = app.rhomaskminEditField.Value;
                                file_vmec_output = app.AxisLCFSFilename.Text;
                                % interference points
                                [~, ~, rho_now, ~, ~, ...
                                    ~, ~, ~, ...
                                    ~, ~, ...
                                    ~, ~, ...
                                    ~, ~, ~, ...
                                    ~, ~, ~, ~, ~, ...
                                    ~, ~, ~, ~, ~] = ...
                                    app.get_VMEC_derived_values(file_vmec_output, ...
                                    x, ...
                                    y, ...
                                    z, 1e-6, ...
                                    .9, pi/2);

                                for ll = 1:length(x)
                                    if (rho_now(ll) > rho_min_mask)

                                        r_chk = sqrt(x(ll)^2 + y(ll)^2);
                                        z_chk = z(ll);
                                        d_chk = sqrt( (r_cur-r_chk).^2 + ...
                                            (z_cur - z_chk).^2);
                                        if (min(d_chk) < ...
                                                app.InverseDensityTolEditField.Value)


                                        else
                                            x_add = [x_add x(ll)];
                                            y_add = [y_add y(ll)];
                                            z_add = [z_add z(ll)];


                                        end
                                    else
                                    end
                                end

                                this_x_trace_ci = [this_x_trace_ci x_add];
                                this_y_trace_ci = [this_y_trace_ci y_add];
                                this_z_trace_ci = [this_z_trace_ci z_add];
                                plot3(app.UIAxes, x_add,y_add,z_add,'b.', 'MarkerSize', 0.01);
                            end

                        end
                        this_r_trace_ci = sqrt(this_x_trace_ci.^2 + this_y_trace_ci.^2);
                        app.interferencePoints_trace_cp{phi_index}.X = this_x_trace_ci;
                        app.interferencePoints_trace_cp{phi_index}.Y = this_y_trace_ci;
                        app.interferencePoints_trace_cp{phi_index}.Z = this_z_trace_ci;
                        app.interferencePoints_trace_cp{phi_index}.R = this_r_trace_ci;
                    end
                    pause(0.001);
                end


                % intermediate points

                for phi_index = 1:app.num_zeta_ip
                    disp(['<----Working on intermediate index ' ...
                        num2str(phi_index) ' of ' num2str(app.num_zeta_ip)]);
                    phi_angle = app.zeta_ip(phi_index);
                    this_x_ci = [];
                    this_y_ci = [];
                    this_z_ci = [];
                    % for jj = 1:app.coilStruct.num_coils
                    for jj = coils_to_include
                        for kk = 1:app.coilStruct.corners_per_coil
                            this_corner_line = app.coilStruct.coil_corner_data.coils{jj}.corner{kk}.points;
                            [x, y, z] =  app.extract_poloidal_plane_coil_points(phi_angle, this_corner_line);
                            %applymask
                            this_x_ci = [this_x_ci x];
                            this_y_ci = [this_y_ci y];
                            this_z_ci = [this_z_ci z];
                        end
                    end
                    this_r_ci = sqrt(this_x_ci.^2 + this_y_ci.^2);
                    app.interferencePoints_ip{phi_index}.X = this_x_ci;
                    app.interferencePoints_ip{phi_index}.Y = this_y_ci;
                    app.interferencePoints_ip{phi_index}.Z = this_z_ci;
                    app.interferencePoints_ip{phi_index}.R = this_r_ci;


                    this_x_trace_ci = [];
                    this_y_trace_ci = [];
                    this_z_trace_ci = [];


                    if (app.TraceDataList.numTraces > 0)
                        for jj = str2num(app.TraceIndexListEditField.Value)
                            X = app.TraceDataList.Traces(jj).X;
                            Y = app.TraceDataList.Traces(jj).Y;
                            Z = app.TraceDataList.Traces(jj).Z;
                            this_trace = [X' Y' Z'];

                            sym_angle = 2*pi/app.vmec_data.nfp;

                            for kk = 1:app.vmec_data.nfp
                                % using the number of field periods and
                                % stellarator symmetry to define many
                                % interference points for the edge traces

                                this_phi_angle = (kk-1) * sym_angle + phi_angle;
                                if (this_phi_angle < -pi)
                                    this_phi_angle2 = this_phi_angle + 2*pi;
                                elseif (this_phi_angle > pi)
                                    this_phi_angle2 = this_phi_angle - 2*pi;
                                else
                                    this_phi_angle2 = this_phi_angle;
                                end

                                [x,y,z] = app.extract_poloidal_plane_coil_points(this_phi_angle2, this_trace, 0);
                                %temp_phi = atan2(y,x);
                                temp_r = sqrt(x.^2 + y.^2);
                                new_phi = this_phi_angle - (kk-1)*sym_angle;
                                x = temp_r .* cos(new_phi);
                                y = temp_r .* sin(new_phi);

                                % check (R,Z)-proximity
                                r_cur = sqrt(this_x_trace_ci.^ 2+ ...
                                    this_y_trace_ci.^2);
                                z_cur = this_z_trace_ci;

                                x_add = [];
                                y_add = [];
                                z_add = [];


                                % not needed now.  needed later for masking, post-definition of
                                rho_min_mask = app.rhomaskminEditField.Value;
                                file_vmec_output = app.AxisLCFSFilename.Text;
                                % interference points
                                [~, ~, rho_now, ~, ~, ...
                                    ~, ~, ~, ...
                                    ~, ~, ...
                                    ~, ~, ...
                                    ~, ~, ~, ...
                                    ~, ~, ~, ~, ~, ...
                                    ~, ~, ~, ~, ~] = ...
                                    app.get_VMEC_derived_values(file_vmec_output, ...
                                    x, ...
                                    y, ...
                                    z, 1e-6, ...
                                    .9, pi/2);


                                for ll = 1:length(x)
                                    if (rho_now(ll) > rho_min_mask)


                                        r_chk = sqrt(x(ll)^2 + y(ll)^2);
                                        z_chk = z(ll);
                                        d_chk = sqrt( (r_cur-r_chk).^2 + ...
                                            (z_cur - z_chk).^2);
                                        if (min(d_chk) < ...
                                                app.InverseDensityTolEditField.Value)
                                        else
                                            x_add = [x_add x(ll)];
                                            y_add = [y_add y(ll)];
                                            z_add = [z_add z(ll)];
                                        end
                                    else
                                    end
                                end

                                this_x_trace_ci = [this_x_trace_ci x_add];
                                this_y_trace_ci = [this_y_trace_ci y_add];
                                this_z_trace_ci = [this_z_trace_ci z_add];
                                %plot3(app.UIAxes, x,y,z,'b+');

                                this_phi_angle = kk * sym_angle - phi_angle;
                                if (this_phi_angle < -pi)
                                    this_phi_angle2 = this_phi_angle + 2*pi;
                                elseif (this_phi_angle > pi)
                                    this_phi_angle2 = this_phi_angle - 2*pi;
                                else
                                    this_phi_angle2 = this_phi_angle;
                                end
                                [x,y,z] = app.extract_poloidal_plane_coil_points(this_phi_angle2, this_trace, 0);
                                %temp_phi = atan2(y,x);
                                temp_r = sqrt(x.^2 + y.^2);
                                new_phi = kk * sym_angle - this_phi_angle;
                                x = temp_r .* cos(new_phi);
                                y = temp_r .* sin(new_phi);
                                z = -z;

                                % check (R,Z)-proximity
                                r_cur = sqrt(this_x_trace_ci.^ 2+ ...
                                    this_y_trace_ci.^2);
                                z_cur = this_z_trace_ci;

                                x_add = [];
                                y_add = [];
                                z_add = [];
                                % not needed now.  needed later for masking, post-definition of
                                rho_min_mask = app.rhomaskminEditField.Value;
                                file_vmec_output = app.AxisLCFSFilename.Text;
                                % interference points
                                [~, ~, rho_now, ~, ~, ...
                                    ~, ~, ~, ...
                                    ~, ~, ...
                                    ~, ~, ...
                                    ~, ~, ~, ...
                                    ~, ~, ~, ~, ~, ...
                                    ~, ~, ~, ~, ~] = ...
                                    app.get_VMEC_derived_values(file_vmec_output, ...
                                    x, ...
                                    y, ...
                                    z, 1e-6, ...
                                    .9, pi/2);
                                for ll = 1:length(x)
                                    if (rho_now(ll) > rho_min_mask)


                                        r_chk = sqrt(x(ll)^2 + y(ll)^2);
                                        z_chk = z(ll);
                                        d_chk = sqrt( (r_cur-r_chk).^2 + ...
                                            (z_cur - z_chk).^2);
                                        if (min(d_chk) < ...
                                                app.InverseDensityTolEditField.Value)
                                        else
                                            x_add = [x_add x(ll)];
                                            y_add = [y_add y(ll)];
                                            z_add = [z_add z(ll)];
                                        end
                                    else
                                    end
                                end

                                this_x_trace_ci = [this_x_trace_ci x_add];
                                this_y_trace_ci = [this_y_trace_ci y_add];
                                this_z_trace_ci = [this_z_trace_ci z_add];
                                %plot3(app.UIAxes, x,y,z,'b+');
                            end
                        end
                        this_r_trace_ci = sqrt(this_x_trace_ci.^2 + this_y_trace_ci.^2);
                        app.interferencePoints_trace_ip{phi_index}.X = this_x_trace_ci;
                        app.interferencePoints_trace_ip{phi_index}.Y = this_y_trace_ci;
                        app.interferencePoints_trace_ip{phi_index}.Z = this_z_trace_ci;
                        app.interferencePoints_trace_ip{phi_index}.R = this_r_trace_ci;
                        app.TraceInterferencePoints_loaded = 1;
                    end
                end

                app.interferencePoints_loaded = 1;
            else
            end
            pause(0.001);
            app.InterferencePointsDefinedLamp.Color = [0 0 1];


        end

        function results = initilializeLoftedSurface(app)
            % input:
            % control points + normal vectors
            % dense (control plane) points + normal vectors for interpolation + expansion
            % intermediate points + normal vectors
            % dense (intermediate plane) points + normal vectors for interpolation + expansion
            % interference points at toroidal angle of control points
            % inteference points at toroidal angle of intermediate points
            % Step size for expanding the control points
            % Step size for reducing the exploded points
            %
            % Algorithm description:
            %  Purpose: 'Loft'/Lift/Expand the surface as much as possible and do NOT encircle
            %  any of the interference points defined at the various toroidal angles
            %  1. Initialize data arrays
            %     1. A mask matrix (detect when points are done moving)
            %     2. A '# of moving'-counter to indicate the remaining number of points
            %        that are still moving
            %     3. Displacement values array for each set of control points define
            %        at a constant toroidal angle

            %spline_or_pchip = spline_type;%  % 1 = spline, 2= pchip
            results = 0;
            app.LoftedSurfaceInitializedLamp.Color = [1 1 0];

            %app.out_surf.SPLINE_TYPE = spline_or_pchip
            app.out_surf.SPLINE_TYPE = app.PoloidalInterpoloationButtonGroup.SelectedObject;

            % ignore nonstellsym, and assume stellarator symmetry

            %  1. Initialize data arrays
            %     1. A mask matrix (detect when points are done moving)
            %     2. A '# of moving'-counter to indicate the remaining number of points
            %        that are still moving
            %     3. Displacement values array for each set of control points define
            %        at a constant toroidal angle
            app.num_tor_angles_ctrl = length(app.vmec_control_points.zeta);
            app.num_pol_angles_ctrl = length(app.vmec_control_points.theta);
            app.num_tor_angles_intermediate = length(app.vmec_intermediate_points.zeta);
            app.num_pol_angles_intermediate = length(app.vmec_intermediate_points.theta);
            app.num_pol_angles_blowup = length(app.vmec_control_points_blowup.theta);

            app.spl_ctrl_pts = app.InitialLoftOffsetEditField.Value + ...
                zeros(app.num_tor_angles_ctrl, app.num_pol_angles_ctrl);

            app.spl_intrmd_pts = zeros(app.num_tor_angles_intermediate, ...
                app.num_pol_angles_intermediate);

            app.mask_still_moving = ones(app.num_tor_angles_ctrl, (app.num_pol_angles_ctrl-1) );
            app.ctr_still_moving = app.num_tor_angles_ctrl * (app.num_pol_angles_ctrl);

            app.pol_angles_ctrl_pts = app.vmec_control_points.theta;

            app.step_expand = app.InitialStepSizeEditField.Value;
            app.intrmd_spline_contour = {''};
            for ii = 1:app.num_tor_angles_intermediate
                app.intrmd_spline_contour{ii} = [];
            end

            if (mod(app.num_pol_angles_ctrl,2) == 1)
                app.num_control_points_even = 0;
                app.ind_cp_middle = floor(app.num_pol_angles_ctrl/2) + 1;
            else
                app.num_control_points_even = 1;
                app.ind_cp_middle = app.num_pol_angles_ctrl/2;
            end

            app.LoftedSurfaceInitializedLamp.Color = [0 0 1];

        end


        function results = optimizeIt(app)

            %
            % input:
            % control points + normal vectors
            % dense (control plane) points + normal vectors for interpolation + expansion
            % intermediate points + normal vectors
            % dense (intermediate plane) points + normal vectors for interpolation + expansion
            % interference points at toroidal angle of control points
            % inteference points at toroidal angle of intermediate points
            % Step size for expanding the control points
            % Step size for reducing the exploded points
            %
            % output:
            % delta displacement values at control points
            % interpoloated displacement values at many poloidal angles at control
            % planes
            % X,Y,Z,R,Phi values of exploded surface at control planes
            % delta displacement values at intermediate points
            % Interpoloated displacement values at many poloidal angles at intermediate
            % planes
            % X,Y,Z,R,Phi values of exploded surface at intermediate planes
            % X,Y,Z,R,Phi values of exploded surface at all points
            %
            % Algorithm description:
            %  Purpose: 'Explode' the surface as much as possible and do NOT encircle
            %  and of the interference points defined at the various toroidal angles
            %  1. Initialize data arrays
            %     1. A mask matrix (detect when points are done moving)
            %     2. A '# of moving'-counter to indicate the remaining number of points
            %        that are still moving
            %     3. Displacement values array for each set of control points define
            %        at a constant toroidal angle
            %  2. Optimizization
            %     1.  While the '# of moving'-counter > 0
            %     2.  Loop over each set of control points at a toroidal angle
            %      3a.  Check to see if control point is really a control point.  Don't
            %           continue on if the point will be adjusted because of
            %           stellarator symmetry or up/down symmetry
            %      3b.  Check mask for each point.  If it isn't moving, do nothing
            %        4. If it is moving, move it out and
            %          5. Interpolate at intermediate points
            %          6. Build contours at dense set of 'blowup' points at control plane
            %          7. and build contours at dense set of 'blowup' points at 1 or 2
            %          intermediate planes
            %          9. Check residue calculation -
            %             10. If residue is 'okay' leave it.
            %             11. If residue indicates collision,
            %                  12. move it and intermediate points back
            %                  13. set the mask matrix to 0 for that element
            %                  14. Reduce the '# of moving'-counter by 1
            %
            % Notes: The last poloidal angle of the control points and intermediate
            % points are the same as the first points of their respective structure.
            % Therefore, these points should be handled at the same time as the first
            % index to avoid strange results.
            results = 0;
            app.intrmd_spline_contour = {[]};
            app.step_expand = app.InitialStepSizeEditField.Value;
            app.mask_still_moving = ones(app.num_tor_angles_ctrl, (app.num_pol_angles_ctrl-1) );
            app.ctr_still_moving = app.num_tor_angles_ctrl * (app.num_pol_angles_ctrl);

            app.out_surf.SPLINE_TYPE = app.PoloidalInterpoloationButtonGroup.SelectedObject.Text;
            %  2. Optimization
            %     1.  While the '# of moving'-counter > 0
            %while ( ctr_still_moving > 0)
            while (max(max(app.mask_still_moving)) > 0)
                if (app.PauseAutoLoftCheckBox.Value == 1)
                    keyboard
                end

                %     2.  Loop over each set of control points at a toroidal angle
                for ii = 1:app.num_tor_angles_ctrl
                    % the last point is the same as the first point and must be handled
                    % at the same time as the first point
                    for jj= 1:(app.num_pol_angles_ctrl-1)

                        %    3a.  Check to see if control point is really a control point.  Don't
                        %           continue on if the point will be adjusted because of
                        %           stellarator symmetry or up/down symmetry
                        if ( ( app.EnforceStellaratorSymmetryatPhi0and2piNFPCheckBox.Value && ...
                                ((ii==1) || (ii==app.num_tor_angles_ctrl)) && ...
                                (jj <= app.ind_cp_middle)) || ...
                                (app.EnforceStellaratorSymmetryatPhi0and2piNFPCheckBox.Value && ...
                                ((ii>1) && (ii<app.num_tor_angles_ctrl))) )

                            %      3b.  Check mask for each point.  If it isn't moving, do nothing
                            if (app.mask_still_moving(ii,jj) == 1)
                                other_jj = app.num_pol_angles_ctrl + 1 - jj;
                                % based on the table for stellarator symmetry
                                % jj, ojj(other_jj)
                                %        |
                                %  tor_angles_ctrl:first or last
                                %   pol_angles_ctrl:even   |   1          ..    jj(=midpoint),
                                %                          (num_pol+1-jj) ..   (jj+1)
                                %   pol_angles_ctrl:odd       |       1   ..   (jj -1), jj(=midpoint),
                                %                          (num_pol+1-jj) ..   (jj+1)
                                %  tor_angles_ctrl:the rest  |
                                %   pol_angles_ctrl:even or odd    |  1 ..  num_pol_angles_ctrl-1
                                %                            num_pol_angles_ctrl
                                %
                                %
                                % assume stellarator symmetry
                                %if (    ~( (jj>=other_jj)&&((ii==1||(ii==num_tor_angles_ctrl)))) || ((ii>1) && (ii<num_tor_angles_ctrl)))
                                disp(['<---    (ii,jj) = (' num2str(ii) ', ' num2str(jj) ')']);
                                %end
                                %if ( ((ii==1) || (ii==num_tor_angles_ctrl)) &&
                                if ((jj==1) || ( (jj<other_jj) && ( (ii==1) || (ii==app.num_tor_angles_ctrl))))
                                    do_sym = 1;
                                    disp(['<---and (ii,ojj) = (' num2str(ii) ', ' num2str(other_jj) ')']);
                                else
                                    do_sym = 0;
                                end
                                %        4. If it is supposed to be moved, (see table above), move it out and
                                %         otherwise exit this if (mask...) check


                                app.spl_ctrl_pts(ii,jj) = app.spl_ctrl_pts(ii,jj) + app.step_expand;
                                %end
                                if do_sym
                                    app.spl_ctrl_pts(ii,other_jj) = app.spl_ctrl_pts(ii,other_jj) + app.step_expand;
                                end
                                if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                    plot3(app.UIAxes2, ii,jj,app.spl_ctrl_pts(ii,jj), 'x');
                                end

                                %   5. Interpolate at intermediate points

                                if (ii >1)
                                    % handles points at smaller toroidal angles.
                                    interp_values = linspace(app.spl_ctrl_pts(ii-1,jj), ...
                                        app.spl_ctrl_pts(ii,jj), (app.numZeta_vmec_ip_per_cp+2));
                                    if do_sym % ((jj==1) || ( (jj<other_jj) && ( (ii==1) || (ii==app.num_tor_angles_ctrl))))
                                        ointerp_values = linspace(app.spl_ctrl_pts(ii-1,other_jj), ...
                                            app.spl_ctrl_pts(ii,other_jj), (app.numZeta_vmec_ip_per_cp+2));
                                    end
                                    for kk = 1:app.numZeta_vmec_ip_per_cp
                                        ind_2_modify = (ii-2)*app.numZeta_vmec_ip_per_cp + kk;
                                        interp_value_2_use = kk+1;
                                        app.spl_intrmd_pts(ind_2_modify,jj) = ...
                                            interp_values(interp_value_2_use);
                                        if do_sym
                                            app.spl_intrmd_pts(ind_2_modify,other_jj) = ...
                                                ointerp_values(interp_value_2_use);
                                        end
                                    end
                                end

                                if (ii < app.num_tor_angles_ctrl)
                                    % handles points at larger toroidal angles.
                                    interp_values = linspace(app.spl_ctrl_pts(ii,jj), ...
                                        app.spl_ctrl_pts(ii+1,jj), (app.numZeta_vmec_ip_per_cp+2));
                                    if do_sym
                                        ointerp_values = linspace(app.spl_ctrl_pts(ii,other_jj), ...
                                            app.spl_ctrl_pts(ii+1,other_jj), (app.numZeta_vmec_ip_per_cp+2));
                                    end
                                    for kk = 1:app.numZeta_vmec_ip_per_cp
                                        ind_2_modify = (ii-1)*app.numZeta_vmec_ip_per_cp + kk;
                                        interp_value_2_use = kk+1;
                                        app.spl_intrmd_pts(ind_2_modify,jj) = ...
                                            interp_values(interp_value_2_use);

                                        if do_sym
                                            app.spl_intrmd_pts(ind_2_modify,other_jj) = ...
                                                ointerp_values(interp_value_2_use);
                                        end
                                    end
                                end

                                %  6. Build contours at dense set of 'blowup' points at control plane
                                if (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'spline'))
                                    delta_ctrl_spline = spline([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                                        app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                                        [app.spl_ctrl_pts(ii,2:(end-1)) app.spl_ctrl_pts(ii,:) ...
                                        app.spl_ctrl_pts(ii,2:(end-1))], ...
                                        app.vmec_control_points_blowup.theta_mesh(ii,:));

                                elseif (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'pchip'))
                                    delta_ctrl_spline = pchip([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                                        app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                                        [app.spl_ctrl_pts(ii,2:(end-1)) app.spl_ctrl_pts(ii,:) ...
                                        app.spl_ctrl_pts(ii,2:(end-1))], ...
                                        app.vmec_control_points_blowup.theta_mesh(ii,:));
                                end
                                ctrl_spline_contour = app.build_exploded_contour(...
                                    app.vmec_control_points_blowup.X(ii,:), ...
                                    app.vmec_control_points_blowup.Y(ii,:), ...
                                    app.vmec_control_points_blowup.Z(ii,:), ...
                                    app.vmec_control_normals_blowup_cyl.normal_x(ii,:), ...
                                    app.vmec_control_normals_blowup_cyl.normal_y(ii,:), ...
                                    app.vmec_control_normals_blowup_cyl.normal_z(ii,:), ...
                                    delta_ctrl_spline);

                                if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                    axis(app.UIAxes3);
                                    hold(app.UIAxes3, 'on');

                                    plot3(app.UIAxes3, ii*ones(1,length(app.pol_angles_ctrl_pts)), app.pol_angles_ctrl_pts, app.spl_ctrl_pts(ii,:), 'o')
                                    plot3(app.UIAxes3, ii*ones(1,length(delta_ctrl_spline)), app.vmec_control_points_blowup.theta_mesh(ii,:), delta_ctrl_spline)
                                    view(app.UIAxes3, -37.5, 30);
                                end
                                % 7. Build contours at intermediate planes
                                %
                                if (ii > 1)
                                    % handles points at smaller toroidal angles.
                                    for kk = 1:app.numZeta_vmec_ip_per_cp
                                        ind_2_spline = (ii-2)*app.numZeta_vmec_ip_per_cp + kk;

                                        if (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'spline'))

                                            delta_intrmd_spline = spline([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                                                app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                                                [app.spl_intrmd_pts(ind_2_spline,2:(end-1)) app.spl_intrmd_pts(ind_2_spline,:) ...
                                                app.spl_intrmd_pts(ind_2_spline,2:(end-1))], ...
                                                app.vmec_intermediate_points_blowup.theta_mesh(ind_2_spline,:));
                                        elseif (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'pchip'))
                                            delta_intrmd_spline = pchip([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                                                app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                                                [app.spl_intrmd_pts(ind_2_spline,2:(end-1)) app.spl_intrmd_pts(ind_2_spline,:) ...
                                                app.spl_intrmd_pts(ind_2_spline,2:(end-1))], ...
                                                app.vmec_intermediate_points_blowup.theta_mesh(ind_2_spline,:));
                                        end
                                        app.intrmd_spline_contour{ind_2_spline} = app.build_exploded_contour(...
                                            app.vmec_intermediate_points_blowup.X(ind_2_spline,:), ...
                                            app.vmec_intermediate_points_blowup.Y(ind_2_spline,:), ...
                                            app.vmec_intermediate_points_blowup.Z(ind_2_spline,:), ...
                                            app.vmec_intermediate_normals_blowup_cyl.normal_x(ind_2_spline,:), ...
                                            app.vmec_intermediate_normals_blowup_cyl.normal_y(ind_2_spline,:), ...
                                            app.vmec_intermediate_normals_blowup_cyl.normal_z(ind_2_spline,:), ...
                                            delta_intrmd_spline);
                                    end
                                end

                                if (ii < app.num_tor_angles_ctrl)
                                    % handles points at larger toroidal angles.
                                    for kk = 1:app.numZeta_vmec_ip_per_cp
                                        ind_2_spline = (ii-1)*app.numZeta_vmec_ip_per_cp + kk;
                                        if (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'spline'))


                                            delta_intrmd_spline = spline([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                                                app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                                                [app.spl_intrmd_pts(ind_2_spline,2:(end-1)) app.spl_intrmd_pts(ind_2_spline,:) ...
                                                app.spl_intrmd_pts(ind_2_spline,2:(end-1))], ...
                                                app.vmec_intermediate_points_blowup.theta_mesh(ind_2_spline,:));
                                        elseif (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'pchip'))
                                            delta_intrmd_spline = pchip([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                                                app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                                                [app.spl_intrmd_pts(ind_2_spline,2:(end-1)) app.spl_intrmd_pts(ind_2_spline,:) ...
                                                app.spl_intrmd_pts(ind_2_spline,2:(end-1))], ...
                                                app.vmec_intermediate_points_blowup.theta_mesh(ind_2_spline,:));
                                        end
                                        app.intrmd_spline_contour{ind_2_spline} = app.build_exploded_contour(...
                                            app.vmec_intermediate_points_blowup.X(ind_2_spline,:), ...
                                            app.vmec_intermediate_points_blowup.Y(ind_2_spline,:), ...
                                            app.vmec_intermediate_points_blowup.Z(ind_2_spline,:), ...
                                            app.vmec_intermediate_normals_blowup_cyl.normal_x(ind_2_spline,:), ...
                                            app.vmec_intermediate_normals_blowup_cyl.normal_y(ind_2_spline,:), ...
                                            app.vmec_intermediate_normals_blowup_cyl.normal_z(ind_2_spline,:), ...
                                            delta_intrmd_spline);
                                    end
                                end


                                % check residue calculation for each point of coils (at this toroidal angle)
                                % for the new control contour and intermediate contour

                                %    9. Check residue calculation -
                                if strcmpi(app.DebugLevelKnob.Value, '11')
                                    figure(10101+ii);
                                    %clf;

                                    % at control location
                                    subplot(2,2,1);hold off
                                    plot(ctrl_spline_contour.R, ctrl_spline_contour.Z, 'b.-');
                                    hold on;
                                    view(2)
                                end
                                residue_ok = 1;

                                coil_pts_at_control_contour = app.interferencePoints_cp{ii};
                                for kk = 1:length(coil_pts_at_control_contour.X)
                                    test_point_R = coil_pts_at_control_contour.R(kk);
                                    test_point_Z = coil_pts_at_control_contour.Z(kk);
                                    residue = app.check_cauchy_integral(ctrl_spline_contour.R,  ...
                                        ctrl_spline_contour.Z, test_point_R, test_point_Z);
                                    % if residue ~> 2*pi, then the point is inside the contour

                                    % 6 is close enough to 2*pi to probably indicate a problem
                                    if (residue  > app.ResidueCoilsEditField.Value)

                                        residue_ok = 0;
                                        if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                            subplot(2,2,1);
                                            plot(test_point_R, test_point_Z, 'r+', 'Linewidth', 3);
                                        end
                                    else
                                        if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                            subplot(2,2,1);
                                            plot(test_point_R, test_point_Z, 'k+');
                                        end
                                    end

                                    if strcmpi(app.DebugLevelKnob.Value, '11')
                                        figure(10101+ii);
                                        subplot(2,2,1);view(2)
                                        subplot(2,2,2);view(2)
                                        subplot(2,2,3);view(2)
                                        pause(0.0001)
                                    end
                                end

                                %
                                if (app.TraceInterferencePoints_loaded == 1)
                                    trace_pts_at_control_contour = app.interferencePoints_trace_cp{ii};
                                    for kk = 1:length(trace_pts_at_control_contour.X)
                                        test_point_R = trace_pts_at_control_contour.R(kk);
                                        test_point_Z = trace_pts_at_control_contour.Z(kk);
                                        residue = app.check_cauchy_integral(ctrl_spline_contour.R,  ...
                                            ctrl_spline_contour.Z, test_point_R, test_point_Z);
                                        % if residue ~> 2*pi, then the point is inside the contour

                                        % 2.5 is close enough to pi to probably indicate no problem
                                        if (residue  > app.ResidueTracesEditField.Value)
                                            if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                                subplot(2,2,1);
                                                plot(test_point_R, test_point_Z, 'k^');
                                            end

                                        else
                                            residue_ok = 0;
                                            if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                                subplot(2,2,1);
                                                plot(test_point_R, test_point_Z, 'g+', 'Linewidth', 2);
                                            end
                                        end

                                        if strcmpi(app.DebugLevelKnob.Value, '11')
                                            figure(10101+ii);
                                            subplot(2,2,1);view(2)
                                            subplot(2,2,2);view(2)
                                            subplot(2,2,3);view(2)
                                            pause(0.0001)
                                        end
                                    end
                                end
                                %

                                % at intermediate location
                                if (ii > 1)
                                    % handles points at smaller toroidal angles.
                                    for kk = 1:app.numZeta_vmec_ip_per_cp
                                        ind_2_check = (ii-2)*app.numZeta_vmec_ip_per_cp + kk;

                                        coil_pts_at_intrmd_contour = app.interferencePoints_ip{ind_2_check};

                                        if strcmpi(app.DebugLevelKnob.Value, '11')
                                            figure(10101+ii);

                                            subplot(2,2,2);hold off
                                            plot(app.intrmd_spline_contour{ind_2_check}.R, app.intrmd_spline_contour{ind_2_check}.Z, 'b.-');
                                            hold on;
                                            view(2)
                                        end
                                        for ll = 1:length(coil_pts_at_intrmd_contour.X)
                                            test_point_R = coil_pts_at_intrmd_contour.R(ll);
                                            test_point_Z = coil_pts_at_intrmd_contour.Z(ll);

                                            residue = app.check_cauchy_integral(app.intrmd_spline_contour{ind_2_check}.R,  ...
                                                app.intrmd_spline_contour{ind_2_check}.Z, test_point_R, test_point_Z);
                                            % if residue ~> 2*pi, then the point is inside the contour

                                            % 6 is close enough to 2*pi to probably indicate a problem
                                            if (residue  > app.ResidueCoilsEditField.Value)

                                                residue_ok = 0;
                                                if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                                    subplot(2,2,2);
                                                    plot(test_point_R, test_point_Z, 'r+', 'Linewidth', 3);
                                                end
                                            else
                                                if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                                    subplot(2,2,2);
                                                    plot(test_point_R, test_point_Z, 'k+');
                                                end
                                            end
                                        end

                                        %

                                        if (app.TraceInterferencePoints_loaded == 1)
                                            trace_pts_at_intrmd_contour = app.interferencePoints_trace_ip{ind_2_check};

                                            if strcmpi(app.DebugLevelKnob.Value, '11')
                                                figure(10101+ii);

                                                subplot(2,2,2);hold off
                                                plot(app.intrmd_spline_contour{ind_2_check}.R, app.intrmd_spline_contour{ind_2_check}.Z, 'b.-');
                                                hold on;
                                                view(2)
                                            end
                                            for ll = 1:length(trace_pts_at_intrmd_contour.X)
                                                test_point_R = trace_pts_at_intrmd_contour.R(ll);
                                                test_point_Z = trace_pts_at_intrmd_contour.Z(ll);

                                                residue = app.check_cauchy_integral(app.intrmd_spline_contour{ind_2_check}.R,  ...
                                                    app.intrmd_spline_contour{ind_2_check}.Z, test_point_R, test_point_Z);
                                                % if residue ~> 2*pi, then the point is inside the contour

                                                % 2.5 is close enough to pi to probably indicate no problem
                                                if (residue  > app.ResidueTracesEditField.Value)
                                                    if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                                        subplot(2,2,2);
                                                        plot(test_point_R, test_point_Z, 'k^');
                                                    end
                                                else

                                                    residue_ok = 0;
                                                    if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                                        subplot(2,2,2);
                                                        plot(test_point_R, test_point_Z, 'g+', 'Linewidth', 3);
                                                    end
                                                end
                                            end


                                            %


                                        end

                                        if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                            pause(0.0001)
                                        end

                                    end

                                end


                                % at intermediate location
                                if (ii < app.num_tor_angles_ctrl)
                                    % handles points at larger toroidal angles.
                                    for kk = 1:app.numZeta_vmec_ip_per_cp
                                        ind_2_check = (ii-1)*app.numZeta_vmec_ip_per_cp + kk;

                                        coil_pts_at_intrmd_contour = app.interferencePoints_ip{ind_2_check};

                                        if strcmpi(app.DebugLevelKnob.Value, '11')
                                            figure(10101+ii);
                                            subplot(2,2,3);hold off
                                            plot(app.intrmd_spline_contour{ind_2_check}.R, app.intrmd_spline_contour{ind_2_check}.Z, 'b.-');
                                            hold on;
                                            view(2)
                                        end
                                        for ll = 1:length(coil_pts_at_intrmd_contour.X)
                                            test_point_R = coil_pts_at_intrmd_contour.R(ll);
                                            test_point_Z = coil_pts_at_intrmd_contour.Z(ll);

                                            residue = app.check_cauchy_integral(app.intrmd_spline_contour{ind_2_check}.R,  ...
                                                app.intrmd_spline_contour{ind_2_check}.Z, test_point_R, test_point_Z);
                                            % if residue ~> 2*pi, then the point is inside the contour

                                            % 6 is close enough to 2*pi to probably indicate a problem
                                            if (residue  > app.ResidueCoilsEditField.Value)

                                                residue_ok = 0;
                                                if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                                    figure(10101+ii);
                                                    subplot(2,2,3);hold off
                                                    plot(test_point_R, test_point_Z, 'r+', 'Linewidth', 3);
                                                    view(2)
                                                end
                                            else
                                                if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                                    figure(10101+ii);
                                                    subplot(2,2,3);hold off
                                                    plot(test_point_R, test_point_Z, 'k+');
                                                    view(2)
                                                end
                                            end

                                        end


                                        %
                                        if (app.TraceInterferencePoints_loaded == 1)
                                            trace_pts_at_intrmd_contour = app.interferencePoints_trace_ip{ind_2_check};

                                            if strcmpi(app.DebugLevelKnob.Value, '11')
                                                figure(10101+ii);

                                                subplot(2,2,2);hold off
                                                plot(app.intrmd_spline_contour{ind_2_check}.R, app.intrmd_spline_contour{ind_2_check}.Z, 'b.-');
                                                hold on;
                                                view(2)
                                            end
                                            for ll = 1:length(trace_pts_at_intrmd_contour.X)
                                                test_point_R = trace_pts_at_intrmd_contour.R(ll);
                                                test_point_Z = trace_pts_at_intrmd_contour.Z(ll);

                                                residue = app.check_cauchy_integral(app.intrmd_spline_contour{ind_2_check}.R,  ...
                                                    app.intrmd_spline_contour{ind_2_check}.Z, test_point_R, test_point_Z);
                                                % if residue ~> 2*pi, then the point is inside the contour

                                                % 2.5 is close enough to pi to probably indicate no problem
                                                if (residue  > app.ResidueTracesEditField.Value)
                                                    if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                                        subplot(2,2,2);
                                                        plot(test_point_R, test_point_Z, 'k^');
                                                    end
                                                else

                                                    residue_ok = 0;
                                                    if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                                        subplot(2,2,2);
                                                        plot(test_point_R, test_point_Z, 'g*', 'Linewidth', 3);
                                                    end
                                                end
                                            end
                                        end

                                        %


                                        if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                            pause(0.0001)
                                        end

                                    end
                                end


                                % keep going!
                                % unless it is beyond a maximum step size.

                                if (app.spl_ctrl_pts(ii,jj) >= app.MaxLoftDistanceEditField.Value)
                                    residue_ok = 0;
                                    %app.mask_still_moving(ii,jj) = 0;
                                    disp(['<---Index (ii,jj) at max_diff_step = (' num2str(ii) ', ' num2str(jj) ')']);


                                end
                                % or if it is below a minimum step size.

                                if (app.spl_ctrl_pts(ii,jj) <= app.MinimumLoftDistanceEditField.Value)
                                    residue_ok = 0;
                                    %app.mask_still_moving(ii,jj) = 0;
                                    disp(['<---Index (ii,jj) at min_distance = (' num2str(ii) ', ' num2str(jj) ')']);


                                end

                                %  10. If residue is 'okay' leave it.
                                if residue_ok

                                else
                                    %  11. If residue indicates collision,
                                    % move the point back, adjust the mask and counter

                                    step_size_backoff = 0;
                                    app.spl_ctrl_pts(ii,jj) = app.spl_ctrl_pts(ii,jj) - app.step_expand-step_size_backoff;
                                    if ((jj==1) || ( (jj<other_jj) && ( (ii==1) || (ii==app.num_tor_angles_ctrl))))

                                        app.spl_ctrl_pts(ii,other_jj) = app.spl_ctrl_pts(ii,other_jj) - app.step_expand-step_size_backoff;
                                    end
                                    if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                        axis(app.UIAxes2);
                                        plot3(app.UIAxes2, ii,jj,app.spl_ctrl_pts(ii,jj), 'ro');
                                        hold on
                                        pause(.000001)
                                    end

                                    % 12. move the intermediate points back

                                    if (ii >1)
                                        % handles points at smaller toroidal angles.
                                        interp_values = linspace(app.spl_ctrl_pts(ii-1,jj), ...
                                            app.spl_ctrl_pts(ii,jj), (app.numZeta_vmec_ip_per_cp+2));
                                        if ( (jj==1) || ( (jj<other_jj) && (ii==app.num_tor_angles_ctrl)) )

                                            ointerp_values = linspace(app.spl_ctrl_pts(ii-1,other_jj), ...
                                                app.spl_ctrl_pts(ii,other_jj), (app.numZeta_vmec_ip_per_cp+2));
                                        end

                                        for kk = 1:app.numZeta_vmec_ip_per_cp
                                            ind_2_modify = (ii-2)*app.numZeta_vmec_ip_per_cp + kk;
                                            interp_value_2_use = kk+1;
                                            app.spl_intrmd_pts(ind_2_modify,jj) = ...
                                                interp_values(interp_value_2_use);

                                            if ((jj==1) || ( (jj<other_jj) && ( (ii==app.num_tor_angles_ctrl))))
                                                app.spl_intrmd_pts(ind_2_modify,other_jj) = ...
                                                    ointerp_values(interp_value_2_use);

                                            end
                                        end
                                    end

                                    if (ii < app.num_tor_angles_ctrl)
                                        % handles points at larger toroidal angles.
                                        interp_values = linspace(app.spl_ctrl_pts(ii,jj), ...
                                            app.spl_ctrl_pts(ii+1,jj), (app.numZeta_vmec_ip_per_cp+2));
                                        if ( (jj==1) ||( (jj<other_jj) && (ii==1)) )
                                            ointerp_values = linspace(app.spl_ctrl_pts(ii,other_jj), ...
                                                app.spl_ctrl_pts(ii+1,other_jj), (app.numZeta_vmec_ip_per_cp+2));

                                        end
                                        for kk = 1:app.numZeta_vmec_ip_per_cp
                                            ind_2_modify = (ii-1)*app.numZeta_vmec_ip_per_cp + kk;
                                            interp_value_2_use = kk+1;
                                            app.spl_intrmd_pts(ind_2_modify,jj) = ...
                                                interp_values(interp_value_2_use);

                                            if ((jj==1) || ( (jj<other_jj) && ( (ii==1) || (ii==app.num_tor_angles_ctrl))))
                                                app.spl_intrmd_pts(ind_2_modify,other_jj) = ...
                                                    ointerp_values(interp_value_2_use);
                                            end

                                        end
                                    end


                                    %
                                    %  13. set the mask matrix to 0 for that element

                                    app.mask_still_moving(ii,jj) = 0;
                                    disp(['<---Removed (ii,jj) = (' num2str(ii) ', ' num2str(jj) ')']);
                                    app.ctr_still_moving = app.ctr_still_moving - 1;
                                    if ((jj==1) || ( (jj<other_jj) && ( (ii==1) || (ii==app.num_tor_angles_ctrl))))
                                        app.mask_still_moving(ii,other_jj) = 0;
                                        disp(['<---Removed (ii,ojj) = (' num2str(ii) ', ' num2str(other_jj) ')']);
                                        app.ctr_still_moving = app.ctr_still_moving - 1;
                                    end
                                    disp(['<---' num2str(app.ctr_still_moving) ' control points still moving']);
                                    if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                        pause(2)
                                    end


                                end  % end of residue_ok check

                            end % end of mask(ii,jj) == 1 check

                        end % stellsym check

                    end % end of poloidal angle loop


                end % of of toroidal angle loop
                disp(['<------' num2str(app.ctr_still_moving) ' control points still moving']);

            end


            %     2.  Loop over each set of control points at a toroidal angle
            axis(app.UIAxes3);
            hold on;
            for ii = 1:app.num_tor_angles_ctrl

                % the last point is the same as the first point and must be handled
                % at the same time as the first point
                % for jj= 1:(num_pol_angles_ctrl-1)

                if (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'spline'))

                    delta_ctrl_spline = spline([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                        app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                        [app.spl_ctrl_pts(ii,2:(end-1)) app.spl_ctrl_pts(ii,:) ...
                        app.spl_ctrl_pts(ii,2:(end-1))], ...
                        app.vmec_control_points_blowup.theta_mesh(ii,:));
                elseif (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'pchip'))

                    delta_ctrl_spline = pchip([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                        app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                        [app.spl_ctrl_pts(ii,2:(end-1)) app.spl_ctrl_pts(ii,:) ...
                        app.spl_ctrl_pts(ii,2:(end-1))], ...
                        app.vmec_control_points_blowup.theta_mesh(ii,:));
                end
                ctrl_spline_contour = app.build_exploded_contour(...
                    app.vmec_control_points_blowup.X(ii,:), ...
                    app.vmec_control_points_blowup.Y(ii,:), ...
                    app.vmec_control_points_blowup.Z(ii,:), ...
                    app.vmec_control_normals_blowup_cyl.normal_x(ii,:), ...
                    app.vmec_control_normals_blowup_cyl.normal_y(ii,:), ...
                    app.vmec_control_normals_blowup_cyl.normal_z(ii,:), ...
                    delta_ctrl_spline);

                if 1
                    plot3(app.UIAxes3, ii*ones(1,length(app.pol_angles_ctrl_pts)), app.pol_angles_ctrl_pts, app.spl_ctrl_pts(ii,:), 'o')
                    plot3(app.UIAxes3, ii*ones(1,length(delta_ctrl_spline)), app.vmec_control_points_blowup.theta_mesh(ii,:), delta_ctrl_spline)
                    view(app.UIAxes3, -37.5, 30);
                end
                xlabel('Toroidal Control Index');
                ylabel('Poloidal Control Index');
                zlabel('Lofting distance  (m)')

                % end
            end


            % build final surface from control points & splines and intermediate points and splines
            num_tor_planes_output = ...
                (app.num_tor_angles_ctrl - 1) * (app.numZeta_vmec_ip_per_cp + 1) + 1;
            num_pol_points_output = app.num_pol_angles_blowup;
            app.out_surf.BadX = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.BadY = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.BadZ = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.X = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.Y = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.Z = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.R = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.Phi = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.X_lcfs = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.Y_lcfs = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.Z_lcfs = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.R_lcfs = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.Phi_lcfs = zeros(num_tor_planes_output, num_pol_points_output);
            app.spl_ctrl_pts = app.spl_ctrl_pts - app.PostOptimizeBackoffEditField.Value;
            tor_index_counter = 0;
            for ii = 1:app.num_tor_angles_ctrl
                if (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'spline'))

                    delta_ctrl_spline = spline([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                        app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                        [app.spl_ctrl_pts(ii,2:(end-1)) app.spl_ctrl_pts(ii,:) ...
                        app.spl_ctrl_pts(ii,2:(end-1))], ...
                        app.vmec_control_points_blowup.theta_mesh(ii,:));
                elseif (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'pchip'))

                    delta_ctrl_spline = pchip([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                        app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                        [app.spl_ctrl_pts(ii,2:(end-1)) app.spl_ctrl_pts(ii,:) ...
                        app.spl_ctrl_pts(ii,2:(end-1))], ...
                        app.vmec_control_points_blowup.theta_mesh(ii,:));
                end
                ctrl_spline_contour = app.build_exploded_contour(...
                    app.vmec_control_points_blowup.X(ii,:), ...
                    app.vmec_control_points_blowup.Y(ii,:), ...
                    app.vmec_control_points_blowup.Z(ii,:), ...
                    app.vmec_control_normals_blowup_cyl.normal_x(ii,:), ...
                    app.vmec_control_normals_blowup_cyl.normal_y(ii,:), ...
                    app.vmec_control_normals_blowup_cyl.normal_z(ii,:), ...
                    delta_ctrl_spline);

                tor_index_counter = tor_index_counter + 1;
                app.out_surf.X(tor_index_counter, :) = ctrl_spline_contour.X;
                app.out_surf.Y(tor_index_counter, :) = ctrl_spline_contour.Y;
                app.out_surf.Z(tor_index_counter, :) = ctrl_spline_contour.Z;
                app.out_surf.R(tor_index_counter, :) = ctrl_spline_contour.R;
                app.out_surf.Phi(tor_index_counter, :) = ctrl_spline_contour.Phi;

                app.out_surf.X_lcfs(tor_index_counter, :) = app.vmec_control_points_blowup.X(ii,:);
                app.out_surf.Y_lcfs(tor_index_counter, :) = app.vmec_control_points_blowup.Y(ii,:);
                app.out_surf.Z_lcfs(tor_index_counter, :) = app.vmec_control_points_blowup.Z(ii,:);
                app.out_surf.R_lcfs(tor_index_counter, :) = app.vmec_control_points_blowup.R(ii,:);
                app.out_surf.Phi_lcfs(tor_index_counter, :) = ctrl_spline_contour.Phi;

                if (ii < app.num_tor_angles_ctrl)

                    % handles points at intermediate toroidal angles.
                    for jj = 1:app.num_pol_angles_ctrl
                        interp_values = linspace(app.spl_ctrl_pts(ii,jj), ...
                            app.spl_ctrl_pts(ii+1,jj), (app.numZeta_vmec_ip_per_cp+2));
                        for ll = 1:app.numZeta_vmec_ip_per_cp
                            ind_2_set = (ii-1)*app.numZeta_vmec_ip_per_cp + ll;
                            interp_value_2_use = ll+1;
                            app.spl_intrmd_pts(ind_2_set,jj) = ...
                                interp_values(interp_value_2_use);

                            if (jj == 1)
                                app.spl_intrmd_pts(ind_2_set,app.num_pol_angles_ctrl) = interp_values(interp_value_2_use);
                            end
                        end
                    end

                    for jj = 1:app.numZeta_vmec_ip_per_cp
                        ind_2_set = (ii-1)*app.numZeta_vmec_ip_per_cp + jj;
                        if (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'spline'))

                            delta_intrmd_spline = spline([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                                app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                                [app.spl_intrmd_pts(ind_2_set,2:(end-1)) app.spl_intrmd_pts(ind_2_set,:) ...
                                app.spl_intrmd_pts(ind_2_set,2:(end-1))], ...
                                app.vmec_intermediate_points_blowup.theta_mesh(ind_2_set,:));
                        elseif (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'pchip'))

                            delta_intrmd_spline = pchip([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                                app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                                [app.spl_intrmd_pts(ind_2_set,2:(end-1)) app.spl_intrmd_pts(ind_2_set,:) ...
                                app.spl_intrmd_pts(ind_2_set,2:(end-1))], ...
                                app.vmec_intermediate_points_blowup.theta_mesh(ind_2_set,:));

                        end
                        app.intrmd_spline_contour = app.build_exploded_contour(...
                            app.vmec_intermediate_points_blowup.X(ind_2_set,:), ...
                            app.vmec_intermediate_points_blowup.Y(ind_2_set,:), ...
                            app.vmec_intermediate_points_blowup.Z(ind_2_set,:), ...
                            app.vmec_intermediate_normals_blowup_cyl.normal_x(ind_2_set,:), ...
                            app.vmec_intermediate_normals_blowup_cyl.normal_y(ind_2_set,:), ...
                            app.vmec_intermediate_normals_blowup_cyl.normal_z(ind_2_set,:), ...
                            delta_intrmd_spline);

                        tor_index_counter = tor_index_counter + 1;
                        app.out_surf.X(tor_index_counter, :) = app.intrmd_spline_contour.X;
                        app.out_surf.Y(tor_index_counter, :) = app.intrmd_spline_contour.Y;
                        app.out_surf.Z(tor_index_counter, :) = app.intrmd_spline_contour.Z;
                        app.out_surf.R(tor_index_counter, :) = app.intrmd_spline_contour.R;
                        app.out_surf.Phi(tor_index_counter, :) = app.intrmd_spline_contour.Phi;

                        app.out_surf.X_lcfs(tor_index_counter, :) = app.vmec_intermediate_points_blowup.X(ind_2_set,:);
                        app.out_surf.Y_lcfs(tor_index_counter, :) = app.vmec_intermediate_points_blowup.Y(ind_2_set,:);
                        app.out_surf.Z_lcfs(tor_index_counter, :) = app.vmec_intermediate_points_blowup.Z(ind_2_set,:);
                        app.out_surf.R_lcfs(tor_index_counter, :) = app.vmec_intermediate_points_blowup.R(ind_2_set,:);
                        app.out_surf.Phi_lcfs(tor_index_counter, :) = app.intrmd_spline_contour.Phi;


                    end
                end
            end


            % initialize data for 2d surface. keep it general to allow for easy plotting and subsequent fourier fitting

            % loop over control points , build contour points and interpolate to find intermediate spline points


            % loop over intermediate spline points and build contour

        end

        function results = build_and_check_surfaces(app)
            residue_ok = -100;
            app.intrmd_spline_contour = {[]};
            app.UIAxes.Color = [1 1 1];
            hold(app.UIAxes3, 'off');
            use_without_coils = app.UseWithoutCoilsSwitch.Value;
            %================
            for ii = 1:app.num_tor_angles_ctrl
                %for jj= 1% :(app.num_pol_angles_ctrl-1)

                %  6. Build contours at dense set of 'blowup' points at control plane

                %if (app.EnforceStellaratorSymmetryatPhi0and2piNFPCheckBox.Value==1)
                if (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'spline'))

                    delta_ctrl_spline = spline([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                        app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                        [app.spl_ctrl_pts(ii,2:(end-1)) app.spl_ctrl_pts(ii,:) ...
                        app.spl_ctrl_pts(ii,2:(end-1))], ...
                        app.vmec_control_points_blowup.theta_mesh(ii,:));
                elseif (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'pchip'))

                    delta_ctrl_spline = pchip([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                        app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                        [app.spl_ctrl_pts(ii,2:(end-1)) app.spl_ctrl_pts(ii,:) ...
                        app.spl_ctrl_pts(ii,2:(end-1))], ...
                        app.vmec_control_points_blowup.theta_mesh(ii,:));
                end
                ctrl_spline_contour = app.build_exploded_contour(...
                    app.vmec_control_points_blowup.X(ii,:), ...
                    app.vmec_control_points_blowup.Y(ii,:), ...
                    app.vmec_control_points_blowup.Z(ii,:), ...
                    app.vmec_control_normals_blowup_cyl.normal_x(ii,:), ...
                    app.vmec_control_normals_blowup_cyl.normal_y(ii,:), ...
                    app.vmec_control_normals_blowup_cyl.normal_z(ii,:), ...
                    delta_ctrl_spline);

                if ~strcmpi(app.DebugLevelKnob.Value, '0')

                    plot3(app.UIAxes3, ii*ones(1,length(app.pol_angles_ctrl_pts)), app.pol_angles_ctrl_pts, app.spl_ctrl_pts(ii,:), 'o')
                    hold(app.UIAxes3, 'on');
                    plot3(app.UIAxes3, ii*ones(1,length(delta_ctrl_spline)), app.vmec_control_points_blowup.theta_mesh(ii,:), delta_ctrl_spline)
                    view(app.UIAxes3, -37.5, 30);
                end
                % 7. Build contours at intermediate planes


                if (ii < app.num_tor_angles_ctrl)
                    % handles points at larger toroidal angles.

                    for jj = 1:app.num_pol_angles_ctrl
                        interp_values = linspace(app.spl_ctrl_pts(ii,jj), ...
                            app.spl_ctrl_pts(ii+1,jj), (app.numZeta_vmec_ip_per_cp+2));
                        for ll = 1:app.numZeta_vmec_ip_per_cp
                            ind_2_set = (ii-1)*app.numZeta_vmec_ip_per_cp + ll;
                            interp_value_2_use = ll+1;
                            app.spl_intrmd_pts(ind_2_set,jj) = ...
                                interp_values(interp_value_2_use);

                            if (jj == 1)
                                app.spl_intrmd_pts(ind_2_set,app.num_pol_angles_ctrl) = interp_values(interp_value_2_use);
                            end
                        end
                    end

                    for kk = 1:app.numZeta_vmec_ip_per_cp
                        ind_2_spline = (ii-1)*app.numZeta_vmec_ip_per_cp + kk;
                        if (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'spline'))


                            delta_intrmd_spline = spline([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                                app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                                [app.spl_intrmd_pts(ind_2_spline,2:(end-1)) app.spl_intrmd_pts(ind_2_spline,:) ...
                                app.spl_intrmd_pts(ind_2_spline,2:(end-1))], ...
                                app.vmec_intermediate_points_blowup.theta_mesh(ind_2_spline,:));
                        elseif (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'pchip'))


                            delta_intrmd_spline = pchip([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                                app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                                [app.spl_intrmd_pts(ind_2_spline,2:(end-1)) app.spl_intrmd_pts(ind_2_spline,:) ...
                                app.spl_intrmd_pts(ind_2_spline,2:(end-1))], ...
                                app.vmec_intermediate_points_blowup.theta_mesh(ind_2_spline,:));
                        end
                        app.intrmd_spline_contour{ind_2_spline} = app.build_exploded_contour(...
                            app.vmec_intermediate_points_blowup.X(ind_2_spline,:), ...
                            app.vmec_intermediate_points_blowup.Y(ind_2_spline,:), ...
                            app.vmec_intermediate_points_blowup.Z(ind_2_spline,:), ...
                            app.vmec_intermediate_normals_blowup_cyl.normal_x(ind_2_spline,:), ...
                            app.vmec_intermediate_normals_blowup_cyl.normal_y(ind_2_spline,:), ...
                            app.vmec_intermediate_normals_blowup_cyl.normal_z(ind_2_spline,:), ...
                            delta_intrmd_spline);
                    end
                end


                % check residue calculation for each point of coils (at this toroidal angle)
                % for the new control contour and intermediate contour

                %    9. Check residue calculation -
                if strcmpi(app.DebugLevelKnob.Value, '11')
                    fh=figure(10101+ii);
                    %clf;

                    % at control location
                    ah = subplot(2,2,1);hold off
                    plot(ah, ctrl_spline_contour.R, ctrl_spline_contour.Z, 'b.-');
                    view(2)
                    hold on;
                end
                residue_ok = 1;
                if (strcmp(use_without_coils,'No'))

                    % check coils
                    coil_pts_at_control_contour = app.interferencePoints_cp{ii};
                    for kk = 1:length(coil_pts_at_control_contour.X)
                        test_point_R = coil_pts_at_control_contour.R(kk);
                        test_point_Z = coil_pts_at_control_contour.Z(kk);
                        residue = app.check_cauchy_integral(ctrl_spline_contour.R,  ...
                            ctrl_spline_contour.Z, test_point_R, test_point_Z);
                        % if residue ~> 2*pi, then the point is inside the contour

                        % 6 is close enough to 2*pi to probably indicate a problem
                        if (residue  > app.ResidueCoilsEditField.Value)

                            residue_ok = 0;
                            if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                plot(ah, test_point_R, test_point_Z, 'r+', 'Linewidth', 3);
                            end
                        else
                            if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                plot(ah, test_point_R, test_point_Z, 'k+');
                            end
                        end
                        if strcmpi(app.DebugLevelKnob.Value, '11')
                            fh = figure(10101+ii);
                            view(fh, 2)

                            pause(0.0001)
                        end
                    end

                    % check traces
                    if (app.TraceInterferencePoints_loaded == 1)
                        trace_pts_at_control_contour = app.interferencePoints_trace_cp{ii};
                        for kk = 1:length(trace_pts_at_control_contour.X)
                            test_point_R = trace_pts_at_control_contour.R(kk);
                            test_point_Z = trace_pts_at_control_contour.Z(kk);
                            test_point_X = trace_pts_at_control_contour.X(kk);
                            test_point_Y = trace_pts_at_control_contour.Y(kk);
                            residue = app.check_cauchy_integral(ctrl_spline_contour.R,  ...
                                ctrl_spline_contour.Z, test_point_R, test_point_Z);
                            % if residue ~> 2*pi, then the point is inside the contour

                            % 3 is close enough to 2*pi to probably indicate no problem
                            if (residue  <= app.ResidueTracesEditField.Value)

                                residue_ok = 0;
                                if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                    plot(test_point_R, test_point_Z, 'g+', 'Linewidth', 3);
                                    plot3(app.UIAxes, test_point_X, ...
                                        test_point_Y, test_point_Z, 'g+', 'Linewidth', 1);
                                end
                            else
                                if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                    %  plot(test_point_R, test_point_Z, 'k+');
                                end
                            end
                            if strcmpi(app.DebugLevelKnob.Value, '11')
                                %figure(10101+ii);
                                %view(2)

                                %pause(0.0001)
                            end
                        end
                    end
                end


                % at intermediate location
                if (ii < app.num_tor_angles_ctrl)


                    % handles points at larger toroidal angles.
                    for kk = 1:app.numZeta_vmec_ip_per_cp
                        if (strcmp(use_without_coils,'No'))
                            ind_2_check = (ii-1)*app.numZeta_vmec_ip_per_cp + kk;
                            coil_pts_at_intrmd_contour = app.interferencePoints_ip{ind_2_check};

                            for ll = 1:length(coil_pts_at_intrmd_contour.X)
                                test_point_R = coil_pts_at_intrmd_contour.R(ll);
                                test_point_Z = coil_pts_at_intrmd_contour.Z(ll);
                                test_point_X = coil_pts_at_intrmd_contour.X(ll);
                                test_point_Y = coil_pts_at_intrmd_contour.Y(ll);

                                residue = app.check_cauchy_integral(app.intrmd_spline_contour{ind_2_check}.R,  ...
                                    app.intrmd_spline_contour{ind_2_check}.Z, test_point_R, test_point_Z);
                                % if residue ~> 2*pi, then the point is inside the contour

                                % 6 is close enough to 2*pi to probably indicate a problem
                                if (residue  > app.ResidueCoilsEditField.Value)

                                    residue_ok = 0;
                                    if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                        plot(test_point_R, test_point_Z, 'g+', 'Linewidth', 3);
                                        plot3(app.UIAxes, test_point_X, ...
                                            test_point_Y, test_point_Z, 'g+', 'Linewidth', 1);

                                    end
                                else
                                    if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                        % plot(test_point_R, test_point_Z, 'k+');
                                    end
                                end

                            end
                            if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                pause(0.0001)
                            end


                            if (app.TraceInterferencePoints_loaded == 1)
                                trace_pts_at_intrmd_contour = app.interferencePoints_trace_ip{ind_2_check};

                                for ll = 1:length(trace_pts_at_intrmd_contour.X)
                                    test_point_R = trace_pts_at_intrmd_contour.R(ll);
                                    test_point_Z = trace_pts_at_intrmd_contour.Z(ll);
                                    test_point_X = trace_pts_at_intrmd_contour.X(ll);
                                    test_point_Y = trace_pts_at_intrmd_contour.Y(ll);

                                    residue = app.check_cauchy_integral(app.intrmd_spline_contour{ind_2_check}.R,  ...
                                        app.intrmd_spline_contour{ind_2_check}.Z, test_point_R, test_point_Z);
                                    % if residue ~> 2*pi, then the point is inside the contour

                                    % 3 is close enough to 2*pi to probably indicate no problem
                                    if (residue  <= app.ResidueTracesEditField.Value)

                                        residue_ok = 0;
                                        if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                            plot3(app.UIAxes, test_point_X, ...
                                                test_point_Y, test_point_Z, 'g+', 'Linewidth', 1);

                                            plot(test_point_R, test_point_Z, 'g+', 'Linewidth', 3);
                                        end
                                    else
                                        if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                            %  plot(test_point_R, test_point_Z, 'k+');
                                        end
                                    end

                                end
                            end
                            if ~strcmpi(app.DebugLevelKnob.Value, '0')
                                pause(0.0001)
                            end
                        end
                    end
                end

                %pause(.01)
                %  10. If residue is 'okay' leave it.
                if residue_ok

                    % keep going!
                    disp('<---No collisions detected')


                    % unless it is beyond a maximum step size.

                else
                    %  11. If residue indicates collision,
                    % move the point back, adjust the mask and counter
                    STARTDEBUG=0;
                    app.UIAxes.Color = [1 0.8 0.8];

                    disp('<----Collisions detected!')
                    if ~strcmpi(app.DebugLevelKnob.Value, '0')
                        axis(app.UIAxes2);

                        %plot3(app.UIAxes2, ii,jj,app.spl_ctrl_pts(ii,jj), 'ro');
                        plot3(app.UIAxes2, ...
                            ii*ones(1,app.num_pol_angles_ctrl), ...
                            1:(app.num_pol_angles_ctrl), ...
                            app.spl_ctrl_pts(ii,:), 'ro');
                        hold on
                        pause(.000001)
                    end

                    % 12. move the intermediate points back

                    %
                    %  13. set the mask matrix to 0 for that element

                    %  14. Reduce the '# of moving'-counter by 1

                    if ~strcmpi(app.DebugLevelKnob.Value, '0')
                        pause(.001)
                    end


                end  % end of residue_ok check

                %end % end of mask(ii,jj) == 1 check

                %=====================


            end


            % prepare surface data for plotting


            % build final surface from control points & splines and intermediate points and splines
            num_tor_planes_output = ...
                (app.num_tor_angles_ctrl - 1) * (app.numZeta_vmec_ip_per_cp + 1) + 1;
            num_pol_points_output = app.num_pol_angles_blowup;
            app.out_surf.BadX = NaN*ones(num_tor_planes_output, num_pol_points_output);
            app.out_surf.BadY = NaN*ones(num_tor_planes_output, num_pol_points_output);
            app.out_surf.BadZ = NaN*ones(num_tor_planes_output, num_pol_points_output);
            app.out_surf.X = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.Y = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.Z = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.R = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.Phi = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.BadZ = NaN*ones(num_tor_planes_output, num_pol_points_output);
            app.out_surf.norm_X = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.norm_Y = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.norm_Z = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.par_X = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.par_Y = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.par_Z = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.binorm_X = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.bonorm_Y = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.binorm_Z = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.X_lcfs = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.Y_lcfs = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.Z_lcfs = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.R_lcfs = zeros(num_tor_planes_output, num_pol_points_output);
            app.out_surf.Phi_lcfs = zeros(num_tor_planes_output, num_pol_points_output);
            %app.spl_ctrl_pts = app.spl_ctrl_pts - app.PostOptimizeBackoffEditField.Value;
            tor_index_counter = 0;
            for ii = 1:app.num_tor_angles_ctrl
                if (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'spline'))


                    delta_ctrl_spline = spline([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                        app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                        [app.spl_ctrl_pts(ii,2:(end-1)) app.spl_ctrl_pts(ii,:) ...
                        app.spl_ctrl_pts(ii,2:(end-1))], ...
                        app.vmec_control_points_blowup.theta_mesh(ii,:));
                elseif (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'pchip'))

                    delta_ctrl_spline = pchip([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                        app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                        [app.spl_ctrl_pts(ii,2:(end-1)) app.spl_ctrl_pts(ii,:) ...
                        app.spl_ctrl_pts(ii,2:(end-1))], ...
                        app.vmec_control_points_blowup.theta_mesh(ii,:));
                end
                ctrl_spline_contour = app.build_exploded_contour(...
                    app.vmec_control_points_blowup.X(ii,:), ...
                    app.vmec_control_points_blowup.Y(ii,:), ...
                    app.vmec_control_points_blowup.Z(ii,:), ...
                    app.vmec_control_normals_blowup_cyl.normal_x(ii,:), ...
                    app.vmec_control_normals_blowup_cyl.normal_y(ii,:), ...
                    app.vmec_control_normals_blowup_cyl.normal_z(ii,:), ...
                    delta_ctrl_spline);

                tor_index_counter = tor_index_counter + 1;
                app.out_surf.X(tor_index_counter, :) = ctrl_spline_contour.X;
                app.out_surf.Y(tor_index_counter, :) = ctrl_spline_contour.Y;
                app.out_surf.Z(tor_index_counter, :) = ctrl_spline_contour.Z;
                app.out_surf.R(tor_index_counter, :) = ctrl_spline_contour.R;
                app.out_surf.Phi(tor_index_counter, :) = ctrl_spline_contour.Phi;
                app.out_surf.norm_X(tor_index_counter, :) = app.vmec_control_normals_blowup_cyl.normal_x(ii,:);
                app.out_surf.norm_Y(tor_index_counter, :) = app.vmec_control_normals_blowup_cyl.normal_y(ii,:);
                app.out_surf.norm_Z(tor_index_counter, :) = app.vmec_control_normals_blowup_cyl.normal_z(ii,:);

                app.out_surf.X_lcfs(tor_index_counter, :) = app.vmec_control_points_blowup.X(ii,:);
                app.out_surf.Y_lcfs(tor_index_counter, :) = app.vmec_control_points_blowup.Y(ii,:);
                app.out_surf.Z_lcfs(tor_index_counter, :) = app.vmec_control_points_blowup.Z(ii,:);
                app.out_surf.R_lcfs(tor_index_counter, :) = app.vmec_control_points_blowup.R(ii,:);
                app.out_surf.Phi_lcfs(tor_index_counter, :) = ctrl_spline_contour.Phi;

                if (ii < app.num_tor_angles_ctrl)

                    for jj = 1:app.numZeta_vmec_ip_per_cp
                        ind_2_set = (ii-1)*app.numZeta_vmec_ip_per_cp + jj;


                        if (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'spline'))

                            delta_intrmd_spline = spline( [app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                                app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                                [app.spl_intrmd_pts(ind_2_set,2:(end-1)) app.spl_intrmd_pts(ind_2_set,:) ...
                                app.spl_intrmd_pts(ind_2_set,2:(end-1))], ...
                                app.vmec_intermediate_points_blowup.theta_mesh(ind_2_set,:));
                        elseif (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'pchip'))
                            delta_intrmd_spline = pchip( [app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                                app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                                [app.spl_intrmd_pts(ind_2_set,2:(end-1)) app.spl_intrmd_pts(ind_2_set,:) ...
                                app.spl_intrmd_pts(ind_2_set,2:(end-1))], ...
                                app.vmec_intermediate_points_blowup.theta_mesh(ind_2_set,:));
                        end
                        app.intrmd_spline_contour = app.build_exploded_contour(...
                            app.vmec_intermediate_points_blowup.X(ind_2_set,:), ...
                            app.vmec_intermediate_points_blowup.Y(ind_2_set,:), ...
                            app.vmec_intermediate_points_blowup.Z(ind_2_set,:), ...
                            app.vmec_intermediate_normals_blowup_cyl.normal_x(ind_2_set,:), ...
                            app.vmec_intermediate_normals_blowup_cyl.normal_y(ind_2_set,:), ...
                            app.vmec_intermediate_normals_blowup_cyl.normal_z(ind_2_set,:), ...
                            delta_intrmd_spline);

                        tor_index_counter = tor_index_counter + 1;
                        app.out_surf.X(tor_index_counter, :) = app.intrmd_spline_contour.X;
                        app.out_surf.Y(tor_index_counter, :) = app.intrmd_spline_contour.Y;
                        app.out_surf.Z(tor_index_counter, :) = app.intrmd_spline_contour.Z;
                        app.out_surf.R(tor_index_counter, :) = app.intrmd_spline_contour.R;
                        app.out_surf.Phi(tor_index_counter, :) = app.intrmd_spline_contour.Phi;

                        app.out_surf.norm_X(tor_index_counter, :) = app.vmec_intermediate_normals_blowup_cyl.normal_x(ind_2_set,:);
                        app.out_surf.norm_Y(tor_index_counter, :) = app.vmec_intermediate_normals_blowup_cyl.normal_y(ind_2_set,:);
                        app.out_surf.norm_Z(tor_index_counter, :) = app.vmec_intermediate_normals_blowup_cyl.normal_z(ind_2_set,:);


                        app.out_surf.X_lcfs(tor_index_counter, :) = app.vmec_intermediate_points_blowup.X(ind_2_set,:);
                        app.out_surf.Y_lcfs(tor_index_counter, :) = app.vmec_intermediate_points_blowup.Y(ind_2_set,:);
                        app.out_surf.Z_lcfs(tor_index_counter, :) = app.vmec_intermediate_points_blowup.Z(ind_2_set,:);
                        app.out_surf.R_lcfs(tor_index_counter, :) = app.vmec_intermediate_points_blowup.R(ind_2_set,:);
                        app.out_surf.Phi_lcfs(tor_index_counter, :) = app.intrmd_spline_contour.Phi;


                    end
                end
            end
            for ii = 1:tor_index_counter
                for jj = 1:num_pol_points_output
                    if (ii == 1)
                        ii_prev = ii;
                        ii_next = ii + 1;
                    elseif (ii == tor_index_counter)
                        ii_prev = ii - 1;
                        ii_next = ii;
                    else
                        ii_next = ii + 1;
                        ii_prev = ii - 1;
                    end

                    par_X = app.out_surf.X(ii_next,jj) - ...
                        app.out_surf.X(ii_prev,jj);
                    par_Y = app.out_surf.Y(ii_next,jj) - ...
                        app.out_surf.Y(ii_prev,jj);
                    par_Z = app.out_surf.Z(ii_next,jj) - ...
                        app.out_surf.Z(ii_prev,jj);
                    norm_par = sqrt(par_X^2 + par_Y^2 + par_Z^2);

                    app.out_surf.par_X(ii, jj) = par_X / norm_par;
                    app.out_surf.par_Y(ii, jj) = par_Y / norm_par;
                    app.out_surf.par_Z(ii, jj) = par_Z / norm_par;

                end
            end

            for ii = 1:tor_index_counter
                for jj = 1:num_pol_points_output
                    if (jj == 1)
                        jj_prev = num_pol_points_output - 1;
                        jj_next = 2;
                    elseif (jj == num_pol_points_output)
                        jj_prev = jj - 1;
                        jj_next = 2;
                    else
                        jj_next = jj + 1;
                        jj_prev = jj - 1;
                    end
                    binorm_X = app.out_surf.X(ii,jj_next) - ...
                        app.out_surf.X(ii,jj_prev);
                    binorm_Y = app.out_surf.Y(ii,jj_next) - ...
                        app.out_surf.Y(ii,jj_prev);
                    binorm_Z = app.out_surf.Z(ii,jj_next) - ...
                        app.out_surf.Z(ii,jj_prev);
                    norm_binorm = sqrt(binorm_X^2 + binorm_Y^2 + binorm_Z^2);

                    app.out_surf.binorm_X(ii, jj) = binorm_X / norm_binorm;
                    app.out_surf.binorm_Y(ii, jj) = binorm_Y / norm_binorm;
                    app.out_surf.binorm_Z(ii, jj) = binorm_Z / norm_binorm;

                end
            end

            % finishing prepping data for plotting

            results = residue_ok;
        end

        function results = plot_lofted_surface(app)

            [aximu, eleva] = view(app.UIAxes);
            torosurf_wall.X = app.out_surf.X;
            torosurf_wall.Y = app.out_surf.Y;
            torosurf_wall.R = app.out_surf.R;
            torosurf_wall.Z = app.out_surf.Z;

            stell_sym_half_angle = app.Phi_EnddegreesEditField.Value*pi/180;
            stell_sym_full_angle = 2*stell_sym_half_angle;


            end_z = -torosurf_wall.Z;
            start_r = torosurf_wall.R;
            start_phi = app.out_surf.Phi;
            phi_flip = stell_sym_full_angle - start_phi;
            end_x = start_r .* cos(phi_flip);
            end_y = start_r .* sin(phi_flip);


            surf(app.UIAxes, ...
                torosurf_wall.X, torosurf_wall.Y, torosurf_wall.Z, 'Edgecolor', 'interp');
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');

            plot3(app.UIAxes, ...
                end_x, end_y, end_z);

            view(app.UIAxes, aximu, eleva);
            title(app.UIAxes,'Lofted Wall')
            results = 0;
            axis(app.UIAxes, 'equal')
            axis(app.UIAxes, 'tight')

        end


        function vmec_data = read_vmec_loft(app, woutfile)


            %    disp('Checking file');
            ncID = netcdf.open(woutfile,'nc_nowrite');

            %    disp('Loading data from VMEC wout file');
            VarID = netcdf.inqVarID(ncID,'nfp');
            vmec_data.nfp = double(netcdf.getVar(ncID,VarID));

            VarID = netcdf.inqVarID(ncID,'lasym__logical__');
            vmec_data.lasym = double(netcdf.getVar(ncID,VarID));

            VarID = netcdf.inqVarID(ncID,'ns');
            vmec_data.ns = double(netcdf.getVar(ncID,VarID));

            VarID = netcdf.inqVarID(ncID, 'mnmax');
            vmec_data.mnmax = double(netcdf.getVar(ncID, VarID));

            VarID = netcdf.inqVarID(ncID, 'mnmax_nyq');
            vmec_data.mnmax_nyq = double(netcdf.getVar(ncID, VarID));

            %(mn_mode) toridal mode numbers
            VarID = netcdf.inqVarID(ncID, 'xn');
            vmec_data.xn = double(netcdf.getVar(ncID, VarID));

            % nyquist toroidal mode numbers
            VarID = netcdf.inqVarID(ncID, 'xn_nyq');
            vmec_data.xn_nyq = double(netcdf.getVar(ncID, VarID));

            %(mn_mode) polidal mode numbers
            VarID = netcdf.inqVarID(ncID, 'xm');
            vmec_data.xm = double(netcdf.getVar(ncID, VarID));

            % nyquist poloidal mode numbers
            VarID = netcdf.inqVarID(ncID, 'xm_nyq');
            vmec_data.xm_nyq = double(netcdf.getVar(ncID, VarID));

            %(radius mn_mode ) cosmn component of cylindrical R, full mesh in [m]
            % taking the transpose
            VarID = netcdf.inqVarID(ncID, 'rmnc');
            vmec_data.rmnc = transpose(netcdf.getVar(ncID, VarID))';

            %(radius mn_mode ) sinmn component of cylindrical Z, full mesh in [m]
            % taking the transpose
            VarID = netcdf.inqVarID(ncID, 'zmns');
            vmec_data.zmns = transpose(netcdf.getVar(ncID, VarID))';


        end

        function [points_out, normals_out, normal_cyl_proj_out] = ...
                get_VMEC_LCFS_points(app, vmec_data, num_theta, num_zeta,  ...
                theta_min, theta_max, zeta_min, zeta_max, normalized_flux_surface)

            if nargin < 8
                theta_min = 0; theta_max = 2*pi; zeta_min = 0; zeta_max = pi/vmec_data.nfp;
            end

            % if the vmec data came from read_vmec.m (from S. Lazerson's library), the
            % xn-terms need to be reverted back to the left-handedness of VMEC
            xn_use = vmec_data.xn; % revert to vmec format
            surface_index = vmec_data.ns;
            if nargin >= 8
                surface_index = round(normalized_flux_surface * surface_index);
            end

            % allocate space for speed
            points_out.zeta = linspace(zeta_min, zeta_max, num_zeta);
            points_out.theta = linspace(theta_min, theta_max, num_theta);
            [points_out.theta_mesh, points_out.zeta_mesh] = meshgrid(points_out.theta, points_out.zeta);
            points_out.X = zeros(num_zeta, num_theta);
            points_out.Y = zeros(num_zeta, num_theta);
            points_out.Z = zeros(num_zeta, num_theta);
            points_out.R = zeros(num_zeta, num_theta);

            % initialize  to 0
            dx_dtheta = zeros(num_zeta, num_theta);
            dy_dtheta = zeros(num_zeta, num_theta);
            dz_dtheta = zeros(num_zeta, num_theta);
            dx_dzeta = zeros(num_zeta, num_theta);
            dy_dzeta = zeros(num_zeta, num_theta);
            dz_dzeta = zeros(num_zeta, num_theta);

            sin_zeta = sin(points_out.zeta_mesh);
            cos_zeta = cos(points_out.zeta_mesh);
            dsinzeta_dzeta = cos_zeta;
            dcoszeta_dzeta = -sin_zeta;

            for ii = 1:vmec_data.mnmax
                angle_mesh = vmec_data.xm(ii) * points_out.theta_mesh - ...
                    xn_use(ii) * points_out.zeta_mesh;
                cos_am = cos(angle_mesh);
                sin_am = sin(angle_mesh);
                dsinam_dtheta = vmec_data.xm(ii) * cos_am;
                dcosam_dtheta = vmec_data.xm(ii) *  -sin_am;
                dsinam_dzeta = xn_use(ii) * -cos_am;
                dcosam_dzeta = xn_use(ii) * sin_am;

                points_out.X = points_out.X + vmec_data.rmnc(ii, surface_index) * ...
                    cos_am .* cos_zeta;
                points_out.Y = points_out.Y + vmec_data.rmnc(ii, surface_index) * ...
                    cos_am .* sin_zeta;
                points_out.Z = points_out.Z + vmec_data.zmns(ii, surface_index) * ...
                    sin_am;

                dx_dtheta = dx_dtheta + vmec_data.rmnc(ii, surface_index) * dcosam_dtheta .* cos_zeta;
                dy_dtheta = dy_dtheta + vmec_data.rmnc(ii, surface_index) * dcosam_dtheta .* sin_zeta;
                dz_dtheta = dz_dtheta + vmec_data.zmns(ii, surface_index) * dsinam_dtheta;

                dx_dzeta = dx_dzeta + vmec_data.rmnc(ii, surface_index) * (dcosam_dzeta .* cos_zeta + cos_am .* dcoszeta_dzeta);
                dy_dzeta = dy_dzeta + vmec_data.rmnc(ii, surface_index) * (dcosam_dzeta .* sin_zeta + cos_am .* dsinzeta_dzeta);
                dz_dzeta = dz_dzeta + vmec_data.zmns(ii, surface_index) * dsinam_dzeta;

                if vmec_data.lasym
                    fixthis
                    points_out.X = points_out.X + rmns_plasma(imn) * sinangle .* cos_zeta;
                    points_out.Y = points_out.Y + rmns_plasma(imn) * sinangle .* sin_zeta;
                    points_out.Z = points_out.Z + zmnc_plasma(imn) * cosangle;

                    dx_dtheta = dx_dtheta + rmns_plasma(imn) * dsinam_dtheta .* cos_zeta;
                    dy_dtheta = dy_dtheta + rmns_plasma(imn) * dsinam_dtheta .* sin_zeta;
                    dz_dtheta = dz_dtheta + zmnc_plasma(imn) * dcosam_dtheta;

                    dx_dzeta = dx_dzeta + rmns_plasma(imn) * (dsinam_dzeta .* cos_zeta + sin_am .* dcoszeta_dzeta);
                    dy_dzeta = dy_dzeta + rmns_plasma(imn) * (dsinam_dzeta .* sin_zeta + sin_am .* dsinzeta_dzeta);
                    dz_dzeta = dz_dzeta + zmnc_plasma(imn) * dcosam_dzeta;
                end

            end

            points_out.R = sqrt(points_out.X.^2 + points_out.Y.^2);

            % Evaluate the (non-unit-magnitude) surface normal vector from N = (dr/dv) cross (dr/du):
            normal_x = dy_dzeta .* dz_dtheta - dy_dtheta .* dz_dzeta;
            normal_y = dz_dzeta .* dx_dtheta - dz_dtheta .* dx_dzeta;
            normal_z = dx_dzeta .* dy_dtheta - dx_dtheta .* dy_dzeta;

            % Now normalize the normal vector so it has unit magnitude:
            normal_abs = sqrt(normal_x.*normal_x + normal_y.*normal_y + normal_z.*normal_z);
            normals_out.normal_x = normal_x ./ normal_abs;
            normals_out.normal_y = normal_y ./ normal_abs;
            normals_out.normal_z = normal_z ./ normal_abs;

            % Make projections outward from the LCFS that are also point along the
            % zeta=const plane
            % project (dot product) the normal vector along the cylindrical r vector
            normal_phi = atan2(normals_out.normal_y, normals_out.normal_x);
            normal_top_l = sqrt(normals_out.normal_y.^2 + normals_out.normal_x.^2);
            normal_delta_phi = normal_phi - points_out.zeta_mesh;
            par_proj = normal_top_l .* cos(normal_delta_phi);
            perp_proj = normal_top_l .* sin(normal_delta_phi);

            r_cyl_x = par_proj.*cos(points_out.zeta_mesh);
            r_cyl_y = par_proj.*sin(points_out.zeta_mesh);
            r_cyl_z = normals_out.normal_z .* ones(size(points_out.R));

            % and normalize it
            proj_normal_abs = sqrt(r_cyl_x.*r_cyl_x + ...
                r_cyl_y.*r_cyl_y + ...
                r_cyl_z.*r_cyl_z);
            %proj_normal_abs =1;

            normal_cyl_proj_out.normal_x = r_cyl_x ./ proj_normal_abs;
            normal_cyl_proj_out.normal_y = r_cyl_y ./ proj_normal_abs;
            normal_cyl_proj_out.normal_z = r_cyl_z ./ proj_normal_abs;


        end

        function [points_out, normals_out, normal_cyl_proj_out] = ...
                get_VMEC_LCFS_points_2(app, vmec_data, theta_list, zeta_list, normalized_flux_surface)


            % if the vmec data came from read_vmec.m (from S. Lazerson's library), the
            % xn-terms need to be reverted back to the left-handedness of VMEC
            xn_use = vmec_data.xn; % revert to vmec format
            surface_index = vmec_data.ns;
            if nargin >= 5
                surface_index = round(normalized_flux_surface * surface_index);
            end

            % allocate space for speed
            points_out.zeta = zeta_list;
            num_zeta = length(zeta_list);
            points_out.theta = theta_list;
            num_theta = length(theta_list);

            [points_out.theta_mesh, points_out.zeta_mesh] = meshgrid(points_out.theta, points_out.zeta);
            points_out.X = zeros(num_zeta, num_theta);
            points_out.Y = zeros(num_zeta, num_theta);
            points_out.Z = zeros(num_zeta, num_theta);
            points_out.R = zeros(num_zeta, num_theta);

            % initialize  to 0
            dx_dtheta = zeros(num_zeta, num_theta);
            dy_dtheta = zeros(num_zeta, num_theta);
            dz_dtheta = zeros(num_zeta, num_theta);
            dx_dzeta = zeros(num_zeta, num_theta);
            dy_dzeta = zeros(num_zeta, num_theta);
            dz_dzeta = zeros(num_zeta, num_theta);

            sin_zeta = sin(points_out.zeta_mesh);
            cos_zeta = cos(points_out.zeta_mesh);
            dsinzeta_dzeta = cos_zeta;
            dcoszeta_dzeta = -sin_zeta;

            for ii = 1:vmec_data.mnmax
                angle_mesh = vmec_data.xm(ii) * points_out.theta_mesh - ...
                    xn_use(ii) * points_out.zeta_mesh;
                cos_am = cos(angle_mesh);
                sin_am = sin(angle_mesh);
                dsinam_dtheta = vmec_data.xm(ii) * cos_am;
                dcosam_dtheta = vmec_data.xm(ii) *  -sin_am;
                dsinam_dzeta = xn_use(ii) * -cos_am;
                dcosam_dzeta = xn_use(ii) * sin_am;

                points_out.X = points_out.X + vmec_data.rmnc(ii, surface_index) * ...
                    cos_am .* cos_zeta;
                points_out.Y = points_out.Y + vmec_data.rmnc(ii, surface_index) * ...
                    cos_am .* sin_zeta;
                points_out.Z = points_out.Z + vmec_data.zmns(ii, surface_index) * ...
                    sin_am;

                dx_dtheta = dx_dtheta + vmec_data.rmnc(ii, surface_index) * dcosam_dtheta .* cos_zeta;
                dy_dtheta = dy_dtheta + vmec_data.rmnc(ii, surface_index) * dcosam_dtheta .* sin_zeta;
                dz_dtheta = dz_dtheta + vmec_data.zmns(ii, surface_index) * dsinam_dtheta;

                dx_dzeta = dx_dzeta + vmec_data.rmnc(ii, surface_index) * (dcosam_dzeta .* cos_zeta + cos_am .* dcoszeta_dzeta);
                dy_dzeta = dy_dzeta + vmec_data.rmnc(ii, surface_index) * (dcosam_dzeta .* sin_zeta + cos_am .* dsinzeta_dzeta);
                dz_dzeta = dz_dzeta + vmec_data.zmns(ii, surface_index) * dsinam_dzeta;

                if vmec_data.lasym
                    fixthis
                    points_out.X = points_out.X + rmns_plasma(imn) * sinangle .* cos_zeta;
                    points_out.Y = points_out.Y + rmns_plasma(imn) * sinangle .* sin_zeta;
                    points_out.Z = points_out.Z + zmnc_plasma(imn) * cosangle;

                    dx_dtheta = dx_dtheta + rmns_plasma(imn) * dsinam_dtheta .* cos_zeta;
                    dy_dtheta = dy_dtheta + rmns_plasma(imn) * dsinam_dtheta .* sin_zeta;
                    dz_dtheta = dz_dtheta + zmnc_plasma(imn) * dcosam_dtheta;

                    dx_dzeta = dx_dzeta + rmns_plasma(imn) * (dsinam_dzeta .* cos_zeta + sin_am .* dcoszeta_dzeta);
                    dy_dzeta = dy_dzeta + rmns_plasma(imn) * (dsinam_dzeta .* sin_zeta + sin_am .* dsinzeta_dzeta);
                    dz_dzeta = dz_dzeta + zmnc_plasma(imn) * dcosam_dzeta;
                end

            end

            points_out.R = sqrt(points_out.X.^2 + points_out.Y.^2);

            % Evaluate the (non-unit-magnitude) surface normal vector from N = (dr/dv) cross (dr/du):
            normal_x = dy_dzeta .* dz_dtheta - dy_dtheta .* dz_dzeta;
            normal_y = dz_dzeta .* dx_dtheta - dz_dtheta .* dx_dzeta;
            normal_z = dx_dzeta .* dy_dtheta - dx_dtheta .* dy_dzeta;

            % Now normalize the normal vector so it has unit magnitude:
            normal_abs = sqrt(normal_x.*normal_x + normal_y.*normal_y + normal_z.*normal_z);
            normals_out.normal_x = normal_x ./ normal_abs;
            normals_out.normal_y = normal_y ./ normal_abs;
            normals_out.normal_z = normal_z ./ normal_abs;

            % Make projections outward from the LCFS that are also point along the
            % zeta=const plane
            % project (dot product) the normal vector along the cylindrical r vector
            normal_phi = atan2(normals_out.normal_y, normals_out.normal_x);
            normal_top_l = sqrt(normals_out.normal_y.^2 + normals_out.normal_x.^2);
            normal_delta_phi = normal_phi - points_out.zeta_mesh;
            par_proj = normal_top_l .* cos(normal_delta_phi);
            perp_proj = normal_top_l .* sin(normal_delta_phi);

            r_cyl_x = par_proj.*cos(points_out.zeta_mesh);
            r_cyl_y = par_proj.*sin(points_out.zeta_mesh);
            r_cyl_z = normals_out.normal_z .* ones(size(points_out.R));

            % and normalize it
            proj_normal_abs = sqrt(r_cyl_x.*r_cyl_x + ...
                r_cyl_y.*r_cyl_y + ...
                r_cyl_z.*r_cyl_z);
            %proj_normal_abs =1;

            normal_cyl_proj_out.normal_x = r_cyl_x ./ proj_normal_abs;
            normal_cyl_proj_out.normal_y = r_cyl_y ./ proj_normal_abs;
            normal_cyl_proj_out.normal_z = r_cyl_z ./ proj_normal_abs;

        end

        function  torosurf = write_flare_torosurf(app, torosurf, filename_out)
            % see make_wall_boundary.m


            fid = fopen(filename_out, 'w');
            scale = 1; % convert to m.  '1' is for meters.  if using cm, change to 100
            %
            % label
            % n  m  symmetry  {R0  Z0}
            % for i = 0...n-1
            %     phi_i [deg]
            %     for j = 0...m-1
            %         R_ij   Z_ij

            %disp(['Loading data from ' torosurf_filename]);

            %disp(torosurf.label);
            fprintf(fid, 'torosurf_label\n');
            fprintf(fid, '%6i %6i %6i %12.8f %12.8f\n', torosurf.n, torosurf.m, ...
                torosurf.sym, scale * torosurf.R0, scale * torosurf.Z0);

            for ii = 1:torosurf.n
                fprintf(fid, '%16.14e\n', torosurf.zeta(ii) * 180/pi);
                for jj = 1:torosurf.m
                    fprintf(fid, '%16.14e %16.14e\n', scale * torosurf.R(ii,jj), scale * torosurf.Z(ii,jj) );
                end

            end

            fclose(fid);

        end

        function [r_out, z_out, rho_out, theta_out, r_rho_out, ...
                r_theta_out, r_phi_out, z_rho_out, ...
                z_theta_out, z_phi_out, ...
                lambda_phi_out, lambda_theta_out, ...
                b_r_out, b_z_out, b_tor_out, ...
                sqrt_g_out, bsq_out, b1_out, b_x_out, b_y_out, ...
                Psi_Phi_out, Psi_R_out, Psi_Z_out, Psi_x_out, Psi_y_out] = ...
                get_VMEC_derived_values(app, file_vmec_output, lab_x, lab_y, lab_z, TOL_INV, ...
                rho_guess_in, theta_guess_in)
            % function [r_out, z_out, rho_out, theta_out, r_rho_out, ...
            %     r_theta_out, r_phi_out, z_rho_out, ...
            %     z_theta_out, z_phi_out, ...
            %     lambda_phi_out, lambda_theta_out, ...
            %     b_r_out, b_z_out, b_tor_out, ...
            %     sqrt_g_out, bsq_out, b1_out, b_x_out, b_y_out, ...
            %     Psi_Phi_out, Psi_R_out, Psi_Z_out, Psi_x_out, Psi_y_out] = ...
            %     get_VMEC_derived_values(file_vmec_output, lab_x, lab_y, lab_z, TOL_INV, ...
            %     rho_guess, theta_guess)
            % Input
            %
            % The input list is:
            %
            %     * file_vmec_output: The complete name of the VMEC output file
            %     * lab_x, lab_y, lab_z: Each is an array of points in lab coordinate space.
            %     * TOL_INV: (optional) A tolerance on the inversion. Default = 1e-6;
            %     * rho_guess, theta_guess: (optional) Initial guesses on the inversion. default = .5 and pi/2
            %
            % Output
            %
            % Each output is an array the same size as lab_x, lab_y, etc, and correspond to the following:
            %
            %     * r_out: The real space R coordinate, after inversion
            %     * z_out: The real space Z coordinate, after inversion
            %     * rho_out, theta_out: The vmec rho coordinate (radial) and theta (poloidal) coordinate
            %     * r_rho_out, r_theta_out, r_phi_out: The derivatives of 'R' w.r.t. VMEC rho, theta, and phi.
            %     * z_rho_out, z_theta_out, z_phi_out: The derivatives of 'Z' w.r.t. VMEC rho, theta, and phi
            %     * lambda_phi_out, lambda_theta_out: The derivatives of the VMEC poloidal angle stream function, w.r.t. phi and theta
            %     * b_r_out, b_z_out, b_tor_out: Magnetic field components from VMEC inversion point.
            %     * sqrt_g_out, bsq_out, b1_out: Jacobian, B^2, and |B| at the VMEC inversion point
            %     * b_x_out, b_y_out: The lab x and y projections of the VMEC output
            %     * Psi_Phi_out, Psi_R_out, Psi_Z_out, Psi_x_out, Psi_y_out: The components of grad(Psi) w.r.t. Phi (lab toroidal angle), R (major radius), Z (vertical coordinate), x (laboratory x coordinate), and y (lab y coordinate).
            %
            % The code
            %
            % Requirements:
            %
            %    1. Although this VMEC code does require it, the DEMOs do require you to have hsx_grid_interp in your matlab path.
            %    2. You need netcdf support enabled in matlab. If you can not get this to work, please see me and I'll help you get it working on your machine.
            %    3. You need a vmec output file in your path. There are a few in Y:\CODES\Auxiliary VMEC and Boozer codes
            %

            DO_TIME_TEST = 0;

            if nargin < 8
                rho_guess_in = .5; theta_guess_in = pi/2;
            end
            if nargin < 6
                TOL_INV = 1e-6;
            end

            if nargin < 5
                disp('Not enuf inputs')
            end

            if isvector(lab_x) ~= 1
                disp('Sorry.  This only works on 1-d arrays');
            end

            persistent VMEC_DATA_LOADED
            persistent VMEC_OUTPUT_FILENAME
            persistent mnmax_vmec
            persistent xm_vmec
            persistent xn_vmec
            persistent spline_vmec
            persistent spline_d_vmec
            persistent phitot_vmec

            if ( (isempty(VMEC_DATA_LOADED)) || (VMEC_DATA_LOADED == 0) || ...
                    ~strcmp(VMEC_OUTPUT_FILENAME, file_vmec_output) )
                LOAD_VMEC_DATA = 1;
                VMEC_OUTPUT_FILENAME = file_vmec_output;
                VMEC_DATA_LOADED = 0;
            else
                LOAD_VMEC_DATA = 0;
            end

            % load VMEC_LOADED
            if LOAD_VMEC_DATA
                [mnmax_vmec, xm_vmec, xn_vmec, spline_vmec, spline_d_vmec, ...
                    phitot_vmec] = load_vmec_wout_file(file_vmec_output);
                VMEC_DATA_LOADED = 1;
            else
                % debug space
            end


            num_pts = length(lab_x);

            if DO_TIME_TEST
                tic
            end

            % First convert lab (x,y,z) to cylindrical
            lab_R = sqrt(lab_x.^2 + lab_y.^2);
            lab_Phi = atan2(lab_y, lab_x);
            rho_out = zeros(1,num_pts);
            theta_out = zeros(1,num_pts);
            r_rho = zeros(1,num_pts);
            r_theta = zeros(1,num_pts);
            r_phi = zeros(1,num_pts);
            z_rho = zeros(1,num_pts);
            z_theta = zeros(1,num_pts);
            z_phi = zeros(1,num_pts);
            output_flag = zeros(1,num_pts);
            for ii = 1:num_pts

                if ((num_pts > 100000) && (ii>99999) )
                    if (mod(ii, 100000) == 0)
                        disp(['working on point # ' num2str(ii) ' of ' num2str(num_pts)]);
                    end

                elseif ((num_pts > 1000) && (ii < 100000))
                    if (mod(ii, 10000) == 0)
                        disp(['working on point # ' num2str(ii) ' of ' num2str(num_pts)]);
                    end

                elseif (num_pts > 100)
                    if (mod(ii, 100) == 0)
                        disp(['working on point # ' num2str(ii) ' of ' num2str(num_pts)]);
                    end

                end

                % find the the vmec coordinates of the point
                if ((ii > 1) && (output_flag(ii-1) < 100))
                    rho_guess = rho_out(ii-1);
                    theta_guess = theta_out(ii-1);
                else
                    rho_guess = rho_guess_in;
                    theta_guess = theta_guess_in;
                end

                [rho_out(ii), theta_out(ii), r_rho(ii), ...
                    r_theta(ii), r_phi(ii), z_rho(ii), ...
                    z_theta(ii), z_phi(ii), output_flag(ii)] = ...
                    app.find_VMEC_coords(rho_guess, theta_guess, ...
                    lab_R(ii), lab_z(ii), lab_Phi(ii), TOL_INV, ...
                    mnmax_vmec, xm_vmec, xn_vmec, spline_vmec, spline_d_vmec, ...
                    phitot_vmec(end));

                if (output_flag(ii) < 100)  % normal solution found
                else
                    % try a whole bunch of inital guess
                    %disp('<---Did not invert correctly. Looping over search grid, keeping 1st ''good'' inverted solution')
                    num_attempts = 0;

                    for iii = 2:-0.2:0.1
                        for jjj = (2*pi) * linspace(0,1,5);
                            num_attempts  = num_attempts +1 ;
                            rho_guess = iii;
                            theta_guess = jjj;

                            [rho_out(ii), theta_out(ii), r_rho(ii), ...
                                r_theta(ii), r_phi(ii), z_rho(ii), ...
                                z_theta(ii), z_phi(ii), output_flag(ii)] = ...
                                app.find_VMEC_coords(rho_guess, theta_guess, ...
                                lab_R(ii), lab_z(ii), lab_Phi(ii), TOL_INV, ...
                                mnmax_vmec, xm_vmec, xn_vmec, spline_vmec, spline_d_vmec, ...
                                phitot_vmec(end));

                            if output_flag(ii) < 100 % normal solution found
                                disp(['<-recovered #1 after ' num2str(num_attempts) ...
                                    ' attempts'])
                                break
                            end

                        end
                        if output_flag(ii) < 100 % normal solution found
                            %disp('<-recovered #2')
                            break
                        end
                    end
                    if output_flag(ii) >= 100 % no solution found
                        %disp('<---Did not invert correctly. Setting rho==Nan, theta=Nan')
                        rho_out(ii) = NaN;
                        theta_out(ii) = NaN;
                    end

                end


                if output_flag(ii) < 100 % normal solution found
                    % find cylindrical components of magnetic field, and jacobian
                    [r_out(ii), z_out(ii), r_rho_out(ii), ...
                        r_theta_out(ii), r_phi_out(ii), z_rho_out(ii), ...
                        z_theta_out(ii), z_phi_out(ii), ...
                        lambda_phi_out(ii), lambda_theta_out(ii), ...
                        b_r_out(ii), b_z_out(ii), b_tor_out(ii), ...
                        sqrt_g_out(ii)] = ...
                        get_VMEC_inverse_coords(rho_out(ii), theta_out(ii), lab_Phi(ii), ...
                        mnmax_vmec, ...
                        xm_vmec, xn_vmec, spline_vmec, spline_d_vmec, phitot_vmec(end));
                else
                    % either way, don't bother w/ the derivatives or derived quantites
                    % out there.
                    r_out(ii) = NaN;
                    z_out(ii) = NaN;
                    rho_out(ii) = NaN;
                    theta_out(ii) = NaN;
                end
                if output_flag(ii) ~= 0
                    r_rho_out(ii) = NaN;
                    r_theta_out(ii) = NaN;
                    r_phi_out(ii) = NaN;
                    z_rho_out(ii) = NaN;
                    z_theta_out(ii) = NaN;
                    z_phi_out(ii) = NaN;
                    lambda_phi_out(ii) = NaN;
                    lambda_theta_out(ii) = NaN;
                    b_r_out(ii) = NaN;
                    b_z_out(ii) = NaN;
                    b_tor_out(ii) = NaN;
                    sqrt_g_out(ii) = NaN;
                end
            end

            % for <B^2> and <B>
            bsq_out = b_r_out.^2 + b_z_out.^2 + b_tor_out.^2;
            b1_out = sqrt(bsq_out);

            % construct grad Psi in cylindrical coords.
            % gradPsi = cross product of co-variant basis vector components,
            % e_Phi and e_Theta, divded by the Jacobian
            Psi_Phi_out = (r_phi_out .* z_theta_out - r_theta_out .* z_phi_out) ./ ...
                sqrt_g_out;
            Psi_R_out = -z_theta_out ./ sqrt_g_out;
            Psi_Z_out = r_theta_out ./ sqrt_g_out;

            % calculate gradPsi and B in cartesian coords.
            b_x_out = -b_tor_out .* sin(lab_Phi) + b_r_out .* cos(lab_Phi);
            b_y_out = b_tor_out .* cos(lab_Phi) + b_r_out .* sin(lab_Phi);
            % use chain rule for gradPsi
            Psi_x_out = (lab_x .* Psi_R_out ./ lab_R) - ...
                (lab_y .* Psi_Phi_out ./ lab_R.^2);
            Psi_y_out = (lab_y .* Psi_R_out ./ lab_R) + ...
                (lab_x .* Psi_Phi_out ./ lab_R.^2);


            if DO_TIME_TEST
                wall_time = toc;
                disp(['Total time in main loop of get_VMEC_derived_values: ' num2str(wall_time)]);
                disp(['Avg time/point in main loop of get_VMEC_derived_values: ' num2str(wall_time/num_pts)]);
            end


        end

        function [x_out, y_out, z_out]  = ...
                extract_poloidal_plane_coil_points(app, phi_angle, corner_line, tol_angle_in)

            if nargin < 4
                tol_angle_in = 1e-8;
            end

            %tol_phi_angle = 1e-8;
            tol_phi_angle = tol_angle_in;

            if abs(phi_angle > pi)
                disp('<---phi angle should be between -pi and pi.');
                andstop
            end

            [num_pts,~] = size(corner_line);

            corner_line_x = corner_line(:,1);
            corner_line_y = corner_line(:,2);
            corner_line_z = corner_line(:,3);


            x_out = [];
            y_out = [];
            z_out = [];

            % loop over each point
            %   find the coordinates of next point
            % if the line that connect the two points crosses the plane of interest,
            % interpolate to find the exact location
            % and add the point to the output arrays

            % written with the assumption that the last point is the same as the first
            % point
            for ii= (1:(num_pts-1))
                phi_0 = atan2(corner_line_y(ii), corner_line_x(ii));
                phi_1 = atan2(corner_line_y(ii+1), corner_line_x(ii+1));
                % check the proximity to the +2pi -> -2pi boundary, adjust if necessary
                % 6 is close to 2pi, and should handle most cases, unless the points
                % are far away from each other
                if ( abs(phi_1 - phi_0) > 6)
                    % adjust points to have same sign as the angle to which they are
                    % being compared.
                    if (phi_angle > 0)
                        if (phi_0 < 0)
                            phi_0 = phi_0+2*pi;
                        else
                            phi_1 = phi_1+2*pi;
                        end
                    else
                        if (phi_0 < 0)
                            phi_1 = phi_1-2*pi;
                        else
                            phi_0 = phi_0-2*pi;
                        end
                    end
                end
                if (abs(phi_angle - phi_0) < tol_phi_angle)
                    x_out = [x_out corner_line_x(ii)];
                    y_out = [y_out corner_line_y(ii)];
                    z_out = [z_out corner_line_z(ii)];
                end
                if ( sign(phi_0 - phi_angle) ~= sign(phi_1 - phi_angle) )
                    x_crosses_phi = interp1( [phi_0 phi_1], corner_line_x(ii:(ii+1)), phi_angle);
                    y_crosses_phi = interp1( [phi_0 phi_1], corner_line_y(ii:(ii+1)), phi_angle);
                    z_crosses_phi = interp1( [phi_0 phi_1], corner_line_z(ii:(ii+1)), phi_angle);

                    x_out = [x_out x_crosses_phi];
                    y_out = [y_out y_crosses_phi];
                    z_out = [z_out z_crosses_phi];


                end
                if (ii == (num_pts - 1))
                    if (abs(phi_1 - phi_angle) < tol_phi_angle)
                        x_out = [x_out corner_line_x(ii+1)];
                        y_out = [y_out corner_line_y(ii+1)];
                        z_out = [z_out corner_line_z(ii+1)];
                    end
                end

            end


        end

        function [rho, theta, dr_drho, dr_dtheta, dr_dphi, dz_drho, dz_dtheta, ...
                dz_dphi, output_flag] = find_VMEC_coords(app, rho_in, theta_in, ...
                r, z, phi, tolinv, mnmax, xm, xn, spline_vmec, spline_d_vmec, torflux_LCFS)

            %             function [rho, theta, dr_drho, dr_dtheta, dr_dphi, dz_drho, dz_dtheta, ...
            %                     dz_dphi, output_flag] = find_VMEC_coords(rho_in, theta_in, ...
            %                     r, z, phi, tolinv, mnmax, xm, xn, spline_vmec, spline_d_vmec, torflux_LCFS)
            % find_VMEC_coords finds the radial and poloidal VMEC flux
            % coordinates of a point specified in cylindrical coordinates
            %References:
            %  Attenberger, Houlberg, Hirshman J Comp Phys 72 (1987) 435
            %  Original Fortran (traflx) by W.A. Houlberg 12/97
            %  Adapted by JC Schmitt for Matlab  11/2009
            %Input:
            %  rho_in, theta_in - initial guesses
            %  r - major radius (m)
            %  z - vertical coordinate (m)
            %  phi - toroidal angle, right-hand rule around z-direction (radians)
            %  tolinv - convergence tolerance on r and z (m)
            %     = 1.0e-5*plasma minor radius is suggested (m)
            %  mnmax-total number of modes
            %  xm-poloidal mode #s
            %  xn-toroidal mode #s
            %  spline_vmec - spline coeffiecinets structure for each mode iota, lmns,
            %       rmnc, zmns
            %  spline_d_vmec - spline coeffiecinets structure for radial derivatives
            %       of each mode of rmnc, zmns
            %  torflux_LCFS-The enclosed toroidal flux at the last closed flux surface.
            %Output:
            %  rho - flux surface label (-)
            %  theta -poloidal coordinate theta (radians)
            %  dr_drho - dr/drho (m)
            %  dr_dtheta - dr/dtheta (m/radian)
            %  dr_dphi - dr/dphi (m/radian)
            %  dz_drho - dz/drho (m)
            %  dz_dtheta - dz/dtheta (m/radian)
            %  dz_dphi - dz/dphi (m/radian)
            %  output_flag - error flag (-)
            %       = 0 normal return, rho and theta within plasma
            %       = 1 converged to point outside plasma
            %       > 1 = no. of iterations, did not converge
            %       > 100 = 100 + no. iterations, no solution
            %       > 200 = 200 + no. iterations, Jacobian problems
            %Comments:
            %  The input values of rho and theta are used for the initial guess.
            %  The basic method is a Newton iteration in 2 dimensions.  See the paper
            %  for details.  Point _bg is the previous best guess and point _tp is a
            %  trial point.  If _tp is further from (r,z) than _bg, a new trial
            %  point is generated by halving the step and using interpolated derivatives
            %======================================================

            max_iter = 100;
            output_flag = NaN;
            %  Physical and conversion constants
            precision_1 = 2.0e-7; % used in cases when too close to rho == 0
            % Set starting points and parameters
            dtheta_max = 0.5;
            halfs = 1;
            tau_bg = 0; % used to track the sign of the Jacobian.  Set to +/- during first iteration
            error_to_bg = Inf;

            rho_bg = rho_in;
            theta_bg = theta_in;
            rho_tp = rho_bg;
            theta_tp = theta_bg;

            if rho_tp == 0.0
                rho_tp = 1.0e-2;
            end

            % Iterate to find flux coordinates
            continue_iterating = 1;
            % recovery_attempt = 0;
            num_iter = 0;
            while continue_iterating
                num_iter = num_iter + 1;


                % find r, z and derivs for current trial point
                [r_tp, z_tp, r_rho_tp, r_theta_tp, r_phi_tp, z_rho_tp, z_theta_tp, ...
                    z_phi_tp] = ...
                    get_VMEC_inverse_coords(rho_tp, theta_tp, phi, mnmax, xm, xn, ...
                    spline_vmec, spline_d_vmec, torflux_LCFS);

                % debugging info
                r_debug(num_iter) = r_tp;
                z_debug(num_iter) = z_tp;

                % check distance from r, z, to the trial point
                dr_tp = (r - r_tp);
                dz_tp = (z - z_tp);
                error_to_tp = dr_tp^2 + dz_tp^2;
                error_debug(num_iter) = error_to_tp;
                %     sqrt(error_to_tp)

                %  Check convergence
                if ( (abs(dr_tp) <= tolinv) && (abs(dz_tp) <= tolinv) )
                    %         disp(['num_iter = ' num2str(num_iter)]);
                    %         continue_iterating = 0; %used in some debugging cases
                    % set output flag
                    if (rho_tp > 1.0)
                        output_flag = 1;
                    else
                        output_flag = 0;
                    end

                    rho = rho_tp;
                    theta = mod(theta_tp, 2*pi);
                    dr_drho = r_rho_tp;
                    dr_dtheta = r_theta_tp;
                    dr_dphi = r_phi_tp;
                    dz_drho = z_rho_tp;
                    dz_dtheta = z_theta_tp;
                    dz_dphi = z_phi_tp;
                    return
                end

                % Not converged.  Now what?

                % Need to improve the estimate for rho and theta - try to get new Jacobian
                if (rho_tp == 0.0)
                    rho_tp = 10.0 * precision_1;
                end

                tau_tp = z_rho_tp * r_theta_tp - r_rho_tp * z_theta_tp;
                tau_tp_debug(num_iter) = tau_tp;

                if  (abs(tau_tp) < 1.0e-37)
                    % No solution exists for the Newton method
                    % Assume the solution is at the origin where tau = 0
                    continue_iterating = 0;
                    output_flag = 100 + iteration;
                    disp('<---Axis found?'); % Note: fix this iff it ever occurs.
                    rho = 0.0; % it was 1.0;  % JCS: Shouldn't this be '0.0'?
                    theta = 0.0;
                end

                %  Check consistency of sign of Jacobian
                %  Check consistency of sign of Jacobian
                %     if ((sign(tau_tp) ~= sign(tau_bg)) && tau_bg ~= 0.0)
                %          halfs = halfs * 2;
                %
                %         % Bad data.  Give up.
                %         % Upon further consideration - this might also happen when jumping
                %         % across the plasma boundary or last-closed flux surface.
                %     end
                % if 0

                if ((sign(tau_tp) ~= sign(tau_bg)) && tau_bg ~= 0.0)
                    % Bad data.  Give up.
                    output_flag = 200 + num_iter;
                    %disp(['<----Entering ''bad jacobian'' region'])
                    continue_iterating = 0;
                    rho = NaN; %1.0;  % this could also be set to NaN?
                    theta = NaN; %0.0;  % this could also be set to NaN?
                    dr_drho = NaN;
                    dr_dtheta = NaN;
                    dr_dphi = NaN;
                    dz_drho = NaN;
                    dz_dtheta = NaN;
                    dz_dphi = NaN;
                    return;
                end
                % end
                % If converging, set the best guess equal to the test point.
                %  Otherwise, halve the step size.
                error_to_tp_debug(num_iter) = error_to_tp;
                error_to_bg_debug(num_iter) = error_to_bg;

                if (error_to_tp < error_to_bg)
                    %         Converging - set trial point = best guess
                    if (halfs > 1)
                        %if recovery_attempt
                        %    halfs = halfs / 2;
                        %    recovery_attempt = 0;
                        %else
                        halfs = halfs / 2;
                        %end
                    end
                    rho_bg = rho_tp;
                    theta_bg = theta_tp;
                    error_to_bg = error_to_tp;
                    r_rho_bg = r_rho_tp;
                    z_rho_bg = z_rho_tp;
                    r_theta_bg = r_theta_tp;
                    z_theta_bg = z_theta_tp;
                    tau_bg = tau_tp;
                    dr_bg = dr_tp;
                    dz_bg = dz_tp;
                else
                    %         Not converging - halve step size
                    halfs = halfs * 2;
                end

                % Compute rho and theta steps - _bg and _tp may coincide here.
                % If the process is converging, all of these 1/4 and 3/4 do nothing
                % except set r_rho (or whatever) to r_rho_tp, etc., as expected for
                % a normal Newton search.  However, if the process is not
                % converging, an alternate jacobian and step size (halved) is used
                p75 = 0.75; p25 = 0.25;
                r_rho = p75 * r_rho_bg + p25 * r_rho_tp;
                z_rho = p75 * z_rho_bg + p25 * z_rho_tp;
                r_theta = p75 * r_theta_bg + p25 * r_theta_tp;
                z_theta = p75 * z_theta_bg + p25 * z_theta_tp;
                tau = p75 * tau_bg + p25 * tau_tp;
                dr = dr_tp / halfs;
                dz = dz_tp / halfs;
                drho = (r_theta * dz - z_theta * dr) / tau;
                dtheta = (z_rho * dr - r_rho * dz) / tau;

                % Avoid jumping around in poloidal space too far
                if  abs(dtheta) > dtheta_max
                    dtheta = abs(dtheta_max) * sign(dtheta);
                end

                % Get new trial point
                % don't let it jump acros boundary unless it is close
                if ( ((rho_bg + drho) > 1) &&  (rho_bg < 0.9) )
                    rho_tp = 0.95;
                elseif ( ((rho_bg + drho) < 1) &&  (rho_bg > 1.05))
                    rho_tp = 1.05;
                else
                    rho_tp = rho_bg + drho;
                end

                theta_tp = theta_bg + dtheta;

                % check to keep rho positive.
                if (rho_tp < 0.0)
                    rho_tp = -rho_tp;
                    theta_tp = theta_tp + pi - 2 * dtheta;
                end

                % check max number of iterations.  Pass back the current trial point
                if num_iter >= max_iter
                    %disp(['<----find_VMEC_coords not converged. using last test_point. num_iter = ' num2str(num_iter)]);
                    %disp(['<----(R, Z, Phi) = ' num2str([r z phi])]);
                    continue_iterating = 0;
                    rho = rho_tp;
                    theta = mod(theta_tp, 2*pi);
                    dr_drho = r_rho_tp;
                    dr_dtheta = r_theta_tp;
                    dr_dphi = r_phi_tp;
                    dz_drho = z_rho_tp;
                    dz_dtheta = z_theta_tp;
                    dz_dphi = z_phi_tp;
                    output_flag = num_iter;
                end
            end % while continue_iteration ...


        end

        function residue = check_cauchy_integral(app, wall_R,  wall_Z, r_chk, z_chk, special_eps)

            if (nargin < 6)
                special_eps = 1e-10;
            end

            numtheta = length(wall_R);


            r_sorted =  wall_R;
            z_sorted = wall_Z;
            r0n = r_chk;
            z0n = z_chk;

            residue = 0*r_chk;
            for jj = 1:numtheta
                jjp1 = jj+1;
                if (jjp1 > numtheta)
                    jjp1 = 1;
                end
                this_x = 0.5 * (r_sorted(jj) + r_sorted(jjp1)) - r0n;
                this_y = 0.5 * (z_sorted(jj) + z_sorted(jjp1)) - z0n;
                this_dx = r_sorted(jjp1) - r_sorted(jj);
                this_dy = z_sorted(jjp1) - z_sorted(jj);
                residue = residue + (this_x * this_dy - this_y * this_dx) ./ ...
                    (this_x.^2 + this_y.^2 + special_eps);
            end


        end


        function [data_out,pts_per_corner,corners_per_coil,num_coils] = ...
                read_coil_corners(app, filename, NFP)

            if nargin < 3
                NFP = 0;
            end

            data_out = [];

            fid = fopen(filename);

            % read header line

            % extract info

            % read in data structure


            % close file

            %fclose(fid);

            %# 257 points per corner, 4 corners, 5 coils
            the_settings = textscan(fid, '%c %d %s %s %s %d %s %d %s', 1);
            fclose(fid);

            pts_per_corner = double(the_settings{2});
            corners_per_coil = double(the_settings{6});
            num_coils = double(the_settings{8});


            opts = detectImportOptions(filename);
            opts.DataLines = [2 Inf];
            thetable = readtable(filename, opts);
            [rows,cols] = size(thetable);

            counter = 0;
            if (NFP == 0)
                data_out.coils{num_coils} = [];
            else
                data_out.coils{2*NFP*num_coils} = [];
            end

            for ii = 1:num_coils
                for jj = 1:corners_per_coil
                    %data_out.coils{ii}.corner{jj}.points = zeros(pts_per_corner, 3);
                    for kk = 1:pts_per_corner
                        counter = counter+1;
                        data_out.coils{ii}.corner{jj}.points(kk,1) =   str2num(string(thetable(counter,1).Variables));
                        data_out.coils{ii}.corner{jj}.points(kk,2) =   str2num(string(thetable(counter,2).Variables));
                        data_out.coils{ii}.corner{jj}.points(kk,3) =   str2num(string(thetable(counter,3).Variables));
                        %keyboard
                    end
                end
            end

            if NFP > 0
                % need to duplicate the coils 2*NFP-1 times
                % first flip the coils to span an entire field period.
                for ii = (num_coils+1):(2*num_coils)
                    for jj = 1:corners_per_coil
                        %data_out.coils{ii}.corner{jj}.points = zeros(pts_per_corner, 3);
                        for kk = 1:pts_per_corner
                            %counter = counter + 1;
                            data_out.coils{ii}.corner{jj}.points(kk,1) = data_out.coils{ii-num_coils}.corner{jj}.points(kk,2);
                            data_out.coils{ii}.corner{jj}.points(kk,2) = data_out.coils{ii-num_coils}.corner{jj}.points(kk,1);
                            data_out.coils{ii}.corner{jj}.points(kk,3) = -data_out.coils{ii-num_coils}.corner{jj}.points(kk,3);
                            %keyboard
                        end
                    end
                end
                num_coils = 2*num_coils;
                % then, duplicate the full-field period set NFP times
                for ll = 1:(NFP-1)
                    phi_rotate = (2*pi)/NFP;
                    for ii = ((ll*num_coils)+1):((ll+1)*num_coils)
                        for jj = 1:corners_per_coil
                            %data_out.coils{ii}.corner{jj}.points = zeros(pts_per_corner, 3);
                            for kk = 1:pts_per_corner
                                %counter = counter + 1;
                                this_x = data_out.coils{ii-num_coils}.corner{jj}.points(kk,1);
                                this_y = data_out.coils{ii-num_coils}.corner{jj}.points(kk,2);
                                this_z = data_out.coils{ii-num_coils}.corner{jj}.points(kk,3);
                                this_r = sqrt(this_x.^2 + this_y.^2);
                                this_phi = atan2(this_y, this_x);
                                new_phi = this_phi + phi_rotate;
                                new_x = this_r * cos(new_phi);
                                new_y = this_r * sin(new_phi);
                                data_out.coils{ii}.corner{jj}.points(kk,1) = new_x;
                                data_out.coils{ii}.corner{jj}.points(kk,2) = new_y;
                                data_out.coils{ii}.corner{jj}.points(kk,3) = this_z;
                                %keyboard
                            end
                        end
                    end
                end
                num_coils = NFP * num_coils;
            end

        end


        function output = build_exploded_contour(app, points_X, points_Y, points_Z, normals_X, ...
                normals_Y, normals_Z, deltas)


            xpld_pts_X = points_X + deltas.*normals_X;
            xpld_pts_Y = points_Y + deltas.*normals_Y;
            xpld_pts_Z = points_Z + deltas.*normals_Z;

            xpld_pts_R = sqrt(xpld_pts_X.^2 + xpld_pts_Y.^2);
            xpld_pts_Phi = atan2(xpld_pts_Y, xpld_pts_X);

            output.X = xpld_pts_X;
            output.Y = xpld_pts_Y;
            output.Z = xpld_pts_Z;
            output.R = xpld_pts_R;
            output.Phi = xpld_pts_Phi;


        end

        function [data_out,pts_per_corner,corners_per_coil_prereduction,num_coils]  =  read_coil_corners_hsx_CAD_DAT(app, make_plot)
            %load coils_grid_points_v20221110.mat  % currently has bad points in it

            if nargin < 1
                make_plot = 0;
            end
            % coil1_data = dlmread('COIL1/DATA/C1.DAT');
            % coil2_data = dlmread('COIL2/DATA/C2.DAT');
            % coil3a_data = dlmread('COIL3/DATA/C3_BARE.DAT');
            % coil3b_data = dlmread('COIL3/DATA/C3_BUNDL.DAT');
            % coil4_data = dlmread('COIL4/DATA/C4.DAT');
            % coil5_data = dlmread('COIL5/DATA/C5.DAT');
            % coil6a_data = dlmread('COIL6/DATA/C6_14T.DAT');
            % coil6b_data = dlmread('COIL6/DATA/C6_BARE.DAT');


            coil_data{1} = dlmread('/Users/schmittj/src/xStellTools/CoilCorners/COILS/DATA/C1PAK.DAT');
            coil_data{2} = dlmread('/Users/schmittj/src/xStellTools/CoilCorners/COILS/DATA/C2PAK.DAT');
            coil_data{3} = dlmread('/Users/schmittj/src/xStellTools/CoilCorners/COILS/DATA/C3PAK.DAT');
            coil_data{4} = dlmread('/Users/schmittj/src/xStellTools/CoilCorners/COILS/DATA/C4PAK.DAT');
            coil_data{5} = dlmread('/Users/schmittj/src/xStellTools/CoilCorners/COILS/DATA/C5PAK.DAT');
            coil_data{6} = dlmread('/Users/schmittj/src/xStellTools/CoilCorners/COILS/DATA/C6PAK.DAT');


            if make_plot*0

                figure
                box on;hold on;view(3);grid on;

                for ii= 1:6
                    plot3(coil_data{ii}(3:end,1), ...
                        coil_data{ii}(3:end,2), ...
                        coil_data{ii}(3:end,3))
                end
                axis equal;

                for ii= 1:6
                    comet3(coil_data{ii}(3:end,1), ...
                        coil_data{ii}(3:end,2), ...
                        coil_data{ii}(3:end,3))
                end


            end


            NFP = 4;
            num_coils = 6;

            pts_per_corner = 251;
            corners_per_coil_prereduction = 19;


            counter = 0;
            if (NFP == 0)
                data_out.coils{num_coils} = [];
            else
                data_out.coils{2*NFP*num_coils} = [];
            end

            corners_to_use = 1:corners_per_coil_prereduction;
            %corners_to_use = [1 3 10 12];

            for ii = 1:num_coils
                jj_counter = 0;
                for jj = corners_to_use
                    jj_counter = jj_counter + 1;
                    %for jj = 1:corners_per_coil
                    list_index = jj+2; % keep track of where in the list we are at - skip the first two values
                    %data_out.coils{ii}.corner{jj}.points = zeros(pts_per_corner, 3);
                    for kk = 1:pts_per_corner
                        data_out.coils{ii}.corner{jj_counter}.points(kk,1) =   coil_data{ii}(list_index,1);
                        data_out.coils{ii}.corner{jj_counter}.points(kk,2) =   coil_data{ii}(list_index,2);
                        data_out.coils{ii}.corner{jj_counter}.points(kk,3) =   coil_data{ii}(list_index,3);
                        list_index = list_index + corners_per_coil_prereduction;
                        %keyboard
                    end
                    % after the 4th point, skip the next index - its a repeat of the
                    % 1st one!
                    %list_index = list_index + 1;
                end
            end

            corners_per_coil = length(corners_to_use);

            if NFP > 0
                % need to duplicate the coils 2*NFP-1 times
                % first flip the coils to span an entire field period.
                for ii = (num_coils+1):(2*num_coils)
                    jj_ind = 0;
                    for jj = 1:corners_per_coil
                        %data_out.coils{ii}.corner{jj}.points = zeros(pts_per_corner, 3);
                        for kk = 1:pts_per_corner
                            %counter = counter + 1;
                            data_out.coils{ii}.corner{jj}.points(kk,1) = data_out.coils{ii-num_coils}.corner{jj}.points(kk,2);
                            data_out.coils{ii}.corner{jj}.points(kk,2) = data_out.coils{ii-num_coils}.corner{jj}.points(kk,1);
                            data_out.coils{ii}.corner{jj}.points(kk,3) = -data_out.coils{ii-num_coils}.corner{jj}.points(kk,3);
                            %keyboard
                        end
                    end
                end
                num_coils = 2*num_coils;
                % then, duplicate the full-field period set NFP times
                for ll = 1:(NFP-1)
                    phi_rotate = (2*pi)/NFP;
                    for ii = ((ll*num_coils)+1):((ll+1)*num_coils)
                        for jj = 1:corners_per_coil
                            %data_out.coils{ii}.corner{jj}.points = zeros(pts_per_corner, 3);
                            for kk = 1:pts_per_corner
                                %counter = counter + 1;
                                this_x = data_out.coils{ii-num_coils}.corner{jj}.points(kk,1);
                                this_y = data_out.coils{ii-num_coils}.corner{jj}.points(kk,2);
                                this_z = data_out.coils{ii-num_coils}.corner{jj}.points(kk,3);
                                this_r = sqrt(this_x.^2 + this_y.^2);
                                this_phi = atan2(this_y, this_x);
                                new_phi = this_phi + phi_rotate;
                                new_x = this_r * cos(new_phi);
                                new_y = this_r * sin(new_phi);
                                data_out.coils{ii}.corner{jj}.points(kk,1) = new_x;
                                data_out.coils{ii}.corner{jj}.points(kk,2) = new_y;
                                data_out.coils{ii}.corner{jj}.points(kk,3) = this_z;
                                %keyboard
                            end
                        end
                    end
                end
                num_coils = NFP * num_coils;
            end


            if make_plot


                figure
                box on;hold on;view(3);grid on;

                for ii= 1:6
                    %for jj = 1:19%[1 8 9 10 16]
                    %for jj = [1 2 3 10 11 12 19]
                    for jj = 1:corners_per_coil
                        plot3(data_out.coils{ii}.corner{jj}.points(:,1), ...
                            data_out.coils{ii}.corner{jj}.points(:,2), ...
                            data_out.coils{ii}.corner{jj}.points(:,3))
                    end
                end
                axis equal;

            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: 
        % ImportCoilCornersGeometryorcoilfilamentGeometryButton
        function ImportCoilCornersGeometryorcoilfilamentGeometryButtonPushed(app, event)

            % the wistell A coilset
            if (app.WISTELLAATENButton.Value == 1)
                corners_file = 'wista_single_00_fb_coils.txt';
                app.CoilCornerFilename.Text = corners_file;

                disp('<---Loading coil corner points')

                app.ImportCoilCornersGeometryorcoilfilamentGeometryButton.BackgroundColor = 'yellow';
                pause(0.001)

                if 0
                    load('CoilData_2022Aug25a.mat')
                    app.coilStruct = coilStruct;
                else
                    try
                        [coil_corner_data,pts_per_corner,corners_per_coil,num_coils] = ...
                            app.read_coil_corners(corners_file, 4);

                        app.coilStruct = [];
                        app.coilStruct.coil_corner_data = coil_corner_data;
                        app.coilStruct.pts_per_corner = pts_per_corner;
                        app.coilStruct.corners_per_coil = corners_per_coil;
                        app.coilStruct.num_coils = num_coils;
                        app.CoilCornersLoadedLamp.Color = [0 0 1];
                    catch
                        app.CoilCornersLoadedLamp.Color = [1 0 0];
                    end
                    app.ImportCoilCornersGeometryorcoilfilamentGeometryButton.BackgroundColor = [ 0 .88 .68];

                end

                coils_file = 'coils.wista_00_1024';
                app.CoilFilename.Text = coils_file;

                try
                    app.coilStruct.coil_data = read_coils(coils_file);
                    app.CoilsLoadedLamp.Color = [0 0 1];
                catch
                    app.CoilsLoadedLamp.Color = [1 0 0];

                end
                app.ImportCoilCornersGeometryorcoilfilamentGeometryButton.BackgroundColor = [ .96 .96 .96];
            elseif (app.HSXButton.Value == 1)
                % the hsx coilset
                app.IndicesofCoilstoUseforInterferenceChecksEditField.Value = ...
                    '1 2 3 4 5 6 11 12 43 44';
                corners_file = 'From CAD .DAT files';
                app.CoilCornerFilename.Text = corners_file;

                disp('<---Loading coil corner points')

                app.ImportCoilCornersGeometryorcoilfilamentGeometryButton.BackgroundColor = 'yellow';
                pause(0.001)

                if 0
                    load('CoilData_2022Aug25a.mat')
                    app.coilStruct = coilStruct;
                else
                    try
                        
                        [coil_corner_data,pts_per_corner,corners_per_coil,num_coils] = ...
                            app.read_coil_corners_hsx_CAD_DAT(0);

                        app.coilStruct = [];
                        app.coilStruct.coil_corner_data = coil_corner_data;
                        app.coilStruct.pts_per_corner = pts_per_corner;
                        app.coilStruct.corners_per_coil = corners_per_coil;
                        app.coilStruct.num_coils = num_coils;
                        app.CoilCornersLoadedLamp.Color = [0 0 1];
                    catch
                        app.CoilCornersLoadedLamp.Color = [1 0 0];
                    end
                    app.ImportCoilCornersGeometryorcoilfilamentGeometryButton.BackgroundColor = [ 0 .88 .68];

                end

                coils_file = 'NOTDEFINED';
                app.CoilFilename.Text = coils_file;

                app.CoilsLoadedLamp.Color = [.76 .96 .36];
                app.ImportCoilCornersGeometryorcoilfilamentGeometryButton.BackgroundColor = [ .96 .96 .96];

            elseif (app.OtherselectLIBSTELLcoilsfileButton.Value == 1)
                [file,path] = uigetfile('*.*', 'Select the LIBSTELL-style coils file', "MultiSelect","off");
                coils_file = fullfile(path,file);

                app.CoilFilename.Text = coils_file;

                corners_file = 'NOTDEFINED';
                app.CoilCornerFilename.Text = corners_file;

                disp('<---Loading coil filament data')

                app.ImportCoilCornersGeometryorcoilfilamentGeometryButton.BackgroundColor = 'yellow';
                pause(0.001)

                try
                    [coil_corner_data,pts_per_corner,num_coils] = ...
                        import_coil_data_as_corner_data(coils_file);

                    app.coilStruct = [];
                    app.coilStruct.coil_corner_data = coil_corner_data;
                    app.coilStruct.pts_per_corner = pts_per_corner;
                    app.coilStruct.corners_per_coil = 1;
                    app.coilStruct.num_coils = num_coils;
                    app.CoilCornersLoadedLamp.Color = [0 0 1];
                catch
                    app.CoilCornersLoadedLamp.Color = [1 0 0];
                end
                app.ImportCoilCornersGeometryorcoilfilamentGeometryButton.BackgroundColor = [ 0 .88 .68];


                app.CoilsLoadedLamp.Color = [.76 .96 .36];
                app.ImportCoilCornersGeometryorcoilfilamentGeometryButton.BackgroundColor = [ .96 .96 .96];

            end
        end

        % Button pushed function: 
        % ImportAxisandLCFSGeometryLoadVMECoutoutfilewout_ncButton
        function ImportAxisandLCFSGeometryLoadVMECoutoutfilewout_ncButtonPushed(app, event)

            % the old way - use the machine and a pre-determined wout file

            % the new way - ask the user!
            [file,path] = uigetfile('*.nc', 'Select the VMEC output file', "MultiSelect","off");
            woutfile = fullfile(path,file);

            app.AxisLCFSFilename.Text = woutfile;
            app.ImportAxisandLCFSGeometryLoadVMECoutoutfilewout_ncButton.BackgroundColor = [1 1 0];
            pause(0.001)
            try
                app.vmec_data = app.read_vmec_loft(woutfile);
                if isa(app.vmec_data, 'struct')
                    app.LCFSLoadedLamp.Color = [0 0 1];
                else
                    app.LCFSLoadedLamp.Color = [1 0 0];
                end
            catch
                app.LCFSLoadedLamp.Color = [1 0 0];
            end
            app.ImportAxisandLCFSGeometryLoadVMECoutoutfilewout_ncButton.BackgroundColor = [0.96 0.96 0.96];


        end

        % Button pushed function: 
        % DefineControlandIntermediateSurfaceGridsButton
        function DefineControlandIntermediateSurfaceGridsButtonPushed(app, event)
            app.DefineControlandIntermediateSurfaceGridsButton.BackgroundColor = [0.8,0.3,0.3];
            pause(0.001)
            x = app.define_control_and_intermediate_grids;
            %y = app.define_inteference_points;
            app.DefineControlandIntermediateSurfaceGridsButton.BackgroundColor = [0.96,0.96,0.96];
            %keyboard
        end

        % Button pushed function: InitializeLoftedSurfaceButton
        function InitializeLoftedSurfaceButtonPushed(app, event)
            app.InitializeLoftedSurfaceButton.BackgroundColor = [0.75 0.4 0.4];
            pause(0.001);
            app.initilializeLoftedSurface
            app.InitializeLoftedSurfaceButton.BackgroundColor = [0.96 0.96 0.96];
        end

        % Button pushed function: AutoLoftButton
        function AutoLoftButtonPushed(app, event)
            app.AutoLoftButton.BackgroundColor = [0.8 0.3 0.3];
            pause(0.001)
            app.optimizeIt
            temp_filename = ['stelloft_temp' char(datetime("now")) '.mat'];
            spline_data.spline_control_loft_values = app.spl_ctrl_pts;
            save(temp_filename, "spline_data", '-mat');
            app.AutoLoftButton.BackgroundColor = [0.78 0.98 0.78];
        end

        % Button pushed function: ExportFLAREWallLCGridsButton
        function ExportFLAREWallLCGridsButtonPushed(app, event)
            % build / make information for FLARE grids
            % Lc maps: Wall is fixed, grid map of launch points is provided

            % Far Wall: wall_out.txt or similar Wall at several R, Z points (check
            % units? cm) and at several phi (in degrees?)
            % Launch Points for Lc + Diffusion maps  Grid_Lc_x.txt  Q-meshes at toroidal
            % angles


            % ====================
            % ====================
            % Lc maps: Wall is fixed, grid map of launch points at several toroidal
            % angles.  grid is generated from a map that is based on
            % interpolated/splined (or splined/interpolated) meshes that are
            % monotonically increasing in some variable in the radial sense

            num_tor_planes_output = ...
                (app.num_tor_angles_ctrl - 1) * (app.numZeta_vmec_ip_per_cp + 1) + 1;
            %num_pol_points_output = app.num_pol_angles_blowup;

            % ====================
            % part 1: The wall
            torosurf_wall.n = num_tor_planes_output; % n is needed
            torosurf_wall.m = app.num_pol_angles_blowup; % m is needed
            torosurf_wall.zeta =  app.out_surf.Phi(:,1);% in radians
            torosurf_wall.R0 = 0.0; % R0 is needed
            torosurf_wall.Z0 = 0.0; % Z0 is needed
            torosurf_wall.sym = app.vmec_data.nfp;
            torosurf_wall.X = app.out_surf.X;
            torosurf_wall.Y = app.out_surf.Y;
            torosurf_wall.R = app.out_surf.R;
            torosurf_wall.Z = app.out_surf.Z;


            wall_out_name_gui = app.FLAREboundaryfilenameEditField.Value;
            wall_filename_out = strcat(app.OutputFolderPrefixLcScanEditField.Value, ...
                '/', wall_out_name_gui);
            mkdir(app.OutputFolderPrefixLcScanEditField.Value);

            % ====================
            % part 2: the Grids at several toroidal angles for the Lc launch points

            [mnmax_vmec, xm_vmec, xn_vmec, spline_vmec, spline_d_vmec, ...
                phitot_vmec] = load_vmec_wout_file(app.AxisLCFSFilename.Text);


            rho_min_mesh = app.LcGridrhominEditField.Value;
            rho_max_mesh = app.LcGridrhomaxEditField.Value;
            radial_divs = app.LcGridofdivisionsEditField.Value;
            num_field_periods = app.vmec_data.nfp;
            num_tor_planes_Lc = app.LcToroidalPlanesEditField.Value;


            stell_sym_angle = 2*pi/num_field_periods;

            tor_angles_Lc_grids = (0.5*360/num_field_periods)*...
                linspace(0.0, 1.0, num_tor_planes_Lc);

            phiGridCounter = 0;


            % ugh.  do this twice, if necessary
            for thisPhi = (tor_angles_Lc_grids*pi/180)
                phiGridCounter = phiGridCounter + 1;
                [mismatch,thisPhiGridIndex] = ...
                    min(abs(thisPhi- app.out_surf.Phi(:,1)));
                %mismatch
                disp(['<---Grid ' num2str(phiGridCounter) ': The toroidal angle/grid mismatch was: ' num2str(mismatch) ' radians']);
                filename = ['Grid_Lc_', num2str(phiGridCounter) '.txt'];

                %warning:using theta mesh from control point theta_mesh
                theta = app.vmec_control_points_blowup.theta_mesh(1,:);
                rho = linspace(rho_min_mesh, rho_max_mesh, radial_divs);

                rhomask = zeros(radial_divs*app.num_pol_angles_blowup, 1);

                % make concentric surfaces
                counter = 0;
                for ii = 1:radial_divs
                    for jj = 1:app.num_pol_angles_blowup
                        counter = counter + 1;

                        if rho(ii) <= 1
                            % in the MHD solution
                            %phi = tor_angle;
                            [thisR, thisZ] = ...
                                get_VMEC_inverse_coords(rho(ii), theta(jj), thisPhi, mnmax_vmec, ...
                                xm_vmec, xn_vmec, ...
                                spline_vmec, spline_d_vmec, phitot_vmec);
                            allR(counter) = thisR;
                            allX(counter) = thisR * cos(thisPhi);
                            allY(counter) = thisR * sin(thisPhi);
                            allZ(counter) = thisZ;
                        else
                            % linearly interpoloate
                            allX(counter) = interp1([0 1], ...
                                [app.out_surf.X_lcfs(thisPhiGridIndex, jj) ...
                                app.out_surf.X(thisPhiGridIndex, jj)], ...
                                (rho(ii) - 1));
                            allY(counter) = interp1([0 1], ...
                                [app.out_surf.Y_lcfs(thisPhiGridIndex, jj) ...
                                app.out_surf.Y(thisPhiGridIndex, jj)], ...
                                (rho(ii) - 1));
                            allR(counter) = interp1([0 1], ...
                                [app.out_surf.R_lcfs(thisPhiGridIndex, jj) ...
                                app.out_surf.R(thisPhiGridIndex, jj)], ...
                                (rho(ii) - 1));
                            allZ(counter) = interp1([0 1], ...
                                [app.out_surf.Z_lcfs(thisPhiGridIndex, jj) ...
                                app.out_surf.Z(thisPhiGridIndex, jj)], ...
                                (rho(ii) - 1));

                        end

                        %if rho(ii) >= maskdata_rholim
                        rhomask(counter) = 1;
                        %end

                    end
                end
                Lc_grids.Grids{phiGridCounter}.allR = allR;
                Lc_grids.Grids{phiGridCounter}.allX = allX;
                Lc_grids.Grids{phiGridCounter}.allY = allY;
                Lc_grids.Grids{phiGridCounter}.allZ = allZ;
                Lc_grids.Phi(phiGridCounter) = thisPhi;
                Lc_grids.RhoMask{phiGridCounter} = rhomask;
                Lc_grids.num_pol_angles_blowup = app.num_pol_angles_blowup;
                Lc_grids.radial_divs = radial_divs;


                Lc_folder_prefix = strcat(app.OutputFolderPrefixLcScanEditField.Value, '/');
                Lc_filename_out = strcat(Lc_folder_prefix, filename);

                fid = fopen(Lc_filename_out, 'w');
                % write to file
                fprintf(fid, '# MAP3D 1 2 %16.14e\n', thisPhi*180/pi);
                fprintf(fid, '# COORDINATES cylindrical_deg\n');
                fprintf(fid, '# UNITS meter\n');
                fprintf(fid, '# TYPE qmesh\n');
                fprintf(fid, '# NODES  %6i %6i\n', app.num_pol_angles_blowup, ...
                    radial_divs);
                for ii = 1:counter
                    fprintf(fid, '%16.14e %16.14e\n', allR(ii), allZ(ii) );
                end
                fclose(fid);

                % make  .ctr and slurm files
                ctr_filename = [Lc_folder_prefix 'L_c_map_' num2str(phiGridCounter) '.ctr'];
                fid = fopen(ctr_filename, 'w');
                fprintf(fid, '#Sample\n');
                fprintf(fid, '[FLARE]\n');
                fprintf(fid, ['model: ' ...
                    app.OutputFolderPrefixLcScanEditField.Value '\n']);

                fprintf(fid, 'fieldline.epsabs:  %16.14e\n', ...
                    app.LcepsabsEditField.Value);

                fprintf(fid, 'fieldline.edom: 1\n');
                if (abs(app.LcDiffusionCoefficientEditField.Value) < eps)
                    fprintf(fid, '#fieldline.diffusion:  %16.14e\n', ...
                        app.LcDiffusionCoefficientEditField.Value);
                else
                    fprintf(fid, 'fieldline.diffusion:  %16.14e\n', ...
                        app.LcDiffusionCoefficientEditField.Value);

                end
                fprintf(fid, '[fieldline_connection]\n');
                fprintf(fid, ['grid: Grid_Lc_' num2str(phiGridCounter) ...
                    '.txt\n']);
                fprintf(fid, 'Lcmax: %16.14e\n', ...
                    app.MaximumLcLcEditField.Value);
                fprintf(fid, ['output: results_' num2str(phiGridCounter) ...
                    '.dat\n']);
                fprintf(fid, 'xbwd: True\n');
                fprintf(fid, 'xfwd: True\n');
                fprintf(fid, 'ubwd: True\n');
                fprintf(fid, 'ufwd: True\n');
                fclose(fid);


                slurm_filename = [Lc_folder_prefix 'slurm_Lc_' num2str(phiGridCounter) '.sh'];
                fid = fopen(slurm_filename, 'w');

                fprintf(fid, '#!/bin/bash -l\n');
                fprintf(fid, '# Standard output and error:\n');
                fprintf(fid, '#SBATCH -o ./job.out.%%j\n');
                fprintf(fid, '#SBATCH -e ./job.err.%%j\n');
                fprintf(fid, '# Initial working directory:\n');
                fprintf(fid, '#SBATCH -D ./\n');
                fprintf(fid, '# Job Name:\n');
                fprintf(fid, '#SBATCH -J FLARE_Lc\n');
                fprintf(fid, '#\n');
                fprintf(fid, '# Number of MPI Tasks, e.g. 8:\n');
                fprintf(fid, '#SBATCH --ntasks=288\n');
                fprintf(fid, '# Memory usage [MB] of the job is required, e.g. 3000 MB per task:\n');
                fprintf(fid, '#SBATCH --mem=240000\n');
                fprintf(fid, '#\n');
                fprintf(fid, '#\n');
                fprintf(fid, '# Wall clock limit (max. is 24 hours):\n');
                fprintf(fid, '#SBATCH --time=24:00:00\n');

                fprintf(fid, '# Load compiler and MPI modules (must be the same as used for compiling the code)\n');
                fprintf(fid, 'module purge\n');
                fprintf(fid, 'module use /u/hfrerich/software/modules\n');
                fprintf(fid, 'module load flare/2022.4\n');
                fprintf(fid, '\n');
                fprintf(fid, '# Run the program:\n');
                fprintf(fid, '#flare -n 288 run\n');
                fprintf(fid, ['flare -n 288 run -c L_c_map_' num2str(phiGridCounter) '.ctr \n']);
                fclose(fid);


            end

            % and the 2nd time
            if (app.SinglePeriodButton_Lc.Value == 1)
                for thisPhi = (fliplr(tor_angles_Lc_grids(1:end-1))*pi/180)
                    phiGridCounter = phiGridCounter + 1;
                    [mismatch,thisPhiGridIndex] = ...
                        min(abs(thisPhi- app.out_surf.Phi(:,1)));
                    %mismatch
                    disp(['<---Grid ' num2str(phiGridCounter) ': The toroidal angle/grid mismatch was: ' num2str(mismatch) ' radians']);
                    filename = ['Grid_Lc_', num2str(phiGridCounter) '.txt'];

                    %theta = linspace(0, 2*pi, theta_divs);
                    %warning:using theta mesh from control point theta_mesh

                    theta = app.vmec_control_points_blowup.theta_mesh(1,:);
                    rho = linspace(rho_min_mesh, rho_max_mesh, radial_divs);


                    % rhomask = zeros(radial_divs*theta_divs, 1);
                    rhomask = zeros(radial_divs*app.num_pol_angles_blowup, 1);

                    % make concentric surfaces
                    counter = 0;
                    for ii = 1:radial_divs
                        for jj = 1:app.num_pol_angles_blowup
                            counter = counter + 1;

                            if rho(ii) <= 1
                                % in the MHD solution
                                %phi = tor_angle;
                                [thisR, thisZ] = ...
                                    get_VMEC_inverse_coords(rho(ii), theta(jj), thisPhi, mnmax_vmec, ...
                                    xm_vmec, xn_vmec, ...
                                    spline_vmec, spline_d_vmec, phitot_vmec);
                                allR(counter) = thisR;
                                allX(counter) = thisR * cos(thisPhi);
                                allY(counter) = thisR * sin(thisPhi);
                                allZ(counter) = thisZ;
                            else
                                % linearly interpoloate
                                allX(counter) = interp1([0 1], ...
                                    [app.out_surf.X_lcfs(thisPhiGridIndex, jj) ...
                                    app.out_surf.X(thisPhiGridIndex, jj)], ...
                                    (rho(ii) - 1));
                                allY(counter) = interp1([0 1], ...
                                    [app.out_surf.Y_lcfs(thisPhiGridIndex, jj) ...
                                    app.out_surf.Y(thisPhiGridIndex, jj)], ...
                                    (rho(ii) - 1));
                                allR(counter) = interp1([0 1], ...
                                    [app.out_surf.R_lcfs(thisPhiGridIndex, jj) ...
                                    app.out_surf.R(thisPhiGridIndex, jj)], ...
                                    (rho(ii) - 1));
                                allZ(counter) = interp1([0 1], ...
                                    [app.out_surf.Z_lcfs(thisPhiGridIndex, jj) ...
                                    app.out_surf.Z(thisPhiGridIndex, jj)], ...
                                    (rho(ii) - 1));

                            end

                            %if rho(ii) >= maskdata_rholim
                            rhomask(counter) = 1;
                            %end

                        end
                    end
                    % note differenes due to stellarator symmetry
                    Lc_grids.Grids{phiGridCounter}.allR = allR;
                    new_phi = stell_sym_angle-thisPhi;
                    Lc_grids.Grids{phiGridCounter}.allX = allR*cos(new_phi);
                    Lc_grids.Grids{phiGridCounter}.allY = allR*sin(new_phi);
                    Lc_grids.Grids{phiGridCounter}.allZ = -allZ;
                    Lc_grids.Phi(phiGridCounter) = new_phi;
                    Lc_grids.RhoMask{phiGridCounter} = rhomask;
                    Lc_grids.num_pol_angles_blowup = app.num_pol_angles_blowup;
                    Lc_grids.radial_divs = radial_divs;


                    Lc_folder_prefix = strcat(app.OutputFolderPrefixLcScanEditField.Value, '/');
                    Lc_filename_out = strcat(Lc_folder_prefix, filename);

                    fid = fopen(Lc_filename_out, 'w');
                    % write to file
                    fprintf(fid, '# MAP3D 1 2 %16.14e\n', new_phi*180/pi);
                    fprintf(fid, '# COORDINATES cylindrical_deg\n');
                    fprintf(fid, '# UNITS meter\n');
                    fprintf(fid, '# TYPE qmesh\n');
                    fprintf(fid, '# NODES  %6i %6i\n', app.num_pol_angles_blowup, ...
                        radial_divs);
                    for ii = 1:counter
                        fprintf(fid, '%16.14e %16.14e\n', ...
                            Lc_grids.Grids{phiGridCounter}.allR(ii), ...
                            Lc_grids.Grids{phiGridCounter}.allZ(ii) );
                    end
                    fclose(fid);

                    % make  .ctr and slurm files
                    ctr_filename = [Lc_folder_prefix 'L_c_map_' num2str(phiGridCounter) '.ctr'];
                    fid = fopen(ctr_filename, 'w');
                    fprintf(fid, '#Sample\n');
                    fprintf(fid, '[FLARE]\n');
                    fprintf(fid, ['model: ' ...
                        app.OutputFolderPrefixLcScanEditField.Value '\n']);

                    fprintf(fid, 'fieldline.epsabs:  %16.14e\n', ...
                        app.LcepsabsEditField.Value);

                    fprintf(fid, 'fieldline.edom: 1\n');
                    if (abs(app.LcDiffusionCoefficientEditField.Value) < eps)
                        fprintf(fid, '#fieldline.diffusion:  %16.14e\n', ...
                            app.LcDiffusionCoefficientEditField.Value);
                    else
                        fprintf(fid, 'fieldline.diffusion:  %16.14e\n', ...
                            app.LcDiffusionCoefficientEditField.Value);

                    end
                    fprintf(fid, '[fieldline_connection]\n');
                    fprintf(fid, ['grid: Grid_Lc_' num2str(phiGridCounter) ...
                        '.txt\n']);
                    fprintf(fid, 'Lcmax: %16.14e\n', ...
                        app.MaximumLcLcEditField.Value);
                    fprintf(fid, ['output: results_' num2str(phiGridCounter) ...
                        '.dat\n']);
                    fprintf(fid, 'xbwd: True\n');
                    fprintf(fid, 'xfwd: True\n');
                    fprintf(fid, 'ubwd: True\n');
                    fprintf(fid, 'ufwd: True\n');
                    fclose(fid);


                    slurm_filename = [Lc_folder_prefix 'slurm_Lc_' num2str(phiGridCounter) '.sh'];
                    fid = fopen(slurm_filename, 'w');

                    fprintf(fid, '#!/bin/bash -l\n');
                    fprintf(fid, '# Standard output and error:\n');
                    fprintf(fid, '#SBATCH -o ./job.out.%%j\n');
                    fprintf(fid, '#SBATCH -e ./job.err.%%j\n');
                    fprintf(fid, '# Initial working directory:\n');
                    fprintf(fid, '#SBATCH -D ./\n');
                    fprintf(fid, '# Job Name:\n');
                    fprintf(fid, '#SBATCH -J FLARE_Lc\n');
                    fprintf(fid, '#\n');
                    fprintf(fid, '# Number of MPI Tasks, e.g. 8:\n');
                    fprintf(fid, '#SBATCH --ntasks=288\n');
                    fprintf(fid, '# Memory usage [MB] of the job is required, e.g. 3000 MB per task:\n');
                    fprintf(fid, '#SBATCH --mem=240000\n');
                    fprintf(fid, '#\n');
                    fprintf(fid, '#\n');
                    fprintf(fid, '# Wall clock limit (max. is 24 hours):\n');
                    fprintf(fid, '#SBATCH --time=24:00:00\n');

                    fprintf(fid, '# Load compiler and MPI modules (must be the same as used for compiling the code)\n');
                    fprintf(fid, 'module purge\n');
                    fprintf(fid, 'module use /u/hfrerich/software/modules\n');
                    fprintf(fid, 'module load flare/2022.4\n');
                    fprintf(fid, '\n');
                    fprintf(fid, '# Run the program:\n');
                    fprintf(fid, '#flare -n 288 run\n');
                    fprintf(fid, ['flare -n 288 run -c L_c_map_' num2str(phiGridCounter) '.ctr \n']);
                    fclose(fid);


                end
            end


            matfilename = ['Grid_Lc_all.mat'];
            mat_filename_out = strcat(Lc_folder_prefix, matfilename);
            save(mat_filename_out, 'Lc_grids');

            % make other files:
            % .bfield, .boundary, .equi3d

            % .bfield
            if (strcmp(app.FLAREbfieldtypeDropDown.Value, 'BMW'))
                fid = fopen([Lc_folder_prefix '.bfield'], 'w');
                fprintf(fid, '[equi3d_bmw]\n');
                bmw_filename = app.FLAREbfieldsourceEditField.Value;
                fprintf(fid, ['filename: ' bmw_filename '\n']);
                fclose(fid);
            end

            % .boundary
            %wall_out_name_gui = app.FLAREboundaryfilenameEditField.Value;
            fid = fopen([Lc_folder_prefix '.boundary'], 'w');
            fprintf(fid, [app.FLAREboundarydoclineEditField.Value '\n']);
            fprintf(fid, ['filename: ' wall_out_name_gui '\n']);
            fprintf(fid, ['stellarator_symmetry: True\n']);
            fprintf(fid, ['units: ' app.FLAREboundaryunitsEditField.Value '\n']);
            fclose(fid);

            % .equi3d
            num_tor_points_equi3d = app.equi3doftoroidalpointsEditField.Value;
            wout_filefrom_before = app.AxisLCFSFilename.Text;
            theaxis = make_equi3d_from_wout(wout_filefrom_before, num_tor_points_equi3d);
            fid = fopen([Lc_folder_prefix '.equi3d'], 'w');
            first_line = [num2str(theaxis.num_phi_points) '  '  num2str(1), '\n'];
            % [num_phi_points  1]
            fprintf(fid, first_line);
            for ii = 1:theaxis.num_phi_points
                % [raxisline' zaxisline' (v*180/(NFP*pi))']
                next_r = theaxis.raxisline(ii);
                next_z = theaxis.zaxisline(ii);
                next_phi = theaxis.v(ii)*180/(theaxis.NFP*pi);
                fprintf(fid, '%16.14e %16.14e %16.14e\n', next_r, next_z, next_phi);

            end
            fclose(fid);


            % ====================
            % ====================

        end

        % Button pushed function: ManipulateSplinePointsInteractivelyButton
        function ManipulateSplinePointsInteractivelyButtonPushed(app, event)
            disp('<--- hint: use app.spl_ctrl_pts to edit the control points')
            disp('<--- hint: use dbcont when finsihed with interactive work and to return to the GUI operation')

            keyboard

            %new_data = load('my_temp_spline_ctrl_and_pol_angle_ctrl_pts_2022Sep7_1000.mat');
            %for ii = 1:6
            %    new_spl_val(ii,:) = spline(new_data.my_pol_angles_ctrl_pts, new_data.my_spl_ctrl_pts(ii,:), app.pol_angles_ctrl_pts)
            %end
            %app.spl_ctrl_pts = new_spl_val

        end

        % Button pushed function: CheckCurrentLoftedSurfaceButton
        function CheckCurrentLoftedSurfaceButtonPushed(app, event)
            app.CheckCurrentLoftedSurfaceButton.BackgroundColor = [0 0 1];
            pause(0.001)
            app.build_and_check_surfaces
            app.CheckCurrentLoftedSurfaceButton.BackgroundColor = [0.96 0.96 0.96];
        end

        % Button pushed function: PlotCurrentLoftedSurfaceButton
        function PlotCurrentLoftedSurfaceButtonPushed(app, event)
            app.PlotCurrentLoftedSurfaceButton.BackgroundColor = [0 0 1];
            pause(0.001)
            app.plot_lofted_surface
            app.PlotCurrentLoftedSurfaceButton.BackgroundColor = [0.96 0.96 0.96];

        end

        % Value changed function: ToroidalIndexSpinner
        function ToroidalIndexSpinnerValueChanged(app, event)
            tor_index = app.ToroidalIndexSpinner.Value;
            pol_index = app.PoloidalIndexSpinner.Value;
            stell_sym_half_angle = app.Phi_EnddegreesEditField.Value*pi/180;
            stell_sym_full_angle = 2*stell_sym_half_angle;


            if (tor_index == 0)
                tor_index = 1;
                app.ToroidalIndexSpinner.Value = 1;
            end
            try
                app.LoftControlPointSpinner.Value = ...
                    app.spl_ctrl_pts(tor_index, pol_index);
            catch
                tor_index = tor_index - 1;
                app.ToroidalIndexSpinner.Value = tor_index;
                app.LoftControlPointSpinner.Value = ...
                    app.spl_ctrl_pts(tor_index, pol_index);
            end
            hold(app.UIAxes, 'off')
            app.plot_lofted_surface
            hold(app.UIAxes, 'on')

            start_x = app.vmec_control_points.X(tor_index, pol_index) + ...
                app.LoftControlPointSpinner.Value * app.vmec_control_normals_cyl.normal_x(tor_index, pol_index);
            start_y = app.vmec_control_points.Y(tor_index, pol_index) + ...
                app.LoftControlPointSpinner.Value * app.vmec_control_normals_cyl.normal_y(tor_index, pol_index);
            start_z = app.vmec_control_points.Z(tor_index, pol_index) + ...
                app.LoftControlPointSpinner.Value * app.vmec_control_normals_cyl.normal_z(tor_index, pol_index);
            end_z = -start_z;
            start_r = sqrt(start_x.^2 + start_y.^2);
            start_phi = atan2(start_y, start_x);
            phi_flip = stell_sym_full_angle - start_phi;
            end_x = start_r .* cos(phi_flip);
            end_y = start_r .* sin(phi_flip);


            plot3(app.UIAxes, ...
                app.vmec_control_points.X(tor_index, pol_index) + ...
                app.LoftControlPointSpinner.Value * app.vmec_control_normals_cyl.normal_x(tor_index, pol_index), ...
                app.vmec_control_points.Y(tor_index, pol_index) + ...
                app.LoftControlPointSpinner.Value * app.vmec_control_normals_cyl.normal_y(tor_index, pol_index), ...
                app.vmec_control_points.Z(tor_index, pol_index) + ...
                app.LoftControlPointSpinner.Value * app.vmec_control_normals_cyl.normal_z(tor_index, pol_index), ...
                'r+', 'Linewidth', 3);
            plot3(app.UIAxes, ...
                end_x, end_y, end_z, ...
                'r+', 'Linewidth', 3);

            alpha(app.UIAxes, 1.0)


        end

        % Callback function
        function ValueEditFieldValueChanged(app, event)
            %             value = app.ValueEditField.Value;
            %             app.LoftControlPointSpinner.Value = value;
            %             tor_index = app.ToroidalIndexSpinner.Value;
            %             pol_index = app.PoloidalIndexSpinner.Value;
            %             app.spl_ctrl_pts(tor_index, pol_index) = value;
            %
            %             if ( app.EnforceStellaratorSymmetryatPhi0and2piNFPCheckBox.Value && ...
            %                     ((tor_index==1) || ...
            %                     (tor_index == app.num_tor_angles_ctrl)) )
            %                 other_pol_index = app.num_pol_angles_ctrl + 1 - pol_index;
            %                 app.spl_ctrl_pts(tor_index, other_pol_index) = value;
            %             end
            %
            %
            %
            %             app.CheckCurrentLoftedSurfaceButton.ButtonPushedFcn(app, event)
            %             app.PlotCurrentLoftedSurfaceButton.ButtonPushedFcn(app, event)
        end

        % Value changed function: PoloidalIndexSpinner
        function PoloidalIndexSpinnerValueChanged(app, event)
            tor_index = app.ToroidalIndexSpinner.Value;
            pol_index = app.PoloidalIndexSpinner.Value;

            stell_sym_half_angle = app.Phi_EnddegreesEditField.Value*pi/180;
            stell_sym_full_angle = 2*stell_sym_half_angle;

            if (pol_index == 0)
                pol_index = 1;
                app.PoloidalIndexSpinner.Value = 1;
            end
            try
                app.LoftControlPointSpinner.Value = ...
                    app.spl_ctrl_pts(tor_index, pol_index);
            catch
                pol_index = pol_index - 1;
                app.PoloidalIndexSpinner.Value = pol_index;
                app.LoftControlPointSpinner.Value = ...
                    app.spl_ctrl_pts(tor_index, pol_index);
            end

            hold(app.UIAxes, 'off')
            app.plot_lofted_surface
            hold(app.UIAxes, 'on')

            start_x = app.vmec_control_points.X(tor_index, pol_index) + ...
                app.LoftControlPointSpinner.Value * app.vmec_control_normals_cyl.normal_x(tor_index, pol_index);
            start_y = app.vmec_control_points.Y(tor_index, pol_index) + ...
                app.LoftControlPointSpinner.Value * app.vmec_control_normals_cyl.normal_y(tor_index, pol_index);
            start_z = app.vmec_control_points.Z(tor_index, pol_index) + ...
                app.LoftControlPointSpinner.Value * app.vmec_control_normals_cyl.normal_z(tor_index, pol_index);
            end_z = -start_z;
            start_r = sqrt(start_x.^2 + start_y.^2);
            start_phi = atan2(start_y, start_x);
            phi_flip = stell_sym_full_angle - start_phi;
            end_x = start_r .* cos(phi_flip);
            end_y = start_r .* sin(phi_flip);


            plot3(app.UIAxes, ...
                app.vmec_control_points.X(tor_index, pol_index) + ...
                app.LoftControlPointSpinner.Value * app.vmec_control_normals_cyl.normal_x(tor_index, pol_index), ...
                app.vmec_control_points.Y(tor_index, pol_index) + ...
                app.LoftControlPointSpinner.Value * app.vmec_control_normals_cyl.normal_y(tor_index, pol_index), ...
                app.vmec_control_points.Z(tor_index, pol_index) + ...
                app.LoftControlPointSpinner.Value * app.vmec_control_normals_cyl.normal_z(tor_index, pol_index), ...
                'r+', 'Linewidth', 3);
            plot3(app.UIAxes, ...
                end_x, end_y, end_z, ...
                'r+', 'Linewidth', 3);

            alpha(app.UIAxes, 1.0);


        end

        % Callback function
        function SelectOutputFolderButtonPushed(app, event)
            aa=uiopen();
            app.OutputFolderPrefixLcScanEditField.Value = aa;
        end

        % Button pushed function: ExportFLAREWallStrikelineGridsButton
        function ExportFLAREWallStrikelineGridsButtonPushed(app, event)


            % ====================
            % ====================
            % Strikeline maps
            % ====================
            % ====================
            % Strikeline maps, diffusion on
            %    wall is varied, launch points are inside LCFS of specific mhd
            %    equlibrium
            % Probably a good idea to coordinate walls+LCFSes
            %
            % wall position is scanned from 'close' to LCFS to close to far wall,
            % and strikelines are recorded.

            num_tor_planes_output = ...
                (app.num_tor_angles_ctrl - 1) * (app.numZeta_vmec_ip_per_cp + 1) + 1;
            %num_pol_points_output = app.num_pol_angles_blowup;

            % ====================
            % part 1: The wall - interpolated between the two extrumum set during
            % the closing part of the 'optimization' section
            strikeline_torosurf_wall.n = num_tor_planes_output; % n is needed
            strikeline_torosurf_wall.m = app.num_pol_angles_blowup; % m is needed
            strikeline_torosurf_wall.zeta =  app.out_surf.Phi(:,1);% in radians
            strikeline_torosurf_wall.R0 = 0.0; % R0 is needed
            strikeline_torosurf_wall.Z0 = 0.0; % Z0 is needed
            strikeline_torosurf_wall.sym = app.vmec_data.nfp;


            % find the wall points through interpolation
            for ii = 1:num_tor_planes_output
                for jj = 1:app.num_pol_angles_blowup
                    strikeline_torsurf.FIXTOWALL(ii,jj) = 0;
                end
            end
            if (strcmp(app.PauseForMaskEditsSwitch.Value, 'Yes'))
                keyboard
            else
            end

            %rho_min_mesh = app.StrikelineGridrhominEditField;
            %rho_max_mesh = app.StrikelineGridrhomaxEditField;
            %radial_divs = app.StrikelineGridofdivisionsEditField;
            %num_field_periods = app.vmec_data.nfp;
            %num_tor_planes_Lc = app.StrikelineToroidalPlanesEditField;
            %tor_angles_Lc_grids = (360/num_field_periods)*...
            %    linspace(0, 1, num_tor_planes_Lc);

            num_strikeline_launchpoint_radial_locations = ...
                app.StrikelineGridofdivisionsEditField.Value;
            strikeline_launchpoint_scan_inner_rad = ...
                app.StrikelineGridrhominEditField.Value;
            strikeline_launchpoint_scan_outer_rad = ...
                app.StrikelineGridrhomaxEditField.Value;
            %num_strikeline_launchpoint_radial_locations = 5;
            %strikeline_launchpoint_scan_inner_rad = 0.95;
            %strikeline_launchpoint_scan_outer_rad = 0.99;
            %num_strikeline_launchpoint_poloidal_locations = 3600;

            maskdata_rholim_strikeline = 0.;

            strikeline_wall_radial_location = app.StrkelineWallLocationEditFIeld.Value;

            strikeline_launchpoint_radial_locations = ...
                linspace(strikeline_launchpoint_scan_inner_rad, ...
                strikeline_launchpoint_scan_outer_rad, ...
                num_strikeline_launchpoint_radial_locations);

            num_strikeline_launchpoint_poloidal_locations = ...
                app.StrikelineNumofpoloidalptsEditField.Value;
            % This trims off the point which would otherwise be duplicated in the
            % scan
            strikeline_lauchpoint_poloidal_locations = ...
                linspace(0, 2*pi, num_strikeline_launchpoint_poloidal_locations+1);
            strikeline_lauchpoint_poloidal_locations = ...
                strikeline_lauchpoint_poloidal_locations(1:(end-1));

            %num_phi_wall
            %num_theta_wall = num_pol_angles_blowup

            [mnmax_vmec, xm_vmec, xn_vmec, spline_vmec, spline_d_vmec, ...
                phitot_vmec] = load_vmec_wout_file(app.AxisLCFSFilename.Text);

            % ====================
            % loop over all radial position of the outer wall
            for this_strikeline_wall_rad_loc = strikeline_wall_radial_location


                % find the wall points through interpolation
                for ii = 1:num_tor_planes_output
                    for jj = 1:app.num_pol_angles_blowup
                        if (~strikeline_torsurf.FIXTOWALL(ii,jj))
                            % linearly interpoloate
                            strikeline_torosurf_wall.X(ii,jj) = interp1([0 1], ...
                                [app.out_surf.X_lcfs(ii, jj) ...
                                app.out_surf.X(ii, jj)], ...
                                (this_strikeline_wall_rad_loc - 1));
                            strikeline_torosurf_wall.Y(ii,jj) = interp1([0 1], ...
                                [app.out_surf.Y_lcfs(ii, jj) ...
                                app.out_surf.Y(ii, jj)], ...
                                (this_strikeline_wall_rad_loc - 1));
                            strikeline_torosurf_wall.Z(ii,jj) = interp1([0 1], ...
                                [app.out_surf.Z_lcfs(ii, jj) ...
                                app.out_surf.Z(ii, jj)], ...
                                (this_strikeline_wall_rad_loc - 1));
                            strikeline_torosurf_wall.R(ii,jj) = interp1([0 1], ...
                                [app.out_surf.R_lcfs(ii, jj) ...
                                app.out_surf.R(ii, jj)], ...
                                (this_strikeline_wall_rad_loc - 1));
                        else
                            strikeline_torosurf_wall.X(ii,jj) = ...
                                app.out_surf.X(ii, jj);
                            strikeline_torosurf_wall.Y(ii,jj) = ...
                                app.out_surf.Y(ii, jj);
                            strikeline_torosurf_wall.Z(ii,jj) = ...
                                app.out_surf.Z(ii, jj);
                            strikeline_torosurf_wall.R(ii,jj) = ...
                                app.out_surf.R(ii, jj);

                        end
                    end
                end

                % build folder name, make that folder and cd to it
                folder_name = strcat(app.OutputFolderPrefixStrikelineScanEditField.Value, ...
                    '_', ...
                    zeropaddednum2str(round(this_strikeline_wall_rad_loc*1000), 4));
                mkdir(folder_name);
                %try
                cd(folder_name);


                % write out the surface
                %write_flare_torosurf(strikeline_torosurf_wall, 'wall_out.txt');

                wall_out_name_gui = app.FLAREboundaryfilenameEditField.Value;
                wall_filename_out = wall_out_name_gui;
                app.write_flare_torosurf(strikeline_torosurf_wall, wall_filename_out);


                save('strikeline_torosurf_wall.mat', ...
                    'strikeline_torosurf_wall')

                % now make grid files for strikeline calculations - start points inside
                % of LCFS of target equilibrium

                num_tor_planes_strikeline = app.StrikelineToroidalPlanesEditField.Value;
                num_field_periods = app.vmec_data.nfp;
                %tor_angles_strikeline_grids = (360/num_field_periods)*...
                %linspace(0, 1, num_tor_planes_strikeline);
                if (app.SingleHalfPeriodButton_strikeline.Value == 1)
                    tor_angles_strikeline_grids = (0.5*360/num_field_periods)*...
                        linspace(0.0, 1.0, num_tor_planes_strikeline);
                end

                %tor_angles_strikeline_grids = 45*[0:.25:1];

                phiGridCounter_strikeline = 0;
                for thisStrikelinePhi = (tor_angles_strikeline_grids*pi/180)
                    phiGridCounter_strikeline = phiGridCounter_strikeline + 1;
                    [mismatch,thisStrikelinePhiGridIndex] = ...
                        min(abs(thisStrikelinePhi- app.out_surf.Phi(:,1)));
                    %mismatch
                    disp(['<---Grid ' num2str(phiGridCounter_strikeline) ...
                        ': The toroidal angle/grid mismatch was: ' ...
                        num2str(mismatch) ' radians']);

                    strikeline_grid_filename = ['Grid_strikeline_', num2str(phiGridCounter_strikeline) '.txt'];
                    strikeline_grid_matfilename = ['Grid_strikeline_', num2str(phiGridCounter_strikeline) '.mat'];


                    strikelineLP_R = zeros(...
                        num_strikeline_launchpoint_radial_locations * ...
                        num_strikeline_launchpoint_poloidal_locations, 1);
                    strikelineLP_Z = zeros( ...
                        num_strikeline_launchpoint_radial_locations * ...
                        num_strikeline_launchpoint_poloidal_locations, 1);
                    strikelineLP_X = zeros(...
                        num_strikeline_launchpoint_radial_locations * ...
                        num_strikeline_launchpoint_poloidal_locations, 1);
                    strikelineLP_Y = zeros(...
                        num_strikeline_launchpoint_radial_locations * ...
                        num_strikeline_launchpoint_poloidal_locations, 1);
                    strikelineLP_rhomask = zeros(...
                        num_strikeline_launchpoint_radial_locations * ...
                        num_strikeline_launchpoint_poloidal_locations, 1);

                    counter = 0;
                    for ii = 1:num_strikeline_launchpoint_radial_locations
                        for jj = 1:num_strikeline_launchpoint_poloidal_locations
                            counter = counter + 1;

                            if strikeline_launchpoint_radial_locations(ii) <= 1
                                % in the MHD solution
                                %phi = tor_angle;
                                [thisR, thisZ] = ...
                                    get_VMEC_inverse_coords(...
                                    strikeline_launchpoint_radial_locations(ii), ...
                                    strikeline_lauchpoint_poloidal_locations(jj), ...
                                    thisStrikelinePhi, mnmax_vmec, ...
                                    xm_vmec, xn_vmec, ...
                                    spline_vmec, spline_d_vmec, phitot_vmec);
                                strikelineLP_R(counter) = thisR;
                                strikelineLP_X(counter) = thisR * cos(thisStrikelinePhi);
                                strikelineLP_Y(counter) = thisR * sin(thisStrikelinePhi);
                                strikelineLP_Z(counter) = thisZ;
                            else
                                disp('<----case not yet handled');
                                blah
                                %                     % linearly interpoloate
                                %                     allX(counter) = interp1([0 1], ...
                                %                         [out_surf.X_lcfs(thisPhiGridIndex, jj) ...
                                %                         out_surf.X(thisPhiGridIndex, jj)], ...
                                %                         (rho(ii) - 1));
                                %                     allY(counter) = interp1([0 1], ...
                                %                         [out_surf.Y_lcfs(thisPhiGridIndex, jj) ...
                                %                         out_surf.Y(thisPhiGridIndex, jj)], ...
                                %                         (rho(ii) - 1));
                                %                     allR(counter) = interp1([0 1], ...
                                %                         [out_surf.R_lcfs(thisPhiGridIndex, jj) ...
                                %                         out_surf.R(thisPhiGridIndex, jj)], ...
                                %                         (rho(ii) - 1));
                                %                     allZ(counter) = interp1([0 1], ...
                                %                         [out_surf.Z_lcfs(thisPhiGridIndex, jj) ...
                                %                         out_surf.Z(thisPhiGridIndex, jj)], ...
                                %                         (rho(ii) - 1));

                            end


                            if strikeline_launchpoint_radial_locations(ii) >= maskdata_rholim_strikeline
                                strikelineLP_rhomask(counter) = 1;
                            end

                        end
                    end


                    if 1
                        figure(40440);

                        hold on;
                        plot3(strikelineLP_X, strikelineLP_Y, strikelineLP_Z, '.');
                        title('strikeline lauchpoints')
                        view(3);axis equal;axis tight;box on;grid on;
                    end

                    save(strikeline_grid_matfilename, ...
                        'strikelineLP_R', 'strikelineLP_X', 'strikelineLP_Y', ...
                        'strikelineLP_Z', 'strikelineLP_rhomask');

                    fid = fopen(strikeline_grid_filename, 'w');
                    % write to file
                    fprintf(fid, '# MAP3D 1 2 %16.14e\n', thisStrikelinePhi*180/pi);
                    fprintf(fid, '# COORDINATES cylindrical_deg\n');
                    fprintf(fid, '# UNITS meter\n');
                    fprintf(fid, '# TYPE qmesh\n');
                    fprintf(fid, '# NODES  %6i %6i\n', ...
                        num_strikeline_launchpoint_poloidal_locations, ...
                        num_strikeline_launchpoint_radial_locations);
                    for ii = 1:counter
                        fprintf(fid, '%16.14e %16.14e\n', strikelineLP_R(ii), ...
                            strikelineLP_Z(ii) );
                    end
                    fclose(fid);

                    % make .ctr file

                    ctr_filename = ['Strikeline_map_' num2str(phiGridCounter_strikeline) '.ctr'];
                    fid = fopen(ctr_filename, 'w');
                    fprintf(fid, '#Sample\n');
                    fprintf(fid, '[FLARE]\n');
                    fprintf(fid, ['model: ' ...
                        app.OutputFolderPrefixStrikelineScanEditField.Value '\n']);

                    fprintf(fid, 'fieldline.epsabs:  %16.14e\n', ...
                        app.StriikelineepsabsEditField.Value);

                    fprintf(fid, 'fieldline.edom: 1\n');
                    if (abs(app.StrikelineDiffusionCoefficientEditField.Value)<eps)
                        fprintf(fid, '%fieldline.diffusion:  %16.14e\n', ...
                            app.StrikelineDiffusionCoefficientEditField.Value);
                    else
                        fprintf(fid, 'fieldline.diffusion:  %16.14e\n', ...
                            app.StrikelineDiffusionCoefficientEditField.Value);
                    end
                    fprintf(fid, '[fieldline_connection]\n');
                    fprintf(fid, ['grid: Grid_strikeline_' num2str(phiGridCounter_strikeline) ...
                        '.txt\n']);
                    fprintf(fid, 'Lcmax: %16.14e\n', ...
                        app.MaximumLcStrikelineEditField.Value);
                    fprintf(fid, ['output: results_' num2str(phiGridCounter_strikeline) ...
                        '.dat\n']);
                    fprintf(fid, 'xbwd: True\n');
                    fprintf(fid, 'xfwd: True\n');
                    fprintf(fid, 'ubwd: True\n');
                    fprintf(fid, 'ufwd: True\n');
                    fclose(fid);

                    % make slurm file
                    slurm_filename = ['slurm_Strikeline_' num2str(phiGridCounter_strikeline) '.sh'];
                    fid = fopen(slurm_filename, 'w');

                    fprintf(fid, '#!/bin/bash -l\n');
                    fprintf(fid, '# Standard output and error:\n');
                    fprintf(fid, '#SBATCH -o ./job.out.%%j\n');
                    fprintf(fid, '#SBATCH -e ./job.err.%%j\n');
                    fprintf(fid, '# Initial working directory:\n');
                    fprintf(fid, '#SBATCH -D ./\n');
                    fprintf(fid, '# Job Name:\n');
                    fprintf(fid, '#SBATCH -J FLARE_Strkln\n');
                    fprintf(fid, '#\n');
                    fprintf(fid, '# Number of MPI Tasks, e.g. 8:\n');
                    fprintf(fid, '#SBATCH --ntasks=288\n');
                    fprintf(fid, '# Memory usage [MB] of the job is required, e.g. 3000 MB per task:\n');
                    fprintf(fid, '#SBATCH --mem=240000\n');
                    fprintf(fid, '#\n');
                    fprintf(fid, '#\n');
                    fprintf(fid, '# Wall clock limit (max. is 24 hours):\n');
                    fprintf(fid, '#SBATCH --time=24:00:00\n');

                    fprintf(fid, '# Load compiler and MPI modules (must be the same as used for compiling the code)\n');
                    fprintf(fid, 'module purge\n');
                    fprintf(fid, 'module use /u/hfrerich/software/modules\n');
                    fprintf(fid, 'module load flare/2022.4\n');
                    fprintf(fid, '\n');
                    fprintf(fid, '# Run the program:\n');
                    fprintf(fid, '#flare -n 288 run\n');
                    fprintf(fid, ['flare -n 288 run -c Strikeline_map_' num2str(phiGridCounter_strikeline) '.ctr \n']);
                    fclose(fid);


                    %     else
                    %         disp('<----Not writing grid file');
                    %     end
                    % end
                    %
                    %
                end


                % make other files:
                % .bfield, .boundary, , .equi3d

                % .bfield
                if (strcmp(app.FLAREbfieldtypeDropDown.Value, 'BMW'))
                    fid = fopen('.bfield', 'w');
                    fprintf(fid, '[equi3d_bmw]\n');
                    bmw_filename = app.FLAREbfieldsourceEditField.Value;
                    fprintf(fid, ['filename: ' bmw_filename '\n']);
                    fclose(fid);
                end


                % .boundary
                wall_out_name_gui = app.FLAREboundaryfilenameEditField.Value;
                fid = fopen('.boundary', 'w');
                fprintf(fid, [app.FLAREboundarydoclineEditField.Value '\n']);
                fprintf(fid, ['filename: ' app.FLAREboundaryfilenameEditField.Value '\n']);
                fprintf(fid, ['stellarator_symmetry: True\n']);
                fprintf(fid, ['units: ' app.FLAREboundaryunitsEditField.Value '\n']);
                fclose(fid);


                % .equi3d
                num_tor_points_equi3d = app.equi3doftoroidalpointsEditField.Value;
                wout_filefrom_before = app.AxisLCFSFilename.Text;
                theaxis = make_equi3d_from_wout(wout_filefrom_before, num_tor_points_equi3d);
                fid = fopen('.equi3d', 'w');
                first_line = [num2str(theaxis.num_phi_points) '  '  num2str(1), '\n'];
                % [num_phi_points  1]
                fprintf(fid, first_line);
                for ii = 1:theaxis.num_phi_points
                    % [raxisline' zaxisline' (v*180/(NFP*pi))']
                    next_r = theaxis.raxisline(ii);
                    next_z = theaxis.zaxisline(ii);
                    next_phi = theaxis.v(ii)*180/(theaxis.NFP*pi);
                    fprintf(fid, '%16.14e %16.14e %16.14e\n', next_r, next_z, next_phi);

                end
                fclose(fid);


                % ======

                cd('..')
                %catch
                %    disp('<----something failed')
                %    cd('..')
                %end
            end

        end

        % Value changed function: LoftControlPointSpinner
        function LoftControlPointSpinnerValueChanged(app, event)
            value = app.LoftControlPointSpinner.Value;
            tor_index = app.ToroidalIndexSpinner.Value;
            pol_index = app.PoloidalIndexSpinner.Value;

            stell_sym_half_angle = app.Phi_EnddegreesEditField.Value*pi/180;
            stell_sym_full_angle = 2*stell_sym_half_angle;

            app.spl_ctrl_pts(tor_index, pol_index) = value;
            other_pol_index = app.num_pol_angles_ctrl + 1 - pol_index;

            if ( app.EnforceStellaratorSymmetryatPhi0and2piNFPCheckBox.Value && ...
                    ((tor_index==1) || ...
                    (tor_index == app.num_tor_angles_ctrl)) )
                if (pol_index ~= other_pol_index)
                    app.spl_ctrl_pts(tor_index, other_pol_index) = value;
                end
            elseif (mod(app.num_pol_angles_ctrl,2) && ...
                    ( (pol_index == 1) || ...
                    (pol_index == app.num_pol_angles_ctrl)))
                app.spl_ctrl_pts(tor_index, other_pol_index) = value;
            end

            app.build_and_check_surfaces

            hold(app.UIAxes, 'off')
            app.plot_lofted_surface
            hold(app.UIAxes, 'on')

            start_x = app.vmec_control_points.X(tor_index, pol_index) + ...
                app.LoftControlPointSpinner.Value * app.vmec_control_normals_cyl.normal_x(tor_index, pol_index);
            start_y = app.vmec_control_points.Y(tor_index, pol_index) + ...
                app.LoftControlPointSpinner.Value * app.vmec_control_normals_cyl.normal_y(tor_index, pol_index);
            start_z = app.vmec_control_points.Z(tor_index, pol_index) + ...
                app.LoftControlPointSpinner.Value * app.vmec_control_normals_cyl.normal_z(tor_index, pol_index);
            end_z = -start_z;
            start_r = sqrt(start_x.^2 + start_y.^2);
            start_phi = atan2(start_y, start_x);
            phi_flip = stell_sym_full_angle - start_phi;
            end_x = start_r .* cos(phi_flip);
            end_y = start_r .* sin(phi_flip);


            plot3(app.UIAxes, ...
                app.vmec_control_points.X(tor_index, pol_index) + ...
                value * app.vmec_control_normals_cyl.normal_x(tor_index, pol_index), ...
                app.vmec_control_points.Y(tor_index, pol_index) + ...
                value * app.vmec_control_normals_cyl.normal_y(tor_index, pol_index), ...
                app.vmec_control_points.Z(tor_index, pol_index) + ...
                value * app.vmec_control_normals_cyl.normal_z(tor_index, pol_index), ...
                'r+', 'Linewidth', 3);
            plot3(app.UIAxes, ...
                end_x, end_y, end_z, ...
                'r+', 'Linewidth', 3);
            alpha(app.UIAxes, 1.0)

        end

        % Button pushed function: ExportFigureWindowinMatlabFormatButton
        function ExportFigureWindowinMatlabFormatButtonPushed(app, event)

            [aximu, eleva] = view(app.UIAxes);
            torosurf_wall.X = app.out_surf.X;
            torosurf_wall.Y = app.out_surf.Y;
            torosurf_wall.R = app.out_surf.R;
            torosurf_wall.Z = app.out_surf.Z;
            fh = figure;
            this_ax=gca;

            surf(this_ax, ...
                torosurf_wall.X, torosurf_wall.Y, torosurf_wall.Z);
            hold on; grid on;axis equal
            %app.UIAxes.Color = my_color;
            view(this_ax, aximu, eleva);
            %view(3)
            title('torosurf wall')

            if 1
                for ii = 1:(app.numberOfToroidalControlPointsEditField.Value - 1)
                    %for ii = 1:5
                    fh = figure;
                    ind_0 = 1+(ii-1)*(app.numberOfintermedplanesbtwntorcntrlptsEditField.Value+1);
                    ind_1 = ind_0 + app.numberOfintermedplanesbtwntorcntrlptsEditField.Value+1;
                    %subplot(2,3,ii);
                    surf(torosurf_wall.X(ind_0:ind_1, :), ...
                        torosurf_wall.Y(ind_0:ind_1, :), ...
                        torosurf_wall.Z(ind_0:ind_1, :));
                    axis equal;
                    axis tight
                end
            end

        end

        % Button pushed function: ExportLoftedSurfaceasSTLFilesButton
        function ExportLoftedSurfaceasSTLFilesButtonPushed(app, event)

            % step 1
            if 1


                STL_export_filename = ...
                    strcat(app.STLPrefixEditField.Value, '.stl');
                % need to fill x_STL, y_STL, z_STL, numTheta_output,
                % numZeta

                x_STL = app.out_surf.X;
                y_STL = app.out_surf.Y;
                z_STL = app.out_surf.Z;

                [numZeta, numTheta_output] = size(x_STL);
                disp(['<---num toroidal slices:' num2str(numZeta)])
                disp(['<---num poloidal points:' num2str(numTheta_output)])


                % Here is a sketch of the mesh
                %      theta -> (ii)
                %
                %    z    x---x---x---x---x---x    ... and last point is same as 1st
                %    e    | \ | \ | \ | \ | \ |
                %    t    x---x---x---x---x---x
                %    a    | \ | \ | \ | \ | \ |
                %         x---x---x---x---x---x
                %    |    | \ | \ | \ | \ | \ |
                %    v    x---x---x---x---x---x
                %  (jj)   | \ | \ | \ | \ | \ |
                %         x---x---x---x---x---x
                %   .
                %   .
                %   .
                %   And last point IS NOT NECESSARILY THE SAME AS THE 1st in the zeta
                %   directions (should probably check, but it doesn't actually
                %   matter.)
                % the number of faces will be: 2 * (# of Theta - 1) * (# of Zeta - 1)
                %
                % the vertices are in the _STL(jj,ii) indices
                % as one loops over the jj and ii indices, make a vertex for each
                % point, and two patches for each point except for the last
                % ii and last jj
                % vertices: (1,1) -> 1, (1,2) -> 2, ... (1,numtheta) -> numtheta
                %           (2,1) -> numtheta+1, (2,2) -> numtheta+2, ...
                % patch #1: connect:  (jj,ii) -- (jj,ii+1) -- (jj+1,ii+1)
                %                 (vertex) -- (vertex+1) -- (vertex+numtheta+1)
                % patch #2: connect:  (jj,ii)-- (jj+1,ii+1) -- (jj+1, ii)
                %                 (vertex) -- (vertex+numtheta+1) -- (vertex+numtheta)
                %

                % total # of vertices is the number of points in the mesh
                % (# of theta) * (# of zeta)
                nvertex = numTheta_output * numZeta;
                vertex = zeros(nvertex, 3);

                % total # of faces is 2 * (# of theta - 1) * (# of zeta - 1)
                faces = zeros(2 * (numTheta_output - 1) * (numZeta - 1), 3);

                disp(['<---nvertex:' num2str(nvertex)])
                [numfaces, ~] = size(faces);
                disp(['<---numfaces:' num2str(numfaces)])

                % Now we calculate all of the vertex data and all of the face data
                % for the patch surfaces

                % keep track of the indices
                ind_vertex = 0;
                ind_faces = 0;
                % jj is the loop variable for the toroidal direction
                for jj = 1:numZeta
                    % ii is the loop variable for the poloidal direction
                    for ii = 1:numTheta_output
                        % take care of Vertex data
                        ind_vertex = ind_vertex + 1;
                        % X Position
                        vertex(ind_vertex, 1) = x_STL(jj, ii);
                        % Y Position
                        vertex(ind_vertex, 2) = y_STL(jj, ii);
                        % Z Position
                        vertex(ind_vertex, 3) = z_STL(jj, ii);

                        % handle the face/patch data
                        % make two patches

                        % patch #1
                        if (jj == numZeta)
                            % do nothing for the last index

                        elseif (ii == numTheta_output)
                            % final poloidal index - should be a duplicate
                            % of the first point, so do nothing.
                        else
                            ind_faces = ind_faces + 1;
                            % this is the 'normal' case
                            faces(ind_faces, 1) = ind_vertex;
                            faces(ind_faces, 2) = ind_vertex + 1; %theta+1
                            faces(ind_faces, 3) = ind_vertex + numTheta_output + 1; %zeta+1,theta+1
                        end

                        % patch #2
                        if (jj == numZeta)
                            % do nothing for the last index

                        elseif (ii == numTheta_output)
                            % final poloidal index - should be a duplicate
                            % of the first point, so do nothing.
                        else
                            ind_faces = ind_faces + 1;
                            faces(ind_faces, 1)=ind_vertex;
                            faces(ind_faces, 2)=ind_vertex + numTheta_output + 1; %zeta+1,theta+1
                            faces(ind_faces, 3)=ind_vertex + numTheta_output;
                        end

                    end
                end

                figure
                %hpatch=patch('Vertices',vertex,'Faces',faces,'FaceVertexCData',cfacedata);
                hpatch=patch('Vertices', vertex, 'Faces', faces);
                %set(hpatch,'EdgeColor','none','FaceColor',[.3 .3 .3]);
                %stlwrite_local(STL_export_filename, x_STL, y_STL, z_STL, 'mode', 'ascii')

                stlwrite_local(STL_export_filename, hpatch.Faces, hpatch.Vertices, 'mode', 'ascii');

                disp(['<---ivertex:' num2str(ind_vertex) '  (should match nvertex)'])
                [numfaces, ~] = size(faces);
                disp(['<---numfaces(post):' num2str(numfaces) '  (should match numfaces)'])

            end
            % end step 1

            % step 2 - sections by control points
            if 1
                num_sections = app.numberOfToroidalControlPointsEditField.Value - 1;
                for cp_index = 1:num_sections

                    STL_export_filename = ...
                        strcat(app.STLPrefixEditField.Value, '_section_', ...
                        num2str(cp_index), '_of_', num2str(num_sections), '.stl');
                    % need to fill x_STL, y_STL, z_STL, numTheta_output,
                    % numZeta
                    tor_start_index = 1+(cp_index-1) * ...
                        (app.numberOfintermedplanesbtwntorcntrlptsEditField.Value+1);
                    tor_end_index = tor_start_index + ...
                        app.numberOfintermedplanesbtwntorcntrlptsEditField.Value+1;

                    x_STL = app.out_surf.X(tor_start_index:tor_end_index, :);
                    y_STL = app.out_surf.Y(tor_start_index:tor_end_index, :);
                    z_STL = app.out_surf.Z(tor_start_index:tor_end_index, :);

                    [numZeta, numTheta_output] = size(x_STL);
                    disp(['<---num toroidal slices:' num2str(numZeta)])
                    disp(['<---num poloidal points:' num2str(numTheta_output)])


                    % Here is a sketch of the mesh
                    %      theta -> (ii)
                    %
                    %    z    x---x---x---x---x---x    ... and last point is same as 1st
                    %    e    | \ | \ | \ | \ | \ |
                    %    t    x---x---x---x---x---x
                    %    a    | \ | \ | \ | \ | \ |
                    %         x---x---x---x---x---x
                    %    |    | \ | \ | \ | \ | \ |
                    %    v    x---x---x---x---x---x
                    %  (jj)   | \ | \ | \ | \ | \ |
                    %         x---x---x---x---x---x
                    %   .
                    %   .
                    %   .
                    %   And last point IS NOT NECESSARILY THE SAME AS THE 1st in the zeta
                    %   directions (should probably check, but it doesn't actually
                    %   matter.)
                    % the number of faces will be: 2 * (# of Theta - 1) * (# of Zeta - 1)
                    %
                    % the vertices are in the _STL(jj,ii) indices
                    % as one loops over the jj and ii indices, make a vertex for each
                    % point, and two patches for each point except fo the last ii and
                    % last jj
                    % vertices: (1,1) -> 1, (1,2) -> 2, ... (1,numtheta) -> numtheta
                    %           (2,1) -> numtheta+1, (2,2) -> numtheta+2, ...
                    % patch #1: connect:  (jj,ii) -- (jj,ii+1) -- (jj+1,ii+1)
                    %                 (vertex) -- (vertex+1) -- (vertex+numtheta+1)
                    % patch #2: connect:  (jj,ii)-- (jj+1,ii+1) -- (jj+1, ii)
                    %                 (vertex) -- (vertex+numtheta+1) -- (vertex+numtheta)
                    %

                    % total # of vertices is the number of points in the mesh
                    % (# of theta) * (# of zeta)
                    nvertex = numTheta_output * numZeta;
                    vertex = zeros(nvertex, 3);

                    % total # of faces is 2 * (# of theta - 1) * (# of zeta - 1)
                    faces = zeros(2 * (numTheta_output - 1) * (numZeta - 1), 3);

                    disp(['<---nvertex:' num2str(nvertex)])
                    [numfaces, ~] = size(faces);
                    disp(['<---numfaces:' num2str(numfaces)])

                    % Now we calculate all of the vertex data and all of the face data
                    % for the patch surfaces
                    figure

                    % keep track of the indices
                    ind_vertex = 0;
                    ind_faces = 0;
                    % jj is the loop variable for the toroidal direction
                    for jj = 1:numZeta
                        % ii is the loop variable for the poloidal direction
                        for ii = 1:numTheta_output
                            % take care of Vertex data
                            ind_vertex = ind_vertex+1;
                            % X Position
                            vertex(ind_vertex, 1) = x_STL(jj, ii);
                            % Y Position
                            vertex(ind_vertex, 2) = y_STL(jj, ii);
                            % Z Position
                            vertex(ind_vertex, 3) = z_STL(jj, ii);

                            % handle the face/patch data
                            % make two patches

                            % patch #1
                            if (jj == numZeta)
                                % do nothing for the last index

                            elseif (ii == numTheta_output)
                                % final poloidal index
                                %faces(ifaces,1)=ivertex;
                                %faces(ifaces,2)=ivertex-numTheta_output+1;
                                %faces(ifaces,3)=ivertex+1;

                            else
                                ind_faces = ind_faces + 1;
                                % this is the 'normal' case
                                faces(ind_faces, 1) = ind_vertex;
                                faces(ind_faces, 2) = ind_vertex + 1; %theta+1
                                faces(ind_faces, 3) = ind_vertex + numTheta_output + 1; %zeta+1,theta+1
                            end

                            % patch #2
                            if (jj == numZeta)
                                % do nothing for the last index

                            elseif (ii == numTheta_output)
                                %faces(ifaces,1)=ivertex;
                                %faces(ifaces,3)=ivertex+1;
                                %faces(ifaces,2)=ivertex+numTheta_output;
                            else
                                ind_faces = ind_faces+1;
                                faces(ind_faces, 1)=ind_vertex;
                                faces(ind_faces, 2)=ind_vertex + numTheta_output + 1; %zeta+1,theta+1
                                faces(ind_faces, 3)=ind_vertex + numTheta_output;
                            end
                            %ifaces=ifaces+1;
                            %ivertex=ivertex+1;

                        end
                    end


                    %hpatch=patch('Vertices',vertex,'Faces',faces,'FaceVertexCData',cfacedata);
                    hpatch=patch('Vertices', vertex, 'Faces', faces);
                    %set(hpatch,'EdgeColor','none','FaceColor',[.3 .3 .3]);
                    %stlwrite_local(STL_export_filename, x_STL, y_STL, z_STL, 'mode', 'ascii')

                    stlwrite_local(STL_export_filename, hpatch.Faces, hpatch.Vertices, 'mode', 'ascii');

                    disp(['<---ivertex:' num2str(ind_vertex) '  (should match nvertex)'])
                    [numfaces, ~] = size(faces);
                    disp(['<---numfaces(post):' num2str(numfaces) '  (should match numfaces)'])
                end
            end
            % end step 2

            % step 3 - sections in 1 degree increments
            if 0
                not_done
                num_sections = app.numberOfToroidalControlPointsEditField.Value - 1;
                for cp_index = 1:num_sections

                    STL_export_filename = ...
                        strcat(app.STLPrefixEditField.Value, '_section_', ...
                        num2str(cp_index), '_of_', num2str(num_sections), '.stl');
                    % need to fill x_STL, y_STL, z_STL, numTheta_output,
                    % numZeta
                    tor_start_index = 1+(cp_index-1) * ...
                        (app.numberOfintermedplanesbtwntorcntrlptsEditField.Value+1);
                    tor_end_index = tor_start_index + ...
                        app.numberOfintermedplanesbtwntorcntrlptsEditField.Value+1;

                    x_STL = app.out_surf.X(tor_start_index:tor_end_index, :);
                    y_STL = app.out_surf.Y(tor_start_index:tor_end_index, :);
                    z_STL = app.out_surf.Z(tor_start_index:tor_end_index, :);

                    [numZeta, numTheta_output] = size(x_STL);
                    disp(['<---num toroidal slices:' num2str(numZeta)])
                    disp(['<---num poloidal points:' num2str(numTheta_output)])


                    % Here is a sketch of the mesh
                    %      theta -> (ii)
                    %
                    %    z    x---x---x---x---x---x    ... and last point is same as 1st
                    %    e    | \ | \ | \ | \ | \ |
                    %    t    x---x---x---x---x---x
                    %    a    | \ | \ | \ | \ | \ |
                    %         x---x---x---x---x---x
                    %    |    | \ | \ | \ | \ | \ |
                    %    v    x---x---x---x---x---x
                    %  (jj)   | \ | \ | \ | \ | \ |
                    %         x---x---x---x---x---x
                    %   .
                    %   .
                    %   .
                    %   And last point IS NOT NECESSARILY THE SAME AS THE 1st in the zeta
                    %   directions (should probably check, but it doesn't actually
                    %   matter.)
                    % the number of faces will be: 2 * (# of Theta - 1) * (# of Zeta - 1)
                    %
                    % the vertices are in the _STL(jj,ii) indices
                    % as one loops over the jj and ii indices, make a vertex for each
                    % point, and two patches for each point except fo the last ii and
                    % last jj
                    % vertices: (1,1) -> 1, (1,2) -> 2, ... (1,numtheta) -> numtheta
                    %           (2,1) -> numtheta+1, (2,2) -> numtheta+2, ...
                    % patch #1: connect:  (jj,ii) -- (jj,ii+1) -- (jj+1,ii+1)
                    %                 (vertex) -- (vertex+1) -- (vertex+numtheta+1)
                    % patch #2: connect:  (jj,ii)-- (jj+1,ii+1) -- (jj+1, ii)
                    %                 (vertex) -- (vertex+numtheta+1) -- (vertex+numtheta)
                    %

                    % total # of vertices is the number of points in the mesh
                    % (# of theta) * (# of zeta)
                    nvertex = numTheta_output * numZeta;
                    vertex = zeros(nvertex, 3);

                    % total # of faces is 2 * (# of theta - 1) * (# of zeta - 1)
                    faces = zeros(2 * (numTheta_output - 1) * (numZeta - 1), 3);

                    disp(['<---nvertex:' num2str(nvertex)])
                    [numfaces, ~] = size(faces);
                    disp(['<---numfaces:' num2str(numfaces)])

                    % Now we calculate all of the vertex data and all of the face data
                    % for the patch surfaces
                    figure

                    % keep track of the indices
                    ind_vertex = 0;
                    ind_faces = 0;
                    % jj is the loop variable for the toroidal direction
                    for jj = 1:numZeta
                        % ii is the loop variable for the poloidal direction
                        for ii = 1:numTheta_output
                            % take care of Vertex data
                            ind_vertex = ind_vertex+1;
                            % X Position
                            vertex(ind_vertex, 1) = x_STL(jj, ii);
                            % Y Position
                            vertex(ind_vertex, 2) = y_STL(jj, ii);
                            % Z Position
                            vertex(ind_vertex, 3) = z_STL(jj, ii);

                            % handle the face/patch data
                            % make two patches

                            % patch #1
                            if (jj == numZeta)
                                % do nothing for the last index

                            elseif (ii == numTheta_output)
                                % final poloidal index
                                %faces(ifaces,1)=ivertex;
                                %faces(ifaces,2)=ivertex-numTheta_output+1;
                                %faces(ifaces,3)=ivertex+1;

                            else
                                ind_faces = ind_faces + 1;
                                % this is the 'normal' case
                                faces(ind_faces, 1) = ind_vertex;
                                faces(ind_faces, 2) = ind_vertex + 1; %theta+1
                                faces(ind_faces, 3) = ind_vertex + numTheta_output + 1; %zeta+1,theta+1
                            end

                            % patch #2
                            if (jj == numZeta)
                                % do nothing for the last index

                            elseif (ii == numTheta_output)
                                %faces(ifaces,1)=ivertex;
                                %faces(ifaces,3)=ivertex+1;
                                %faces(ifaces,2)=ivertex+numTheta_output;
                            else
                                ind_faces = ind_faces+1;
                                faces(ind_faces, 1)=ind_vertex;
                                faces(ind_faces, 2)=ind_vertex + numTheta_output + 1; %zeta+1,theta+1
                                faces(ind_faces, 3)=ind_vertex + numTheta_output;
                            end
                            %ifaces=ifaces+1;
                            %ivertex=ivertex+1;

                        end
                    end


                    %hpatch=patch('Vertices',vertex,'Faces',faces,'FaceVertexCData',cfacedata);
                    hpatch=patch('Vertices', vertex, 'Faces', faces);
                    %set(hpatch,'EdgeColor','none','FaceColor',[.3 .3 .3]);
                    %stlwrite_local(STL_export_filename, x_STL, y_STL, z_STL, 'mode', 'ascii')

                    stlwrite_local(STL_export_filename, hpatch.Faces, hpatch.Vertices, 'mode', 'ascii');

                    disp(['<---ivertex:' num2str(ind_vertex) '  (should match nvertex)'])
                    [numfaces, ~] = size(faces);
                    disp(['<---numfaces(post):' num2str(numfaces) '  (should match numfaces)'])
                end
            end
            % end step 3


        end

        % Button pushed function: PlotCoilsButton
        function PlotCoilsButtonPushed(app, event)
            colorlist = {'r', 'b', 'm', 'c', 'g', [0.5 0.5 0.5]};
            projectionDownsample = 1;
            hold(app.UIAxes, 'on')
            %for jj = 1:coilStruct.num_coils%[1:5 ]
            %for jj = [1:6 ]
            for jj = str2num(app.IndicesofCoilstoUseforInterferenceChecksEditField.Value)
                cval_ind = mod(jj, length(colorlist));
                if (cval_ind == 0)
                    cval_ind = 1;
                end
                colorval = colorlist{cval_ind};

                for kk = 1:app.coilStruct.corners_per_coil
                    this_corner_line = app.coilStruct.coil_corner_data.coils{jj}.corner{kk}.points;

                    corner_line_x = this_corner_line(1:projectionDownsample:end,1);
                    corner_line_y = this_corner_line(1:projectionDownsample:end,2);
                    corner_line_z = this_corner_line(1:projectionDownsample:end,3);

                    plot3(app.UIAxes, corner_line_x, corner_line_y, corner_line_z, '-', 'Color', colorval, 'Linewidth', 2);
                end
            end
            % for jj = [11  44]
            %     for kk = 1:app.coilStruct.corners_per_coil
            %         this_corner_line = app.coilStruct.coil_corner_data.coils{jj}.corner{kk}.points;
            %
            %         corner_line_x = this_corner_line(1:projectionDownsample:end,1);
            %         corner_line_y = this_corner_line(1:projectionDownsample:end,2);
            %         corner_line_z = this_corner_line(1:projectionDownsample:end,3);
            %
            %         plot3(app.UIAxes, corner_line_x, corner_line_y, corner_line_z, '-', 'Color', [0.7 0.7 0.7], 'Linewidth', 2);
            %     end
            % end
            % for jj = [ 12 43 ]
            %     for kk = 1:app.coilStruct.corners_per_coil
            %         this_corner_line = app.coilStruct.coil_corner_data.coils{jj}.corner{kk}.points;
            %
            %         corner_line_x = this_corner_line(1:projectionDownsample:end,1);
            %         corner_line_y = this_corner_line(1:projectionDownsample:end,2);
            %         corner_line_z = this_corner_line(1:projectionDownsample:end,3);
            %
            %         plot3(app.UIAxes, corner_line_x, corner_line_y, corner_line_z, '-', 'Color', [0.8 0.6 0.6], 'Linewidth', 2);
            %     end
            % end

        end

        % Button pushed function: PlotPlasmaButton
        function PlotPlasmaButtonPushed(app, event)

        end

        % Button pushed function: LoadControlPointLoftValuesButton
        function LoadControlPointLoftValuesButtonPushed(app, event)
            [file,path] = uigetfile('*.mat', 'Select the output file', "MultiSelect","off");
            data_infile = fullfile(path,file);
            file_data = load(data_infile, "spline_data");
            app.spl_ctrl_pts = file_data.spline_data.spline_control_loft_values;

        end

        % Button pushed function: SaveControlPointLoftValuesButton
        function SaveControlPointLoftValuesButtonPushed(app, event)
            [file,path] = uiputfile('*.mat', 'Select the output file');
            data_outfile = fullfile(path,file);
            spline_data.spline_control_loft_values = app.spl_ctrl_pts;
            save(data_outfile, "spline_data", '-mat');

        end

        % Button pushed function: LoadGridDefinitionsButton
        function LoadGridDefinitionsButtonPushed(app, event)
            [file,path] = uigetfile('*.mat', 'Select the output file', "MultiSelect","off");
            data_infile = fullfile(path,file);
            file_data = load(data_infile, "grid_data");
            app.Phi_StartdegreesEditField.Value = file_data.grid_data.Phi_Start;
            app.Phi_EnddegreesEditField.Value = file_data.grid_data.Phi_End;
            app.numberOfPoloidalControlPointsEditField.Value = file_data.grid_data.Num_Poloidal_Control_Pts;
            app.numberOfToroidalControlPointsEditField.Value = file_data.grid_data.Num_Toroidal_Control_Pts;
            app.numberOfintermedplanesbtwntorcntrlptsEditField.Value = file_data.grid_data.Num_Intermediate_Planes_Betwee_TCP;
            app.numberOfpoloidalpointsintheloftedsurfacesEditField.Value = file_data.grid_data.Num_Poloidal_Points_in_Lofted_Surfaces;
            app.IndicesofCoilstoUseforInterferenceChecksEditField.Value = file_data.grid_data.Indices_Of_Coils;

            value1 = app.numberOfToroidalControlPointsEditField.Value;
            value2 = app.numberOfintermedplanesbtwntorcntrlptsEditField.Value;
            app.NumberofToroidalGridpointsintheloftedsurfaceEditField.Value = 1+(value1-1)*(value2+1);

        end

        % Button pushed function: SaveGridDefinitionsButton
        function SaveGridDefinitionsButtonPushed(app, event)
            [file,path] = uiputfile('*.mat', 'Select the output file');
            data_outfile = fullfile(path,file);
            grid_data.Phi_Start = app.Phi_StartdegreesEditField.Value;
            grid_data.Phi_End = app.Phi_EnddegreesEditField.Value;
            grid_data.Num_Poloidal_Control_Pts = app.numberOfPoloidalControlPointsEditField.Value;
            grid_data.Num_Toroidal_Control_Pts = app.numberOfToroidalControlPointsEditField.Value;
            grid_data.Num_Intermediate_Planes_Betwee_TCP = app.numberOfintermedplanesbtwntorcntrlptsEditField.Value;
            grid_data.Num_Poloidal_Points_in_Lofted_Surfaces = app.numberOfpoloidalpointsintheloftedsurfacesEditField.Value;
            grid_data.Indices_Of_Coils = app.IndicesofCoilstoUseforInterferenceChecksEditField.Value;
            save(data_outfile, "grid_data", '-mat');

        end

        % Callback function
        function EnableAdvancedFeatureSwitchValueChanged(app, event)
            value = app.EnableAdvancedFeatureSwitch.Value;
            if strcmp(value, 'Off')
                app.FrameBuilderPanel.Visible=0;
                app.FrameEditorTab.BackgroundColor = [.94 .94 .94];
            elseif strcmp(value, 'On')
                app.FrameBuilderPanel.Visible=1;
                app.FrameEditorTab.BackgroundColor = [1 .07 .65];
            end
        end

        % Callback function
        function BuildMeshCoilFrameButtonPushed(app, event)
            app.BuildMeshCoilFrameButton.BackgroundColor = [1 0 0];
            pause(0.001)
            option1 = 3;
            if (mod(app.NumberofToroidalCoilsorSectionsFPEditField.Value, 2) == 0)
                tor_grids_half_period = app.NumberofToroidalCoilsorSectionsFPEditField.Value / 2;
            else
                tor_grids_half_period = 0;
            end
            num_zeta_arclength = app.NumberofToroidalGridpointsintheloftedsurfaceEditField.Value;
            num_theta_arclength = app.numberOfpoloidalpointsintheloftedsurfacesEditField.Value;
            [num_t1, num_p1] = size(app.out_surf.X);
            num_tor_sections = app.NumberofToroidalCoilsorSectionsFPEditField.Value;
            num_pol_sections = app.NumberofPoloidalSectionsEditField.Value;
            if ((num_t1 ~= num_zeta_arclength) || (num_p1 ~= num_theta_arclength))
                disp('<---grid mismatch in stelloft')
            end
            poloidal_scale = app.PoloidalScaleEditField.Value;
            poloidal_offset = app.PoloidalOffsetEditField.Value;
            toroidal_scale = app.ToroidalScaleEditField.Value;
            num_layers = app.NumberofLayersEditField.Value;
            stack_delta = app.StackDeltaEditField.Value;
            num_zeta_arclength = app.NumberofToroidalGridpointsintheloftedsurfaceEditField.Value;
            dipole_coil_filename_out = app.OutputCoilFilenameEditField.Value;

            %(ii,jj) (tor, pol)
            for ii = 1:num_zeta_arclength
                for jj = 1:num_theta_arclength
                    lofted_surface.center_x(ii,jj) = app.out_surf.X(ii,jj);
                    lofted_surface.center_y(ii,jj) = app.out_surf.Y(ii,jj);
                    lofted_surface.center_z(ii,jj) = app.out_surf.Z(ii,jj);

                    lofted_surface.norm_x(ii,jj) = app.out_surf.norm_X(ii,jj);
                    lofted_surface.norm_y(ii,jj) = app.out_surf.norm_Y(ii,jj);
                    lofted_surface.norm_z(ii,jj) = app.out_surf.norm_Z(ii,jj);
                    lofted_surface.tor_x(ii,jj) = app.out_surf.par_X(ii,jj);
                    lofted_surface.tor_y(ii,jj) = app.out_surf.par_Y(ii,jj);
                    lofted_surface.tor_z(ii,jj) = app.out_surf.par_Z(ii,jj);
                    lofted_surface.pol_x(ii,jj) = app.out_surf.binorm_X(ii,jj);
                    lofted_surface.pol_y(ii,jj) = app.out_surf.bonorm_Y(ii,jj);
                    lofted_surface.pol_z(ii,jj) = app.out_surf.binorm_Z(ii,jj);

                    %             app.out_surf
                    % ans =
                    %   struct with fields:
                    %
                    %     SPLINE_TYPE: [1×1 RadioButton]
                    %            BadX: [61×361 double]
                    %            BadY: [61×361 double]
                    %            BadZ: [61×361 double]
                    %               X: [61×361 double]
                    %               Y: [61×361 double]
                    %               Z: [61×361 double]
                    %               R: [61×361 double]
                    %             Phi: [61×361 double]
                    %          X_lcfs: [61×361 double]
                    %          Y_lcfs: [61×361 double]
                    %          Z_lcfs: [61×361 double]
                    %          R_lcfs: [61×361 double]
                    %        Phi_lcfs: [61×361 double]

                    %             lofted_surface.norm_x
                    %             lofted_surface.norm_y
                    %             lofted_surface.norm_z
                    %             lofted_surface.pol_x
                    %             lofted_surface.pol_y
                    %             lofted_surface.pol_z
                    %             lofted_surface.tor_x
                    %             lofted_surface.tor_y
                    %             lofted_surface.tor_z
                end
            end
            app.BuildMeshCoilFrameButton.BackgroundColor = [0 1 0];
            pause(0.001)
            lofted_surface.NFP = app.vmec_data.nfp;


            if 0
                figure
                plot3(lofted_surface.center_x, lofted_surface.center_y, lofted_surface.center_z)
                figure
                quiver3(lofted_surface.center_x(1:10:end,1:10:end), lofted_surface.center_y(1:10:end,1:10:end), lofted_surface.center_z(1:10:end,1:10:end), lofted_surface.norm_x(1:10:end,1:10:end), lofted_surface.norm_y(1:10:end,1:10:end), lofted_surface.norm_z(1:10:end,1:10:end), 5);axis equal
                figure
                quiver3(lofted_surface.center_x(1:10:end,1:10:end), lofted_surface.center_y(1:10:end,1:10:end), lofted_surface.center_z(1:10:end,1:10:end), lofted_surface.pol_x(1:10:end,1:10:end), lofted_surface.pol_y(1:10:end,1:10:end), lofted_surface.pol_z(1:10:end,1:10:end), 5);axis equal
                figure
                quiver3(lofted_surface.center_x(1:10:end,1:10:end), lofted_surface.center_y(1:10:end,1:10:end), lofted_surface.center_z(1:10:end,1:10:end), lofted_surface.tor_x(1:10:end,1:10:end), lofted_surface.tor_y(1:10:end,1:10:end), lofted_surface.tor_z(1:10:end,1:10:end), 5);axis equal
                figure
                plot3(lofted_surface.center_x, lofted_surface.center_y, lofted_surface.center_z)
                figure
                quiver3(lofted_surface.center_x(1:3:end,1:3:end), lofted_surface.center_y(1:3:end,1:3:end), lofted_surface.center_z(1:3:end,1:3:end), lofted_surface.norm_x(1:3:end,1:3:end), lofted_surface.norm_y(1:3:end,1:3:end), lofted_surface.norm_z(1:3:end,1:3:end), 5);axis equal
                figure
                quiver3(lofted_surface.center_x(1:3:end,1:3:end), lofted_surface.center_y(1:3:end,1:3:end), lofted_surface.center_z(1:3:end,1:3:end), lofted_surface.pol_x(1:3:end,1:3:end), lofted_surface.pol_y(1:3:end,1:3:end), lofted_surface.pol_z(1:3:end,1:3:end), 5);axis equal
                figure
                quiver3(lofted_surface.center_x(1:3:end,1:3:end), lofted_surface.center_y(1:3:end,1:3:end), lofted_surface.center_z(1:3:end,1:3:end), lofted_surface.tor_x(1:3:end,1:3:end), lofted_surface.tor_y(1:3:end,1:3:end), lofted_surface.tor_z(1:3:end,1:3:end), 5);axis equal


            end

            [x_coil, y_coil, z_coil] = make_stacked_dipoles_on_lofted_surface_v1(lofted_surface, ...
                num_zeta_arclength, num_theta_arclength, ...
                num_tor_sections, ...
                num_pol_sections, ...
                num_layers, ...
                stack_delta, ...
                poloidal_scale, ...
                poloidal_offset, ...
                toroidal_scale, ...
                dipole_coil_filename_out);

            num_coils = length(x_coil);
            app.BuildMeshCoilFrameButton.BackgroundColor = [0 0 1];
            pause(0.001)
            %view(app.UIAxes, 3)
            %figure;box on;hold on;grid on;view(3)
            for ii = 1:num_coils
                %plot3(x_coil{ii}, y_coil{ii}, z_coil{ii}, 'k-');
                plot3(app.UIAxes, x_coil{ii}, y_coil{ii}, z_coil{ii}, 'k-', 'Linewidth', 3);
                pause(0.001)

            end
            app.BuildMeshCoilFrameButton.BackgroundColor = [0.96 0.96 0.96];

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            %app.FrameBuilderPanel.Visible=app.EnableAdvancedFeatureSwitch.Value;

            selectedTab = app.TabGroup.SelectedTab;

        end

        % Value changed function: Phi_StartdegreesEditField
        function Phi_StartdegreesEditFieldValueChanged(app, event)
            value = app.Phi_StartdegreesEditField.Value;

        end

        % Value changed function: numberOfToroidalControlPointsEditField
        function numberOfToroidalControlPointsEditFieldValueChanged(app, event)
            value1 = app.numberOfToroidalControlPointsEditField.Value;
            value2 = app.numberOfintermedplanesbtwntorcntrlptsEditField.Value;
            app.NumberofToroidalGridpointsintheloftedsurfaceEditField.Value = 1+(value1-1)*(value2+1);
        end

        % Value changed function: 
        % numberOfintermedplanesbtwntorcntrlptsEditField
        function numberOfintermedplanesbtwntorcntrlptsEditFieldValueChanged(app, event)
            value1 = app.numberOfToroidalControlPointsEditField.Value;
            value2 = app.numberOfintermedplanesbtwntorcntrlptsEditField.Value;
            app.NumberofToroidalGridpointsintheloftedsurfaceEditField.Value = 1+(value1-1)*(value2+1);
        end

        % Callback function
        function BuildToroidalCoilsButtonPushed(app, event)

        end

        % Button pushed function: ExportNESCOILstylewindingsurfaceButton
        function ExportNESCOILstylewindingsurfaceButtonPushed(app, event)
            app.ExportNESCOILstylewindingsurfaceButton.BackgroundColor = [1 0 0];
            pause(0.01)

            num_zeta_arclength = app.NumberofToroidalGridpointsintheloftedsurfaceEditField.Value;
            num_theta_arclength = app.numberOfpoloidalpointsintheloftedsurfacesEditField.Value;
            [num_t1, num_p1] = size(app.out_surf.X);
            num_tor_sections = app.NumberofToroidalCoilsorSectionsFPEditField.Value;
            num_pol_sections = app.NumberofPoloidalSectionsEditField.Value;
            if ((num_t1 ~= num_zeta_arclength) || (num_p1 ~= num_theta_arclength))
                disp('<---grid mismatch in stelloft')
                disp(['<---num_t1: ' num2str(num_t1)]);
                disp(['<---num_zeta_arclength: ' num2str(num_zeta_arclength)]);
                disp(['<---num_p1: ' num2str(num_p1)]);
                disp(['<---num_theta_arclength: ' num2str(num_theta_arclength)]);
                return
            end

            nescoil_coil_filename_out = app.NESCOILFilenameEditField.Value;

            %(ii,jj) (tor, pol)
            winding_surface_R = app.out_surf.R;
            winding_surface_Phi = app.out_surf.Phi;
            winding_surface_Z = app.out_surf.Z;
            % This data only holds the 1/2 field period data
            % now, apply stellarator symmetry to geneate the other half of
            % the field period.  However, keep in mind that for the FFT
            % below, the symmetry planes do not need to be reproduced,
            % and the summations take care of this.
            phi_stelsymm_end = 2* winding_surface_Phi(end,1);
            data_to_dup_R = (fliplr((winding_surface_R(2:end-1,:))'))';
            data_to_dup_Z = -(fliplr((winding_surface_Z(2:end-1,:))'))';
            %data_to_dup_R = fliplr(data_to_dup_R);
            %data_to_dup_Z = fliplr(data_to_dup_Z);
            data_to_dup_R = [data_to_dup_R(:,1) fliplr(data_to_dup_R(:,2:end))];
            data_to_dup_Z = [data_to_dup_Z(:,1) fliplr(data_to_dup_Z(:,2:end))];

            winding_surface_R = [winding_surface_R; data_to_dup_R];
            winding_surface_Z = [winding_surface_Z; data_to_dup_Z];

            app.ExportNESCOILstylewindingsurfaceButton.BackgroundColor = [0 1 0];
            pause(0.01)
            NFP = app.vmec_data.nfp;

            % FFT new surface (use NESCOIL convention, mu + nv)
            mnmax_ws = 0;
            rmnc_ws = 0;
            zmns_ws = 0;
            mmax = app.NESCOILMMAXEditField.Value;
            nmax = app.NESCOILNMAXEditField.Value;
            nub = num_theta_arclength;
            nvb = (2*num_zeta_arclength-2);
            alub = 2*pi  /nub;
            alvb = 2*pi / nvb;
            nuvb = nub * nvb;

            sep_tol = app.NESCOILToleranceEditField.Value;

            for mm = 0:mmax
                for nn = -nmax:nmax
                    if ( ( mm == 0 ) && (nn > 0))
                        continue
                    end
                    dnorm = 1/ nuvb;
                    if ((mm ~= 0) || (nn ~= 0 ))
                        dnorm = 2*dnorm;
                    end
                    rtemp = 0;
                    ztemp = 0;
                    rtemps = 0;
                    ztempc = 0;
                    for kk = 1:num_theta_arclength
                        %  kk from 1 to nub
                        this_theta = alub * (kk-1);
                        cos_mu = cos(mm*this_theta)*dnorm;
                        sin_mu = sin(mm*this_theta)*dnorm;
                        % ll from 1 to nvb
                        for ll = 1:(2*num_zeta_arclength-2)
                            this_zeta = alvb * (ll - 1);
                            cos_nv = cos(nn * this_zeta);
                            sin_nv = sin(nn * this_zeta);
                            cos_mn1 = cos_mu * cos_nv - sin_mu * sin_nv;
                            sin_mn1 = sin_mu * cos_nv + cos_mu * sin_nv;
                            rbn = winding_surface_R(ll,kk);
                            zbn = winding_surface_Z(ll,kk);
                            rtemp = rtemp + rbn * cos_mn1;
                            ztemp = ztemp + zbn * sin_mn1;
                            rtemps = rtemps + rbn * sin_mn1;
                            ztempc = ztempc + zbn * cos_mn1;

                        end
                    end
                    if ((abs(rtemp) < sep_tol) && (abs(ztemp)< sep_tol))
                        continue
                    end
                    mnmax_ws = mnmax_ws + 1;
                    rmnc_ws(mnmax_ws) = rtemp;
                    zmns_ws(mnmax_ws) = ztemp;
                    rmns_ws(mnmax_ws) = rtemps;
                    zmnc_ws(mnmax_ws) = ztempc;
                    ixm_ws(mnmax_ws) = mm;
                    ixn_ws(mnmax_ws) = nn;

                end
            end
            app.ExportNESCOILstylewindingsurfaceButton.BackgroundColor = [1 1 0];


            fid = fopen(nescoil_coil_filename_out, 'w');
            % print header info
            fprintf(fid, '------ Current Surface: Coil-Plasma separation from Stelloft\n');
            fprintf(fid, 'Number of field periods\n');
            fprintf(fid, ['        ', num2str(NFP), '\n']);
            fprintf(fid, 'Number of fourier modes in table\n');
            fprintf(fid, ['        ', num2str(mnmax_ws), '\n']);
            fprintf(fid, 'Table of fourier coefficients\n');
            fprintf(fid, 'm,n,crc2,czs2,crs2,czc2\n');
            for ii = 1:mnmax_ws
                fprintf(fid, '  %8i  %8i  %20.16e  %20.16e  %20.16e  %20.16e\n' , ixm_ws(ii), ixn_ws(ii), rmnc_ws(ii), zmns_ws(ii), rmns_ws(ii), zmnc_ws(ii) );
            end


            % close the file
            fclose(fid);
            app.ExportNESCOILstylewindingsurfaceButton.BackgroundColor = [0.16 0.96 0.46];
            pause(.001)

            % rebuild it
            theta = linspace(0,2*pi, 180);
            zeta = linspace(0,2*pi/NFP, 180);
            [theta_mesh, zeta_mesh] = meshgrid(theta, zeta);
            R_rebuild = 0 * theta_mesh;
            Z_rebuild = 0 * theta_mesh;
            for ii = 1:mnmax_ws
                this_m = ixm_ws(ii);
                this_n = ixn_ws(ii);
                this_rmnc = rmnc_ws(ii);
                this_zmns = zmns_ws(ii);
                this_rmns = rmns_ws(ii);
                this_zmnc = zmnc_ws(ii);
                %  mu + nv
                angle_mesh = this_m * theta_mesh +  NFP * this_n * zeta_mesh;
                cos_am = cos(angle_mesh);
                sin_am = sin(angle_mesh);
                R_rebuild = R_rebuild + this_rmnc * cos_am + this_rmns * sin_am;
                Z_rebuild = Z_rebuild + this_zmnc * cos_am + this_zmns * sin_am;

            end
            X_rebuild = R_rebuild .* cos(zeta_mesh);
            Y_rebuild = R_rebuild .* sin(zeta_mesh);
            %figure;
            %plot3(X_rebuild, Y_rebuild, Z_rebuild, '.')
            surf(app.UIAxes, X_rebuild, Y_rebuild, Z_rebuild, 'FaceColor', [0 0 1]);
            app.ExportNESCOILstylewindingsurfaceButton.BackgroundColor = [0.96 0.96 0.96];

        end

        % Value changed function: UseWithoutCoilsSwitch
        function UseWithoutCoilsSwitchValueChanged(app, event)
            UseWithoutCoils = app.UseWithoutCoilsSwitch.Value;
            if (strcmpi(UseWithoutCoils,'No'))
                app.ImportCoilCornersGeometryorcoilfilamentGeometryButton.Enable = "on";
                app.IndicesofCoilstoUseforInterferenceChecksEditField.Enable = "on";
            end
            if (strcmpi(UseWithoutCoils,'Yes'))
                app.ImportCoilCornersGeometryorcoilfilamentGeometryButton.Enable = "off";
                app.IndicesofCoilstoUseforInterferenceChecksEditField.Enable = "off";
            end

        end

        % Button pushed function: ImportSingleTraceButton
        function ImportSingleTraceButtonPushed(app, event)
            [file,path] = uigetfile('*.*', 'Select the trace file', "MultiSelect","off");
            tracefile = fullfile(path,file);

            app.TraceFileFolder.Text = tracefile;
            app.TraceLoadedLamp.Color = [1 1 0];
            pause(0.001)
            try
                [R, Z, Phi, X, Y, arclength] = read_FieldlineTrace(tracefile, 0);
                app.TraceLoadedLamp.Color = [0 0 1];
            catch
                app.TraceLoadedLamp.Color = [1 0 0];
                return;
            end

            trim_off_the_end = 1;

            app.TraceDataList.numTraces = ...
                app.TraceDataList.numTraces + 1;
            app.TraceDataList.Traces(app.TraceDataList.numTraces).R = R(1:end-trim_off_the_end);
            app.TraceDataList.Traces(app.TraceDataList.numTraces).Z = Z(1:end-trim_off_the_end);
            app.TraceDataList.Traces(app.TraceDataList.numTraces).X = X(1:end-trim_off_the_end);
            app.TraceDataList.Traces(app.TraceDataList.numTraces).Y = Y(1:end-trim_off_the_end);
            app.TraceDataList.Traces(app.TraceDataList.numTraces).Phi = Phi(1:end-trim_off_the_end);
            app.TraceDataList.Traces(app.TraceDataList.numTraces).arclength = arclength(1:end-trim_off_the_end);


            if (app.TraceDataList.numTraces > 1)
                app.TraceIndexListEditField.Value = ['1:' num2str(app.TraceDataList.numTraces)];
            else
                app.TraceIndexListEditField.Value = ['1'];
            end

        end

        % Button pushed function: ClearIniializeTraceDataButton
        function ClearIniializeTraceDataButtonPushed(app, event)
            app.TraceDataList = [];
            app.TraceDataList.numTraces = 0;
            app.TraceDataList.Traces = [];
            app.TraceIndexListEditField.Value = [''];

        end

        % Button pushed function: ImportManyTracesButton
        function ImportManyTracesButtonPushed(app, event)
            [file,path] = uigetfile('*', 'Select the trace files', "MultiSelect","on");
            num_files = length(file);
            counter = 0;
            for file_name = file
                counter = counter + 1;
                disp(['<---Reading in trace #' num2str(counter) ...
                    ' of ' num2str(num_files)]);
                tracefile_cell = fullfile(path,file_name);
                tracefile = tracefile_cell{1};
                app.TraceFileFolder.Text = tracefile;
                app.TraceLoadedLamp.Color = [1 1 0];
                pause(0.001)
                try
                    [R, Z, Phi, X, Y, arclength] = read_FieldlineTrace(tracefile, 0);
                    app.TraceLoadedLamp.Color = [0 0 1];
                catch
                    app.TraceLoadedLamp.Color = [1 0 0];
                    return;
                end

                trim_off_the_end = 1;

                app.TraceDataList.numTraces = ...
                    app.TraceDataList.numTraces + 1;
                app.TraceDataList.Traces(app.TraceDataList.numTraces).R = R(1:end-trim_off_the_end);
                app.TraceDataList.Traces(app.TraceDataList.numTraces).Z = Z(1:end-trim_off_the_end);
                app.TraceDataList.Traces(app.TraceDataList.numTraces).X = X(1:end-trim_off_the_end);
                app.TraceDataList.Traces(app.TraceDataList.numTraces).Y = Y(1:end-trim_off_the_end);
                app.TraceDataList.Traces(app.TraceDataList.numTraces).Phi = Phi(1:end-trim_off_the_end);
                app.TraceDataList.Traces(app.TraceDataList.numTraces).arclength = arclength(1:end-trim_off_the_end);
            end
            if (app.TraceDataList.numTraces > 1)
                app.TraceIndexListEditField.Value = ['1:' num2str(app.TraceDataList.numTraces)];
            else
                app.TraceIndexListEditField.Value = ['1'];
            end
        end

        % Button pushed function: SaveTraceInformationButton
        function SaveTraceInformationButtonPushed(app, event)
            [file,path] = uiputfile('*.mat', 'Select the trace output file');
            data_outfile = fullfile(path,file);

            trace_data.numTraces = app.TraceDataList.numTraces;
            trace_data.Traces = app.TraceDataList.Traces;

            save(data_outfile, "trace_data", '-mat', '-v7.3');
        end

        % Button pushed function: LoadTraceInformationButton
        function LoadTraceInformationButtonPushed(app, event)

            [file,path] = uigetfile('*.mat', 'Select the trace output file', "MultiSelect","off");
            data_infile = fullfile(path,file);
            file_data = load(data_infile, "trace_data");
            app.TraceDataList.numTraces = file_data.trace_data.numTraces;
            app.TraceDataList.Traces = file_data.trace_data.Traces;
            if (app.TraceDataList.numTraces > 1)
                app.TraceIndexListEditField.Value = ['1:' num2str(app.TraceDataList.numTraces)];
            else
                app.TraceIndexListEditField.Value = ['1'];
            end
            disp('Files loaded');
        end

        % Button pushed function: 
        % DefineControlandIntermediateSurfaceGridsButton_2
        function DefineControlandIntermediateSurfaceGridsButton_2Pushed(app, event)
            if (app.SaveonDefineCheckBox.Value)
                PICK_SAVE = 1;
            else
                PICK_SAVE= 0
            end

            app.DefineControlandIntermediateSurfaceGridsButton_2.BackgroundColor = [0.8,0.3,0.3];

            if PICK_SAVE
                [file,path] = uiputfile('*.mat', 'Select the interference output file');
                data_outfile = fullfile(path,file);
            end

            y = app.define_inteference_points;

            if PICK_SAVE
                ip_data.interferencePoints_ip = app.interferencePoints_ip;
                ip_data.interferencePoints_trace_ip = app.interferencePoints_trace_ip;
                ip_data.interferencePoints_cp = app.interferencePoints_cp;
                ip_data.interferencePoints_trace_cp = app.interferencePoints_trace_cp;

                save(data_outfile, "ip_data", '-mat', '-v7.3');
            end

            app.DefineControlandIntermediateSurfaceGridsButton_2.BackgroundColor = [0.96,0.96,0.96];
            %keyboard
        end

        % Button pushed function: SaveInterferencePointsButton
        function SaveInterferencePointsButtonPushed(app, event)


            [file,path] = uiputfile('*.mat', 'Select the interference output file');
            data_outfile = fullfile(path,file);

            ip_data.interferencePoints_ip = app.interferencePoints_ip;
            ip_data.interferencePoints_trace_ip = app.interferencePoints_trace_ip;
            ip_data.interferencePoints_cp = app.interferencePoints_cp;
            ip_data.interferencePoints_trace_cp = app.interferencePoints_trace_cp;

            save(data_outfile, "ip_data", '-mat', '-v7.3');

        end

        % Button pushed function: LoadInterferencePointsButton
        function LoadInterferencePointsButtonPushed(app, event)

            if (app.TraceInterferencePoints_loaded)
                add_to_data = input('Do you want to add the trace points to the existing data set and keep the existing coil point interference map (1=y/0=no)?')
            else add_to_data = 0;
            end

            [file,path] = uigetfile('*.mat', 'Select the interference file', "MultiSelect","off");
            data_infile = fullfile(path,file);
            file_data = load(data_infile, "ip_data");

            if add_to_data
                %keyboard
                %loop over the toroidal indices and merge the .X, .Y, .Z, .R components of the structures.
                for ii = 1:length(app.interferencePoints_trace_cp)
                    app.interferencePoints_trace_cp{ii}.X = ...
                        [app.interferencePoints_trace_cp{ii}.X  ...
                        file_data.ip_data.interferencePoints_trace_cp{ii}.X];
                    app.interferencePoints_trace_cp{ii}.Y = ...
                        [app.interferencePoints_trace_cp{ii}.Y  ...
                        file_data.ip_data.interferencePoints_trace_cp{ii}.Y];
                    app.interferencePoints_trace_cp{ii}.Z = ...
                        [app.interferencePoints_trace_cp{ii}.Z  ...
                        file_data.ip_data.interferencePoints_trace_cp{ii}.Z];
                    app.interferencePoints_trace_cp{ii}.R = ...
                        [app.interferencePoints_trace_cp{ii}.R  ...
                        file_data.ip_data.interferencePoints_trace_cp{ii}.R];


                end

                for ii = 1:length(app.interferencePoints_trace_ip)
                    app.interferencePoints_trace_ip{ii}.X = ...
                        [app.interferencePoints_trace_ip{ii}.X  ...
                        file_data.ip_data.interferencePoints_trace_ip{ii}.X];
                    app.interferencePoints_trace_ip{ii}.Y = ...
                        [app.interferencePoints_trace_ip{ii}.Y  ...
                        file_data.ip_data.interferencePoints_trace_ip{ii}.Y];
                    app.interferencePoints_trace_ip{ii}.Z = ...
                        [app.interferencePoints_trace_ip{ii}.Z  ...
                        file_data.ip_data.interferencePoints_trace_ip{ii}.Z];
                    app.interferencePoints_trace_ip{ii}.R = ...
                        [app.interferencePoints_trace_ip{ii}.R  ...
                        file_data.ip_data.interferencePoints_trace_ip{ii}.R];
                end


            else

                app.interferencePoints_ip = file_data.ip_data.interferencePoints_ip;
                app.interferencePoints_trace_ip = file_data.ip_data.interferencePoints_trace_ip;
                app.interferencePoints_cp = file_data.ip_data.interferencePoints_cp;
                app.interferencePoints_trace_cp = file_data.ip_data.interferencePoints_trace_cp;
                app.interferencePoints_loaded = 1;
                app.TraceInterferencePoints_loaded = 1;
            end

        end

        % Button pushed function: ImportNESCSurfaceButton
        function ImportNESCSurfaceButtonPushed(app, event)
            [file,path] = uigetfile('*', 'Select the NESC output file', "MultiSelect","off");
            nescfile = fullfile(path,file);

            app.NESCFileLabel.Text = nescfile;
            app.ImportNESCSurfaceButton.BackgroundColor = [1 1 0];
            pause(0.001)
            try
                app.nesc_data = read_stelloft_nesc(nescfile);
                if isa(app.nesc_data, 'struct')
                    app.NESCLoadedLamp.Color = [0 0 1];
                else
                    app.NESCLoadedLamp.Color = [1 0 0];
                end
            catch
                app.NESCLoadedLamp.Color = [1 0 0];
            end
            app.ImportNESCSurfaceButton.BackgroundColor = [0.96 0.96 0.96];

        end

        % Button pushed function: ExportInformativePlotsButton
        function ExportInformativePlotsButtonPushed(app, event)
            % Make some informative plots for presentations, papers, etc.
            % control grid data
            cgp.X = app.vmec_control_points.X;
            cgp.Y = app.vmec_control_points.Y;
            cgp.Z = app.vmec_control_points.Z;

            cgp.Xn = app.vmec_control_normals.normal_x;
            cgp.Yn = app.vmec_control_normals.normal_y;
            cgp.Zn = app.vmec_control_normals.normal_z;

            cgp.Xncyl = app.vmec_control_normals_cyl.normal_x;
            cgp.Yncyl = app.vmec_control_normals_cyl.normal_y;
            cgp.Zncyl = app.vmec_control_normals_cyl.normal_z;

            % intermediate grid data
            igp.X = app.vmec_intermediate_points.X;
            igp.Y = app.vmec_intermediate_points.Y;
            igp.Z = app.vmec_intermediate_points.Z;

            igp.Xn = app.vmec_intermediate_normals.normal_x;
            igp.Yn = app.vmec_intermediate_normals.normal_y;
            igp.Zn = app.vmec_intermediate_normals.normal_z;

            igp.Xncyl = app.vmec_intermediate_normals_cyl.normal_x;
            igp.Yncyl = app.vmec_intermediate_normals_cyl.normal_y;
            igp.Zncyl = app.vmec_intermediate_normals_cyl.normal_z;


            % the 'blown up' control points - a denser mesh.
            cgp_bu.X = app.vmec_control_points_blowup.X;
            cgp_bu.Y = app.vmec_control_points_blowup.Y;
            cgp_bu.Z = app.vmec_control_points_blowup.Z;

            cgp_bu.Xn = app.vmec_control_normals_blowup.normal_x;
            cgp_bu.Yn = app.vmec_control_normals_blowup.normal_y;
            cgp_bu.Zn = app.vmec_control_normals_blowup.normal_z;

            cgp_bu.Xncyl = app.vmec_control_normals_blowup_cyl.normal_x;
            cgp_bu.Yncyl = app.vmec_control_normals_blowup_cyl.normal_y;
            cgp_bu.Zncyl = app.vmec_control_normals_blowup_cyl.normal_z;

            % the 'blown up' intermediate points - a denser mesh.
            igp_bu.X = app.vmec_intermediate_points_blowup.X;
            igp_bu.Y = app.vmec_intermediate_points_blowup.Y;
            igp_bu.Z = app.vmec_intermediate_points_blowup.Z;

            igp_bu.Xn = app.vmec_intermediate_normals_blowup.normal_x;
            igp_bu.Yn = app.vmec_intermediate_normals_blowup.normal_y;
            igp_bu.Zn = app.vmec_intermediate_normals_blowup.normal_z;

            igp_bu.Xncyl = app.vmec_intermediate_normals_blowup_cyl.normal_x;
            igp_bu.Yncyl = app.vmec_intermediate_normals_blowup_cyl.normal_y;
            igp_bu.Zncyl = app.vmec_intermediate_normals_blowup_cyl.normal_z;


            % the 'lofted wall' data
            lofted_wall.X = app.out_surf.X;
            lofted_wall.Y = app.out_surf.Y;
            lofted_wall.Z = app.out_surf.Z;


            % Toroidal surface and outward normal directions on 3x3 grid
            fh = figure;
            box on; hold on; grid on; axis equal;view(3);
            for ii = 5   % :(app.numberOfToroidalControlPointsEditField.Value - 1)
                cp_ind_0 = ii;
                cp_ind_1 = ii+1;
                ip_ind_0 = 1+(ii-1)*(app.numberOfintermedplanesbtwntorcntrlptsEditField.Value);
                ip_ind_1 = ip_ind_0 + app.numberOfintermedplanesbtwntorcntrlptsEditField.Value-1;

                %for ii = 1:5
                loft_ind_0 = 1+(ii-1)*(app.numberOfintermedplanesbtwntorcntrlptsEditField.Value+1);
                loft_ind_1 = loft_ind_0 + app.numberOfintermedplanesbtwntorcntrlptsEditField.Value+1;
                %loft_ind_1 = loft_ind_0 + 3;
                %surf(cp.X(cp_ind_0:cp_ind_1, :), cp.Y(cp_ind_0:cp_ind_1, :), ...
                %    cp.Z(cp_ind_0:cp_ind_1, :), 'FaceColor', 'Cyan', 'EdgeColor',[.5 .5 .5]);
                plot3(cgp.X(cp_ind_0:cp_ind_1, :), cgp.Y(cp_ind_0:cp_ind_1, :), ...
                    cgp.Z(cp_ind_0:cp_ind_1, :), 'b.', 'MarkerSize', 32)
                plot3(cgp_bu.X(cp_ind_0:cp_ind_1, :), cgp_bu.Y(cp_ind_0:cp_ind_1, :), ...
                    cgp_bu.Z(cp_ind_0:cp_ind_1, :), 'b.', 'MarkerSize', 12)

                quiver3(cgp.X(cp_ind_0:cp_ind_1, :), cgp.Y(cp_ind_0:cp_ind_1, :), ...
                    cgp.Z(cp_ind_0:cp_ind_1, :), cgp.Xn(cp_ind_0:cp_ind_1, :), ...
                    cgp.Yn(cp_ind_0:cp_ind_1, :), cgp.Zn(cp_ind_0:cp_ind_1, :), ...
                    .4, 'Color', 'b', 'Marker', 'None', 'LineStyle', '-', ...
                    'LineWidth', 3, 'MaxHeadSize', 24);

                quiver3(cgp.X(cp_ind_0:cp_ind_1, :), cgp.Y(cp_ind_0:cp_ind_1, :), ...
                    cgp.Z(cp_ind_0:cp_ind_1, :), cgp.Xncyl(cp_ind_0:cp_ind_1, :), ...
                    cgp.Yncyl(cp_ind_0:cp_ind_1, :), cgp.Zncyl(cp_ind_0:cp_ind_1, :), ...
                    .4, 'Color', 'r', 'Marker', 'None', 'LineStyle', '-', ...
                    'LineWidth', 3, 'MaxHeadSize', 24);

                plot3(igp.X(ip_ind_0:ip_ind_1, :), igp.Y(ip_ind_0:ip_ind_1, :), ...
                    igp.Z(ip_ind_0:ip_ind_1, :), 'm.', 'MarkerSize', 32)

                plot3(igp_bu.X(ip_ind_0:ip_ind_1, :), igp_bu.Y(ip_ind_0:ip_ind_1, :), ...
                    igp_bu.Z(ip_ind_0:ip_ind_1, :), 'm.', 'MarkerSize', 12)

                surf(lofted_wall.X(loft_ind_0:loft_ind_1, :), ...
                    lofted_wall.Y(loft_ind_0:loft_ind_1, :), ...
                    lofted_wall.Z(loft_ind_0:loft_ind_1, :), ...
                    'FaceColor', [.8 .8 .8], 'EdgeColor',[.7 .7 .7], ...
                    'LineStyle', '--');
                axis equal;
                axis tight;
                xlabel('X  (m)');
                ylabel('Y  (m)');
                zlabel('Z  (m)');
                set(gca, 'FontSize', 18);
                set(gcf, 'Position', [20 20 800 800]);
                alpha(0.8)


            end

            % interference points
            % cloud of coils points at control grid angles
            fh = figure;
            box on; hold on; grid on; axis equal;view(3);
            for ii = 5   % :(app.numberOfToroidalControlPointsEditField.Value - 1)
                cp_ind_0 = ii;
                cp_ind_1 = ii+1;
                ip_ind_0 = 1+(ii-1)*(app.numberOfintermedplanesbtwntorcntrlptsEditField.Value);
                ip_ind_1 = ip_ind_0 + app.numberOfintermedplanesbtwntorcntrlptsEditField.Value-1;

                loft_ind_0 = 1+(ii-1)*(app.numberOfintermedplanesbtwntorcntrlptsEditField.Value+1);
                loft_ind_1 = loft_ind_0 + app.numberOfintermedplanesbtwntorcntrlptsEditField.Value+1;

                plot3(cgp_bu.X(cp_ind_0:cp_ind_1, :), cgp_bu.Y(cp_ind_0:cp_ind_1, :), ...
                    cgp_bu.Z(cp_ind_0:cp_ind_1, :), 'b.', 'MarkerSize', 8, ...
                    'Linewidth', 1)

                plot3(igp_bu.X(ip_ind_0:ip_ind_1, :), igp_bu.Y(ip_ind_0:ip_ind_1, :), ...
                    igp_bu.Z(ip_ind_0:ip_ind_1, :), 'm.', 'MarkerSize', 8,  ...
                    'Linewidth', 1)

                surf(lofted_wall.X(loft_ind_0:loft_ind_1, :), ...
                    lofted_wall.Y(loft_ind_0:loft_ind_1, :), ...
                    lofted_wall.Z(loft_ind_0:loft_ind_1, :), ...
                    'FaceColor', [.9 .9 .9], 'EdgeColor',[.7 .7 .7], ...
                    'LineStyle', '--');

                for jj = 4

                    for kk = 1:app.coilStruct.corners_per_coil
                        this_corner_line = app.coilStruct.coil_corner_data.coils{jj}.corner{kk}.points;

                        corner_line_x = this_corner_line(1:end,1);
                        corner_line_y = this_corner_line(1:end,2);
                        corner_line_z = this_corner_line(1:end,3);

                        plot3(corner_line_x, corner_line_y, corner_line_z, ...
                            '-', 'Color', 'k', 'Linewidth', 1);
                    end
                end

                % interference points
                plot3(app.interferencePoints_cp{cp_ind_0}.X, ...
                    app.interferencePoints_cp{cp_ind_0}.Y, ...
                    app.interferencePoints_cp{cp_ind_0}.Z, 'b+', ...
                    'LineWidth', 2);
                plot3(app.interferencePoints_cp{cp_ind_1}.X, ...
                    app.interferencePoints_cp{cp_ind_1}.Y, ...
                    app.interferencePoints_cp{cp_ind_1}.Z, 'b+', ...
                    'LineWidth', 2);
                for jj = ip_ind_0:ip_ind_1
                    plot3(app.interferencePoints_ip{jj}.X, ...
                        app.interferencePoints_ip{jj}.Y, ...
                        app.interferencePoints_ip{jj}.Z, 'm+', ...
                        'LineWidth', 2);

                end
            end
            axis equal;
            axis tight;
            xlabel('X  (m)');
            ylabel('Y  (m)');
            zlabel('Z  (m)');
            set(gca, 'FontSize', 18);
            set(gcf, 'Position', [20 20 800 800]);
            alpha(0.8)


            % interference points
            % cloud of coils points at control grid angles
            fh = figure;
            box on; hold on; grid on; axis equal;view(3);
            for ii = 5   % :(app.numberOfToroidalControlPointsEditField.Value - 1)
                cp_ind_0 = ii;
                cp_ind_1 = ii+1;
                ip_ind_0 = 1+(ii-1)*(app.numberOfintermedplanesbtwntorcntrlptsEditField.Value);
                ip_ind_1 = ip_ind_0 + app.numberOfintermedplanesbtwntorcntrlptsEditField.Value-1;

                %for ii = 1:5
                loft_ind_0 = 1+(ii-1)*(app.numberOfintermedplanesbtwntorcntrlptsEditField.Value+1);
                loft_ind_1 = loft_ind_0 + app.numberOfintermedplanesbtwntorcntrlptsEditField.Value+1;

                surf(lofted_wall.X(loft_ind_0:loft_ind_1, :), ...
                    lofted_wall.Y(loft_ind_0:loft_ind_1, :), ...
                    lofted_wall.Z(loft_ind_0:loft_ind_1, :), ...
                    'FaceColor', [.9 .9 .9], 'EdgeColor',[.7 .7 .7], ...
                    'LineStyle', '-');

                for jj = 4

                    for kk = 1:app.coilStruct.corners_per_coil
                        this_corner_line = app.coilStruct.coil_corner_data.coils{jj}.corner{kk}.points;

                        corner_line_x = this_corner_line(1:end,1);
                        corner_line_y = this_corner_line(1:end,2);
                        corner_line_z = this_corner_line(1:end,3);

                        plot3(corner_line_x, corner_line_y, corner_line_z, ...
                            '-', 'Color', 'k', 'Linewidth', 1);
                    end
                end

                % interference points
                plot3(app.interferencePoints_cp{cp_ind_0}.X, ...
                    app.interferencePoints_cp{cp_ind_0}.Y, ...
                    app.interferencePoints_cp{cp_ind_0}.Z, 'b+', ...
                    'LineWidth', 2);
                plot3(app.interferencePoints_cp{cp_ind_1}.X, ...
                    app.interferencePoints_cp{cp_ind_1}.Y, ...
                    app.interferencePoints_cp{cp_ind_1}.Z, 'b+', ...
                    'LineWidth', 2);
                for jj = ip_ind_0:ip_ind_1
                    plot3(app.interferencePoints_ip{jj}.X, ...
                        app.interferencePoints_ip{jj}.Y, ...
                        app.interferencePoints_ip{jj}.Z, 'm+', ...
                        'LineWidth', 2);

                end
            end
            axis equal;
            axis tight;
            xlabel('X  (m)');
            ylabel('Y  (m)');
            zlabel('Z  (m)');
            set(gca, 'FontSize', 18);
            set(gcf, 'Position', [20 20 800 800]);
            alpha(0.2)
            view(2)


            % Control points, curves, splines - contour at 1 toroidal
            % angle, the 'offset' curve and lofted contour
            ii = 5;
            if (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'spline'))
                delta_ctrl_spline = spline([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                    app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                    [app.spl_ctrl_pts(ii,2:(end-1)) app.spl_ctrl_pts(ii,:) ...
                    app.spl_ctrl_pts(ii,2:(end-1))], ...
                    app.vmec_control_points_blowup.theta_mesh(ii,:));
            elseif (strcmpi(app.PoloidalInterpoloationButtonGroup.SelectedObject.Text, 'pchip'))
                delta_ctrl_spline = pchip([app.pol_angles_ctrl_pts(2:(end-1))-2*pi ...
                    app.pol_angles_ctrl_pts app.pol_angles_ctrl_pts(2:(end-1))+2*pi], ...
                    [app.spl_ctrl_pts(ii,2:(end-1)) app.spl_ctrl_pts(ii,:) ...
                    app.spl_ctrl_pts(ii,2:(end-1))], ...
                    app.vmec_control_points_blowup.theta_mesh(ii,:));
            end
            ctrl_spline_contour = app.build_exploded_contour(...
                app.vmec_control_points_blowup.X(ii,:), ...
                app.vmec_control_points_blowup.Y(ii,:), ...
                app.vmec_control_points_blowup.Z(ii,:), ...
                app.vmec_control_normals_blowup_cyl.normal_x(ii,:), ...
                app.vmec_control_normals_blowup_cyl.normal_y(ii,:), ...
                app.vmec_control_normals_blowup_cyl.normal_z(ii,:), ...
                delta_ctrl_spline);

            figure
            box on; hold on; grid on;

            plot(app.pol_angles_ctrl_pts, app.spl_ctrl_pts(ii,:), 'ko')
            plot(app.vmec_control_points_blowup.theta_mesh(ii,:), delta_ctrl_spline, 'k.')
            xlabel('\theta  (radians)')
            ylabel('Loft Distance  (cm)')
            legend('Control Points', 'Dense Poloidal Set')
            xlim([0 2*pi]);
            ylim([-0.01 0.1]);
            legend boxoff
            make_my_plot_pretty3

            fh = figure;
            box on; hold on; grid on; axis equal;view(3);
            for ii = 5   % :(app.numberOfToroidalControlPointsEditField.Value - 1)
                cp_ind_0 = ii;
                cp_ind_1 = ii+1;
                ip_ind_0 = 1+(ii-1)*(app.numberOfintermedplanesbtwntorcntrlptsEditField.Value);
                ip_ind_1 = ip_ind_0 + app.numberOfintermedplanesbtwntorcntrlptsEditField.Value-1;

                loft_ind_0 = 1+(ii-1)*(app.numberOfintermedplanesbtwntorcntrlptsEditField.Value+1);
                loft_ind_1 = loft_ind_0 + app.numberOfintermedplanesbtwntorcntrlptsEditField.Value+1;


                plot3(cgp.X(cp_ind_0:cp_ind_1, :), cgp.Y(cp_ind_0:cp_ind_1, :), ...
                    cgp.Z(cp_ind_0:cp_ind_1, :), 'b.', 'MarkerSize', 32)
                plot3(cgp_bu.X(cp_ind_0:cp_ind_1, :), cgp_bu.Y(cp_ind_0:cp_ind_1, :), ...
                    cgp_bu.Z(cp_ind_0:cp_ind_1, :), 'b.', 'MarkerSize', 12)


                plot3(igp.X(ip_ind_0:ip_ind_1, :), igp.Y(ip_ind_0:ip_ind_1, :), ...
                    igp.Z(ip_ind_0:ip_ind_1, :), 'm.', 'MarkerSize', 32)

                plot3(igp_bu.X(ip_ind_0:ip_ind_1, :), igp_bu.Y(ip_ind_0:ip_ind_1, :), ...
                    igp_bu.Z(ip_ind_0:ip_ind_1, :), 'm.', 'MarkerSize', 12)

                surf(lofted_wall.X(loft_ind_0:loft_ind_1, :), ...
                    lofted_wall.Y(loft_ind_0:loft_ind_1, :), ...
                    lofted_wall.Z(loft_ind_0:loft_ind_1, :), ...
                    'FaceColor', [.8 .8 .8], 'EdgeColor',[.7 .7 .7], ...
                    'LineStyle', '--');


                axis equal;
                axis tight;
                xlabel('X  (m)');
                ylabel('Y  (m)');
                zlabel('Z  (m)');
                set(gca, 'FontSize', 18);
                set(gcf, 'Position', [20 20 800 800]);
                alpha(0.8)

            end


        end

        % Button pushed function: MagicButton
        function MagicButtonPushed(app, event)
            app.InitialStepSizeEditField.Value = -0.03;
            pause(1);
            app.AutoLoftButtonPushed(app)
            data_filename = 'stelloft_temp_3cm.mat';
            spline_data.spline_control_loft_values = app.spl_ctrl_pts;
            save(data_filename, "spline_data", '-mat');

            app.InitialStepSizeEditField.Value = -0.01;
            pause(1);
            app.AutoLoftButtonPushed(app)
            data_filename = 'stelloft_temp_1cm.mat';
            spline_data.spline_control_loft_values = app.spl_ctrl_pts;
            save(data_filename, "spline_data", '-mat');

            app.InitialStepSizeEditField.Value = -0.005;
            pause(1);
            app.AutoLoftButtonPushed(app)
            data_filename = 'stelloft_temp_5mm.mat';
            spline_data.spline_control_loft_values = app.spl_ctrl_pts;
            save(data_filename, "spline_data", '-mat');

            app.InitialStepSizeEditField.Value = -0.002;
            pause(1);
            app.AutoLoftButtonPushed(app)
            data_filename = 'stelloft_temp_2mm.mat';
            spline_data.spline_control_loft_values = app.spl_ctrl_pts;
            save(data_filename, "spline_data", '-mat');

            app.InitialStepSizeEditField.Value = -0.001;
            pause(1);
            app.AutoLoftButtonPushed(app)
            data_filename = 'stelloft_temp_1mm.mat';
            spline_data.spline_control_loft_values = app.spl_ctrl_pts;
            save(data_filename, "spline_data", '-mat');

        end

        % Button pushed function: 
        % PlotInterferencePointsatControlPlanesButton
        function PlotInterferencePointsatControlPlanesButtonPushed(app, event)
            for ii = 1:app.numberOfToroidalControlPointsEditField.Value
                plot3(app.UIAxes, app.interferencePoints_trace_cp{ii}.X, ...
                    app.interferencePoints_trace_cp{ii}.Y, ...
                    app.interferencePoints_trace_cp{ii}.Z, 'r.')


            end
        end

        % Button pushed function: ReSpaceTraceInterferencePointsButton
        function ReSpaceTraceInterferencePointsButtonPushed(app, event)

        end

        % Button pushed function: 
        % ExportManyNESCOILstylewindingsurfacesButton
        function ExportManyNESCOILstylewindingsurfacesButtonPushed(app, event)
            theMarray = str2num(app.MMAXArrayEditField.Value);
            theNarray = str2num(app.NMAXArrayEditField.Value);
            theNtolarray = str2num(app.NESCOILToleranceArrayEditField.Value);
            for ii = 1:size(theMarray)
                app.NESCOILMMAXEditField.set('Value', theMarray(ii));
                app.NESCOILNMAXEditField.set('Value', theNarray(ii));
                app.NESCOILToleranceEditField.set('Value', theNtolarray(ii));
                text_value = strcat(str2num(app.NESCOILFilenameBaseEditField.Value), ...
                    '_', 'M_', num2str(app.NESCOILMMAXEditField.Value), ...
                    '_N_', num2str(app.NESCOILNMAXEditField.Value), ...
                    '_tol_', num2str(app.NESCOILToleranceEditField.Value));
                app.NESCOILFilenameEditField.set('Value', text_value);
                app.ExportNESCOILstylewindingsurfaceButton();


            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Stelloftv00aUIFigure = uifigure('Visible', 'off');
            app.Stelloftv00aUIFigure.Name = 'Stelloft v0.0a';
            app.Stelloftv00aUIFigure.Icon = 'Stelloft.png';
            app.Toolbar = uitoolbar(app.Stelloftv00aUIFigure);
            app.UIAxes = uiaxes(app.Stelloftv00aUIFigure);
            title(app.UIAxes, 'Lofted surface')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.Stelloftv00aUIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.Stelloftv00aUIFigure);
            title(app.UIAxes3, 'Loft Values at Control Points')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.InfoStatusPanel = uipanel(app.Stelloftv00aUIFigure);
            app.InfoStatusPanel.Title = 'Info & Status';
            app.CoilFileLabel = uilabel(app.InfoStatusPanel);
            app.CoilFileLabel.Text = 'Coil File:';
            app.AxisLCFSFilename = uilabel(app.InfoStatusPanel);
            app.AxisLCFSFilename.Tag = 'AxisLCFSFilename';
            app.AxisLCFSFilename.Text = '';
            app.AxisLCFSFIleLabel = uilabel(app.InfoStatusPanel);
            app.AxisLCFSFIleLabel.Text = {'Axis & '; 'LCFS FIle:'};
            app.CoilFilename = uilabel(app.InfoStatusPanel);
            app.CoilFilename.Tag = 'coilFilename';
            app.CoilFilename.Text = '';
            app.CoilsLoadedLabel = uilabel(app.InfoStatusPanel);
            app.CoilsLoadedLabel.Text = {'Coils'; 'Loaded'};
            app.CoilsLoadedLamp = uilamp(app.InfoStatusPanel);
            app.LCFSLoadedLabel = uilabel(app.InfoStatusPanel);
            app.LCFSLoadedLabel.Text = {'LCFS'; 'Loaded'};
            app.LCFSLoadedLamp = uilamp(app.InfoStatusPanel);
            app.GridsDefinedLabel = uilabel(app.InfoStatusPanel);
            app.GridsDefinedLabel.Text = {'Grids'; 'Defined'};
            app.GridsDefinedLamp = uilamp(app.InfoStatusPanel);
            app.InterferencePointsDefinedLabel = uilabel(app.InfoStatusPanel);
            app.InterferencePointsDefinedLabel.Text = {'Interference'; 'Points Defined'};
            app.InterferencePointsDefinedLamp = uilamp(app.InfoStatusPanel);
            app.LoftedSurfaceInitializedLabel = uilabel(app.InfoStatusPanel);
            app.LoftedSurfaceInitializedLabel.Text = {'Lofted Surface'; 'Initialized'};
            app.LoftedSurfaceInitializedLamp = uilamp(app.InfoStatusPanel);
            app.AutoLoftingLampLabel = uilabel(app.InfoStatusPanel);
            app.AutoLoftingLampLabel.Text = 'Auto-Lofting';
            app.AutoLoftingLamp = uilamp(app.InfoStatusPanel);
            app.CoilCornerFileLabel = uilabel(app.InfoStatusPanel);
            app.CoilCornerFileLabel.Text = 'Coil Corner File:';
            app.CoilCornerFilename = uilabel(app.InfoStatusPanel);
            app.CoilCornerFilename.Tag = 'coilFilename';
            app.CoilCornerFilename.Text = '';
            app.CoilCornersLoadedLabel = uilabel(app.InfoStatusPanel);
            app.CoilCornersLoadedLabel.Text = {'Coil Corners'; 'Loaded'};
            app.CoilCornersLoadedLamp = uilamp(app.InfoStatusPanel);
            app.StatusPanel = uipanel(app.InfoStatusPanel);
            app.StatusPanel.Title = 'Status';
            app.GoodLampLabel = uilabel(app.StatusPanel);
            app.GoodLampLabel.Text = 'Good';
            app.GoodLamp = uilamp(app.StatusPanel);
            app.BadLampLabel = uilabel(app.StatusPanel);
            app.BadLampLabel.Text = 'Bad';
            app.BadLamp = uilamp(app.StatusPanel);
            app.NotDefLampLabel = uilabel(app.StatusPanel);
            app.NotDefLampLabel.Text = 'Not Def';
            app.NotDefLamp = uilamp(app.StatusPanel);
            app.OtherLabel = uilabel(app.StatusPanel);
            app.OtherLabel.Text = 'Other      /';
            app.OtherLamp = uilamp(app.StatusPanel);
            app.OtherLamp_2 = uilamp(app.StatusPanel);
            app.TraceFolderorFileLabel = uilabel(app.InfoStatusPanel);
            app.TraceFolderorFileLabel.Text = 'Trace Folder or File:';
            app.TraceFileFolder = uilabel(app.InfoStatusPanel);
            app.TraceFileFolder.Text = 'Label2';
            app.TraceLoadedLampLabel = uilabel(app.InfoStatusPanel);
            app.TraceLoadedLampLabel.Text = {'Trace'; 'Loaded'};
            app.TraceLoadedLamp = uilamp(app.InfoStatusPanel);
            app.NESCLoadedLampLabel = uilabel(app.InfoStatusPanel);
            app.NESCLoadedLampLabel.Text = {'NESC'; 'Loaded'};
            app.NESCLoadedLamp = uilamp(app.InfoStatusPanel);
            app.NESCFileLabel = uilabel(app.InfoStatusPanel);
            app.NESCFileLabel.Text = 'NESC File:';
            app.NESCFilenameLabel = uilabel(app.InfoStatusPanel);
            app.NESCFilenameLabel.Tag = 'coilFilename';
            app.NESCFilenameLabel.Text = '';
            app.PlotPlasmaButton = uibutton(app.Stelloftv00aUIFigure, 'push');
            app.PlotPlasmaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPlasmaButtonPushed, true);
            app.PlotPlasmaButton.Enable = 'off';
            app.PlotPlasmaButton.Text = 'Plot Plasma';
            app.PlotCoilsButton = uibutton(app.Stelloftv00aUIFigure, 'push');
            app.PlotCoilsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCoilsButtonPushed, true);
            app.PlotCoilsButton.Text = 'Plot Coils';
            app.TabGroup = uitabgroup(app.Stelloftv00aUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CoilsMHDTab = uitab(app.TabGroup);
            app.CoilsMHDTab.Title = 'Coils & MHD';
            app.MachineButtonGroup = uibuttongroup(app.CoilsMHDTab);
            app.MachineButtonGroup.Title = 'Machine ';
            app.WISTELLAATENButton = uiradiobutton(app.MachineButtonGroup);
            app.WISTELLAATENButton.Text = 'WISTELL A (ATEN)';
            app.HSXButton = uiradiobutton(app.MachineButtonGroup);
            app.HSXButton.Text = 'HSX';
            app.OtherselectLIBSTELLcoilsfileButton = uiradiobutton(app.MachineButtonGroup);
            app.OtherselectLIBSTELLcoilsfileButton.Text = 'Other (select LIBSTELL coils file)';
            app.OtherselectLIBSTELLcoilsfileButton.Value = true;
            app.ImportCoilCornersGeometryorcoilfilamentGeometryButton = uibutton(app.CoilsMHDTab, 'push');
            app.ImportCoilCornersGeometryorcoilfilamentGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @ImportCoilCornersGeometryorcoilfilamentGeometryButtonPushed, true);
            app.ImportCoilCornersGeometryorcoilfilamentGeometryButton.Text = {'Import Coil "Corners" Geometry'; '(or coil filament Geometry)'};
            app.ImportAxisandLCFSGeometryLoadVMECoutoutfilewout_ncButton = uibutton(app.CoilsMHDTab, 'push');
            app.ImportAxisandLCFSGeometryLoadVMECoutoutfilewout_ncButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAxisandLCFSGeometryLoadVMECoutoutfilewout_ncButtonPushed, true);
            app.ImportAxisandLCFSGeometryLoadVMECoutoutfilewout_ncButton.Text = {'Import Axis and LCFS Geometry'; '(Load VMEC outout file: ''wout_*.nc'' )'};
            app.UseWithoutCoilsLabel = uilabel(app.CoilsMHDTab);
            app.UseWithoutCoilsLabel.Text = 'Use Without Coils?';
            app.UseWithoutCoilsSwitch = uiswitch(app.CoilsMHDTab, 'slider');
            app.UseWithoutCoilsSwitch.Items = {'No', 'Yes'};
            app.UseWithoutCoilsSwitch.ValueChangedFcn = createCallbackFcn(app, @UseWithoutCoilsSwitchValueChanged, true);
            app.UseWithoutCoilsSwitch.Value = 'No';
            app.ImportNESCSurfaceButton = uibutton(app.CoilsMHDTab, 'push');
            app.ImportNESCSurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @ImportNESCSurfaceButtonPushed, true);
            app.ImportNESCSurfaceButton.Enable = 'off';
            app.ImportNESCSurfaceButton.Visible = 'off';
            app.ImportNESCSurfaceButton.Text = 'Import NESC Surface';
            app.UseNESCinsteadofWoutforLoftingSwitchLabel = uilabel(app.CoilsMHDTab);
            app.UseNESCinsteadofWoutforLoftingSwitchLabel.Enable = 'off';
            app.UseNESCinsteadofWoutforLoftingSwitchLabel.Text = 'Use NESC instead of Wout for Lofting';
            app.UseNESCinsteadofWoutforLoftingSwitch = uiswitch(app.CoilsMHDTab, 'slider');
            app.UseNESCinsteadofWoutforLoftingSwitch.Enable = 'off';
            app.TraceImportTab = uitab(app.TabGroup);
            app.TraceImportTab.Title = 'Trace Import';
            app.ImportSingleTraceButton = uibutton(app.TraceImportTab, 'push');
            app.ImportSingleTraceButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSingleTraceButtonPushed, true);
            app.ImportSingleTraceButton.Text = 'Import Single Trace';
            app.rhomaskminEditFieldLabel = uilabel(app.TraceImportTab);
            app.rhomaskminEditFieldLabel.Text = 'rho  mask (min)';
            app.rhomaskminEditField = uieditfield(app.TraceImportTab, 'numeric');
            app.rhomaskminEditField.ValueDisplayFormat = '%11.4f';
            app.rhomaskminEditField.Value = 1.065;
            app.ImportManyTracesButton = uibutton(app.TraceImportTab, 'push');
            app.ImportManyTracesButton.ButtonPushedFcn = createCallbackFcn(app, @ImportManyTracesButtonPushed, true);
            app.ImportManyTracesButton.Text = 'Import Many Traces';
            app.ClearIniializeTraceDataButton = uibutton(app.TraceImportTab, 'push');
            app.ClearIniializeTraceDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearIniializeTraceDataButtonPushed, true);
            app.ClearIniializeTraceDataButton.Text = 'Clear / Iniialize Trace Data';
            app.SaveTraceInformationButton = uibutton(app.TraceImportTab, 'push');
            app.SaveTraceInformationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTraceInformationButtonPushed, true);
            app.SaveTraceInformationButton.Text = 'Save Trace Information';
            app.LoadTraceInformationButton = uibutton(app.TraceImportTab, 'push');
            app.LoadTraceInformationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTraceInformationButtonPushed, true);
            app.LoadTraceInformationButton.Text = 'Load Trace Information';
            app.InverseDensityTolEditFieldLabel = uilabel(app.TraceImportTab);
            app.InverseDensityTolEditFieldLabel.Text = 'Inverse Density Tol';
            app.InverseDensityTolEditField = uieditfield(app.TraceImportTab, 'numeric');
            app.InverseDensityTolEditField.ValueDisplayFormat = '%11.6f';
            app.InverseDensityTolEditField.Value = 0.001;
            app.TraceIndexListEditFieldLabel = uilabel(app.TraceImportTab);
            app.TraceIndexListEditFieldLabel.Text = {'Trace Index'; ' List'};
            app.TraceIndexListEditField = uieditfield(app.TraceImportTab, 'text');
            app.TraceIndexListEditField.Value = '8   12  15  16  20  23  26  31  37  38  44  47  48  50  53  58  60  68    71  74  77  78  79  80  82  83  84  85  89  94  101 102 104 107 108 109    111 116 118 120 123 124 125 129 130 131 132 135 136 138 140 141 146 148  152 153 154 157 158 160 162 164 173 176 177 178 179 181 183 184 190 191 193 194 24 32 33 39 41 42 106 143 151 168 29 70 119 167 170 67 72 98 171 3 43 180 189';
            app.GridDefinitionsTab = uitab(app.TabGroup);
            app.GridDefinitionsTab.Title = 'Grid Definitions';
            app.LoadGridDefinitionsButton = uibutton(app.GridDefinitionsTab, 'push');
            app.LoadGridDefinitionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGridDefinitionsButtonPushed, true);
            app.LoadGridDefinitionsButton.Text = 'Load Grid Definitions';
            app.SaveGridDefinitionsButton = uibutton(app.GridDefinitionsTab, 'push');
            app.SaveGridDefinitionsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGridDefinitionsButtonPushed, true);
            app.SaveGridDefinitionsButton.Text = 'Save Grid Definitions';
            app.Phi_StartdegreesEditFieldLabel = uilabel(app.GridDefinitionsTab);
            app.Phi_StartdegreesEditFieldLabel.Text = 'Phi_{Start} (degrees)';
            app.Phi_StartdegreesEditField = uieditfield(app.GridDefinitionsTab, 'numeric');
            app.Phi_StartdegreesEditField.ValueChangedFcn = createCallbackFcn(app, @Phi_StartdegreesEditFieldValueChanged, true);
            app.Phi_EnddegreesEditFieldLabel = uilabel(app.GridDefinitionsTab);
            app.Phi_EnddegreesEditFieldLabel.Text = 'Phi_{End} (degrees)';
            app.Phi_EnddegreesEditField = uieditfield(app.GridDefinitionsTab, 'numeric');
            app.Phi_EnddegreesEditField.Value = 30;
            app.ofToroidalControlPointsEditFieldLabel = uilabel(app.GridDefinitionsTab);
            app.ofToroidalControlPointsEditFieldLabel.Text = '# of Toroidal Control Points';
            app.numberOfToroidalControlPointsEditField = uieditfield(app.GridDefinitionsTab, 'numeric');
            app.numberOfToroidalControlPointsEditField.ValueChangedFcn = createCallbackFcn(app, @numberOfToroidalControlPointsEditFieldValueChanged, true);
            app.numberOfToroidalControlPointsEditField.Value = 11;
            app.ofPoloidalControlPointsEditFieldLabel = uilabel(app.GridDefinitionsTab);
            app.ofPoloidalControlPointsEditFieldLabel.Text = '# of Poloidal Control Points';
            app.numberOfPoloidalControlPointsEditField = uieditfield(app.GridDefinitionsTab, 'numeric');
            app.numberOfPoloidalControlPointsEditField.Value = 7;
            app.ofintermediateanglesplanesbetweencontrolpointsEditFieldLabel = uilabel(app.GridDefinitionsTab);
            app.ofintermediateanglesplanesbetweencontrolpointsEditFieldLabel.Text = '# of intermediate angles (planes) between toroidal control points';
            app.numberOfintermedplanesbtwntorcntrlptsEditField = uieditfield(app.GridDefinitionsTab, 'numeric');
            app.numberOfintermedplanesbtwntorcntrlptsEditField.ValueChangedFcn = createCallbackFcn(app, @numberOfintermedplanesbtwntorcntrlptsEditFieldValueChanged, true);
            app.numberOfintermedplanesbtwntorcntrlptsEditField.Value = 35;
            app.ofpoloidalpointsintheloftedsurfacesEditFieldLabel = uilabel(app.GridDefinitionsTab);
            app.ofpoloidalpointsintheloftedsurfacesEditFieldLabel.Text = '# of poloidal points in the lofted surfaces';
            app.numberOfpoloidalpointsintheloftedsurfacesEditField = uieditfield(app.GridDefinitionsTab, 'numeric');
            app.numberOfpoloidalpointsintheloftedsurfacesEditField.Value = 361;
            app.ReferenceRadiusNormalizedToroidalFluxLabel = uilabel(app.GridDefinitionsTab);
            app.ReferenceRadiusNormalizedToroidalFluxLabel.Text = {'Reference Radius '; '(Normalized Toroidal Flux)'};
            app.ReferenceRadiusNormalizedToroidalFluxEditField = uieditfield(app.GridDefinitionsTab, 'numeric');
            app.ReferenceRadiusNormalizedToroidalFluxEditField.Limits = [0 1];
            app.ReferenceRadiusNormalizedToroidalFluxEditField.ValueDisplayFormat = '%6.4f';
            app.ReferenceRadiusNormalizedToroidalFluxEditField.Value = 1;
            app.IndicesofCoilstoUseforInterferenceChecksLabel = uilabel(app.GridDefinitionsTab);
            app.IndicesofCoilstoUseforInterferenceChecksLabel.Text = {'Indices of Coils to Use'; 'for Interference Checks'};
            app.IndicesofCoilstoUseforInterferenceChecksEditField = uieditfield(app.GridDefinitionsTab, 'text');
            app.IndicesofCoilstoUseforInterferenceChecksEditField.Value = '1 2 12 13 14 24 25 36 37 38 48 49 60';
            app.InitialLoftOffsetEditFieldLabel = uilabel(app.GridDefinitionsTab);
            app.InitialLoftOffsetEditFieldLabel.Text = 'Initial Loft Offset';
            app.InitialLoftOffsetEditField = uieditfield(app.GridDefinitionsTab, 'numeric');
            app.InitialLoftOffsetEditField.Value = 0.07;
            app.InitialStepSizeEditFieldLabel = uilabel(app.GridDefinitionsTab);
            app.InitialStepSizeEditFieldLabel.Text = 'Initial Step Size';
            app.InitialStepSizeEditField = uieditfield(app.GridDefinitionsTab, 'numeric');
            app.InitialStepSizeEditField.Value = 0.01;
            app.NumberofToroidalGridpointsintheloftedsurfaceEditFieldLabel = uilabel(app.GridDefinitionsTab);
            app.NumberofToroidalGridpointsintheloftedsurfaceEditFieldLabel.Text = {'Number of Toroidal Grid'; 'points in the lofted surface'};
            app.NumberofToroidalGridpointsintheloftedsurfaceEditField = uieditfield(app.GridDefinitionsTab, 'numeric');
            app.NumberofToroidalGridpointsintheloftedsurfaceEditField.Editable = 'off';
            app.NumberofToroidalGridpointsintheloftedsurfaceEditField.Value = 361;
            app.InitilizationTab = uitab(app.TabGroup);
            app.InitilizationTab.Title = 'Initilization';
            app.DefineControlandIntermediateSurfaceGridsButton = uibutton(app.InitilizationTab, 'push');
            app.DefineControlandIntermediateSurfaceGridsButton.ButtonPushedFcn = createCallbackFcn(app, @DefineControlandIntermediateSurfaceGridsButtonPushed, true);
            app.DefineControlandIntermediateSurfaceGridsButton.Text = {'Define Control and '; 'Intermediate Surface Grids '};
            app.InitializeLoftedSurfaceButton = uibutton(app.InitilizationTab, 'push');
            app.InitializeLoftedSurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeLoftedSurfaceButtonPushed, true);
            app.InitializeLoftedSurfaceButton.Text = 'Initialize Lofted Surface';
            app.DefineControlandIntermediateSurfaceGridsButton_2 = uibutton(app.InitilizationTab, 'push');
            app.DefineControlandIntermediateSurfaceGridsButton_2.ButtonPushedFcn = createCallbackFcn(app, @DefineControlandIntermediateSurfaceGridsButton_2Pushed, true);
            app.DefineControlandIntermediateSurfaceGridsButton_2.Text = 'Define Interference Points';
            app.SaveInterferencePointsButton = uibutton(app.InitilizationTab, 'push');
            app.SaveInterferencePointsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInterferencePointsButtonPushed, true);
            app.SaveInterferencePointsButton.Text = 'Save Interference Points';
            app.LoadInterferencePointsButton = uibutton(app.InitilizationTab, 'push');
            app.LoadInterferencePointsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInterferencePointsButtonPushed, true);
            app.LoadInterferencePointsButton.Text = 'Load Interference Points';
            app.PlotInterferencePointsatControlPlanesButton = uibutton(app.InitilizationTab, 'push');
            app.PlotInterferencePointsatControlPlanesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInterferencePointsatControlPlanesButtonPushed, true);
            app.PlotInterferencePointsatControlPlanesButton.Text = 'Plot Interference Points at Control Planes';
            app.ReSpaceTraceInterferencePointsButton = uibutton(app.InitilizationTab, 'push');
            app.ReSpaceTraceInterferencePointsButton.ButtonPushedFcn = createCallbackFcn(app, @ReSpaceTraceInterferencePointsButtonPushed, true);
            app.ReSpaceTraceInterferencePointsButton.Text = 'ReSpace Trace Interference Points';
            app.SaveonDefineCheckBox = uicheckbox(app.InitilizationTab);
            app.SaveonDefineCheckBox.Text = 'Save on Define';
            app.InterpolationTab = uitab(app.TabGroup);
            app.InterpolationTab.Title = 'Interpolation';
            app.PoloidalInterpoloationButtonGroup = uibuttongroup(app.InterpolationTab);
            app.PoloidalInterpoloationButtonGroup.Title = 'Poloidal Interpoloation';
            app.SplineButton = uiradiobutton(app.PoloidalInterpoloationButtonGroup);
            app.SplineButton.Text = 'Spline';
            app.pchipButton = uiradiobutton(app.PoloidalInterpoloationButtonGroup);
            app.pchipButton.Text = 'pchip';
            app.pchipButton.Value = true;
            app.alphadeltaButton = uiradiobutton(app.PoloidalInterpoloationButtonGroup);
            app.alphadeltaButton.Text = '(alpha,delta)';
            app.alphaEditFieldLabel = uilabel(app.PoloidalInterpoloationButtonGroup);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.PoloidalInterpoloationButtonGroup, 'numeric');
            app.deltaEditFieldLabel = uilabel(app.PoloidalInterpoloationButtonGroup);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.PoloidalInterpoloationButtonGroup, 'numeric');
            app.EnforceStellaratorSymmetryatPhi0and2piNFPCheckBox = uicheckbox(app.PoloidalInterpoloationButtonGroup);
            app.EnforceStellaratorSymmetryatPhi0and2piNFPCheckBox.Enable = 'off';
            app.EnforceStellaratorSymmetryatPhi0and2piNFPCheckBox.Text = {'Enforce Stellarator Symmetry at '; 'Phi = (0 and 2 * pi / NFP)'};
            app.EnforceStellaratorSymmetryatPhi0and2piNFPCheckBox.Value = true;
            app.ToroidalInterpolationButtonGroup = uibuttongroup(app.InterpolationTab);
            app.ToroidalInterpolationButtonGroup.Title = 'Toroidal Interpolation';
            app.LinearInterpolationButton = uiradiobutton(app.ToroidalInterpolationButtonGroup);
            app.LinearInterpolationButton.Enable = 'off';
            app.LinearInterpolationButton.Text = 'Linear Interpolation';
            app.LinearInterpolationButton.Value = true;
            app.ResidueCoilsEditFieldLabel = uilabel(app.InterpolationTab);
            app.ResidueCoilsEditFieldLabel.Text = 'Residue (Coils)';
            app.ResidueCoilsEditField = uieditfield(app.InterpolationTab, 'numeric');
            app.ResidueCoilsEditField.ValueDisplayFormat = '%11.4f';
            app.ResidueCoilsEditField.Value = 5;
            app.ResidueTracesEditFieldLabel = uilabel(app.InterpolationTab);
            app.ResidueTracesEditFieldLabel.Text = 'Residue (Traces)';
            app.ResidueTracesEditField = uieditfield(app.InterpolationTab, 'numeric');
            app.ResidueTracesEditField.ValueDisplayFormat = '%11.4f';
            app.ResidueTracesEditField.Value = 5;
            app.LoftingTab = uitab(app.TabGroup);
            app.LoftingTab.Title = 'Lofting';
            app.LoadControlPointLoftValuesButton = uibutton(app.LoftingTab, 'push');
            app.LoadControlPointLoftValuesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadControlPointLoftValuesButtonPushed, true);
            app.LoadControlPointLoftValuesButton.Text = 'Load Control Point Loft Values';
            app.SaveControlPointLoftValuesButton = uibutton(app.LoftingTab, 'push');
            app.SaveControlPointLoftValuesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveControlPointLoftValuesButtonPushed, true);
            app.SaveControlPointLoftValuesButton.Text = 'Save Control Point Loft Values';
            app.PlotCurrentLoftedSurfaceButton = uibutton(app.LoftingTab, 'push');
            app.PlotCurrentLoftedSurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCurrentLoftedSurfaceButtonPushed, true);
            app.PlotCurrentLoftedSurfaceButton.Text = 'Plot Current Lofted Surface';
            app.CheckCurrentLoftedSurfaceButton = uibutton(app.LoftingTab, 'push');
            app.CheckCurrentLoftedSurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @CheckCurrentLoftedSurfaceButtonPushed, true);
            app.CheckCurrentLoftedSurfaceButton.Text = 'Check Current Lofted Surface';
            app.ManipulateSplinePointsInteractivelyButton = uibutton(app.LoftingTab, 'push');
            app.ManipulateSplinePointsInteractivelyButton.ButtonPushedFcn = createCallbackFcn(app, @ManipulateSplinePointsInteractivelyButtonPushed, true);
            app.ManipulateSplinePointsInteractivelyButton.Text = 'Manipulate Spline Points Interactively';
            app.AutoloftSettingsPanel = uipanel(app.LoftingTab);
            app.AutoloftSettingsPanel.Title = 'Autoloft Settings';
            app.MaxLoftDistanceEditFieldLabel = uilabel(app.AutoloftSettingsPanel);
            app.MaxLoftDistanceEditFieldLabel.Text = 'Max Loft Distance';
            app.MaxLoftDistanceEditField = uieditfield(app.AutoloftSettingsPanel, 'numeric');
            app.MaxLoftDistanceEditField.Value = 8;
            app.PostOptimizeBackoffEditFieldLabel = uilabel(app.AutoloftSettingsPanel);
            app.PostOptimizeBackoffEditFieldLabel.Text = 'Post-Optimize Backoff';
            app.PostOptimizeBackoffEditField = uieditfield(app.AutoloftSettingsPanel, 'numeric');
            app.MinimumLoftDistanceEditFieldLabel = uilabel(app.AutoloftSettingsPanel);
            app.MinimumLoftDistanceEditFieldLabel.Text = 'Minimum Loft Distance';
            app.MinimumLoftDistanceEditField = uieditfield(app.AutoloftSettingsPanel, 'numeric');
            app.EZEditPanel = uipanel(app.LoftingTab);
            app.EZEditPanel.Title = 'EZ-Edit';
            app.ToroidalIndexSpinnerLabel = uilabel(app.EZEditPanel);
            app.ToroidalIndexSpinnerLabel.Text = 'Toroidal Index';
            app.ToroidalIndexSpinner = uispinner(app.EZEditPanel);
            app.ToroidalIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @ToroidalIndexSpinnerValueChanged, true);
            app.ToroidalIndexSpinner.Value = 1;
            app.PoloidalIndexSpinnerLabel = uilabel(app.EZEditPanel);
            app.PoloidalIndexSpinnerLabel.Text = 'Poloidal Index';
            app.PoloidalIndexSpinner = uispinner(app.EZEditPanel);
            app.PoloidalIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @PoloidalIndexSpinnerValueChanged, true);
            app.PoloidalIndexSpinner.Value = 1;
            app.LoftControlPointSpinnerLabel = uilabel(app.EZEditPanel);
            app.LoftControlPointSpinnerLabel.Text = 'Loft @ Control Point';
            app.LoftControlPointSpinner = uispinner(app.EZEditPanel);
            app.LoftControlPointSpinner.Step = 0.0025;
            app.LoftControlPointSpinner.Limits = [0 Inf];
            app.LoftControlPointSpinner.ValueChangedFcn = createCallbackFcn(app, @LoftControlPointSpinnerValueChanged, true);
            app.EZLoftStepSizeEditFieldLabel = uilabel(app.EZEditPanel);
            app.EZLoftStepSizeEditFieldLabel.Text = 'EZ Loft Step Size';
            app.EZLoftStepSizeEditField = uieditfield(app.EZEditPanel, 'numeric');
            app.EZLoftStepSizeEditField.Value = 0.01;
            app.DebugLevelKnobLabel = uilabel(app.LoftingTab);
            app.DebugLevelKnobLabel.Text = 'Debug Level';
            app.DebugLevelKnob = uiknob(app.LoftingTab, 'discrete');
            app.DebugLevelKnob.Items = {'0', '1', '10', '11'};
            app.DebugLevelKnob.Value = '0';
            app.AutoLoftButton = uibutton(app.LoftingTab, 'push');
            app.AutoLoftButton.ButtonPushedFcn = createCallbackFcn(app, @AutoLoftButtonPushed, true);
            app.AutoLoftButton.Text = '=---> Auto-Loft <---=';
            app.PauseAutoLoftCheckBox = uicheckbox(app.LoftingTab);
            app.PauseAutoLoftCheckBox.Text = 'Pause AutoLoft';
            app.MagicButton = uibutton(app.LoftingTab, 'push');
            app.MagicButton.ButtonPushedFcn = createCallbackFcn(app, @MagicButtonPushed, true);
            app.MagicButton.Text = 'Magic';
            app.FLAREdotfilesTab = uitab(app.TabGroup);
            app.FLAREdotfilesTab.Title = 'FLARE dot files';
            app.bfieldsourceEditFieldLabel = uilabel(app.FLAREdotfilesTab);
            app.bfieldsourceEditFieldLabel.Text = '.bfield source';
            app.FLAREbfieldsourceEditField = uieditfield(app.FLAREdotfilesTab, 'text');
            app.FLAREbfieldsourceEditField.Value = 'bmw_hsx.nc';
            app.boundarydoclineEditFieldLabel = uilabel(app.FLAREdotfilesTab);
            app.boundarydoclineEditFieldLabel.Text = '.boundary docline';
            app.FLAREboundarydoclineEditField = uieditfield(app.FLAREdotfilesTab, 'text');
            app.FLAREboundarydoclineEditField.Value = '[torosurf_label:torosurf]';
            app.boundaryfilenameEditFieldLabel = uilabel(app.FLAREdotfilesTab);
            app.boundaryfilenameEditFieldLabel.Text = '.boundary filename';
            app.FLAREboundaryfilenameEditField = uieditfield(app.FLAREdotfilesTab, 'text');
            app.FLAREboundaryfilenameEditField.Value = 'wall.txt';
            app.FLAREboundarystellarator_symmetryCheckBox = uicheckbox(app.FLAREdotfilesTab);
            app.FLAREboundarystellarator_symmetryCheckBox.Enable = 'off';
            app.FLAREboundarystellarator_symmetryCheckBox.Text = '.boundary stellarator_symmetry';
            app.FLAREboundarystellarator_symmetryCheckBox.Value = true;
            app.boundaryunitsEditFieldLabel = uilabel(app.FLAREdotfilesTab);
            app.boundaryunitsEditFieldLabel.Text = '.boundary units';
            app.FLAREboundaryunitsEditField = uieditfield(app.FLAREdotfilesTab, 'text');
            app.FLAREboundaryunitsEditField.Value = 'meter';
            app.bfieldtypeLabel = uilabel(app.FLAREdotfilesTab);
            app.bfieldtypeLabel.Text = '.bfield type  ';
            app.FLAREbfieldtypeDropDown = uidropdown(app.FLAREdotfilesTab);
            app.FLAREbfieldtypeDropDown.Items = {'BMW', 'Coilset', 'Option 3', 'Option 4'};
            app.FLAREbfieldtypeDropDown.Value = 'BMW';
            app.equi3dsourceLabel = uilabel(app.FLAREdotfilesTab);
            app.equi3dsourceLabel.Text = '.equi3d source';
            app.equi3dsource = uidropdown(app.FLAREdotfilesTab);
            app.equi3dsource.Items = {'VMEC woutfile', 'Option 2', 'Option 3', 'Option 4'};
            app.equi3dsource.Value = 'VMEC woutfile';
            app.equi3doftoroidalpointsEditFieldLabel = uilabel(app.FLAREdotfilesTab);
            app.equi3doftoroidalpointsEditFieldLabel.Text = {'.equi3d # of'; 'toroidal points  '};
            app.equi3doftoroidalpointsEditField = uieditfield(app.FLAREdotfilesTab, 'numeric');
            app.equi3doftoroidalpointsEditField.Value = 360;
            app.ExportLcTab = uitab(app.TabGroup);
            app.ExportLcTab.Title = 'Export Lc';
            app.ExportFLAREWallLCGridsButton = uibutton(app.ExportLcTab, 'push');
            app.ExportFLAREWallLCGridsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFLAREWallLCGridsButtonPushed, true);
            app.ExportFLAREWallLCGridsButton.Text = 'Export FLARE Wall & LC Grids';
            app.OutputFolderPrefixLcScanLabel = uilabel(app.ExportLcTab);
            app.OutputFolderPrefixLcScanLabel.Text = 'Output Folder Prefix (Lc Scan)';
            app.OutputFolderPrefixLcScanEditField = uieditfield(app.ExportLcTab, 'text');
            app.stlLabel = uilabel(app.ExportLcTab);
            app.stlLabel.Text = '.stl';
            app.LcGridrhominEditFieldLabel = uilabel(app.ExportLcTab);
            app.LcGridrhominEditFieldLabel.Text = 'Lc Grid rho (min)';
            app.LcGridrhominEditField = uieditfield(app.ExportLcTab, 'numeric');
            app.LcGridrhominEditField.Value = 0.95;
            app.LcGridrhomaxEditFieldLabel = uilabel(app.ExportLcTab);
            app.LcGridrhomaxEditFieldLabel.Text = 'Lc Grid rho (max)';
            app.LcGridrhomaxEditField = uieditfield(app.ExportLcTab, 'numeric');
            app.LcGridrhomaxEditField.Value = 1.8;
            app.LcGridofdivisionsEditFieldLabel = uilabel(app.ExportLcTab);
            app.LcGridofdivisionsEditFieldLabel.Text = 'Lc Grid # of divisions';
            app.LcGridofdivisionsEditField = uieditfield(app.ExportLcTab, 'numeric');
            app.LcGridofdivisionsEditField.Value = 101;
            app.TorodalPlanesEditFieldLabel = uilabel(app.ExportLcTab);
            app.TorodalPlanesEditFieldLabel.Text = '# Torodal Planes';
            app.LcToroidalPlanesEditField = uieditfield(app.ExportLcTab, 'numeric');
            app.LcToroidalPlanesEditField.Value = 5;
            app.LcDiffusionCoefficientEditFieldLabel = uilabel(app.ExportLcTab);
            app.LcDiffusionCoefficientEditFieldLabel.Text = 'Lc Diffusion Coefficient';
            app.LcDiffusionCoefficientEditField = uieditfield(app.ExportLcTab, 'numeric');
            app.LaunchPlanesLocatedinButtonGroup = uibuttongroup(app.ExportLcTab);
            app.LaunchPlanesLocatedinButtonGroup.Title = 'Launch Planes Located in ...';
            app.SingleHalfPeriodButton_Lc = uiradiobutton(app.LaunchPlanesLocatedinButtonGroup);
            app.SingleHalfPeriodButton_Lc.Text = 'Single Half-Period';
            app.SinglePeriodButton_Lc = uiradiobutton(app.LaunchPlanesLocatedinButtonGroup);
            app.SinglePeriodButton_Lc.Text = 'Single Period';
            app.SinglePeriodButton_Lc.Value = true;
            app.FullMachineButton_Lc = uiradiobutton(app.LaunchPlanesLocatedinButtonGroup);
            app.FullMachineButton_Lc.Enable = 'off';
            app.FullMachineButton_Lc.Text = 'Full Machine';
            app.LcepsabsEditFieldLabel = uilabel(app.ExportLcTab);
            app.LcepsabsEditFieldLabel.Text = 'Lc epsabs';
            app.LcepsabsEditField = uieditfield(app.ExportLcTab, 'numeric');
            app.LcepsabsEditField.ValueDisplayFormat = '%16.12g';
            app.LcepsabsEditField.Value = 1e-10;
            app.MaximumLconewayLabel = uilabel(app.ExportLcTab);
            app.MaximumLconewayLabel.Text = {'Maximum Lc'; '(one way)'};
            app.MaximumLcLcEditField = uieditfield(app.ExportLcTab, 'numeric');
            app.MaximumLcLcEditField.Value = 10000;
            app.ExportStrikelineTab = uitab(app.TabGroup);
            app.ExportStrikelineTab.Title = 'Export Strikeline';
            app.ExportFLAREWallStrikelineGridsButton = uibutton(app.ExportStrikelineTab, 'push');
            app.ExportFLAREWallStrikelineGridsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFLAREWallStrikelineGridsButtonPushed, true);
            app.ExportFLAREWallStrikelineGridsButton.Text = 'Export FLARE Wall & Strikeline Grids';
            app.OutputFolderPrefixStrikelineScanLabel = uilabel(app.ExportStrikelineTab);
            app.OutputFolderPrefixStrikelineScanLabel.Text = 'Output Folder Prefix (Strikeline Scan)';
            app.OutputFolderPrefixStrikelineScanEditField = uieditfield(app.ExportStrikelineTab, 'text');
            app.PauseForMaskEditsSwitchLabel = uilabel(app.ExportStrikelineTab);
            app.PauseForMaskEditsSwitchLabel.Text = 'Pause For Mask Edits';
            app.PauseForMaskEditsSwitch = uiswitch(app.ExportStrikelineTab, 'slider');
            app.PauseForMaskEditsSwitch.Items = {'No', 'Yes'};
            app.PauseForMaskEditsSwitch.Value = 'No';
            app.StrikelineGridrhominEditFieldLabel = uilabel(app.ExportStrikelineTab);
            app.StrikelineGridrhominEditFieldLabel.Text = 'Strikeline Grid rho (min)';
            app.StrikelineGridrhominEditField = uieditfield(app.ExportStrikelineTab, 'numeric');
            app.StrikelineGridrhominEditField.Value = 0.95;
            app.StrikelineGridrhomaxEditFieldLabel = uilabel(app.ExportStrikelineTab);
            app.StrikelineGridrhomaxEditFieldLabel.Text = 'Strikeline Grid rho (max)';
            app.StrikelineGridrhomaxEditField = uieditfield(app.ExportStrikelineTab, 'numeric');
            app.StrikelineGridrhomaxEditField.Value = 0.99;
            app.StrikelineGridofdivisionsEditFieldLabel = uilabel(app.ExportStrikelineTab);
            app.StrikelineGridofdivisionsEditFieldLabel.Text = 'Strikeline Grid # of divisions';
            app.StrikelineGridofdivisionsEditField = uieditfield(app.ExportStrikelineTab, 'numeric');
            app.StrikelineGridofdivisionsEditField.Value = 5;
            app.WallLocation121LCFS2LoftedWal12InterpolationLabel = uilabel(app.ExportStrikelineTab);
            app.WallLocation121LCFS2LoftedWal12InterpolationLabel.Text = {'Wall Location  [1 - 2]'; '1 :  LCFS           '; '2 :  Lofted Wal   '; '1 < 2 :  Interpolation'};
            app.StrkelineWallLocationEditFIeld = uieditfield(app.ExportStrikelineTab, 'numeric');
            app.StrkelineWallLocationEditFIeld.Value = 2;
            app.StrikelineDiffusionCoefficientEditFieldLabel = uilabel(app.ExportStrikelineTab);
            app.StrikelineDiffusionCoefficientEditFieldLabel.Text = 'Strikeline Diffusion Coefficient';
            app.StrikelineDiffusionCoefficientEditField = uieditfield(app.ExportStrikelineTab, 'numeric');
            app.StrikelineDiffusionCoefficientEditField.ValueDisplayFormat = '%16.12g';
            app.StrikelineDiffusionCoefficientEditField.Value = 0.001;
            app.TorodalPlanesEditFieldLabel_2 = uilabel(app.ExportStrikelineTab);
            app.TorodalPlanesEditFieldLabel_2.Text = '# Torodal Planes';
            app.StrikelineToroidalPlanesEditField = uieditfield(app.ExportStrikelineTab, 'numeric');
            app.StrikelineToroidalPlanesEditField.Value = 5;
            app.StrikelineofpoloidalptsEditFieldLabel = uilabel(app.ExportStrikelineTab);
            app.StrikelineofpoloidalptsEditFieldLabel.Text = 'Strikeline # of poloidal pts';
            app.StrikelineNumofpoloidalptsEditField = uieditfield(app.ExportStrikelineTab, 'numeric');
            app.StrikelineNumofpoloidalptsEditField.Value = 3600;
            app.StriikelineepsabsEditFieldLabel = uilabel(app.ExportStrikelineTab);
            app.StriikelineepsabsEditFieldLabel.Text = 'Striikeline epsabs';
            app.StriikelineepsabsEditField = uieditfield(app.ExportStrikelineTab, 'numeric');
            app.StriikelineepsabsEditField.ValueDisplayFormat = '%16.12g';
            app.StriikelineepsabsEditField.Value = 1e-10;
            app.MaximumLcEditField_2Label = uilabel(app.ExportStrikelineTab);
            app.MaximumLcEditField_2Label.Text = 'Maximum Lc';
            app.MaximumLcStrikelineEditField = uieditfield(app.ExportStrikelineTab, 'numeric');
            app.MaximumLcStrikelineEditField.Value = 10000;
            app.LaunchPlanesLocatedinButtonGroup_strikeline = uibuttongroup(app.ExportStrikelineTab);
            app.LaunchPlanesLocatedinButtonGroup_strikeline.Title = 'Launch Planes Located in ...';
            app.SingleHalfPeriodButton_strikeline = uiradiobutton(app.LaunchPlanesLocatedinButtonGroup_strikeline);
            app.SingleHalfPeriodButton_strikeline.Text = 'Single Half-Period';
            app.SinglePeriodButton_strikeline = uiradiobutton(app.LaunchPlanesLocatedinButtonGroup_strikeline);
            app.SinglePeriodButton_strikeline.Text = 'Single Period';
            app.SinglePeriodButton_strikeline.Value = true;
            app.FullMachineButton_strikeline = uiradiobutton(app.LaunchPlanesLocatedinButtonGroup_strikeline);
            app.FullMachineButton_strikeline.Enable = 'off';
            app.FullMachineButton_strikeline.Text = 'Full Machine';
            app.ExportSTLTab = uitab(app.TabGroup);
            app.ExportSTLTab.Title = 'Export STL';
            app.ExportLoftedSurfaceasSTLFilesButton = uibutton(app.ExportSTLTab, 'push');
            app.ExportLoftedSurfaceasSTLFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportLoftedSurfaceasSTLFilesButtonPushed, true);
            app.ExportLoftedSurfaceasSTLFilesButton.Text = 'Export Lofted Surface as STL Files';
            app.STLPrefixEditFieldLabel = uilabel(app.ExportSTLTab);
            app.STLPrefixEditFieldLabel.Text = 'STL Prefix';
            app.STLPrefixEditField = uieditfield(app.ExportSTLTab, 'text');
            app.ExportNESCOILTab = uitab(app.TabGroup);
            app.ExportNESCOILTab.Title = 'Export NESCOIL';
            app.ExportNESCOILstylewindingsurfaceButton = uibutton(app.ExportNESCOILTab, 'push');
            app.ExportNESCOILstylewindingsurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNESCOILstylewindingsurfaceButtonPushed, true);
            app.ExportNESCOILstylewindingsurfaceButton.Text = 'Export NESCOIL-style winding surface';
            app.NESCOILFilenameEditFieldLabel = uilabel(app.ExportNESCOILTab);
            app.NESCOILFilenameEditFieldLabel.Text = 'NESCOIL Filename';
            app.NESCOILFilenameEditField = uieditfield(app.ExportNESCOILTab, 'text');
            app.NESCOILFilenameEditField.Value = 'nescin_loft.out';
            app.NESCOILMMAXEditFieldLabel = uilabel(app.ExportNESCOILTab);
            app.NESCOILMMAXEditFieldLabel.Text = 'NESCOIL M MAX';
            app.NESCOILMMAXEditField = uieditfield(app.ExportNESCOILTab, 'numeric');
            app.NESCOILMMAXEditField.Value = 24;
            app.NESCOILNMAXEditFieldLabel = uilabel(app.ExportNESCOILTab);
            app.NESCOILNMAXEditFieldLabel.Text = 'NESCOIL N MAX';
            app.NESCOILNMAXEditField = uieditfield(app.ExportNESCOILTab, 'numeric');
            app.NESCOILNMAXEditField.Value = 24;
            app.NESCOILToleranceEditFieldLabel = uilabel(app.ExportNESCOILTab);
            app.NESCOILToleranceEditFieldLabel.Text = 'NESCOIL Tolerance';
            app.NESCOILToleranceEditField = uieditfield(app.ExportNESCOILTab, 'numeric');
            app.NESCOILToleranceEditField.Value = 0.001;
            app.MMAXArrayLabel = uilabel(app.ExportNESCOILTab);
            app.MMAXArrayLabel.Text = {'M MAX'; '(Array)'};
            app.MMAXArrayEditField = uieditfield(app.ExportNESCOILTab, 'text');
            app.MMAXArrayEditField.Value = '8, 8, 12, 12, 16, 16, 24, 32, 32';
            app.NMAXArrayLabel = uilabel(app.ExportNESCOILTab);
            app.NMAXArrayLabel.Text = {'N MAX'; '(Array)'};
            app.NMAXArrayEditField = uieditfield(app.ExportNESCOILTab, 'text');
            app.NMAXArrayEditField.Value = '12, 16 12, 16, 16, 24, 24, 32, 40';
            app.NESCOILFilenameBaseEditFieldLabel = uilabel(app.ExportNESCOILTab);
            app.NESCOILFilenameBaseEditFieldLabel.Text = 'NESCOIL Filename Base';
            app.NESCOILFilenameBaseEditField = uieditfield(app.ExportNESCOILTab, 'text');
            app.NESCOILFilenameBaseEditField.Value = 'nescin_loft_';
            app.ToleranceArrayLabel = uilabel(app.ExportNESCOILTab);
            app.ToleranceArrayLabel.Text = {'NESCOIL Tolerance'; '(Array)'};
            app.NESCOILToleranceArrayEditField = uieditfield(app.ExportNESCOILTab, 'text');
            app.NESCOILToleranceArrayEditField.Value = '1e-4, 1e-4, 1e-4, 1e-4, 1e-4, 1e-5, 1e-5, 1e-5, 1e-6';
            app.ExportManyNESCOILstylewindingsurfacesButton = uibutton(app.ExportNESCOILTab, 'push');
            app.ExportManyNESCOILstylewindingsurfacesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportManyNESCOILstylewindingsurfacesButtonPushed, true);
            app.ExportManyNESCOILstylewindingsurfacesButton.Text = {'Export Many'; 'NESCOIL-style'; 'winding surfaces'};
            app.ExportMatlabTab = uitab(app.TabGroup);
            app.ExportMatlabTab.Title = 'Export Matlab';
            app.ExportFigureWindowinMatlabFormatButton = uibutton(app.ExportMatlabTab, 'push');
            app.ExportFigureWindowinMatlabFormatButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFigureWindowinMatlabFormatButtonPushed, true);
            app.ExportFigureWindowinMatlabFormatButton.Text = 'Export Figure Window in Matlab Format';
            app.ExportInformativePlotsButton = uibutton(app.ExportMatlabTab, 'push');
            app.ExportInformativePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportInformativePlotsButtonPushed, true);
            app.ExportInformativePlotsButton.Text = 'Export Informative Plots';
            % Show the figure after all components are created
            app.Stelloftv00aUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Stelloft_v0_20221222a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Stelloftv00aUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Stelloftv00aUIFigure)
        end
    end
end